classdef SphereImageAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SphereImageProcessingUIFigure   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        AutomaticProcessingPanel        matlab.ui.container.Panel
        AggregateSelectedVesselCheckBox  matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        BATCHSTATUSLabel                matlab.ui.control.Label
        BATCHButton                     matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        AggregateRepeatedImageFilesNASCheckBox  matlab.ui.control.CheckBox
        VesselIDDropDown                matlab.ui.control.DropDown
        VesselIDDropDownLabel           matlab.ui.control.Label
        ReadImagesfromSingleVesselCheckBox  matlab.ui.control.CheckBox
        ADSImagesCheckBox               matlab.ui.control.CheckBox
        ImageDateEditField              matlab.ui.control.EditField
        ImageDateEditFieldLabel         matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        ProgressEditField               matlab.ui.control.EditField
        FolderEditField                 matlab.ui.control.EditField
        ProgressEditFieldLabel          matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
        ExportingDataEditField          matlab.ui.control.EditField
        ExportingDataEditFieldLabel     matlab.ui.control.Label
        ImageProcessingEditField        matlab.ui.control.EditField
        ImageProcessingEditFieldLabel   matlab.ui.control.Label
        FILENAMEEditField               matlab.ui.control.EditField
        FILENAMEEditFieldLabel          matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        SphereDataCheckBox              matlab.ui.control.CheckBox
        ImageProcessingFilesCheckBox    matlab.ui.control.CheckBox
        WritingSpecificationsLabel      matlab.ui.control.Label
        NumberofFilestobeReadEditField  matlab.ui.control.EditField
        NumberofFilestobeReadEditFieldLabel  matlab.ui.control.Label
        VisualizePanel                  matlab.ui.container.Panel
        SelectGroupButton               matlab.ui.control.Button
        ShowPassagesButton              matlab.ui.control.StateButton
        PolydispersityCheckBox          matlab.ui.control.CheckBox
        EccentricityCheckBox            matlab.ui.control.CheckBox
        SolidityCheckBox                matlab.ui.control.CheckBox
        CircularityCheckBox             matlab.ui.control.CheckBox
        IncludeLabel                    matlab.ui.control.Label
        PlotTitleEditField_4            matlab.ui.control.EditField
        PlotTitleEditField_4Label       matlab.ui.control.Label
        YLabelEditField_2               matlab.ui.control.EditField
        XLabelEditField_2Label_2        matlab.ui.control.Label
        XLabelEditField_2               matlab.ui.control.EditField
        XLabelEditFieldLabel_2          matlab.ui.control.Label
        PLOT3Label                      matlab.ui.control.Label
        YDataDropDown                   matlab.ui.control.DropDown
        YDataDropDownLabel              matlab.ui.control.Label
        XDataDropDown                   matlab.ui.control.DropDown
        XDataDropDownLabel              matlab.ui.control.Label
        PlotTitleEditField_3            matlab.ui.control.EditField
        PlotTitleEditField_3Label       matlab.ui.control.Label
        RefreshButton                   matlab.ui.control.Button
        PLOT2Label                      matlab.ui.control.Label
        PLOT1Label_2                    matlab.ui.control.Label
        YLabelEditField                 matlab.ui.control.EditField
        XLabelEditField_2Label          matlab.ui.control.Label
        XLabelEditField                 matlab.ui.control.EditField
        XLabelEditFieldLabel            matlab.ui.control.Label
        PlotTitleEditField_2            matlab.ui.control.EditField
        PlotTitleEditField_2Label       matlab.ui.control.Label
        FileEditField_2                 matlab.ui.control.EditField
        FileEditField_2Label            matlab.ui.control.Label
        SelectAggregateFileButton       matlab.ui.control.Button
        ManualProcessingPanel           matlab.ui.container.Panel
        FilterMaxCheckBox               matlab.ui.control.CheckBox
        STDFilterSpinner                matlab.ui.control.Spinner
        STDFilterSpinnerLabel           matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        HELPButton                      matlab.ui.control.Button
        ImagePropertyDropDown           matlab.ui.control.DropDown
        ImagePropertyLabel              matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        HighSpinner                     matlab.ui.control.Spinner
        HighSpinnerLabel                matlab.ui.control.Label
        CalibrateButton                 matlab.ui.control.Button
        SaveProtocolCheckBox            matlab.ui.control.CheckBox
        ProcessImageButton              matlab.ui.control.Button
        ApplyWatershedButton            matlab.ui.control.Button
        ImageExtendedMinimumSpinner     matlab.ui.control.Spinner
        ImageExtendedMinimumSpinnerLabel  matlab.ui.control.Label
        IterSpinner                     matlab.ui.control.Spinner
        IterSpinnerLabel                matlab.ui.control.Label
        MaskingWatershedandSizingLabel  matlab.ui.control.Label
        ViewOverlayButton               matlab.ui.control.Button
        SmoothingCheckBox               matlab.ui.control.CheckBox
        RemoveBorderObjectsCheckBox     matlab.ui.control.CheckBox
        BWAreaOpenCheckBox              matlab.ui.control.CheckBox
        BWAreaOpenSpinner               matlab.ui.control.Spinner
        AdditionalProcessingLabel       matlab.ui.control.Label
        GenerateBinaryImageButton       matlab.ui.control.Button
        LowSpinner                      matlab.ui.control.Spinner
        LowSpinnerLabel                 matlab.ui.control.Label
        DisplayHistogramButton          matlab.ui.control.Button
        ThresholdingLabel               matlab.ui.control.Label
        DisplayMontageButton            matlab.ui.control.Button
        imadjustCheckBox                matlab.ui.control.CheckBox
        GreyImageContrastEnhanceLabel   matlab.ui.control.Label
        ReadImageButton                 matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        SelectImagefromFileLabel        matlab.ui.control.Label
        VisualizeCheckBox               matlab.ui.control.CheckBox
        ManualCheckBox                  matlab.ui.control.CheckBox
        AutoCheckBox                    matlab.ui.control.CheckBox
        CenterPanel                     matlab.ui.container.Panel
        NEWDATALabel                    matlab.ui.control.Label
        OLDDATALabel                    matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        EXITButton                      matlab.ui.control.Button
        AverageEccentricityEditField    matlab.ui.control.EditField
        AverageEccentricityEditFieldLabel  matlab.ui.control.Label
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        ProgressEditField_3             matlab.ui.control.EditField
        ofLabel                         matlab.ui.control.Label
        ProgressEditField_2             matlab.ui.control.EditField
        ProgressEditField_2Label        matlab.ui.control.Label
        FlaggedFileEditField            matlab.ui.control.EditField
        FlaggedFileEditFieldLabel       matlab.ui.control.Label
        ReprocessflaggedfilesButton     matlab.ui.control.Button
        UpdateLabel                     matlab.ui.control.Label
        FlaggedFilesLabel               matlab.ui.control.Label
        AverageSolidityEditField        matlab.ui.control.EditField
        AverageSolidityEditFieldLabel   matlab.ui.control.Label
        ExportSphereDataButton          matlab.ui.control.Button
        AverageDoCEditField             matlab.ui.control.EditField
        AverageDoCEditFieldLabel        matlab.ui.control.Label
        PlotTitleEditField              matlab.ui.control.EditField
        PlotTitleEditFieldLabel         matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.EditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        muEditField                     matlab.ui.control.EditField
        muEditFieldLabel                matlab.ui.control.Label
        ViewCDFButton                   matlab.ui.control.Button
        ViewPDFButton                   matlab.ui.control.Button
        AnalysisLabel                   matlab.ui.control.Label
        PolydispersityIndexEditField    matlab.ui.control.EditField
        PolydispersityIndexEditFieldLabel  matlab.ui.control.Label
        umLabel_3                       matlab.ui.control.Label
        umLabel_2                       matlab.ui.control.Label
        umLabel                         matlab.ui.control.Label
        AverageDiameterEditField        matlab.ui.control.EditField
        AverageDiameterEditFieldLabel   matlab.ui.control.Label
        MinDiameterEditField            matlab.ui.control.EditField
        MinDiameterEditFieldLabel       matlab.ui.control.Label
        MaxDiameterEditField            matlab.ui.control.EditField
        MaxDiameterEditFieldLabel       matlab.ui.control.Label
        NumberofSpheresEditField        matlab.ui.control.EditField
        NumberofSpheresEditFieldLabel   matlab.ui.control.Label
        CaptureLabel                    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        VesselIDEditField               matlab.ui.control.EditField
        VesselIDEditFieldLabel          matlab.ui.control.Label
        FileEditField                   matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        ResultsLabel                    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        captionFontSize %font size for annotations and titles
        pwdGlobal %working directory for use with multiple users
        selectedFileGlobal %selected file in microscope image directory
        fullFileNameGlobal %full path of selected file
        originalImage %greyscale image and subsequent enhanced image
        sphere_imadjust %greyscale contrast enhance
        sphere_histeq %greyscale contrast enhance
        sphere_adapthisteq %greyscale contrast enhance
        binaryImage %binary image
        binaryImage_final %binary image
        binaryImage_bwAreaOpen %binary image
        binaryImage_noBorder %binary image
        binaryImage_smoothing %binary image
        binaryImage_preProcess %binary image
        tempImage %intermediate image
        D %distance transform of binary image
        Ld %watershed -->imshow(label2rgb(Ld))
        bw2 %placeholder binary image
        mask %image mask
        D2 %imimposemin distance transform on mask
        bw3 %placeholder binary image
        Ld2 %second watershed transform
        distancePerPixel %called from workspace via spatialCalibration function
        props %region properties of binary imgage
        numberOfSpheres %intial and final sphere count
        newProps %region properties of filtered sphere sizes
        sphereDiameter % array of sphere diameters, duh
        SphereStats %quick reference of image stats
        circularity %degreee of circularity of spheres, ranges from 0 to 1.
        solidity %degree of solidity of spheres
        eccentricity %degree of perpendicular radii ratio

        %Auto Variables
        selectionState %identifies selected folder options for organization
        directoryPath
        autoFileGroupGlobal
        selectedFolder
        imageProperty %nolight (0) vs light (1)
        toBeProcessed %folders fetched from NAS
        fileList
        individualVesselFolder %selected from dropdown
        individualVesselFolderIndex
        processedVesselFolderName
        individualToBeProcessed
        aggregateSphereStats %for aggregated data
        aggregateDiameters
        aggregateCircularities
        aggregateSolidities
        aggregateEccentricities
        overwriteIndex %structure of file components that will be used to rewrite excel file 
        flagFilter %marker to separate manual mode strategies
        flagFileList % table of flagged files to be re-processed
        flagFileListCount
        oldSphereStats
        newSphereStats
        xChoice
        xDataChoice
        yChoice
        yDataChoice
        vizTable
        ax1
        ax2
        ax3
        batchAx %for selecting batch control image
        operatorID %% for user tracking
        operatorInitials
        lockFileName %%reference for close function
        vesselCompare %structure holding every comparison vessel
        compareState
        keepLockFile %determine whether to lock app.
        vesselLetterTag

        driveID %get drive for adjusted file path
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.keepLockFile = 0; 
            app.pwdGlobal = pwd; 
            app.captionFontSize = 14;
            %Check if locked file is present (if yes, then another operator
            %has the application open. 
            app.lockFileName = [app.pwdGlobal '\lockFile.txt'];
            %^text file with operator ID of last person to use app
            if isfile(app.lockFileName)
                %identify user to has file open
                fid = fopen('lockFile.txt');
                tline = fgetl(fid);
                lineStore = strings(3,1); 
                count = 0;
                while ischar(tline)
                    count = count+1; 
                    disp(tline)
                    lineStore(count,1) = tline; 
                    tline = fgetl(fid);
                end
                fclose(fid);
                uiwait(msgbox(lineStore)); %modal default
                app.keepLockFile = 1;
                delete(app.SphereImageProcessingUIFigure);
            else
                  %%% close potentially opened excel files that are opened
                % user specific until other servers can be accessed
                try
                    %Check if an Excel server is running
                    ex = actxGetRunningServer('Excel.Application');
                catch ME
                    disp(ME.message)
                end
                if exist('ex','var')
                    %Get the names of all open Excel files
                    wbs = ex.Workbooks;
                    %List the entire path of all excel workbooks that are currently open
                    fileStore = strings(wbs.Count,1);
                    while wbs.Count > 0
                        fileStore = string(ex.ActiveWorkbook.FullName);
                        fileMessage = sprintf(['Opened Excel files detected. To ensure files are properly \n',...
                        'written, the following Excel file will be saved and closed: \n%s\n'],fileStore);
                        uiwait(warndlg(fileMessage));
                        invoke(ex.ActiveWorkbook,'Save');
                        invoke(ex.ActiveWorkbook,'Close');
                    end
                    Quit(ex);
                    delete(ex);
                    clear ex
                    closeExcel()
                end
    
                currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                userPrompt = {'Operator Initials:'};
                dialogTitle = 'Input Operator ID Before Starting:';
                numberOfLines = 1;
                def = {''};
                answer = inputdlg(userPrompt, dialogTitle, numberOfLines, def);
                if isempty(answer)
                    app.operatorInitials = "NULL";
                end
                app.operatorInitials = answer{1}; 
                nameAndDate = [app.operatorInitials currentDate];
                app.operatorID = strjoin(nameAndDate,' ');
    
                %write lockFile
                formatSpec = ['Last operator did not close the application.\n',...
                    'Operator ID: %s.\n', 'Please confirm the application and associated files are closed.'];
                fid = fopen(app.lockFileName,'w');
                fprintf(fid,formatSpec,app.operatorID);
                fclose(fid);

                %%find proper drive ID and confirm

                [app.driveID] = driveIDParse();
                if app.driveID == "NULL"
                    userPrompt = {'Mounted Drive Letter:'};
                    dialogTitle = 'Input Drive Letter for Microscope Images';
                    numberOfLines = 1;
                    def = {''};
                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines, def);
                    if isempty(answer)
                        app.driveID = 'Z'; %default
                    end
                    app.driveID = answer{1};
                else
                    msg = {'Confirm AB126 Cell Culture Images';...
                        ['NAS is mounted to drive ' app.driveID '.']};
                    banner = 'Mounted Drive ID';
                    selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                        'Options', {'Confirm','Assign Different Letter'});
                    switch selection
                        case 'Confirm'
                            %do nothing
                        case 'Assign Different Letter'
                            userPrompt = {'Mounted Drive Letter:'};
                            dialogTitle = 'Input Drive Letter (letter only) for Microscope Images';
                            numberOfLines = 1;
                            def = {''};
                            answer = inputdlg(userPrompt, dialogTitle, numberOfLines, def);
                            if isempty(answer)
                                app.driveID = 'Z'; %default
                            end
                            app.driveID = answer{1};
                    end
                end
            end
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            value = app.AutoCheckBox.Value;
            if value == 1
                app.AutomaticProcessingPanel.Visible = 'on';
                app.AutomaticProcessingPanel.Enable = 'on';
                app.ManualCheckBox.Enable = 'off';
                app.VisualizeCheckBox.Enable = 'off';
            else
                app.AutomaticProcessingPanel.Visible = 'off';
                app.AutomaticProcessingPanel.Enable = 'off';
                app.ManualCheckBox.Enable = 'on';
                app.VisualizeCheckBox.Enable = 'on';
            end
        end

        % Value changed function: ManualCheckBox
        function ManualCheckBoxValueChanged(app, event)
            value = app.ManualCheckBox.Value;
            filePath = [app.pwdGlobal '\Microscope Images\Training'];
            if value == 1
                app.ManualProcessingPanel.Visible = 'on';
                app.ManualProcessingPanel.Enable = 'on';
                app.AutoCheckBox.Enable = 'off';
                app.VisualizeCheckBox.Enable = 'off';

                app.DropDown.Enable = 'on';
                dirFileInfo = dir(fullfile(filePath,'*.tif')); %PD is tif, ADS is tif
                dirFileNames = {dirFileInfo.name}';
                dirFileNamesStrings = convertCharsToStrings(dirFileNames);
                for i = 1:length(dirFileNamesStrings)
                    stringParts = strsplit(dirFileNamesStrings(i),'.');
                    dirFileNamesStrings(i) = stringParts(1);
                end
                app.DropDown.Items = [{""}; dirFileNamesStrings];
                app.DropDown.ItemsData = [{""}; fullfile({dirFileInfo.folder}, {dirFileInfo.name})'];
                app.ReprocessflaggedfilesButton.Enable = 'on';
                app.flagFilter = 0; 
            else
                app.ManualProcessingPanel.Visible = 'off';
                app.ManualProcessingPanel.Enable = 'off';
                app.AutoCheckBox.Enable = 'on';
                app.VisualizeCheckBox.Enable = 'on';
                app.DropDown.Enable = 'off';
                app.DropDown.Items = {};
                app.ReprocessflaggedfilesButton.Enable = 'off';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.fullFileNameGlobal = value; 
            valueSplit = strsplit(value,'\');
            app.selectedFileGlobal = string(valueSplit{end});
            fprintf('Full File Name = "%s".\n', app.fullFileNameGlobal);
               
            app.FileEditFieldLabel.Visible = 'on';
            app.FileEditField.Visible = 'on';
            app.VesselIDEditFieldLabel.Visible = 'on';
            app.VesselIDEditField.Visible = 'on';
            app.EditField.Visible = 'on';
            app.Label.Visible = 'on';
            
            app.FileEditField.Value = app.selectedFileGlobal;
            newSplit = strsplit(app.selectedFileGlobal, ' ');
            app.VesselIDEditField.Value = newSplit{1};
            %app.EditField.Value = newSplit{3}; %need to adjust this for consistent referencing
            app.ReadImageButton.Enable = 'on';
        end

        % Button pushed function: ReadImageButton
        function ReadImageButtonPushed(app, event)
            if app.flagFilter == 1
                app.ReadImageButton.Enable = 'off';
                app.flagFileListCount = app.flagFileListCount + 1;  
                app.ProgressEditField_2.Value = string(app.flagFileListCount);
                app.fullFileNameGlobal = app.flagFileList.FilePath{app.flagFileListCount};

                %%% Adjust filepath to correct mounted drive
                app.fullFileNameGlobal(1) = app.driveID; 
                %%%

                app.FlaggedFileEditField.Value = string(app.flagFileList.Name{app.flagFileListCount});
                app.originalImage = imread(app.fullFileNameGlobal);
                app.UIAxes.Visible = 'on';
                cla(app.UIAxes, 'reset');
                imshow(app.originalImage,'Parent',app.UIAxes);
                % Display the greyscale image.
                pause(1)
                app.originalImage = rgb2gray(app.originalImage);
                imshow(app.originalImage, 'Parent',app.UIAxes);
                pause(1)


                app.ImagePropertyDropDown.Enable = 'on';
                imagePropertyName = ["";"NO LIGHT"; "LIGHT"; "NO LIGHT-UNSATURATED"; "LIGHT-NONHOMOGENEOUS"];
                app.ImagePropertyDropDown.Items = imagePropertyName;
                app.ImagePropertyDropDown.ItemsData = imagePropertyName;
                
                %old sphere stats
                app.oldSphereStats = table2struct(app.flagFileList(app.flagFileListCount,:),"ToScalar",true);
                %assignin('base',"oldSphereStats",app.oldSphereStats);
                %copy new sphere stats until replaced in processing
                app.newSphereStats = app.oldSphereStats; 
                
                app.CommentsEditField.Value = string(app.oldSphereStats.Comments);
            else
                app.originalImage = imread(app.fullFileNameGlobal);
                app.UIAxes.Visible = 'on';
                cla(app.UIAxes, 'reset');
                imshow(app.originalImage,'Parent',app.UIAxes);
                % Check to make sure that it is grayscale, just in case the user substituted their own image.
                [rows, columns, numberOfColorChannels] = size(app.originalImage);
                if numberOfColorChannels > 1
	                promptMessage = sprintf('Your image file has %d color channels.\nThis app is designed for greyscale images.\nDo you want me to convert it to greyscale for you so you can continue?', numberOfColorChannels);
	                button = questdlg(promptMessage, 'Continue', 'Convert and Continue', 'Cancel', 'Convert and Continue');
	                if strcmp(button, 'Cancel')
		                fprintf(1, 'Finished running SphereImageAnalysis.m.\n');
		                return;
	                end
	                % Do the conversion using standard book formula
	                app.originalImage = rgb2gray(app.originalImage);
                end 
                % Display the greyscale image.
                imshow(app.originalImage, 'Parent',app.UIAxes);
    
                  %FIGURE SETTINGS  
    %             % Maximize the figure window.
    %             hFig1 = gcf;
    %             hFig1.Units = 'normalized';
    %             hFig1.WindowState = 'maximized'; % Go to full screen.
    %             hFig1.NumberTitle = 'off'; % Get rid of "Figure 1"
    %             hFig1.Name = 'Demo by Image Analyst'; % Put this into title bar.
    %             % Force it to display RIGHT NOW (otherwise it might not display until it's all done, unless you've stopped at a breakpoint.)
    %             drawnow;
    %             caption = sprintf('Sphere Image Converted to BLK and WHT for Thresholding.');
    %             title(caption, 'FontSize', app.captionFontSize);
    %             axis('on', 'image'); % Make sure image is not artificially stretched because of screen's aspect ratio.
                app.ImagePropertyDropDown.Enable = 'on';
                imagePropertyName = ["";"NO LIGHT"; "LIGHT"; "NO LIGHT-UNSATURATED"; "LIGHT-NONHOMOGENEOUS"];
                app.ImagePropertyDropDown.Items = imagePropertyName;
                app.ImagePropertyDropDown.ItemsData = imagePropertyName;
            end
        end

        % Button pushed function: DisplayMontageButton
        function DisplayMontageButtonPushed(app, event)
            app.sphere_imadjust = imadjust(app.originalImage); %This seems to be the best!
            app.sphere_histeq = histeq(app.originalImage);
            app.sphere_adapthisteq = adapthisteq(app.originalImage);
%             cla(app.UIAxes,'reset');
            montage({app.originalImage,app.sphere_imadjust,app.sphere_histeq,app.sphere_adapthisteq},"Size",[1 4]);
            title("Original Image and Enhanced Images using imadjust, histeq, and adapthisteq");
            app.imadjustCheckBox.Enable = 'on';
            app.imadjustCheckBox.Value = 1; 
            imadjustCheckBoxValueChanged(app,event)
        end

        % Value changed function: imadjustCheckBox
        function imadjustCheckBoxValueChanged(app, event)
            value = app.imadjustCheckBox.Value;
            app.originalImage = app.sphere_imadjust;
            if app.flagFilter == 1
                cla(app.UIAxes,'reset');
                imshow(app.originalImage, 'Parent',app.UIAxes);
                title(app.UIAxes,"Enhanced with 'imadjust' Image");
                app.CheckBox_3.Enable = 'on';
                app.DisplayHistogramButton.Enable = 'on';
                app.HELPButton.Enable = 'on';       
            else
                if value == 1
                    cla(app.UIAxes,'reset');
                    imshow(app.originalImage, 'Parent',app.UIAxes);
                    title(app.UIAxes,"Enhanced with 'imadjust' Image");
%                     if app.imageProperty == "LIGHT" || app.imageProperty == "LIGHT-NONHOMOGENEOUS" || app.imageProperty == "NO LIGHT-UNSATURATED"
%                         app.CheckBox_3.Enable = 'on';
%                         app.DisplayHistogramButton.Enable = 'off';
%                         app.HELPButton.Enable = 'off';
%                     else
                        app.CheckBox_3.Enable = 'on';
                        app.DisplayHistogramButton.Enable = 'on';
                        app.HELPButton.Enable = 'on';
%                     end
                else
                    app.DisplayHistogramButton.Enable = 'off';
                    app.HELPButton.Enable = 'off';
                end
            end
        end

        % Button pushed function: DisplayHistogramButton
        function DisplayHistogramButtonPushed(app, event)
            [pixelCount, grayLevels] = imhist(app.originalImage);
            cla(app.UIAxes,'reset');
            bar(pixelCount,'Parent',app.UIAxes);
            title(app.UIAxes,'Histogram of original image');
            xlim(app.UIAxes,[0 (grayLevels(end)+5)]); % Scale x axis manually.
            grid(app.UIAxes,'on');
            app.CheckBox.Enable = 'on';
            app.CheckBox_2.Enable = 'on';
        end

        % Value changed function: LowSpinner
        function LowSpinnerValueChanged(app, event)
            value = app.LowSpinner.Value;
            %app.binaryImage = (app.originalImage >= value); %& (enhancedOriginalImage <= highThreshold);
            % ========== IMPORTANT OPTION ============================================================
            % Use < if you want to find dark objects instead of bright objects.
            %   binaryImage = originalImage < thresholdValue; % Dark objects will be chosen if you use <.          
            % Show the threshold as a vertical red bar on the histogram.
            hold(app.UIAxes,'on');
            maxYValue = ylim(app.UIAxes);
            line(app.UIAxes,[value, value], maxYValue, 'Color', 'r');
            %line([highThreshold, highThreshold],maxYValue, 'Color','r');
            % Place a text label on the bar chart showing the threshold.
            annotationText1 = sprintf('Low Thresholded at %d gray levels', value);
            %annotationText2 = sprintf('High Thresholded at %d gray levels', highThreshold);
            % For text(), the x and y need to be of the data class "double" so let's cast both to double.
            text(double(value + 5), double(0.5 * maxYValue(2)), annotationText1, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes);
            hold(app.UIAxes,'off');
            app.GenerateBinaryImageButton.Enable = 'on';
        end

        % Button pushed function: GenerateBinaryImageButton
        function GenerateBinaryImageButtonPushed(app, event)
                % Do a "hole fill" to get rid of any background pixels or "holes" inside the blobs.
                if app.imageProperty == "NO LIGHT"
                    if app.CheckBox.Value == 1 && app.CheckBox_2.Value == 1
                        app.binaryImage = (app.originalImage >= app.LowSpinner.Value) & (app.originalImage <= app.HighSpinner.Value);
                        app.binaryImage = imfill(app.binaryImage, 'holes');
                        cla(app.UIAxes,'reset');
                        imshow(app.binaryImage, 'Parent', app.UIAxes);
                        app.BWAreaOpenCheckBox.Enable = 'on';
                        app.RemoveBorderObjectsCheckBox.Enable = 'on';
                        app.SmoothingCheckBox.Enable = 'on';
                        app.ViewOverlayButton.Enable = 'on';
                    elseif app.CheckBox.Value == 1 && app.CheckBox_2.Value == 0
                        app.binaryImage = (app.originalImage >= app.LowSpinner.Value);
                        app.binaryImage = imfill(app.binaryImage, 'holes');
                        cla(app.UIAxes,'reset');
                        imshow(app.binaryImage, 'Parent', app.UIAxes);
                        app.BWAreaOpenCheckBox.Enable = 'on';
                        app.RemoveBorderObjectsCheckBox.Enable = 'on';
                        app.SmoothingCheckBox.Enable = 'on';
                        app.ViewOverlayButton.Enable = 'on';
                    elseif app.CheckBox.Value == 0 && app.CheckBox_2.Value == 1
                        app.binaryImage = (app.originalImage <= app.HighSpinner.Value);
                        app.binaryImage = imfill(app.binaryImage, 'holes');
                        cla(app.UIAxes,'reset');
                        imshow(app.binaryImage, 'Parent', app.UIAxes);
                        app.BWAreaOpenCheckBox.Enable = 'on';
                        app.RemoveBorderObjectsCheckBox.Enable = 'on';
                        app.SmoothingCheckBox.Enable = 'on';
                        app.ViewOverlayButton.Enable = 'on';
                    end
                elseif app.imageProperty == "LIGHT" || app.imageProperty == "LIGHT-NONHOMOGENEOUS"
                    app.binaryImage = imfill(app.tempImage, 'holes');
                    cla(app.UIAxes,'reset');
                    imshow(app.binaryImage, 'Parent', app.UIAxes);
                    app.BWAreaOpenCheckBox.Enable = 'on';
                    app.RemoveBorderObjectsCheckBox.Enable = 'on';
                    app.SmoothingCheckBox.Enable = 'on';
                    app.ViewOverlayButton.Enable = 'on';
                else 
                    uiwait(msgbox('LIGHT-UNSATURATED SELECTED: Filter Max prior to processing'));
                    app.FilterMaxCheckBox.Enable = 'on';
                end
        end

        % Value changed function: BWAreaOpenCheckBox
        function BWAreaOpenCheckBoxValueChanged(app, event)
            value = app.BWAreaOpenCheckBox.Value;
            if value == 1
                app.BWAreaOpenSpinner.Enable = 'on';
            else
                app.BWAreaOpenSpinner.Enable = 'off';
                app.binaryImage_final = app.binaryImage; 
            end
            app.ViewOverlayButton.Enable = 'on';
        end

        % Value changed function: BWAreaOpenSpinner
        function BWAreaOpenSpinnerValueChanged(app, event)
            value = app.BWAreaOpenSpinner.Value;
            app.binaryImage_bwAreaOpen = bwareaopen(app.binaryImage,value);
            cla(app.UIAxes,'reset');
            imshow(app.binaryImage_bwAreaOpen,'Parent',app.UIAxes);
            title(app.UIAxes,'Objects Smaller than Set Value in Foreground are Filtered');
            app.binaryImage_final = app.binaryImage_bwAreaOpen;
        end

        % Value changed function: RemoveBorderObjectsCheckBox
        function RemoveBorderObjectsCheckBoxValueChanged(app, event)
            value = app.RemoveBorderObjectsCheckBox.Value;
            if value == 1 && app.BWAreaOpenCheckBox.Value == 1
                %Remove connected Objects on Border
                app.binaryImage_noBorder = imclearborder(app.binaryImage_bwAreaOpen,4);
                cla(app.UIAxes,'reset');
                imshow(app.binaryImage_noBorder,'Parent',app.UIAxes);
                title(app.UIAxes, 'Cleared Border Image');
                app.binaryImage_final = app.binaryImage_noBorder;
            elseif value == 1 && app.BWAreaOpenCheckBox == 0
                app.binaryImage_noBorder = imclearborder(app.binaryImage,4);
                cla(app.UIAxes,'reset');
                imshow(app.binaryImage_noBorder,'Parent',app.UIAxes);
                title(app.UIAxes, 'Cleared Border Image');
                app.binaryImage_final = app.binaryImage_noBorder;
            elseif value == 0 && app.BWAreaOpenCheckBox.Value == 1
                cla(app.UIAxes,'reset');
                imshow(app.binaryImage_bwAreaOpen, 'Parent',app.UIAxes);
                title(app.UIAxes,'Objects Smaller than Set Value in Foreground are Filtered');
                app.binaryImage_final = app.binaryImage_bwAreaOpen;
            elseif value == 0 && app.BWAreaOpenCheckBox.Value == 0
                cla(app.UIAxes,'reset');
                imshow(app.binaryImage,'Parent',app.UIAxes);
                title(app.UIAxes,'Generated Binary Image');
                app.binaryImage_final = app.binaryImage; 
            end
            app.ViewOverlayButton.Enable = 'on';
        end

        % Value changed function: SmoothingCheckBox
        function SmoothingCheckBoxValueChanged(app, event)
            value = app.SmoothingCheckBox.Value;
            if value == 1
                app.IterSpinner.Enable = 'on';
            elseif value == 0 && app.BWAreaOpenCheckBox.Value == 1 && app.RemoveBorderObjectsCheckBox == 1
                app.IterSpinner.Enable = 'off';
                app.binaryImage_final = app.binaryImage_noBorder;
                cla(app.UIAxes,'reset');
                imshow(app.binaryImage_final,'Parent',app.UIAxes);
                title(app.UIAxes,'Cleared Border Image');
            elseif value == 0 && app.BWAreaOpenCheckBox.Value == 1 && app.RemoveBorderObjectsCheckBox == 0
                app.IterSpinner.Enable = 'off';
                app.binaryImage_final = app.binaryImage_bwAreaOpen;
                cla(app.UIAxes,'reset');
                imshow(app.binaryImage_final,'Parent',app.UIAxes);
                title(app.UIAxes,'Objects Smaller than Set Value in Foreground are Filtered');
            elseif value == 0 && app.BWAreaOpenCheckBox.Value == 0 && app.RemoveBorderObjectsCheckBox == 0
                app.IterSpinner.Enable = 'off';
                app.binaryImage_final = app.binaryImage; 
                cla(app.UIAxes,'reset');
                imshow(app.binaryImage_final,'Parent',app.UIAxes);
                title(app.UIAxes,'Generated Binary Image');
            end
            app.ViewOverlayButton.Enable = 'on';
        end

        % Value changed function: IterSpinner
        function IterSpinnerValueChanged(app, event)
            value = app.IterSpinner.Value;
            seD = strel('diamond',1);
            iter = 1; 
            while iter <= value
                if app.SmoothingCheckBox.Value == 1
                    if app.RemoveBorderObjectsCheckBox.Value == 1 || app.BWAreaOpenCheckBox.Value == 1
                        app.binaryImage_smoothing = imerode(app.binaryImage_final,seD);
                    elseif app.RemoveBorderObjectsCheckBox.Value == 0 && app.BWAreaOpenCheckBox.Value == 0
                        app.binaryImage_smoothing = imerode(app.binaryImage,seD);
                    end
                end
                iter = iter + 1; 
            end
            cla(app.UIAxes,'reset');
            imshow(app.binaryImage_smoothing,'Parent',app.UIAxes);
            title(app.UIAxes,'Smoothed Image');
            app.binaryImage_preProcess = app.binaryImage_smoothing;
        end

        % Button pushed function: ViewOverlayButton
        function ViewOverlayButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            imshow(labeloverlay(app.originalImage, app.binaryImage_preProcess), 'Parent', app.UIAxes);
            title(app.UIAxes, 'Mask Over Original Image');
            app.ImageExtendedMinimumSpinner.Enable = 'on';
            app.ApplyWatershedButton.Enable = 'on';

            %Start watershed transform processing here:
            app.D = -bwdist(~app.binaryImage_preProcess);
            app.Ld = watershed(app.D);
            app.bw2 = app.binaryImage_preProcess;
            app.bw2(app.Ld == 0) = 0; 
        end

        % Value changed function: ImageExtendedMinimumSpinner
        function ImageExtendedMinimumSpinnerValueChanged(app, event)
            value = app.ImageExtendedMinimumSpinner.Value;
            app.mask = imextendedmin(app.D, value);
            imshowpair(app.binaryImage_preProcess, app.mask, 'blend','Parent',app.UIAxes);
        end

        % Button pushed function: ApplyWatershedButton
        function ApplyWatershedButtonPushed(app, event)
            app.D2 = imimposemin(app.D, app.mask);
            app.Ld2 = watershed(app.D2);
            app.bw3 = app.binaryImage_preProcess; 
            app.bw3(app.Ld2 == 0) = 0;
            imshow(app.bw3, 'Parent', app.UIAxes);
            app.CalibrateButton.Enable = 'on';
            app.ProcessImageButton.Enable = 'on';
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            spatialCalibration();
            app.distancePerPixel = evalin('base','distancePerPixel'); %distance per pixel in microns, SAVE this to excel folder for auto mode
        end

        % Button pushed function: ProcessImageButton
        function ProcessImageButtonPushed(app, event)
            %%% Assess image size
            imageSize = imfinfo(app.fullFileNameGlobal);
            imageWidth = imageSize.Width;
            imageHeight = imageSize.Height; 
            %%%
            %%%% ADD distance per pixel
            if evalin('base','exist("distancePerPixel","var")') 
            else
                if imageWidth == 2592
                    app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                elseif imageWidth == 1024
                    app.distancePerPixel = 2.7037; %scale bar of 4X image, small
                else
                    app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                end
            end

            if app.flagFilter == 1
                app.newSphereStats.ImageSize = string([num2str(imageWidth) ' x ' num2str(imageHeight)]);
    
                imshow(app.bw3, 'Parent', app.UIAxes);
                app.props = regionprops(app.bw3,app.originalImage, 'all');
                app.numberOfSpheres = numel(app.props);
                sprintf('%d spheres initially detected.\n', app.numberOfSpheres);
    
                % Print out the measurements to the command window, and display blob numbers on the image.
                textFontSize = 14;	% Used to control size of "blob number" labels put atop the image.
                % Print header line in the command window.
                fprintf(1,'Sphere #      Mean Intensity  Area   Perimeter    Centroid       Diameter\n');
                % Extract all the mean diameters into an array.
                % The "diameter" is the "Equivalent Circular Diameter", which is the diameter of a circle with the same number of pixels as the blob.
                % Enclosing in brackets is a nice trick to concatenate all the values from all the structure fields (every structure in the props structure array).
                blobECD = [app.props.EquivDiameter];
    
                    %prompt for thresholding value
                % Ask the user for the real-world distance.
                userPrompt = {'Minimum Diameter:', 'Maximum Diameter:'};
                dialogTitle = 'Choose Size Exclusion Filter:';
                numberOfLines = 1;
                %opts.Resize = 'on';
                def = {num2str(min(blobECD)),num2str(max(blobECD))};
                answer = inputdlg(userPrompt, dialogTitle, numberOfLines, def);
                if isempty(answer)
                    return;
                end
                minSize = str2double(answer{1});
                maxSize = str2double(answer{2});
                % Loop over all blobs printing their measurements to the command window.
                count = 0;
                kStore = [];
                for k = 1 : app.numberOfSpheres          % Loop through all blobs.
                    if blobECD(k) >= minSize && blobECD(k) <= maxSize %minimum calculated diameter to filter out tiny detected blobs
                        count = count + 1;
	                    % Find the individual measurements of each blob.  They are field of each structure in the props strucutre array.
	                    % You could use the bracket trick (like with blobECD above) OR you can get the value from the field of this particular structure.
	                    % I'm showing you both ways and you can use the way you like best.
	                    meanGL = app.props(k).MeanIntensity;		% Get average intensity.
	                    blobArea = app.props(k).Area;				% Get area.
	                    blobPerimeter = app.props(k).Perimeter;		% Get perimeter.
	                    blobCentroid = app.props(k).Centroid;		% Get centroid one at a time
                        blobDiameter(count) = blobECD(k);              %Specific diameter value
                        kStore = [kStore; k];       %for indexing props after
	                    % Now do the printing of this blob's measurements to the command window.
	                    fprintf(1,'#%2d %17.1f %11.1f %8.1f %8.1f %8.1f % 8.1f\n', k, meanGL, blobArea, blobPerimeter, blobCentroid, blobDiameter(count));
	                    % Put the "blob number" labels on the grayscale image that is showing the red boundaries on it.
	                    %text(blobCentroid(1), blobCentroid(2), num2str(count), 'FontSize', textFontSize, 'FontWeight', 'Bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                    end
                end
    
                %Convert applicable fields to microns using calibration value 
                %Get circularity and other characteristics
                app.newProps = app.props(kStore);
                app.sphereDiameter = zeros(numel(app.newProps),1);
                app.circularity = zeros(numel(app.newProps),1);
                app.solidity = zeros(numel(app.newProps),1);
                app.eccentricity = zeros(numel(app.newProps),1);
                for i = 1:numel(app.newProps)
                    app.newProps(i).EquivDiameter = app.newProps(i).EquivDiameter.*app.distancePerPixel;
                    app.sphereDiameter(i) = app.newProps(i).EquivDiameter; 
                    app.circularity(i) = app.newProps(i).Circularity;
                    app.solidity(i) = app.newProps(i).Solidity; 
                    app.eccentricity(i) = app.newProps(i).Eccentricity;
                end
                app.numberOfSpheres = numel(app.newProps);
                sprintf('%d spheres ultimately detected.\n', app.numberOfSpheres);
                app.newSphereStats.count = app.numberOfSpheres;
                app.newSphereStats.max = max(app.sphereDiameter); % converted
                app.newSphereStats.min = min(app.sphereDiameter); % converted
                app.newSphereStats.mean = mean(app.sphereDiameter); % converted
                app.newSphereStats.median = median(app.sphereDiameter); %converted
                app.newSphereStats.std = std(app.sphereDiameter); %converted
                %find mode bin size
                [N, edges] = histcounts(app.sphereDiameter,30);
                maxIndex = find(N==max(N));
                modeBin = [];
                if numel(maxIndex) > 1
                    edgeStore = zeros(numel(maxIndex)*2, 1);
                    for j = 1:numel(maxIndex)
                        edgeStore(j) = edges(maxIndex(j));
                        edgeStore(j+1) = edges(maxIndex(j)+1);
                        if j == 1                         
                            modeBin = [modeBin [num2str(edgeStore(j)) '-' num2str(edgeStore(j+1))]];
                        else
                            modeBin = [modeBin ', ' [num2str(edgeStore(j)) '-' num2str(edgeStore(j+1))]];
                        end
                    end
                    app.newSphereStats.modeBin = cellstr(modeBin);
                else
                    edge1 = edges(maxIndex);
                    edge2 = edges(maxIndex+1);
                    app.newSphereStats.modeBin = cellstr([num2str(edge1) '-' num2str(edge2)]);
                end
    
    
                app.CaptureLabel.Visible = 'on';
                app.NumberofSpheresEditField.Visible = 'on';
                app.NumberofSpheresEditFieldLabel.Visible = 'on';
                app.MaxDiameterEditField.Visible = 'on';
                app.MaxDiameterEditFieldLabel.Visible = 'on';
                app.MinDiameterEditField.Visible = 'on';
                app.MinDiameterEditFieldLabel.Visible = 'on';
                app.AverageDiameterEditField.Visible = 'on';
                app.AverageDiameterEditFieldLabel.Visible = 'on';
                app.AnalysisLabel.Visible = 'on';
                app.PlotTitleEditField.Visible = 'on';
                app.PlotTitleEditFieldLabel.Visible = 'on';
                if numel(app.sphereDiameter) > 1
                    app.ViewPDFButton.Visible = 'on';
                    app.ViewCDFButton.Visible = 'on';
                    ViewPDFButtonPushed(app, event);
                else
                    app.ViewPDFButton.Visible = 'on';
                    app.ViewCDFButton.Visible = 'on';
                    app.ViewPDFButton.Enable = 'off';
                    app.ViewCDFButton.Enable = 'off';
                    app.muEditField.Value = string(mean(app.sphereDiameter));
                    app.sigmaEditField.Value = string(std(app.sphereDiameter));
                    polyD = (std(app.sphereDiameter)/mean(app.sphereDiameter))^2; 
                    app.PolydispersityIndexEditField.Value = string(polyD);
                    app.newSphereStats.PolyDispersity = polyD; 
                end
                app.PolydispersityIndexEditField.Visible = 'on';
                app.PolydispersityIndexEditFieldLabel.Visible = 'on';
                app.muEditField.Visible = 'on';
                app.muEditFieldLabel.Visible = 'on';
                app.sigmaEditField.Visible = 'on';
                app.sigmaEditFieldLabel.Visible = 'on';
                app.AverageDoCEditField.Visible = 'on';
                app.AverageDoCEditFieldLabel.Visible = 'on';
                app.AverageSolidityEditField.Visible = 'on';
                app.AverageSolidityEditFieldLabel.Visible = 'on';
                app.AverageEccentricityEditField.Visible = 'on';
                app.AverageEccentricityEditFieldLabel.Visible = 'on';
                app.ExportSphereDataButton.Visible = 'on';
    
                %Populate Results Section
                app.NumberofSpheresEditField.Value = string(app.numberOfSpheres); 
                app.MaxDiameterEditField.Value = string(app.newSphereStats.max);
                app.MinDiameterEditField.Value = string(app.newSphereStats.min);
                app.AverageDiameterEditField.Value = string(app.newSphereStats.mean);
    
                app.newSphereStats.AverageCircularity = mean(app.circularity);
                app.newSphereStats.AverageSolidity = mean(app.solidity);
                app.newSphereStats.AverageEccentricity = mean(app.eccentricity);
                app.AverageDoCEditField.Value = num2str(app.newSphereStats.AverageCircularity);
                app.AverageSolidityEditField.Value = num2str(app.newSphereStats.AverageSolidity);
                app.AverageEccentricityEditField.Value = num2str(app.newSphereStats.AverageEccentricity);   

                app.newSphereStats.FLAG = "PENDING";
                app.newSphereStats.Comments = "PENDING";

                currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                nameAndDate = [app.operatorInitials currentDate];
                app.operatorID = strjoin(nameAndDate,' ');

                app.newSphereStats.Operator = string(app.operatorID);
                app.newSphereStats.FilePath = string(app.fullFileNameGlobal);

                uiwait(msgbox('Processing complete! Proceed to Export Sphere Data.'))
                app.ExportSphereDataButton.Visible = 'on';
                app.ExportSphereDataButton.Enable = 'on';

                ExportSphereDataButtonPushed(app,event)
            else
                app.SphereStats.ImageSize = [num2str(imageWidth) ' x ' num2str(imageHeight)];
    
                imshow(app.bw3, 'Parent', app.UIAxes);
                app.props = regionprops(app.bw3,app.originalImage, 'all');
                app.numberOfSpheres = numel(app.props);
                sprintf('%d spheres initially detected.\n', app.numberOfSpheres);
    
                % Print out the measurements to the command window, and display blob numbers on the image.
                textFontSize = 14;	% Used to control size of "blob number" labels put atop the image.
                % Print header line in the command window.
                fprintf(1,'Sphere #      Mean Intensity  Area   Perimeter    Centroid       Diameter\n');
                % Extract all the mean diameters into an array.
                % The "diameter" is the "Equivalent Circular Diameter", which is the diameter of a circle with the same number of pixels as the blob.
                % Enclosing in brackets is a nice trick to concatenate all the values from all the structure fields (every structure in the props structure array).
                blobECD = [app.props.EquivDiameter];
    
                    %prompt for thresholding value
                % Ask the user for the real-world distance.
                userPrompt = {'Minimum Diameter:', 'Maximum Diameter:'};
                dialogTitle = 'Choose Size Exclusion Filter:';
                numberOfLines = 1;
                %opts.Resize = 'on';
                def = {num2str(min(blobECD)),num2str(max(blobECD))};
                answer = inputdlg(userPrompt, dialogTitle, numberOfLines, def);
                if isempty(answer)
                    return;
                end
                minSize = str2double(answer{1});
                maxSize = str2double(answer{2});
                % Loop over all blobs printing their measurements to the command window.
                count = 0;
                kStore = [];
                for k = 1 : app.numberOfSpheres          % Loop through all blobs.
                    if blobECD(k) >= minSize && blobECD(k) <= maxSize %minimum calculated diameter to filter out tiny detected blobs
                        count = count + 1;
	                    % Find the individual measurements of each blob.  They are field of each structure in the props strucutre array.
	                    % You could use the bracket trick (like with blobECD above) OR you can get the value from the field of this particular structure.
	                    % I'm showing you both ways and you can use the way you like best.
	                    meanGL = app.props(k).MeanIntensity;		% Get average intensity.
	                    blobArea = app.props(k).Area;				% Get area.
	                    blobPerimeter = app.props(k).Perimeter;		% Get perimeter.
	                    blobCentroid = app.props(k).Centroid;		% Get centroid one at a time
                        blobDiameter(count) = blobECD(k);              %Specific diameter value
                        kStore = [kStore; k];       %for indexing props after
	                    % Now do the printing of this blob's measurements to the command window.
	                    fprintf(1,'#%2d %17.1f %11.1f %8.1f %8.1f %8.1f % 8.1f\n', k, meanGL, blobArea, blobPerimeter, blobCentroid, blobDiameter(count));
	                    % Put the "blob number" labels on the grayscale image that is showing the red boundaries on it.
	                    %text(blobCentroid(1), blobCentroid(2), num2str(count), 'FontSize', textFontSize, 'FontWeight', 'Bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                    end
                end
    
                %Convert applicable fields to microns using calibration value 
                %Get circularity and other characteristics
                app.newProps = app.props(kStore);
                app.sphereDiameter = zeros(numel(app.newProps),1);
                app.circularity = zeros(numel(app.newProps),1);
                app.solidity = zeros(numel(app.newProps),1);
                app.eccentricity = zeros(numel(app.newProps),1);
                for i = 1:numel(app.newProps)
                    app.newProps(i).EquivDiameter = app.newProps(i).EquivDiameter.*app.distancePerPixel;
                    app.sphereDiameter(i) = app.newProps(i).EquivDiameter; 
                    app.circularity(i) = app.newProps(i).Circularity;
                    app.solidity(i) = app.newProps(i).Solidity; 
                    app.eccentricity(i) = app.newProps(i).Eccentricity;
                end
                app.numberOfSpheres = numel(app.newProps);
                sprintf('%d spheres ultimately detected.\n', app.numberOfSpheres);
                app.SphereStats.count = app.numberOfSpheres;
                app.SphereStats.max = max(app.sphereDiameter); % converted
                app.SphereStats.min = min(app.sphereDiameter); % converted
                app.SphereStats.mean = mean(app.sphereDiameter); % converted
                app.SphereStats.median = median(app.sphereDiameter); %converted
                app.SphereStats.std = std(app.sphereDiameter); %converted
                %find mode bin size
                [N, edges] = histcounts(app.sphereDiameter,30);
                maxIndex = find(N==max(N));
                modeBin = [];
                if numel(maxIndex) > 1
                    edgeStore = zeros(numel(maxIndex)*2, 1);
                    for j = 1:numel(maxIndex)
                        edgeStore(j) = edges(maxIndex(j));
                        edgeStore(j+1) = edges(maxIndex(j)+1);
                        if j == 1                         
                            modeBin = [modeBin [num2str(edgeStore(j)) '-' num2str(edgeStore(j+1))]];
                        else
                            modeBin = [modeBin ', ' [num2str(edgeStore(j)) '-' num2str(edgeStore(j+1))]];
                        end
                    end
                    app.SphereStats.modeBin = modeBin;
                else
                    edge1 = edges(maxIndex);
                    edge2 = edges(maxIndex+1);
                    app.SphereStats.modeBin = [num2str(edge1) '-' num2str(edge2)];
                end
    
    
                app.CaptureLabel.Visible = 'on';
                app.NumberofSpheresEditField.Visible = 'on';
                app.NumberofSpheresEditFieldLabel.Visible = 'on';
                app.MaxDiameterEditField.Visible = 'on';
                app.MaxDiameterEditFieldLabel.Visible = 'on';
                app.MinDiameterEditField.Visible = 'on';
                app.MinDiameterEditFieldLabel.Visible = 'on';
                app.AverageDiameterEditField.Visible = 'on';
                app.AverageDiameterEditFieldLabel.Visible = 'on';
                app.AnalysisLabel.Visible = 'on';
                app.PlotTitleEditField.Visible = 'on';
                app.PlotTitleEditFieldLabel.Visible = 'on';
                if numel(app.sphereDiameter) > 1
                    app.ViewPDFButton.Visible = 'on';
                    app.ViewCDFButton.Visible = 'on';
                else
                    app.ViewPDFButton.Visible = 'on';
                    app.ViewCDFButton.Visible = 'on';
                    app.ViewPDFButton.Enable = 'off';
                    app.ViewCDFButton.Enable = 'off';
                    app.muEditField.Value = string(mean(app.sphereDiameter));
                    app.sigmaEditField.Value = string(std(app.sphereDiameter));
                    polyD = (std(app.sphereDiameter)/mean(app.sphereDiameter))^2; 
                    app.PolydispersityIndexEditField.Value = string(polyD);
                    app.SphereStats.PolyDispersity = polyD; 
                end
                app.PolydispersityIndexEditField.Visible = 'on';
                app.PolydispersityIndexEditFieldLabel.Visible = 'on';
                app.muEditField.Visible = 'on';
                app.muEditFieldLabel.Visible = 'on';
                app.sigmaEditField.Visible = 'on';
                app.sigmaEditFieldLabel.Visible = 'on';
                app.AverageDoCEditField.Visible = 'on';
                app.AverageDoCEditFieldLabel.Visible = 'on';
                app.AverageSolidityEditField.Visible = 'on';
                app.AverageSolidityEditFieldLabel.Visible = 'on';
                app.AverageEccentricityEditField.Visible = 'on';
                app.AverageEccentricityEditFieldLabel.Visible = 'on';
                app.ExportSphereDataButton.Visible = 'on';
    
                %Populate Results Section
                app.NumberofSpheresEditField.Value = string(app.numberOfSpheres); 
                app.MaxDiameterEditField.Value = string(app.SphereStats.max);
                app.MinDiameterEditField.Value = string(app.SphereStats.min);
                app.AverageDiameterEditField.Value = string(app.SphereStats.mean);
    
                app.SphereStats.AverageCircularity = mean(app.circularity);
                app.SphereStats.AverageSolidity = mean(app.solidity);
                app.SphereStats.AverageEccentricity = mean(app.eccentricity);
                app.AverageDoCEditField.Value = num2str(app.SphereStats.AverageCircularity);
                app.AverageSolidityEditField.Value = num2str(app.SphereStats.AverageSolidity);
                app.AverageEccentricityEditField.Value = num2str(app.SphereStats.AverageEccentricity);

                app.EXITButton.Enable = 'on';

            end

        end

        % Button pushed function: ViewPDFButton
        function ViewPDFButtonPushed(app, event)
                %create PDF from spherediameter
                app.sphereDiameter = app.sphereDiameter(:);
                cla(app.UIAxes,'reset');
                hold(app.UIAxes,'on');
                LegHandles = [];
                LegText = {};
    
                % --- Plot data originally in dataset "sphereDiameter data"
    
                [CdfF,CdfX] = ecdf(app.sphereDiameter,'Function','cdf');  % compute empirical cdf
                BinInfo.rule = 3;
                BinInfo.nbins = 30;
                [~,BinEdge] = internal.stats.histbins(app.sphereDiameter,[],[],BinInfo,CdfF,CdfX);
                [BinHeight,BinCenter] = ecdfhist(CdfF,CdfX,'edges',BinEdge);
                hLine = bar(BinCenter,BinHeight,'hist','Parent',app.UIAxes);
                set(hLine,'FaceColor','none','EdgeColor',[0.333333 0 0.666667],...
                    'LineStyle','-', 'LineWidth',1);
                xlabel(app.UIAxes,'Sphere Diameter (um)');
                ylabel(app.UIAxes,'Density');
                LegHandles(end+1) = hLine;
                LegText{end+1} = 'sphereDiameter data';
    
                % Create grid where function will be computed
    
                XLim = get(app.UIAxes,'XLim'); %previously gca (get current axis)
                XLim = XLim + [-1 1] * 0.01 * diff(XLim);
                XGrid = linspace(XLim(1),XLim(2),100);
    
                % --- Create fit "normal fit"
                pd1 = fitdist(app.sphereDiameter, 'normal');
                YPlot = pdf(pd1,XGrid);
                hLine = plot(XGrid,YPlot,'Color',[1 0 0],...
                    'LineStyle','-', 'LineWidth',2,...
                    'Marker','none', 'MarkerSize',6,'Parent',app.UIAxes);
                LegHandles(end+1) = hLine;
                LegText{end+1} = 'normal fit';
    
                % Adjust figure
    
                box(app.UIAxes,'on');
                hold(app.UIAxes,'off');
    
                % Create legend from accumulated handles and labels
    
                hLegend = legend(app.UIAxes,LegHandles,LegText,'Orientation', 'vertical', 'FontSize', 9, 'Location', 'northeast');
                set(hLegend,'Interpreter','none');
                
                %Calculate polydispersity
                app.muEditField.Value = string(pd1.mu);
                app.sigmaEditField.Value = string(pd1.sigma);
                polyD = (pd1.sigma/pd1.mu)^2;
                app.PolydispersityIndexEditField.Value = string(polyD);
                app.newSphereStats.PolyDispersity = polyD; 
    %             app.SphereStats.Sigma = pd1.sigma;
              
        end

        % Button pushed function: ViewCDFButton
        function ViewCDFButtonPushed(app, event)
            %create CDF from spherediameter
            app.sphereDiameter = app.sphereDiameter(:);
            cla(app.UIAxes,'reset');
            hold(app.UIAxes,'on');
            LegHandles = [];
            LegText = {};

            % --- Plot data originally in dataset "sphereDiameter data"

            [CdfY,CdfX] = ecdf(app.sphereDiameter,'Function','cdf');  % compute empirical function
            hLine = stairs(CdfX,CdfY,'Color',[0.333333 0 0.666667],'LineStyle','-', 'LineWidth',1,'Parent',app.UIAxes);
            xlabel(app.UIAxes,'Data');
            ylabel(app.UIAxes,'Cumulative probability')
            LegHandles(end+1) = hLine;
            LegText{end+1} = 'sphereDiameter data';
            
            % Create grid where function will be computed

            XLim = get(app.UIAxes,'XLim'); %previously gca (get current axis)
            XLim = XLim + [-1 1] * 0.01 * diff(XLim);
            XGrid = linspace(XLim(1),XLim(2),100);

            % --- Create fit "fit 1"

            % Fit this distribution to get parameter values
            % To use parameter estimates from the original fit:
            %     pd1 = ProbDistUnivParam('normal',[ 134.7571330391, 57.9002558198])
            pd2 = fitdist(app.sphereDiameter, 'normal');
            YPlot = cdf(pd2,XGrid);
            hLine = plot(XGrid,YPlot,'Color',[1 0 0],...
                'LineStyle','-', 'LineWidth',2,...
                'Marker','none', 'MarkerSize',6, 'Parent',app.UIAxes);
            LegHandles(end+1) = hLine;
            LegText{end+1} = 'normal fit';

            % Adjust figure

            box(app.UIAxes,'on');
            hold(app.UIAxes,'off');

            % Create legend from accumulated handles and labels

            hLegend = legend(app.UIAxes,LegHandles,LegText,'Orientation', 'vertical', 'FontSize', 9, 'Location', 'northwest');
            set(hLegend,'Interpreter','none');
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % AUTO MODE
            %%% Store input data for reference in reprocessing %%%
            autoStore = [];
            %%%retrieve all individual data files that exist and store their lengths%%%
            app.BATCHButton.Enable = 'off';

            initialSearch = dir(fullfile('**\Individual Image Data\*.xlsx'));
            initialSearch = initialSearch(~startsWith({initialSearch.name},'~'));
            [initialDetectStore] = individualImageDataSearch(initialSearch,0);
            %assignin('base','initialDetectStore',initialDetectStore);
            
            vesselLetterStore = struct; %two rows for letter and counts
            %aggregateIDStore = struct; %for selection state 1
            for i = 1:numel(app.autoFileGroupGlobal)
                if app.selectionState == 1
                    app.ImageProcessingEditField.Value = "WAITING";
                    app.ExportingDataEditField.Value = "WAITING";
    
                    %get full file name
                    app.ProgressEditField.Value = [num2str(i) ' of ' num2str(numel(app.autoFileGroupGlobal))];
                    %baseFileName = app.autoFileGroupGlobal{i};
                    if app.ReadImagesfromSingleVesselCheckBox.Value == 1
                        app.FILENAMEEditField.Value = app.individualToBeProcessed.AdjustedFileName(i);
                        app.SphereStats.Name = app.individualToBeProcessed.AdjustedFileName(i);
                        app.ImageDateEditField.Value = app.individualToBeProcessed.FileDate(i);
                        app.fullFileNameGlobal = app.individualToBeProcessed.FilePath(i);
                        %get vessel ID
                        vesselFileName = app.individualToBeProcessed.AdjustedFileName(i);
                        vesselString = strsplit(vesselFileName, ' ');
                        vesselString = vesselString{1};
                        app.SphereStats.VesselID = vesselString; 

%                         aggregateIDStore.ID(i) = string(vesselString); 
%                         aggregateIDStore.Date(i) = app.individualToBeProcessed.FileDate(i); 
                    else %sort through file list in order to reference toBeProcessed properly
                        %get the letter
                        vesselFileName = app.fileList(i).name;
                        vesselString = strsplit(vesselFileName, ' ');
                        vesselString = vesselString{1};
                        vesselLetter = regexp(vesselString,'([A-Z]+)','match');
                        vesselLetter = vesselLetter{1};
                    
                        %count letters
                        if isfield(vesselLetterStore, vesselLetter)
                            vesselLetterStore.(vesselLetter) = vesselLetterStore.(vesselLetter) + 1;
                        else 
                            vesselLetterStore.(vesselLetter) = 1;
                        end
                        
                        indexCount = vesselLetterStore.(vesselLetter);
                        %get the folder
                        folderString = strrep(app.FolderEditField.Value,'-','_'); %should still be character array
                        folderString = convertStringsToChars(folderString);
                        fieldNameString = [folderString '_' vesselLetter];
                        app.FILENAMEEditField.Value = app.toBeProcessed.(fieldNameString).AdjustedFileName(indexCount); 
                        app.SphereStats.Name = app.toBeProcessed.(fieldNameString).AdjustedFileName(indexCount);
                        app.SphereStats.VesselID = vesselString; 
                        app.ImageDateEditField.Value = app.toBeProcessed.(fieldNameString).FileDate(indexCount); 
                        app.fullFileNameGlobal = app.toBeProcessed.(fieldNameString).FilePath(indexCount); 

%                         aggregateIDStore.ID(i) = vesselString; 
%                         aggregateIDStore.Date(i) = app.ImageDateEditField.Value;
                    end                 
                    %read and create greyscale image

                    %%% Adjust filepath to correct mounted drive
                    app.originalImage = imread(app.fullFileNameGlobal);

                    %%% Assess image size
                    imageSize = imfinfo(app.fullFileNameGlobal);
                    imageWidth = imageSize.Width;
                    imageHeight = imageSize.Height; 
                    %%%

                    cla(app.UIAxes, 'reset');
                    imshow(app.originalImage, 'Parent',app.UIAxes);
                    %determine if input image is NO LIGHT or NORMAL 
    
                    msg = 'Select Image Property for Modified Processing';
                    banner = 'Image Property';
                    selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'NO LIGHT','LIGHT','NO LIGHT-UNSATURATED', 'LIGHT-NONHOMOGENEOUS'});                   
                    app.imageProperty = convertCharsToStrings(selection);

                    if app.imageProperty == "NO LIGHT"
                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        %begin parsed processing
                        app.originalImage = rgb2gray(app.originalImage);
                        %app.UIAxes.Visible = 'on';
                        cla(app.UIAxes, 'reset');
        
                        % Display the greyscale image.
                        imshow(app.originalImage, 'Parent',app.UIAxes);
        
                        %enhance greyscale with IMADJUST
                        cla(app.UIAxes,'reset');
                        app.originalImage = imadjust(app.originalImage); %This seems to be the best!
                        imshow(app.originalImage, 'Parent',app.UIAxes);
                        pause(1);
          
                        %display histogram
                        [pixelCount, grayLevels] = imhist(app.originalImage);
                        cla(app.UIAxes,'reset');
                        bar(pixelCount,'Parent',app.UIAxes);
                        title(app.UIAxes,'Histogram of original image');
                        xlim(app.UIAxes,[0 (grayLevels(end)+5)]); % Scale x axis manually.
                        grid(app.UIAxes,'on');
        
                        %prompt for thresholding value
                        % Ask the user for the real-world distance.
	                    userPrompt = {'Choose low threshold:', 'Choose high threshold:'};
	                    dialogTitle = 'Specify threshold value for binary image';
	                    numberOfLines = 1;
                        %opts.Resize = 'on';
	                    def = {'',''};
	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
	                    if isempty(answer)
		                    return;
	                    end
	                    lowThreshold = str2double(answer{1});
                        highThreshold = str2double(answer{2});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        autoStore = [autoStore string(answer{2})];
                        %%% ---------------- %%%
        
                        %show user input
                        app.binaryImage = (app.originalImage >= lowThreshold) & (app.originalImage <= highThreshold);    
                        % Show the threshold as a vertical red bar on the histogram.
                        hold(app.UIAxes,'on');
                        maxYValue = ylim(app.UIAxes);
                        line(app.UIAxes,[lowThreshold, lowThreshold], maxYValue, 'Color', 'r');
                        line(app.UIAxes,[highThreshold,highThreshold],maxYValue, 'Color', 'r');
                        % Place a text label on the bar chart showing the threshold.
                        annotationText1 = sprintf('Low Thresholded at %d gray levels', lowThreshold);
                        annotationText2 = sprintf('High Thresholded at %d gray levels', highThreshold);
                        % For text(), the x and y need to be of the data class "double" so let's cast both to double.
                        text(double(lowThreshold + 5), double(0.5 * maxYValue(2)), annotationText1, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes);
                        text(double(highThreshold + 5), double(0.5 * maxYValue(2)), annotationText2, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes);
                        text(double(highThreshold - 70), double(0.94 * maxYValue(2)), 'Background', 'FontSize', 10, 'Color', [0 0 .5],'Parent',app.UIAxes);
                        text(double(highThreshold + 50), double(0.94 * maxYValue(2)), 'Foreground', 'FontSize', 10, 'Color', [0 0 .5],'Parent',app.UIAxes);
                        hold(app.UIAxes,'off');
        
                        %generate binary image
                        % Do a "hole fill" to get rid of any background pixels or "holes" inside the blobs.
                        app.binaryImage = imfill(app.binaryImage, 'holes');
                        cla(app.UIAxes, 'reset');
                        imshow(app.binaryImage, 'Parent', app.UIAxes);
        
                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
	                            app.binaryImage = imclearborder(app.binaryImage,4);
                                cla(app.UIAxes, 'reset');
                                imshow(app.binaryImage,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%
   
                        %bwareaopen
                        app.binaryImage = bwareaopen(app.binaryImage,200);
                        cla(app.UIAxes,'reset');
                        imshow(app.binaryImage,'Parent',app.UIAxes);
                        title(app.UIAxes,'Objects Smaller than Set Value in Foreground are Filtered');
                        pause(1)
      
                        app.binaryImage = bwmorph(app.binaryImage,'close');
                        imshow(app.binaryImage,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1)

                        %overlay
                        cla(app.UIAxes,'reset');
                        imshow(labeloverlay(app.originalImage, app.binaryImage), 'Parent', app.UIAxes);
                        title(app.UIAxes, 'Mask Over Original Image');
                        pause(1)
            
                        %Start watershed transform processing here:
                        app.D = -bwdist(~app.binaryImage);
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld), 'Parent', app.UIAxes);
                        pause(1)
        
                        app.bw2 = app.binaryImage;
                        app.bw2(app.Ld == 0) = 0; 
                        %imshow(app.bw2,'Parent',app.UIAxes);
        
                        %extendedmin
                        app.mask = imextendedmin(app.D, 2); %catch more spheres with 1
                        cla(app.UIAxes, 'reset');
                        imshowpair(app.binaryImage, app.mask, 'blend','Parent',app.UIAxes);
                        pause(1)
        
                        %apply watershed to binary mask
                        app.D2 = imimposemin(app.D, app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = app.binaryImage; 
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3, 'Parent', app.UIAxes);
                        pause(1)
        
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";
                        
                    elseif app.imageProperty == "LIGHT" %add light processing
                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
	                    userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
	                    dialogTitle = 'Ridge Line';
	                    numberOfLines = 1;
	                    def = {''};
	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
	                    if isempty(answer)
		                    return;
	                    end
	                    ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%
    
                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes); %map
                        pause(1)
                        
                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
	                            M = imclearborder(M,4);
                                cla(app.UIAxes, 'reset');
                                imshow(M,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%

                        % apply bwareaopen
                        BWopen = bwareaopen(M, 200);
                        cla(app.UIAxes, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes);
                        pause(1)

                        
                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWopen;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes);
                            title(app.UIAxes,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWopen,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%
                        
                        %fill holes

                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Filled Binary Image');
                        pause(1)

                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfill;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfill,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%

                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1) 
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes);
                        pause(1);
                        
%                         %prompt for Extended-minima transform
% 	                    userPrompt = {'higher crowding, 1 <= Value <= less crowding, 5'};
% 	                    dialogTitle = 'Extended-minima transform';
% 	                    numberOfLines = 1;
% 	                    def = {''};
% 	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
% 	                    if isempty(answer)
% 		                    return;
% 	                    end
% 	                    H = str2double(answer{1}); %regional minima aare connected components of pixels with a constant intensity value.

                        H = 2; %not extremely necessary to manually enter this number
                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes)
    
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";

                    elseif app.imageProperty == "NO LIGHT-UNSATURATED" %unsaturated, no light image (no clear boundaries, OR overcrowding)

                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
	                    userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
	                    dialogTitle = 'Ridge Line Value';
	                    numberOfLines = 1;
	                    def = {''};
	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
	                    if isempty(answer)
		                    return;
	                    end
	                    ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%
    
                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        M = ~M; %reverse image
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes); %map
                        pause(1)
    
                        %filter negative area space around outside of
                        %spheres

                        %erode image to eliminate the deletion of connected
                        %spheres
                        %Dilate
                        %prelocate dilated image

                        %Erode filled image until the overlay fits the
                        %original
                        BWerode = M;
                        erodeState = 1;
                        radius = 0;
                        while erodeState == 1
                            cla(app.UIAxes,'reset');
                            imshow(BWerode, 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Erode Image');
                            msg = 'Are spheres disconnected enough to filter out negative space?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                erodeState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('disk',radius);
                                    BWerode = imerode(M,seD);
                                    BWerode = imerode(BWerode,seD);
                                    erodeState = 1; 
                                case 'Exit'
                                    erodeState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %%%%%% NEED TO FINE TUNE THIS!%%%%%%%%
                        %Connected spheres get erased
                        BWfilt = BWerode;
                        filterState = 1;
                        while filterState == 1
                            cla(app.UIAxes,'reset');
                            imshow(BWfilt,'Parent',app.UIAxes);
                            filterProps = regionprops(BWfilt,'Area');
                            areaVals = [filterProps.Area];
                            areaVals_excludeHigh = max(areaVals);
                            msg = 'Filter out max area?';
                            banner = 'Eliminate large areas';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg,banner,...
                                'Options', {'YES','NO'});
                            switch selection
                                case 'YES'
	                                filterState = 1; 
                                    BWfilt = bwareafilt(BWerode,[1,areaVals_excludeHigh-100]); %range is the allowable pixel area
                                case 'NO'
                                    filterState = 0; 
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(areaVals_excludeHigh)];
                        %%% ---------------- %%%

                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
	                            BWfilt = imclearborder(BWfilt,4);
                                cla(app.UIAxes, 'reset');
                                imshow(BWfilt,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%

                        BWopen= bwareaopen(BWfilt, 200);
                        cla(app.UIAxes, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes);
                        pause(1)

                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWopen;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes);
                            title(app.UIAxes,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWopen,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%
                        
                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Filled Binary Image');
                        pause(1)

                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfill;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfill,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1)
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes);
                        pause(1);
                        
%                         %prompt for Extended-minima transform
% 	                    userPrompt = {'higher crowding, 1 <= Value <= less crowding, 5'};
% 	                    dialogTitle = 'Extended-minima transform';
% 	                    numberOfLines = 1;
% 	                    def = {''};
% 	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
% 	                    if isempty(answer)
%                             return;
% 	                    end
% 	                    H = str2double(answer{1}); %regional minima are connected components of pixels with a constant intensity value.

                        H = 2; %not extremely necessary to manually enter this number
                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes)
    
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";

                    elseif app.imageProperty == "LIGHT-NONHOMOGENEOUS" %inconsitent sphere sizes in light processing

                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
	                    userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
	                    dialogTitle = 'Ridge Line Value';
	                    numberOfLines = 1;
	                    def = {''};
	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
	                    if isempty(answer)
		                    return;
	                    end
	                    ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%

                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes); %map
                        pause(1)

                        BWopen= bwareaopen(M, 200);
                        cla(app.UIAxes, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes);
                        pause(1)
                        
                        %Erode filled image until the overlay fits the
                        %original
                        BWerode = BWopen;
                        erodeState = 1;
                        radius = 0;
                        while erodeState == 1
                            cla(app.UIAxes,'reset');
                            imshow(BWerode, 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Erode Image');
                            msg = 'Are spheres disconnected enough to start fill process?';
                            banner = 'Erode Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                erodeState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('disk',radius);
                                    BWerode = imerode(BWopen,seD);
                                    BWerode = imerode(BWerode,seD);
                                    erodeState = 1; 
                                case 'Exit'
                                    erodeState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%

                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWerode;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes);
                            title(app.UIAxes,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWerode,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%

                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Filled Binary Image');
                        pause(1)

                         %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
	                            BWfilt = imclearborder(BWfill,4);
                                cla(app.UIAxes, 'reset');
                                imshow(BWfilt,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                            case 'NO'
                                BWfilt = BWfill; 
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%

                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfilt;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfilt,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0; 
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1)
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes);
                        pause(1);
                        
%                         %prompt for Extended-minima transform
% 	                    userPrompt = {'higher crowding, 1 <= Value <= less crowding, 5'};
% 	                    dialogTitle = 'Extended-minima transform';
% 	                    numberOfLines = 1;
% 	                    def = {''};
% 	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
% 	                    if isempty(answer)
%                             return;
% 	                    end
% 	                    H = str2double(answer{1}); %regional minima are connected components of pixels with a constant intensity value.
    
                        H = 2; %not extremely necessary to manually enter this number

                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes)
    
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";
                    end
                elseif app.selectionState == 2
                    app.ImageProcessingEditField.Value = "WAITING";
                    app.ExportingDataEditField.Value = "WAITING";
    
                    %get full file name
                    app.ProgressEditField.Value = [num2str(i) ' of ' num2str(numel(app.autoFileGroupGlobal))];
                    baseFileName = app.autoFileGroupGlobal{i};
                    app.FILENAMEEditField.Value = baseFileName;
                    splitFileName = strsplit(app.FILENAMEEditField.Value,' ');
                    app.ImageDateEditField.Value = splitFileName{2};
                    app.fullFileNameGlobal = fullfile(app.directoryPath, baseFileName);


                    %read and create greyscale image
                    app.originalImage = imread(app.fullFileNameGlobal);
                    cla(app.UIAxes, 'reset');
                    imshow(app.originalImage, 'Parent',app.UIAxes);
                    %determine if input image is NO LIGHT or NORMAL 
    
                    msg = 'Select Image Property for Modified Processing';
                    banner = 'Image Property';
                    selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'NO LIGHT','LIGHT','NO LIGHT-UNSATURATED', 'LIGHT-NONHOMOGENEOUS'});                   
                    app.imageProperty = convertCharsToStrings(selection);

                    if app.imageProperty == "NO LIGHT"
                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        %begin parsed processing
                        app.originalImage = rgb2gray(app.originalImage);
                        %app.UIAxes.Visible = 'on';
                        cla(app.UIAxes, 'reset');
        
                        % Display the greyscale image.
                        imshow(app.originalImage, 'Parent',app.UIAxes);
        
                        %enhance greyscale with IMADJUST
                        cla(app.UIAxes,'reset');
                        app.originalImage = imadjust(app.originalImage); %This seems to be the best!
                        imshow(app.originalImage, 'Parent',app.UIAxes);
                        pause(3);
          
                        %display histogram
                        [pixelCount, grayLevels] = imhist(app.originalImage);
                        cla(app.UIAxes,'reset');
                        bar(pixelCount,'Parent',app.UIAxes);
                        title(app.UIAxes,'Histogram of original image');
                        xlim(app.UIAxes,[0 (grayLevels(end)+5)]); % Scale x axis manually.
                        grid(app.UIAxes,'on');
        
                        %prompt for thresholding value
                        % Ask the user for the real-world distance.
	                    userPrompt = {'Choose low threshold:', 'Choose high threshold:'};
	                    dialogTitle = 'Specify threshold value for binary image';
	                    numberOfLines = 1;
                        %opts.Resize = 'on';
	                    def = {'',''};
	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
	                    if isempty(answer)
		                    return;
	                    end
	                    lowThreshold = str2double(answer{1});
                        highThreshold = str2double(answer{2});
                        
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        autoStore = [autoStore string(answer{2})];
                        %%% ---------------- %%%
                        %show user input
                        app.binaryImage = (app.originalImage >= lowThreshold) & (app.originalImage <= highThreshold);    
                        % Show the threshold as a vertical red bar on the histogram.
                        hold(app.UIAxes,'on');
                        maxYValue = ylim(app.UIAxes);
                        line(app.UIAxes,[lowThreshold, lowThreshold], maxYValue, 'Color', 'r');
                        line(app.UIAxes,[highThreshold,highThreshold],maxYValue, 'Color', 'r');
                        % Place a text label on the bar chart showing the threshold.
                        annotationText1 = sprintf('Low Thresholded at %d gray levels', lowThreshold);
                        annotationText2 = sprintf('High Thresholded at %d gray levels', highThreshold);
                        % For text(), the x and y need to be of the data class "double" so let's cast both to double.
                        text(double(lowThreshold + 5), double(0.5 * maxYValue(2)), annotationText1, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes);
                        text(double(highThreshold + 5), double(0.5 * maxYValue(2)), annotationText2, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes);
                        text(double(highThreshold - 70), double(0.94 * maxYValue(2)), 'Background', 'FontSize', 10, 'Color', [0 0 .5],'Parent',app.UIAxes);
                        text(double(highThreshold + 50), double(0.94 * maxYValue(2)), 'Foreground', 'FontSize', 10, 'Color', [0 0 .5],'Parent',app.UIAxes);
                        hold(app.UIAxes,'off');
        
                        %generate binary image
                        % Do a "hole fill" to get rid of any background pixels or "holes" inside the blobs.
                        app.binaryImage = imfill(app.binaryImage, 'holes');
                        cla(app.UIAxes, 'reset');
                        imshow(app.binaryImage, 'Parent', app.UIAxes);
        
                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
	                            app.binaryImage = imclearborder(app.binaryImage,4);
                                cla(app.UIAxes, 'reset');
                                imshow(app.binaryImage,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%
                        %bwareaopen
                        app.binaryImage = bwareaopen(app.binaryImage,200);
                        cla(app.UIAxes,'reset');
                        imshow(app.binaryImage,'Parent',app.UIAxes);
                        title(app.UIAxes,'Objects Smaller than Set Value in Foreground are Filtered');
                        pause(1)
      
                        app.binaryImage = bwmorph(app.binaryImage,'close');
                        imshow(app.binaryImage,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1)

                        %overlay
                        cla(app.UIAxes,'reset');
                        imshow(labeloverlay(app.originalImage, app.binaryImage), 'Parent', app.UIAxes);
                        title(app.UIAxes, 'Mask Over Original Image');
                        pause(1)
            
                        %Start watershed transform processing here:
                        app.D = -bwdist(~app.binaryImage);
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld), 'Parent', app.UIAxes);
                        pause(1)
        
                        app.bw2 = app.binaryImage;
                        app.bw2(app.Ld == 0) = 0; 
                        %imshow(app.bw2,'Parent',app.UIAxes);
        
                        %extendedmin
                        app.mask = imextendedmin(app.D, 2); %catch more spheres with 1
                        cla(app.UIAxes, 'reset');
                        imshowpair(app.binaryImage, app.mask, 'blend','Parent',app.UIAxes);
                        pause(1)
        
                        %apply watershed to binary mask
                        app.D2 = imimposemin(app.D, app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = app.binaryImage; 
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3, 'Parent', app.UIAxes);
                        pause(1)
        
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";
                        
                    elseif app.imageProperty == "LIGHT" %add light processing
                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
	                    userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
	                    dialogTitle = 'Ridge Line';
	                    numberOfLines = 1;
	                    def = {''};
	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
	                    if isempty(answer)
		                    return;
	                    end
	                    ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%
    
                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes); %map
                        pause(1)
                        
                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
	                            M = imclearborder(M,4);
                                cla(app.UIAxes, 'reset');
                                imshow(M,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%
                        % apply bwareaopen
                        BWopen = bwareaopen(M, 200);
                        cla(app.UIAxes, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes);
                        pause(1)

                        
                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWopen;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes);
                            title(app.UIAxes,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWopen,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%
                        %fill holes

                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Filled Binary Image');
                        pause(1)

                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfill;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfill,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0; 
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1) 
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes);
                        pause(1);
                        
%                         %prompt for Extended-minima transform
% 	                    userPrompt = {'higher crowding, 1 <= Value <= less crowding, 5'};
% 	                    dialogTitle = 'Extended-minima transform';
% 	                    numberOfLines = 1;
% 	                    def = {''};
% 	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
% 	                    if isempty(answer)
% 		                    return;
% 	                    end
% 	                    H = str2double(answer{1}); %regional minima aare connected components of pixels with a constant intensity value.

                        H = 2; %not extremely necessary to manually enter this number
                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes)
    
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";

                    elseif app.imageProperty == "NO LIGHT-UNSATURATED" %unsaturated, no light image (no clear boundaries, OR overcrowding)

                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
	                    userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
	                    dialogTitle = 'Ridge Line Value';
	                    numberOfLines = 1;
	                    def = {''};
	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
	                    if isempty(answer)
		                    return;
	                    end
	                    ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%
                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        M = ~M; %reverse image
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes); %map
                        pause(1)
    
                        %filter negative area space around outside of
                        %spheres

                        %erode image to eliminate the deletion of connected
                        %spheres
                        %Dilate
                        %prelocate dilated image

                        %Erode filled image until the overlay fits the
                        %original
                        BWerode = M;
                        erodeState = 1;
                        radius = 0;
                        while erodeState == 1
                            cla(app.UIAxes,'reset');
                            imshow(BWerode, 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Erode Image');
                            msg = 'Are spheres disconnected enough to filter out negative space?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                erodeState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('disk',radius);
                                    BWerode = imerode(M,seD);
                                    BWerode = imerode(BWerode,seD);
                                    erodeState = 1; 
                                case 'Exit'
                                    erodeState = 0; 
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %%%%%% NEED TO FINE TUNE THIS!%%%%%%%%
                        %Connected spheres get erased
                        BWfilt = BWerode;
                        filterState = 1;
                        while filterState == 1
                            cla(app.UIAxes,'reset');
                            imshow(BWfilt,'Parent',app.UIAxes);
                            filterProps = regionprops(BWfilt,'Area');
                            areaVals = [filterProps.Area];
                            areaVals_excludeHigh = max(areaVals);
                            msg = 'Filter out max area?';
                            banner = 'Eliminate large areas';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg,banner,...
                                'Options', {'YES','NO'});
                            switch selection
                                case 'YES'
	                                filterState = 1; 
                                    BWfilt = bwareafilt(BWerode,[1,areaVals_excludeHigh-100]); %range is the allowable pixel area
                                case 'NO'
                                    filterState = 0; 
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(areaVals_excludeHigh)];
                        %%% ---------------- %%%
                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
	                            BWfilt = imclearborder(BWfilt,4);
                                cla(app.UIAxes, 'reset');
                                imshow(BWfilt,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%
                        BWopen= bwareaopen(BWfilt, 200);
                        cla(app.UIAxes, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes);
                        pause(1)

                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWopen;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes);
                            title(app.UIAxes,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWopen,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%
                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Filled Binary Image');
                        pause(1)

                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfill;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfill,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0; 
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1)
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes);
                        pause(1);
                        
%                         %prompt for Extended-minima transform
% 	                    userPrompt = {'higher crowding, 1 <= Value <= less crowding, 5'};
% 	                    dialogTitle = 'Extended-minima transform';
% 	                    numberOfLines = 1;
% 	                    def = {''};
% 	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
% 	                    if isempty(answer)
%                             return;
% 	                    end
% 	                    H = str2double(answer{1}); %regional minima are connected components of pixels with a constant intensity value.

                        H = 2; %not extremely necessary to manually enter this number
                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes)
    
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";

                    elseif app.imageProperty == "LIGHT-NONHOMOGENEOUS" %inconsitent sphere sizes in light processing

                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
	                    userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
	                    dialogTitle = 'Ridge Line Value';
	                    numberOfLines = 1;
	                    def = {''};
	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
	                    if isempty(answer)
		                    return;
	                    end
	                    ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%
                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes); %map
                        pause(1)

                        BWopen= bwareaopen(M, 200);
                        cla(app.UIAxes, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes);
                        pause(1)
                        
                        %Erode filled image until the overlay fits the
                        %original
                        BWerode = BWopen;
                        erodeState = 1;
                        radius = 0;
                        while erodeState == 1
                            cla(app.UIAxes,'reset');
                            imshow(BWerode, 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Erode Image');
                            msg = 'Are spheres disconnected enough to start fill process?';
                            banner = 'Erode Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                erodeState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('disk',radius);
                                    BWerode = imerode(BWopen,seD);
                                    BWerode = imerode(BWerode,seD);
                                    erodeState = 1; 
                                case 'Exit'
                                    erodeState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWerode;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes);
                            title(app.UIAxes,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWerode,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%
                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Filled Binary Image');
                        pause(1)

                         %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
	                            BWfilt = imclearborder(BWfill,4);
                                cla(app.UIAxes, 'reset');
                                imshow(BWfilt,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                                BWfilt = BWfill; 
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%
                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfilt;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
	                                overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfilt,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1)
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes);
                        pause(1);
                        
%                         %prompt for Extended-minima transform
% 	                    userPrompt = {'higher crowding, 1 <= Value <= less crowding, 5'};
% 	                    dialogTitle = 'Extended-minima transform';
% 	                    numberOfLines = 1;
% 	                    def = {''};
% 	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
% 	                    if isempty(answer)
%                             return;
% 	                    end
% 	                    H = str2double(answer{1}); %regional minima are connected components of pixels with a constant intensity value.
                        
                        H = 2; %not extremely necessary to manually enter this number
                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes)
    
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";
                    end
                elseif app.selectionState == 3 %ADS Images
                    app.ImageProcessingEditField.Value = "WAITING";
                    app.ExportingDataEditField.Value = "WAITING";
    
                    %get full file name
                    app.ProgressEditField.Value = [num2str(i) ' of ' num2str(numel(app.autoFileGroupGlobal))];
                    baseFileName = app.autoFileGroupGlobal{i};
                    app.FILENAMEEditField.Value = baseFileName;
                    app.fullFileNameGlobal = fullfile(app.directoryPath, baseFileName);
                    A = imfinfo(app.fullFileNameGlobal);
                    B = A.FileModDate;
                    C = strsplit(B,' ');
                    app.SphereStats.Date = C{1};
                    app.ImageDateEditField.Value = C{1};
    
                    %read and create greyscale image
                    app.originalImage = imread(app.fullFileNameGlobal);
                    cla(app.UIAxes, 'reset');
                    imshow(app.originalImage, 'Parent',app.UIAxes);

                    app.ImageProcessingEditField.Value = "WORKING";
                    app.ExportingDataEditField.Value = "WAITING";
                    %begin parsed processing
                    app.originalImage = rgb2gray(app.originalImage);
                    %app.UIAxes.Visible = 'on';
                    cla(app.UIAxes, 'reset');
    
                    % Display the greyscale image.
                    imshow(app.originalImage, 'Parent',app.UIAxes);
    
                    %enhance greyscale with IMADJUST
                    cla(app.UIAxes,'reset');
                    app.originalImage = imadjust(app.originalImage); %This seems to be the best!
                    imshow(app.originalImage, 'Parent',app.UIAxes);
                    pause(3);
      
                    %display histogram
                    [pixelCount, grayLevels] = imhist(app.originalImage);
                    cla(app.UIAxes,'reset');
                    bar(pixelCount,'Parent',app.UIAxes);
                    title(app.UIAxes,'Histogram of original image');
                    xlim(app.UIAxes,[0 (grayLevels(end)+5)]); % Scale x axis manually.
                    grid(app.UIAxes,'on');
    
                    %prompt for thresholding value
                    % Ask the user for the real-world distance.
                    userPrompt = {'Choose low threshold:', 'Choose high threshold:'};
                    dialogTitle = 'Specify threshold value for binary image';
                    numberOfLines = 1;
                    def = {'',''};
                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                    if isempty(answer)
	                    return;
                    end
                    lowThreshold = str2double(answer{1});
                    highThreshold = str2double(answer{2});
                    %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        autoStore = [autoStore string(answer{2})];
                        %%% ---------------- %%%
                    %show user input
                    app.binaryImage = (app.originalImage >= lowThreshold) & (app.originalImage <= highThreshold);    
                    % Show the threshold as a vertical red bar on the histogram.
                    hold(app.UIAxes,'on');
                    maxYValue = ylim(app.UIAxes);
                    line(app.UIAxes,[lowThreshold, lowThreshold], maxYValue, 'Color', 'r');
                    line(app.UIAxes,[highThreshold,highThreshold],maxYValue, 'Color', 'r');
                    % Place a text label on the bar chart showing the threshold.
                    annotationText1 = sprintf('Low Thresholded at %d gray levels', lowThreshold);
                    annotationText2 = sprintf('High Thresholded at %d gray levels', highThreshold);
                    % For text(), the x and y need to be of the data class "double" so let's cast both to double.
                    text(double(lowThreshold + 5), double(0.5 * maxYValue(2)), annotationText1, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes);
                    text(double(highThreshold + 5), double(0.5 * maxYValue(2)), annotationText2, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes);
                    text(double(highThreshold - 70), double(0.94 * maxYValue(2)), 'Background', 'FontSize', 10, 'Color', [0 0 .5],'Parent',app.UIAxes);
                    text(double(highThreshold + 50), double(0.94 * maxYValue(2)), 'Foreground', 'FontSize', 10, 'Color', [0 0 .5],'Parent',app.UIAxes);
                    hold(app.UIAxes,'off');
    
                    %generate binary image
                    % Do a "hole fill" to get rid of any background pixels or "holes" inside the blobs.
                    app.binaryImage = imfill(app.binaryImage, 'holes');
                    %cla(app.UIAxes,'reset');
                    %imshow(app.binaryImage, 'Parent', app.UIAxes);
    
                    %Remove connected Objects on Border
                    msg = 'Clear Border Objects?';
                    banner = 'Border Objects';
                    selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                    'Options', {'YES','NO'});  
                    switch selection
                        case 'YES'
                            app.binaryImage = imclearborder(app.binaryImage,4);
                            cla(app.UIAxes, 'reset');
                            imshow(app.binaryImage,'Parent',app.UIAxes);
                            title(app.UIAxes,'Cleared Border Image');
                            pause(1);
                        case 'NO'
                    end
                    %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%
                    %perform processing protocol
                    %bwareaopen
                    app.binaryImage = bwareaopen(app.binaryImage,200);
                    cla(app.UIAxes,'reset');
                    imshow(app.binaryImage,'Parent',app.UIAxes);
                    title(app.UIAxes,'Objects Smaller than Set Value in Foreground are Filtered');
                    pause(1)
  
%                     %Smoothing
%                     seD = strel('diamond',1);
%                     app.binaryImage = imerode(app.binaryImage,seD);
%                     app.binaryImage = imerode(app.binaryImage,seD);
%                     cla(app.UIAxes, 'reset');
%                     imshow(app.binaryImage,'Parent',app.UIAxes);
%                     title(app.UIAxes,'Segmented Image');
%                     pause(1)
                    app.binaryImage = bwmorph(app.binaryImage,'close');
                    imshow(app.binaryImage,'Parent',app.UIAxes);
                    title(app.UIAxes, 'Closed Binary Image');
                    pause(1)
                    %overlay
                    cla(app.UIAxes,'reset');
                    imshow(labeloverlay(app.originalImage, app.binaryImage), 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Mask Over Original Image');
                    pause(1)
        
                    %Start watershed transform processing here:
                    app.D = -bwdist(~app.binaryImage);
                    app.Ld = watershed(app.D);
                    cla(app.UIAxes, 'reset');
                    imshow(label2rgb(app.Ld), 'Parent', app.UIAxes);
                    pause(1)
    
                    app.bw2 = app.binaryImage;
                    app.bw2(app.Ld == 0) = 0; 
                    %imshow(app.bw2,'Parent',app.UIAxes);
    
                    %extendedmin
                    app.mask = imextendedmin(app.D, 2); %catch more spheres with 1
                    cla(app.UIAxes, 'reset');
                    imshowpair(app.binaryImage, app.mask, 'blend','Parent',app.UIAxes);
                    pause(1)
    
                    %apply watershed to binary mask
                    app.D2 = imimposemin(app.D, app.mask);
                    app.Ld2 = watershed(app.D2);
                    app.bw3 = app.binaryImage; 
                    app.bw3(app.Ld2 == 0) = 0;
                    cla(app.UIAxes, 'reset');
                    imshow(app.bw3, 'Parent', app.UIAxes);
                    pause(1)
    
                    app.ImageProcessingEditField.Value = "DONE";
                    app.ExportingDataEditField.Value = "WORKING";
                end
        
                %%%%%--------------------------------------------%%%%%
                %%%%%--------------SPHERE DATA-------------------%%%%%
                %%%%%--------------------------------------------%%%%%

                %%%% ADD distance per pixel
                if app.ADSImagesCheckBox.Value == 0
                    if imageWidth == 2592
                        app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                    elseif imageWidth == 1024
                        app.distancePerPixel = 2.7037; %scale bar of 4X image, small
                    else
                        app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                    end
                else
                    app.distancePerPixel = 1.8587; %Martonio's measurement
                end
            
                %imshow(app.originalImage, 'Parent', app.UIAxes);
                app.props = regionprops(app.bw3,app.originalImage, 'all');

%                 % FILTER REGION PROPS FIRST BY ECCENTRICITY
%                 allSphereEccentricities = [app.props.Eccentricity];
%                 cla(app.UIAxes,'reset');
%                 bar(app.UIAxes,allSphereEccentricities);
%                 pause(1);
%                 %index and filter these values
%                 indx = find([app.props.Eccentricity]<0.60); %0 is perfect circle, 1 is line segment
%                 filteredImage = ismember(app.bw3,indx);
%                 labeledFilteredImage = bwlabel(filteredImage,8);
%                 cla(app.UIAxes,'reset');
%                 imshow(labeledFilteredImage,'Parent',app.UIAxes);
%                 title(app.UIAxes,'Filtered Image --> Eccentricity < 0.65');
%                 pause(1)
                % FILTER REGION PROPS FIRST BY CIRCULARITY
%                 allSphereCicularities = [app.props.Circularity];
% %                 cla(app.UIAxes,'reset');
% %                 bar(app.UIAxes,allSphereCicularities);
% %                 pause(1);
%                 %index and filter these values
%                 indx = find(([app.props.Circularity]<=1) & ([app.props.Circularity] >= 0.5)); %1 is perfect cirlce
%                 filteredImage = ismember(app.bw3,indx);
%                 labeledFilteredImage = bwlabel(filteredImage,8);
% %                 cla(app.UIAxes,'reset');
% %                 imshow(labeledFilteredImage,'Parent',app.UIAxes);
% %                 title(app.UIAxes,'Filtered Image --> Circularity > 0.5');
% %                 pause(1)

%               
%                 app.props = app.props(indx);
%                 assignin('base','filteredprops',app.props);
                app.numberOfSpheres = numel(app.props);
                %sprintf('%d spheres initially detected.\n', app.numberOfSpheres);
    
                % Print out the measurements to the command window, and display blob numbers on the image.
                textFontSize = 14;	% Used to control size of "blob number" labels put atop the image.
                % Print header line in the command window.
                fprintf(1,'Sphere #      Mean Intensity  Area   Perimeter    Centroid       Diameter\n');
                % Extract all the mean diameters into an array.
                % The "diameter" is the "Equivalent Circular Diameter", which is the diameter of a circle with the same number of pixels as the blob.
                % Enclosing in brackets is a nice trick to concatenate all the values from all the structure fields (every structure in the props structure array).

                blobECD = [app.props.EquivDiameter];

                %prompt for thresholding value
                % Ask the user for the real-world distance.
                userPrompt = {'Minimum Diameter:', 'Maximum Diameter:'};
                dialogTitle = 'Choose Size Exclusion Filter:';
                numberOfLines = 1;
                %opts.Resize = 'on';
                def = {num2str(min(blobECD)),num2str(max(blobECD))};
                answer = inputdlg(userPrompt, dialogTitle, numberOfLines, def);
                if isempty(answer)
                    return;
                end
                minSize = str2double(answer{1});
                maxSize = str2double(answer{2});
                %%% AUTO STORE VALUE %%%
                autoStore = [autoStore string(answer{1})];
                autoStore = [autoStore string(answer{2})];
                %%% ---------------- %%%
                % Loop over all blobs printing their measurements to the command window.
                count = 0;
                kStore = [];
                if app.ADSImagesCheckBox == 1
                    for k = 1 : app.numberOfSpheres          % Loop through all blobs.
                        if blobECD(k) >= minSize && blobECD(k) <= maxSize %minimum calculated diameter to filter out tiny detected blobs
                            count = count + 1;
	                        % Find the individual measurements of each blob.  They are field of each structure in the props strucutre array.
	                        % You could use the bracket trick (like with blobECD above) OR you can get the value from the field of this particular structure.
	                        % I'm showing you both ways and you can use the way you like best.
	                        meanGL = app.props(k).MeanIntensity;		% Get average intensity.
	                        blobArea = app.props(k).Area;				% Get area.
	                        blobPerimeter = app.props(k).Perimeter;		% Get perimeter.
	                        blobCentroid = app.props(k).Centroid;		% Get centroid one at a time
                            blobDiameter(count) = blobECD(k);              %Specific diameter value
                            kStore = [kStore; k];       %for indexing props after
	                        % Now do the printing of this blob's measurements to the command window.
	                        fprintf(1,'#%2d %17.1f %11.1f %8.1f %8.1f %8.1f % 8.1f\n', k, meanGL, blobArea, blobPerimeter, blobCentroid, blobDiameter(count));
	                        % Put the "blob number" labels on the grayscale image that is showing the red boundaries on it.
	                        %text(blobCentroid(1), blobCentroid(2), num2str(count), 'FontSize', textFontSize, 'FontWeight', 'Bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                        end
                    end
                else
                    for k = 1 : app.numberOfSpheres          % Loop through all blobs.
                        if blobECD(k) >= minSize && blobECD(k) <= maxSize %minimum calculated diameter to filter out tiny detected blobs
                            count = count + 1;
	                        % Find the individual measurements of each blob.  They are field of each structure in the props strucutre array.
	                        % You could use the bracket trick (like with blobECD above) OR you can get the value from the field of this particular structure.
	                        % I'm showing you both ways and you can use the way you like best.
	                        meanGL = app.props(k).MeanIntensity;		% Get average intensity.
	                        blobArea = app.props(k).Area;				% Get area.
	                        blobPerimeter = app.props(k).Perimeter;		% Get perimeter.
	                        blobCentroid = app.props(k).Centroid;		% Get centroid one at a time
                            blobDiameter(count) = blobECD(k);              %Specific diameter value
                            kStore = [kStore; k];       %for indexing props after
	                        % Now do the printing of this blob's measurements to the command window.
	                        fprintf(1,'#%2d %17.1f %11.1f %8.1f %8.1f %8.1f % 8.1f\n', k, meanGL, blobArea, blobPerimeter, blobCentroid, blobDiameter(count));
	                        % Put the "blob number" labels on the grayscale image that is showing the red boundaries on it.
	                        %text(blobCentroid(1), blobCentroid(2), num2str(count), 'FontSize', textFontSize, 'FontWeight', 'Bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                        end
                    end
                end

    
                %Convert applicable fields to microns using calibration value
                app.newProps = app.props(kStore);
                app.sphereDiameter = zeros(numel(app.newProps),1);
                app.circularity = zeros(numel(app.newProps),1);
                app.solidity = zeros(numel(app.newProps),1);
                app.eccentricity = zeros(numel(app.newProps),1);
                for k = 1:numel(app.newProps)
                    app.newProps(k).EquivDiameter = app.newProps(k).EquivDiameter.*app.distancePerPixel;
                    app.sphereDiameter(k) = app.newProps(k).EquivDiameter; 
                    app.circularity(k) = app.newProps(k).Circularity;
                    app.solidity(k) = app.newProps(k).Solidity; 
                    app.eccentricity(k) = app.newProps(k).Eccentricity; 
                end
                app.numberOfSpheres = numel(app.newProps);
                sprintf('%d spheres ultimately detected.\n', app.numberOfSpheres);
                if app.ADSImagesCheckBox.Value == 0
                    app.SphereStats.Date = app.ImageDateEditField.Value;
                elseif app.ADSImagesCheckBox.Value == 1
                    app.SphereStats.ID = app.FILENAMEEditField.Value; 
                    A = imfinfo(app.fullFileNameGlobal);
                    B = A.FileModDate;
                    C = strsplit(B,' ');
                    app.SphereStats.Date = C{1};
                end
                app.SphereStats.count = app.numberOfSpheres;
                app.SphereStats.max = max(app.sphereDiameter); % converted
                app.SphereStats.min = min(app.sphereDiameter); % converted
                app.SphereStats.mean = mean(app.sphereDiameter); % converted
                app.SphereStats.median = median(app.sphereDiameter); %converted
                app.SphereStats.std = std(app.sphereDiameter); %converted
                %find mode bin size
                [N, edges] = histcounts(app.sphereDiameter,20);
                maxIndex = find(N==max(N));
                modeBin = [];
                if numel(maxIndex) > 1
                    edgeStore = zeros(numel(maxIndex)*2, 1);
                    for j = 1:numel(maxIndex)
                        edgeStore(j) = edges(maxIndex(j));
                        edgeStore(j+1) = edges(maxIndex(j)+1);
                        if j == 1                         
                            modeBin = [modeBin [num2str(edgeStore(j)) '-' num2str(edgeStore(j+1))]];
                        else
                            modeBin = [modeBin ', ' [num2str(edgeStore(j)) '-' num2str(edgeStore(j+1))]];
                        end
                    end
                    app.SphereStats.modeBin = modeBin;
                else
                    edge1 = edges(maxIndex);
                    edge2 = edges(maxIndex+1);
                    app.SphereStats.modeBin = [num2str(edge1) '-' num2str(edge2)];
                end
  
                app.CaptureLabel.Visible = 'on';
                app.NumberofSpheresEditField.Visible = 'on';
                app.NumberofSpheresEditFieldLabel.Visible = 'on';
                app.MaxDiameterEditField.Visible = 'on';
                app.MaxDiameterEditFieldLabel.Visible = 'on';
                app.MinDiameterEditField.Visible = 'on';
                app.MinDiameterEditFieldLabel.Visible = 'on';
                app.AverageDiameterEditField.Visible = 'on';
                app.AverageDiameterEditFieldLabel.Visible = 'on';
                app.AnalysisLabel.Visible = 'on';
                app.PlotTitleEditField.Visible = 'on';
                app.PlotTitleEditFieldLabel.Visible = 'on';
                app.ViewPDFButton.Visible = 'on';
                app.ViewCDFButton.Visible = 'on';
                app.PolydispersityIndexEditField.Visible = 'on';
                app.PolydispersityIndexEditFieldLabel.Visible = 'on';
                app.muEditField.Visible = 'on';
                app.muEditFieldLabel.Visible = 'on';
                app.sigmaEditField.Visible = 'on';
                app.sigmaEditFieldLabel.Visible = 'on';
                app.AverageDoCEditField.Visible = 'on';
                app.AverageDoCEditFieldLabel.Visible = 'on';
                app.AverageSolidityEditField.Visible = 'on';
                app.AverageSolidityEditFieldLabel.Visible = 'on';
                app.AverageEccentricityEditFieldLabel.Visible = 'on';
                app.AverageEccentricityEditField.Visible = 'on';
    
                %Populate Results Section
                app.NumberofSpheresEditField.Value = string(app.numberOfSpheres); 
                app.MaxDiameterEditField.Value = string(app.SphereStats.max);
                app.MinDiameterEditField.Value = string(app.SphereStats.min);
                app.AverageDiameterEditField.Value = string(app.SphereStats.mean);

                app.SphereStats.AverageCircularity = mean(app.circularity);
                app.SphereStats.AverageSolidity = mean(app.solidity);
                app.SphereStats.AverageEccentricity = mean(app.eccentricity);
                app.AverageDoCEditField.Value = num2str(app.SphereStats.AverageCircularity);
                app.AverageSolidityEditField.Value = num2str(app.SphereStats.AverageSolidity);
                app.AverageEccentricityEditField.Value = num2str(app.SphereStats.AverageEccentricity);
                
                if numel(app.sphereDiameter) > 1
                    %Run PDF Distribution
                    %create PDF from spherediameter
                    app.sphereDiameter = app.sphereDiameter(:);
                    cla(app.UIAxes,'reset');
                    hold(app.UIAxes,'on');
                    LegHandles = [];
                    LegText = {};
        
                    % --- Plot data originally in dataset "sphereDiameter data"
        
                    [CdfF,CdfX] = ecdf(app.sphereDiameter,'Function','cdf');  % compute empirical cdf
                    BinInfo.rule = 3;
                    BinInfo.nbins = 40;
                    [~,BinEdge] = internal.stats.histbins(app.sphereDiameter,[],[],BinInfo,CdfF,CdfX);
                    [BinHeight,BinCenter] = ecdfhist(CdfF,CdfX,'edges',BinEdge);
                    hLine = bar(BinCenter,BinHeight,'hist','Parent',app.UIAxes);
                    set(hLine,'FaceColor','none','EdgeColor',[0.333333 0 0.666667],...
                        'LineStyle','-', 'LineWidth',1);
                    xlabel(app.UIAxes,'Sphere Diameter (um)');
                    ylabel(app.UIAxes,'Density');
                    LegHandles(end+1) = hLine;
                    LegText{end+1} = 'sphereDiameter data';
        
                    % Create grid where function will be computed
        
                    XLim = get(app.UIAxes,'XLim'); %previously gca (get current axis)
                    XLim = XLim + [-1 1] * 0.01 * diff(XLim);
                    XGrid = linspace(XLim(1),XLim(2),100);
        
                    % --- Create fit "normal fit"
                    pd1 = fitdist(app.sphereDiameter, 'normal'); 
                    YPlot = pdf(pd1,XGrid);
                    hLine = plot(XGrid,YPlot,'Color',[1 0 0],...
                        'LineStyle','-', 'LineWidth',2,...
                        'Marker','none', 'MarkerSize',6,'Parent',app.UIAxes);
                    LegHandles(end+1) = hLine;
                    LegText{end+1} = 'normal fit';
        
                    % Adjust figure
        
                    box(app.UIAxes,'on');
                    hold(app.UIAxes,'off');
        
                    % Create legend from accumulated handles and labels
        
                    hLegend = legend(app.UIAxes,LegHandles,LegText,'Orientation', 'vertical', 'FontSize', 9, 'Location', 'northeast');
                    set(hLegend,'Interpreter','none');
                    
                    pause(1);
    
                    %Calculate polydispersity
                    app.muEditField.Value = string(pd1.mu);
                    app.sigmaEditField.Value = string(pd1.sigma);
                    polyD = (pd1.sigma/pd1.mu)^2;
                    app.PolydispersityIndexEditField.Value = string(polyD);
                    app.SphereStats.PolyDispersity = polyD; 
                else
                    app.muEditField.Value = string(mean(app.sphereDiameter));
                    app.sigmaEditField.Value = string(std(app.sphereDiameter));
                    polyD = (std(app.sphereDiameter)/mean(app.sphereDiameter))^2; 
                    app.PolydispersityIndexEditField.Value = string(polyD);
                    app.SphereStats.PolyDispersity = polyD; 
                end

                %FLAG FILE IF PROCESSING REQUIRES FOLLOW-UP
                msg = 'Flag File for Follow-up?';
                banner = 'Flag File';
                selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                'Options', {'YES','NO'});  
                switch selection
                    case 'YES'
	                    app.SphereStats.FLAG = 1;
                        %prompt for flag comments
	                    userPrompt = {'Flag Notes'};
	                    dialogTitle = 'Comments';
	                    numberOfLines = 1;
	                    def = {''};
	                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                        app.SphereStats.Comments = answer{1};
                    case 'NO'
                        app.SphereStats.FLAG = 0;
                        app.SphereStats.Comments = "NA";
                end

                %add ImageProperty to the sphere stats
                app.SphereStats.ImageProperty = app.imageProperty;
                app.SphereStats.ImageSize = [num2str(imageWidth) ' x ' num2str(imageHeight)];
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                %EXPORTING%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                           %AGGREGATE FILES OF SAME NAME SCHEME%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
                if app.AggregateRepeatedImageFilesNASCheckBox.Value == 1
                    %include file in aggregate
                    msg = 'Include file in aggregated counts?';
                    banner = 'Did you mess up tho';
                    selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                    'Options', {'YES','NO'});  
                    switch selection
                        case 'YES'
                            include = 1; 
                            app.SphereStats.includedInAggregate = "YES";
                        case 'NO'
                            app.SphereStats.includedInAggregate = "NO";
                    end
                end
                %%%% Add in AUTO Processing Data! %%%%
                autoStore = [autoStore "AUTO"];
                autoStore = strjoin(autoStore,'_');
                app.SphereStats.AutoProcess = autoStore; 

                %put native path with original file name into excel file
                %%%%%EXPORTING TO FILE AND THEN MOVING TO DIFFERENT FOLDER
                % detect existing folders and files, then overwrite files
                % if needed
                if app.selectionState == 1 && app.ReadImagesfromSingleVesselCheckBox.Value == 1 %NAS files from individual folder
                    
                    %put native path with original file name into excel file
                    app.SphereStats.FilePath = app.fullFileNameGlobal;
                    app.SphereStats.ProcessedFileName = app.individualToBeProcessed.ProcessedFileName(i);  
                    app.SphereStats.ProcessedFolder = app.processedVesselFolderName;
                    folderPath = [app.pwdGlobal '\Microscope Images\'];
                    folderName = app.processedVesselFolderName;
                    if isfolder(folderPath) == 0
                        mkdir(folderPath,folderName);
                    end
                    newFolderPath = [folderPath folderName '\'];
                    newFolderName = 'Individual Image Data';
                    if isfolder([newFolderPath newFolderName]) == 0
                        mkdir(newFolderPath,newFolderName);
                    end
                    %get vessel letter
                    vesselFileName = app.FILENAMEEditField.Value; 
                    vesselString = strsplit(vesselFileName, ' ');
                    vesselString = vesselString{1};
                    vesselLetter = regexp(vesselString,'([A-Z]+)','match');
                    excelFileName = vesselLetter{1};
                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];

                    currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                    nameAndDate = [app.operatorInitials currentDate];
                    app.operatorID = strjoin(nameAndDate,' ');
                    app.SphereStats.Operator = app.operatorID;

                    sphereTable = struct2table(app.SphereStats);

                    %%% SORTING %%%
                    sphereTable.Date = datetime(sphereTable.Date,'InputFormat',"ddMMMyyyy");
                    sphereTable = sortrows(sphereTable,'Date');
                    sphereTable.Date = string(sphereTable.Date,"ddMMMyyyy");
                    %%% END SORTING %%%

                    if isfile(excelPathLocal) == 1                     
                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                    else
                        writetable(sphereTable, excelPathLocal);
                    end
                   
                    %save individual sphere data as .mat file in same folder
                    splitFileName = strsplit(app.individualToBeProcessed.ProcessedFileName(i),'.');
                    splitFileName = convertStringsToChars(splitFileName{1});
                    spherePropsFileName = [newFolderPath newFolderName '\' splitFileName]; 
                    %%%
                    if numel(app.sphereDiameter) > 1
                        individualSphereTable = struct2table(app.newProps);
                        save(spherePropsFileName, 'individualSphereTable');
                    else
                        individualSphereTable = struct2table(app.newProps,'AsArray',true);
                        save(spherePropsFileName, 'individualSphereTable');
                    end

                elseif app.selectionState == 1 && app.ReadImagesfromSingleVesselCheckBox.Value == 0

                    %put native path with original file name into excel file
                    app.SphereStats.FilePath = app.fullFileNameGlobal;

                    %get vessel letter
                    vesselFileName = app.FILENAMEEditField.Value; 
                    vesselString = strsplit(vesselFileName, ' ');
                    vesselString = vesselString{1};
                    vesselLetter = regexp(vesselString,'([A-Z]+)','match');
                    vesselLetter = vesselLetter{1};

                    folderPath = [app.pwdGlobal '\Microscope Images\'];
                    folderName = [app.FolderEditField.Value ' ' vesselLetter '-PROCESSED'];
                    app.SphereStats.ProcessedFolder = foldername; 
                    if isfolder([folderPath foldername]) == 0
                        mkdir(folderpath, folderName);
                    end
                    newFolderPath = [folderPath folderName '\'];
                    newFolderName = 'Individual Image Data';
                    if isfolder([newFolderPath newFolderName]) == 0
                        mkdir(newFolderPath,newFolderName);
                    end
                    excelFileName = vesselLetter;
                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];

                    currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                    nameAndDate = [app.operatorInitials currentDate];
                    app.operatorID = strjoin(nameAndDate,' ');
                    app.SphereStats.Operator = app.operatorID;

                    sphereTable = struct2table(app.SphereStats);

                    %%% SORTING %%%
                    sphereTable.Date = datetime(sphereTable.Date,'InputFormat',"ddMMMyyyy");
                    sphereTable = sortrows(sphereTable,'Date');
                    sphereTable.Date = string(sphereTable.Date,"ddMMMyyyy");
                    %%% END SORTING %%%

                    if isfile(excelPathLocal) == 1
                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                    else
                        writetable(sphereTable, excelPathLocal);
                    end
                    %get the folder
                    folderString = strrep(app.FolderEditField.Value,'-','_'); %should still be character array
                    folderString = convertStringsToChars(folderString);
                    fieldNameString = [folderString '_' vesselLetter];
                    app.FILENAMEEditField.Value = app.toBeProcessed.(fieldNameString).AdjustedFileName(indexCount);

                    %%%
                    % add procesed File Name
                    app.SphereStats.ProcessedFileName = app.toBeProcessed.(fieldNameString).ProcessedFileName(indexCount); 
                    %save individual sphere data as .mat file in same folder
                    splitFileName = strsplit(app.toBeProcessed.(fieldNameString).ProcessedFileName(indexCount),'.'); %%%%need an index for each file group
                    splitFileName = convertStringsToChars(splitFileName{1});
                    spherePropsFileName = [newFolderPath newFolderName '\' splitFileName]; 
                    %%%
                    if numel(app.sphereDiameter) > 1
                        individualSphereTable = struct2table(app.newProps);
                        save(spherePropsFileName, 'individualSphereTable');
                    else
                        individualSphereTable = struct2table(app.newProps,'AsArray',true);
                        save(spherePropsFileName, 'individualSphereTable');
                    end
                
                elseif app.selectionState == 2
                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                    newFolderName = [app.FolderEditField.Value '-PROCESSED']; 
                    if isfolder([newFolderPath newFolderName]) == 0
                        mkdir(newFolderPath,newFolderName);
                    end
                    splitFileName = strsplit(app.FILENAMEEditField.Value,' ');
                    excelFileName = splitFileName{1};
                    excelPathLocal = [app.pwdGlobal '\Microscope Images\' newFolderName '\' excelFileName '.xlsx'];

                    currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                    nameAndDate = [app.operatorInitials currentDate];
                    app.operatorID = strjoin(nameAndDate,' ');
                    app.SphereStats.Operator = app.operatorID;

                    sphereTable = struct2table(app.SphereStats);

                    %%% SORTING %%%
                    sphereTable.Date = datetime(sphereTable.Date,'InputFormat',"ddMMMyyyy");
                    sphereTable = sortrows(sphereTable,'Date');
                    sphereTable.Date = string(sphereTable.Date,"ddMMMyyyy");
                    %%% END SORTING %%%

                    if isfile(excelPathLocal) == 1
                        writetable(sphereTable, excelPathLocal,'WriteMode','Append','WriteVariableNames', false);
                    else
                        writetable(sphereTable, excelPathLocal);
                    end
                    
                    %save individual sphere data as .mat file in same folder
                    spherePropsFileName = [app.pwdGlobal '\Microscope Images\' newFolderName '\' splitFileName{1} '-' splitFileName{2}];
                    if numel(app.sphereDiameter) > 1
                        individualSphereTable = struct2table(app.newProps);
                        save(spherePropsFileName, 'individualSphereTable');
                    else
                        individualSphereTable = struct2table(app.newProps,'AsArray',true);
                        save(spherePropsFileName, 'individualSphereTable');
                    end
    
                    %move processed file to new PROCESSED folder
                    moveFileSource = [newFolderPath app.FolderEditField.Value '\' app.autoFileGroupGlobal{i}];
                    moveFilePath = [newFolderPath newFolderName];
                    movefile(moveFileSource, moveFilePath);
                
                elseif app.selectionState == 3
                    newFolderPath = app.pwdGlobal;
                    newFolderName = [app.FolderEditField.Value '-PROCESSED']; 
                    if isfolder([newFolderPath newFolderName]) == 0
                        mkdir(newFolderPath,newFolderName);
                    end
                    splitFileName = strsplit(app.FILENAMEEditField.Value,' ');
                    excelFileName = splitFileName{1};
                    excelPathLocal = [app.pwdGlobal '\' newFolderName '\' excelFileName '.xlsx'];

                    currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                    nameAndDate = [app.operatorInitials currentDate];
                    app.operatorID = strjoin(nameAndDate,' ');
                    app.SphereStats.Operator = app.operatorID;

                    sphereTable = struct2table(app.SphereStats);

                    %%% SORTING %%%
                    sphereTable.Date = datetime(sphereTable.Date,'InputFormat',"ddMMMyyyy");
                    sphereTable = sortrows(sphereTable,'Date');
                    sphereTable.Date = string(sphereTable.Date,"ddMMMyyyy");
                    %%% END SORTING %%%

                    if isfile(excelPathLocal) == 1
                        writetable(sphereTable, excelPathLocal,'WriteMode','Append','WriteVariableNames', false);
                    else
                        writetable(sphereTable, excelPathLocal);
                    end
                    
                    %Commented Out until Proper Algorithm Warrants
                    %post-processing
                    %save individual sphere data as .mat file in same folder
                    spherePropsFileName = [app.pwdGlobal '\' newFolderName '\' app.autoFileGroupGlobal{i}];
                    if numel(app.sphereDiameter) > 1
                        individualSphereTable = struct2table(app.newProps);
                        save(spherePropsFileName, 'individualSphereTable');
                    else
                        individualSphereTable = struct2table(app.newProps,'AsArray',true);
                        save(spherePropsFileName, 'individualSphereTable');
                    end
    
                    %move processed file to new PROCESSED folder
                    moveFileSource = [newFolderPath '\' app.FolderEditField.Value '\' app.autoFileGroupGlobal{i}];
                    moveFilePath = [newFolderPath '\' newFolderName];
                    movefile(moveFileSource, moveFilePath);
                end

                app.ExportingDataEditField.Value = "DONE";
                

                %reset variables
                app.sphereDiameter = [];
                blobECD = [];
                meanGL = [];
                blobArea = [];
                blobPerimeter = [];
                blobCentroid = [];
                blobDiameter = [];
                app.props = [];
                app.newProps = [];
                app.circularity = [];
                app.solidity = [];
                app.eccentricity = [];
                app.SphereStats = [];
                autoStore = []; 

                msg = 'Advance to next file?';
                banner = 'Continue Processing';
                selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                'Options', {'YES','NO'});  
                switch selection
                    case 'YES'
                        %do nothing and continue :)
                    case 'NO'
                        break; %get outta here
                end
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %trigger aggregate data function and close
            %figure
            %still search for the same files again 
            %search fileDetectStore
            if app.selectionState == 1

                finalSearch = dir(fullfile('**\Individual Image Data\*.xlsx'));
                [finalDetectStore] = individualImageDataSearch(finalSearch, 1);
                %assignin('base','finalDetectStore',finalDetectStore);

                uiwait(msgbox('Processing will stop and aggregate data will be updated.'));
                [filesToAggregate] = detectChange(initialDetectStore, finalDetectStore);

                if numel(filesToAggregate) == 0
                    %account for new folder processed that is not counted
                    %in initialDetectStore
                    if numel(finalDetectStore.File) > numel(initialDetectStore)
                        msg = 'Novel processed folder detected...continue?';
                        banner = 'Aggregate Data Processing';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'Aggregate Initial Data','Exit'});  
                            switch selection
                                case 'Aggregate Initial'
                                    fileSetDiff = setdiff(finalDetectStore.File, initialDetectStore.File);
                                    initialDetectStore.File = [initialDetectStore.File fileSetDiff];
                                    initialDetectStore.Num(numel(initialDetectStore.File)) = 0;
                                    %assignin('base','initialDetectStore_update',initialDetectStore);
                                case 'Exit'
                                    %do nothing and continue :)
                            end
                    else
                        uiwait(msgbox('No new files detected.'));
                    end
                    uiwait(msgbox('Initial file detection updated. Click OK to process.'));
                    [filesToAggregate] = detectChange(initialDetectStore, finalDetectStore);
                    for i = 1:numel(filesToAggregate)
                        filename = filesToAggregate(i); 
                        %find files that are to be included in aggregate data
                        opts = detectImportOptions(filename,'NumHeaderLines',0); %reads first row
                        fullTable = readtable(filename,opts);
                        condYES = convertCharsToStrings(fullTable.includedInAggregate) == "YES";
                        newFullTable = fullTable(condYES, :);
                        %assignin('base','newfulltable',newFullTable);
                        if height(newFullTable) == 0
                            disp("No new aggregate files to add")
                        else
                            writeCount = 0;
                            for j = 1:height(newFullTable)
                                %find processed file data and parse necessary data points
                                pathSplit = strsplit(filename, '\');
                                pathSplit = pathSplit(1:end-1);
                                parentPath = convertStringsToChars(strjoin(pathSplit, '\'));
                                %now load the corresponding processed data while conditionally
                                aggregateFile = load([parentPath '\' convertStringsToChars(newFullTable.ProcessedFileName{j})]); %loads struct with individualSphereTable as field
                                %appending data to duplicate image files        
                                if j == 1
                                    aggregateTable = aggregateFile.individualSphereTable; 
                                    %%%steps to remove file data
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
%                                     %%%%%%%%%%% remove file data %%%%%
                                    if height(newFullTable) == 1
                                        writetable(aggregateTable,excelPathLocal);
                                        disp('Single file uploaded');
                                    end
                                elseif j < height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                                    %concatenate sphere data of the same date
                                    aggregateTableToAppend = aggregateFile.individualSphereTable; 
                                    aggregateTable = [aggregateTable; aggregateTableToAppend];
                                elseif j == height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                                    %accounts for end of loop where dates match
                                    aggregateTableToAppend = aggregateFile.individualSphereTable; 
                                    aggregateTable = [aggregateTable; aggregateTableToAppend];
        
                                    %analyze aggregate data 
                                    [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end

                                elseif j == height(newFullTable) && (string(newFullTable.Date{j}) ~= string(newFullTable.Date{j-1}))
                                    %accounts for end of loop where dates do not match
                                    %will need to analyze data of current aggregateTable AND
                                    %endTable
        
                                    %analyze aggregate data before the mismatch
                                    [aggregateStructure] = aggregateData(newFullTable, aggregateTable,j);
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
        
                                    %then pass new aggregateFile through
                                    %aggregateData() 
                                    aggregateEndTable = aggregateFile.individualSphereTable;
        
                                    %analyze aggregate end data 
                                    [aggregateStructure] = aggregateData(newFullTable, aggregateEndTable,j+1); %add 1 to j index for function
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
                                else
                                    writeCount = writeCount + 1; 
                                    %analyze aggregate data of the current aggregateTable
                                    %new exportable excel file
                                    %analyze aggregate data 
                                    [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);
                                    %then, clear aggregateTable and assign new aggregateFile to it
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    %assignin('base','aggregateStructure',aggregateStructure);
                                    if isfile(excelPathLocal) == 1 && writeCount == 1
                                        writetable(sphereTable, excelPathLocal,'WriteMode','replacefile');
                                        disp(['Data reset from ' excelPathLocal]);
                                    elseif isfile(excelPathLocal) == 1 && writeCount ~= 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
                                    
                                    %clear aggregateTable
                                    aggregateTable = [];
                                    sphereTable = []; 
                                    aggregateTable = aggregateFile.individualSphereTable;                           
                                end
                            end
                        end
                    end
                else
                    for i = 1:numel(filesToAggregate)
                        filename = filesToAggregate(i); 
                        %find files that are to be included in aggregate data
                        opts = detectImportOptions(filename,'NumHeaderLines',0); %reads first row
                        fullTable = readtable(filename,opts);
                        condYES = convertCharsToStrings(fullTable.includedInAggregate) == "YES";
                        newFullTable = fullTable(condYES, :);
                        %assignin('base','newfulltable',newFullTable);
                        if height(newFullTable) == 0
                            disp("No new aggregate files to add")
                        else
                            writeCount = 0;
                            for j = 1:height(newFullTable)
                                %disp(j);
                                %find processed file data and parse necessary data points
                                pathSplit = strsplit(filename, '\');
                                pathSplit = pathSplit(1:end-1);
                                parentPath = convertStringsToChars(strjoin(pathSplit, '\'));
                                %now load the corresponding processed data while conditionally
                                aggregateFile = load([parentPath '\' convertStringsToChars(newFullTable.ProcessedFileName{j})]); %loads struct with individualSphereTable as field
                                %appending data to duplicate image files        
                                if j == 1
                                    aggregateTable = aggregateFile.individualSphereTable; 
                                    %assignin('base',"aggregateTable",aggregateTable);
                                    %%%steps to remove file data
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
%                                     %%%%%%%%%%% remove file data %%%%%
%                                     if isfile(excelPathLocal) == 1
%                                         writetable(aggregateTable, excelPathLocal,'WriteMode','replacefile');
%                                         disp(['Data cleared from ' excelPathLocal]);
%                                     end
                                elseif j < height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                                    %concatenate sphere data of the same date
                                    aggregateTableToAppend = aggregateFile.individualSphereTable; 
                                    aggregateTable = [aggregateTable; aggregateTableToAppend];
                                elseif j == height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                                    %accounts for end of loop where dates match
                                    aggregateTableToAppend = aggregateFile.individualSphereTable; 
                                    aggregateTable = [aggregateTable; aggregateTableToAppend];
        
                                    %analyze aggregate data 
                                    [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end

                                elseif j == height(newFullTable) && (string(newFullTable.Date{j}) ~= string(newFullTable.Date{j-1}))
                                    %accounts for end of loop where dates do not match
                                    %will need to analyze data of current aggregateTable AND
                                    %endTable
        
                                    %analyze aggregate data before the mismatch
                                    [aggregateStructure] = aggregateData(newFullTable, aggregateTable,j);
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
        
                                    %then pass new aggregateFile through
                                    %aggregateData() 
                                    aggregateEndTable = aggregateFile.individualSphereTable;
        
                                    %analyze aggregate end data 
                                    [aggregateStructure] = aggregateData(newFullTable, aggregateEndTable,j+1); %add 1 to j index for function
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
                                else
                                    writeCount = writeCount + 1; 
                                    %analyze aggregate data of the current aggregateTable
                                    %new exportable excel file
                                    %analyze aggregate data 
                                    [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);
                                    %then, clear aggregateTable and assign new aggregateFile to it
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    %assignin('base','aggregateStructure',aggregateStructure);
                                    if isfile(excelPathLocal) == 1 && writeCount == 1
                                        writetable(sphereTable, excelPathLocal,'WriteMode','replacefile');
                                        disp(['Data reset from ' excelPathLocal]);
                                    elseif isfile(excelPathLocal) == 1 && writeCount ~= 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
                                    
                                    %clear aggregateTable
                                    aggregateTable = [];
                                    sphereTable = []; 
                                    aggregateTable = aggregateFile.individualSphereTable;                           
                                end
                            end
                        end
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            end
            msg = 'Done! Process Flagged Files?';
            banner = 'Export is Complete';
            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
            'Options', {'YES','Return Home'});  
            switch selection
                case 'YES'
                    uiwait(msgbox({'Manual Mode will open. Use bottom-right';...
                        'panel to process flagged files.'}));
                    app.AutoCheckBox.Value = 0;
                    AutoCheckBoxValueChanged(app,event)
                    app.ManualCheckBox.Value = 1;
                    ManualCheckBoxValueChanged(app,event)
                    app.FlaggedFilesLabel.Enable = 'on';
                    app.ReprocessflaggedfilesButton.Enable = 'on';
                case 'Return Home'
                    app.UIAxes.Visible = 'off';
                    app.AutoCheckBox.Value = 0; 
                    AutoCheckBoxValueChanged(app,event);

                    app.CaptureLabel.Visible = 'off';
                    app.NumberofSpheresEditField.Visible = 'off';
                    app.NumberofSpheresEditFieldLabel.Visible = 'off';
                    app.MaxDiameterEditField.Visible = 'off';
                    app.MaxDiameterEditFieldLabel.Visible = 'off';
                    app.MinDiameterEditField.Visible = 'off';
                    app.MinDiameterEditFieldLabel.Visible = 'off';
                    app.AverageDiameterEditField.Visible = 'off';
                    app.AverageDiameterEditFieldLabel.Visible = 'off';
                    app.AnalysisLabel.Visible = 'off';
                    app.PlotTitleEditField.Visible = 'off';
                    app.PlotTitleEditFieldLabel.Visible = 'off';
                    app.ViewPDFButton.Visible = 'off';
                    app.ViewCDFButton.Visible = 'off';
                    app.PolydispersityIndexEditField.Visible = 'off';
                    app.PolydispersityIndexEditFieldLabel.Visible = 'off';
                    app.muEditField.Visible = 'off';
                    app.muEditFieldLabel.Visible = 'off';
                    app.sigmaEditField.Visible = 'off';
                    app.sigmaEditFieldLabel.Visible = 'off';
                    app.AverageDoCEditField.Visible = 'off';
                    app.AverageDoCEditFieldLabel.Visible = 'off';
                    app.AverageSolidityEditField.Visible = 'off';
                    app.AverageSolidityEditFieldLabel.Visible = 'off';
                    app.AverageEccentricityEditFieldLabel.Visible = 'off';
                    app.AverageEccentricityEditField.Visible = 'off';
        
                    %Populate Results Section
                    app.NumberofSpheresEditField.Value = ""; 
                    app.MaxDiameterEditField.Value = "";
                    app.MinDiameterEditField.Value = "";
                    app.AverageDiameterEditField.Value = "";
                    app.AverageDoCEditField.Value = "";
                    app.AverageSolidityEditField.Value = "";
                    app.AverageEccentricityEditField.Value = "";
                    app.muEditField.Value = "";
                    app.sigmaEditField.Value = "";
                    app.PolydispersityIndexEditField.Value = "";
                        %get outta here
                    app.SphereImageProcessingUIFigure.Visible = 'off';
                    app.SphereImageProcessingUIFigure.Visible = 'on';
            end
%                 %close request callback OR just simply close(app.SphereImageProcessingUIFigure)
%                 close(app.SphereImageProcessingUIFigure);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                app.LowSpinner.Enable = 'on';
            else
                app.LowSpinner.Enable = 'off';
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value == 1
                app.HighSpinner.Enable = 'on';
            else 
                app.HighSpinner.Enable = 'off';
            end
        end

        % Value changed function: HighSpinner
        function HighSpinnerValueChanged(app, event)
            value = app.HighSpinner.Value;          
            %hold(app.UIAxes,'on');
            maxYValue = ylim(app.UIAxes);
            %line(app.UIAxes,[value, value], maxYValue, 'Color', 'r');
            line(app.UIAxes,[value, value],maxYValue, 'Color','r');
            % Place a text label on the bar chart showing the threshold.
            %annotationText1 = sprintf('Low Thresholded at %d gray levels', value);
            annotationText2 = sprintf('High Thresholded at %d gray levels', value);
            % For text(), the x and y need to be of the data class "double" so let's cast both to double.
            %text(double(value + 5), double(0.5 * maxYValue(2)), annotationText1, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes);
            text(double(value + 5), double(0.5 * maxYValue(2)), annotationText2, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes);
            %hold(app.UIAxes,'off');
            app.GenerateBinaryImageButton.Enable = 'on';
        end

        % Button pushed function: ExportSphereDataButton
        function ExportSphereDataButtonPushed(app, event)
            uiwait(msgbox('Old and new results will be displayed.'))
            cla(app.UIAxes,'reset');
            app.UIAxes.Visible = 'off';
            %begin comparison 
            app.OLDDATALabel.Visible = 'on';
            app.NEWDATALabel.Visible = 'on';
            app.UITable.Visible = 'on';
            app.UITable_2.Visible = 'on';
            %assignin('base','oldSphereStats',app.oldSphereStats);
            %assignin('base','newSphereStats',app.newSphereStats);
            oldTable = rows2vars(struct2table(app.oldSphereStats));
            newTable = rows2vars(struct2table(app.newSphereStats));
            app.UITable.Data = oldTable; 
            %app.UITable.RowName = oldTable(:,1);
            app.UITable_2.Data = newTable; 
            %app.UITable_2.RowName = newTable(:,1);
            
            %accept or reject new data
            msg = 'Accept new data?';
            banner = 'Data update';
            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
            'Options', {'ACCEPT','REJECT'});  
            switch selection
                case 'ACCEPT'
                    rejectNew = 0;
                    app.newSphereStats.FLAG = 0;
                    app.newSphereStats.Comments = "Re-processed";
                    %include file in aggregate
                    msg = 'Include file in aggregated counts?';
                    banner = 'Did you mess up tho';
                    selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                    'Options', {'YES','NO'});  
                    switch selection
                        case 'YES'
                            app.newSphereStats.includedInAggregate = "YES";
                        case 'NO'
                            app.newSphereStats.includedInAggregate = "NO";
                    end                 
                case 'REJECT'
                    rejectNew = 1; 
                    app.oldSphereStats.FLAG = 0;
                    app.oldSphereStats.Comments = convertStringsToChars(string(app.oldSphereStats.Comments));
                    app.oldSphereStats.Comments = [app.oldSphereStats.Comments '--' ' Unsuccessful re-processing'];
                    %assignin('base',"Comments",app.oldSphereStats.Comments);
                    %%% update old Sphere Stats %%%
                    folderPath = [app.pwdGlobal '\Microscope Images\'];
                    folderName = app.oldSphereStats.ProcessedFolder{1};
                    app.processedVesselFolderName = folderName; 
                    if isfolder(folderPath) == 0
                        mkdir(folderPath,folderName);
                    end
                    newFolderPath = [folderPath folderName '\'];
                    newFolderName = 'Individual Image Data';
                    if isfolder([newFolderPath newFolderName]) == 0
                        mkdir(newFolderPath,newFolderName);
                    end
                    %get vessel letter
                    vesselFileName = string(app.oldSphereStats.Name); 
                    vesselString = strsplit(vesselFileName, ' ');
                    vesselString = vesselString{1};
                    vesselLetter = regexp(vesselString,'([A-Z]+)','match');
                    excelFileName = vesselLetter{1};
                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];

                    sphereTable = struct2table(app.oldSphereStats);
                    
                    %read and find data to be replaced, delete it, add in new,
                    %then sort
                    opts = detectImportOptions(excelPathLocal,'NumHeaderLines',0); %reads first row
                    searchTable = readtable(excelPathLocal,opts);
                    %remove old sphere analysis
                    searchTable((string(searchTable.Name) == string(app.oldSphereStats.Name)),:) = [];
                    searchTable = [searchTable; sphereTable];
                    %%% SORTING %%%
                    searchTable.Date = datetime(searchTable.Date,'InputFormat',"ddMMMyyyy");
                    searchTable = sortrows(searchTable,'Date');
                    searchTable.Date = string(searchTable.Date,"ddMMMyyyy");
                    %%% END SORTING %%%
                    writetable(searchTable, excelPathLocal);
            end
            
            if rejectNew == 0
                %create 
                folderPath = [app.pwdGlobal '\Microscope Images\'];
                folderName = app.newSphereStats.ProcessedFolder{1};
                app.processedVesselFolderName = folderName; 
                if isfolder(folderPath) == 0
                    mkdir(folderPath,folderName);
                end
                newFolderPath = [folderPath folderName '\'];
                newFolderName = 'Individual Image Data';
                if isfolder([newFolderPath newFolderName]) == 0
                    mkdir(newFolderPath,newFolderName);
                end
                %get vessel letter
                vesselFileName = string(app.newSphereStats.Name); 
                vesselString = strsplit(vesselFileName, ' ');
                vesselString = vesselString{1};
                vesselLetter = regexp(vesselString,'([A-Z]+)','match');
                excelFileName = vesselLetter{1};
                excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];

                currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                nameAndDate = [app.operatorInitials currentDate];
                app.operatorID = strjoin(nameAndDate,' ');

                app.newSphereStats.Operator = string([convertStringsToChars(app.oldSphereStats.Operator)...
                    '...Re-Processed by: ' convertStringsToChars(app.operatorID)]);
                app.newSphereStats.Operator = strjoin(app.newSphereStats.Operator); 

                sphereTable = struct2table(app.newSphereStats);
                
                %read and find data to be replaced, delete it, add in new,
                %then sort
                opts = detectImportOptions(excelPathLocal,'NumHeaderLines',0); %reads first row
                searchTable = readtable(excelPathLocal,opts);
                %remove old sphere analysis
                searchTable((string(searchTable.Name) == string(app.oldSphereStats.Name)),:) = [];
                searchTable = [searchTable; sphereTable];
                %%% SORTING %%%
                searchTable.Date = datetime(searchTable.Date,'InputFormat',"ddMMMyyyy");
                searchTable = sortrows(searchTable,'Date');
                searchTable.Date = string(searchTable.Date,"ddMMMyyyy");
                %%% END SORTING %%%
                writetable(searchTable, excelPathLocal);
               
                %save individual sphere data as .mat file in same folder
                splitFileName = strsplit(app.newSphereStats.ProcessedFileName{1},'.');
                splitFileName = convertStringsToChars(splitFileName{1});
                spherePropsFileName = [newFolderPath newFolderName '\' splitFileName]; 
                %%%
                if numel(app.sphereDiameter) > 1
                    individualSphereTable = struct2table(app.newProps);
                    save(spherePropsFileName, 'individualSphereTable');
                else
                    individualSphereTable = struct2table(app.newProps,'AsArray',true);
                    save(spherePropsFileName, 'individualSphereTable');
                end
                
                %reset variables
                app.sphereDiameter = [];
                blobECD = [];
                meanGL = [];
                blobArea = [];
                blobPerimeter = [];
                blobCentroid = [];
                blobDiameter = [];
                app.props = [];
                app.newProps = [];
                app.circularity = [];
                app.solidity = [];
                app.eccentricity = [];
                app.SphereStats = [];
                app.oldSphereStats = [];
                app.newSphereStats = [];
                autoStore = []; 


            %%%%%EXPORTING FLAGGED FILE AND THEN UPDATING AGGREGATE DATA 
                filesToAggregate = string(excelPathLocal); 
                for i = 1:numel(filesToAggregate)
                    filename = filesToAggregate(i); 
                    %find files that are to be included in aggregate data
                    opts = detectImportOptions(filename,'NumHeaderLines',0); %reads first row
                    fullTable = readtable(filename,opts);
                    condYES = convertCharsToStrings(fullTable.includedInAggregate) == "YES";
                    newFullTable = fullTable(condYES, :);
                    app.processedVesselFolderName
                    %assignin('base','newfulltable',newFullTable);
                    if height(newFullTable) == 0
                        disp("No new aggregate files to add")
                    else
                        writeCount = 0;
                        for j = 1:height(newFullTable)
                            %find processed file data and parse necessary data points
                            pathSplit = strsplit(filename, '\');
                            pathSplit = pathSplit(1:end-1);
                            parentPath = convertStringsToChars(strjoin(pathSplit, '\'));
                            %now load the corresponding processed data while conditionally
                            aggregateFile = load([parentPath '\' convertStringsToChars(newFullTable.ProcessedFileName{j})]); %loads struct with individualSphereTable as field
                            %appending data to duplicate image files        
                            if j == 1
                                aggregateTable = aggregateFile.individualSphereTable; 
                                %%%steps to remove file data
                                newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                newFolderName = app.processedVesselFolderName;
                                if isfolder([newFolderPath newFolderName]) == 0
                                    mkdir(newFolderPath,newFolderName);
                                end
                                %get vessel letter
                                vesselLetter = regexp(newFullTable.VesselID(j),'([A-Z]+)','match');
                                vesselLetter = vesselLetter{1};
                                excelFileName = [vesselLetter '-Aggregated Data'];
                                excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                excelPathLocal = [excelPathLocal{1:end}];
%                                 %%%%%%%%%%% remove file data %%%%%
%                                 if isfile(excelPathLocal) == 1
%                                     writetable(aggregateTable,excelPathLocal,'WriteMode','replacefile');
%                                     disp(['Data cleared from ' excelPathLocal]);
%                                 end
                            elseif j < height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                                %concatenate sphere data of the same date
                                aggregateTableToAppend = aggregateFile.individualSphereTable; 
                                aggregateTable = [aggregateTable; aggregateTableToAppend];
                            elseif j == height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                                %accounts for end of loop where dates match
                                aggregateTableToAppend = aggregateFile.individualSphereTable; 
                                aggregateTable = [aggregateTable; aggregateTableToAppend];
        
                                %analyze aggregate data 
                                [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);
        
                                %%%write aggregate excel file
                                newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                newFolderName = app.processedVesselFolderName;
                                if isfolder([newFolderPath newFolderName]) == 0
                                    mkdir(newFolderPath,newFolderName);
                                end
                                %get vessel letter
                                vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                vesselLetter = vesselLetter{1};
                                excelFileName = [vesselLetter '-Aggregated Data'];
                                excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                excelPathLocal = [excelPathLocal{1:end}];
                                sphereTable = struct2table(aggregateStructure);
                                if isfile(excelPathLocal) == 1
                                    writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                else
                                    writetable(sphereTable, excelPathLocal);
                                end
        
                            elseif j == height(newFullTable) && (string(newFullTable.Date{j}) ~= string(newFullTable.Date{j-1}))
                                %accounts for end of loop where dates do not match
                                %will need to analyze data of current aggregateTable AND
                                %endTable
        
                                %analyze aggregate data before the mismatch
                                [aggregateStructure] = aggregateData(newFullTable, aggregateTable,j);
        
                                %%%write aggregate excel file
                                newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                newFolderName = app.processedVesselFolderName;
                                if isfolder([newFolderPath newFolderName]) == 0
                                    mkdir(newFolderPath,newFolderName);
                                end
                                %get vessel letter
                                vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                vesselLetter = vesselLetter{1};
                                excelFileName = [vesselLetter '-Aggregated Data'];
                                excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                excelPathLocal = [excelPathLocal{1:end}];
                                sphereTable = struct2table(aggregateStructure);
                                if isfile(excelPathLocal) == 1
                                    writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                else
                                    writetable(sphereTable, excelPathLocal);
                                end
        
                                %then pass new aggregateFile through
                                %aggregateData() 
                                aggregateEndTable = aggregateFile.individualSphereTable;
        
                                %analyze aggregate end data 
                                [aggregateStructure] = aggregateData(newFullTable, aggregateEndTable,j+1); %add 1 to j index for function
        
                                %%%write aggregate excel file
                                newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                newFolderName = app.processedVesselFolderName;
                                if isfolder([newFolderPath newFolderName]) == 0
                                    mkdir(newFolderPath,newFolderName);
                                end
                                %get vessel letter
                                vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                vesselLetter = vesselLetter{1};
                                excelFileName = [vesselLetter '-Aggregated Data'];
                                excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                excelPathLocal = [excelPathLocal{1:end}];
                                sphereTable = struct2table(aggregateStructure);
                                if isfile(excelPathLocal) == 1
                                    writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                else
                                    writetable(sphereTable, excelPathLocal);
                                end
                            else
                                writeCount = writeCount + 1; 
                                %analyze aggregate data of the current aggregateTable
                                %new exportable excel file
                                %analyze aggregate data 
                                [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);
                                %then, clear aggregateTable and assign new aggregateFile to it
                                %%%write aggregate excel file
                                newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                newFolderName = app.processedVesselFolderName;
                                if isfolder([newFolderPath newFolderName]) == 0
                                    mkdir(newFolderPath,newFolderName);
                                end
                                %get vessel letter
                                vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                vesselLetter = vesselLetter{1};
                                excelFileName = [vesselLetter '-Aggregated Data'];
                                excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                excelPathLocal = [excelPathLocal{1:end}];
                                sphereTable = struct2table(aggregateStructure);
                                %assignin('base','aggregateStructure',aggregateStructure);
                                if isfile(excelPathLocal) == 1 && writeCount == 1
                                    writetable(sphereTable, excelPathLocal, 'WriteMode','replacefile');
                                elseif isfile(excelPathLocal) == 1 && writeCount ~= 1
                                    writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                else
                                    writetable(sphereTable, excelPathLocal);
                                end
                                
                                %clear aggregateTable
                                aggregateTable = [];
                                sphereTable = []; 
                                aggregateTable = aggregateFile.individualSphereTable;                           
                            end
                        end
                    end
                end
                app.UITable.Data = [];
                app.UITable_2.Data = [];
                app.UITable.Visible = 'off';
                app.UITable_2.Visible = 'off';
                app.UIAxes.Visible = 'on';
            end
            
            if app.flagFileListCount == height(app.flagFileList)
                uiwait(msgbox('Processing finished. Click OK to exit.'));
                close(app.SphereImageProcessingUIFigure);
            else
                msg = 'Advance to next flagged file?';
                banner = 'Continue Processing';
                selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                'Options', {'YES','Exit App'});  
                switch selection
                    case 'YES'
                        app.ReadImageButton.Enable = 'on';
                        ReadImageButtonPushed(app,event);
                    case 'Exit App'
                        uiwait(msgbox('Processing finished. Click OK to exit.'));
                        close(app.SphereImageProcessingUIFigure);
                end
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            [app.toBeProcessed, folder, answer, app.fileList] = fileSearch(); %returns folders from NAS or Aruna SharePoint
            selectedButton = convertCharsToStrings(answer); 
            %the first two statements below do not need to consider the
            %presence of repeated file names since the files read are
            %deposited into their respective PROCESSED FOLDER
            %-----
            [app.toBeProcessed, app.fileList, app.overwriteIndex] = fileListParse(app.fileList, app.toBeProcessed);
            %for the 'SELECT' option, we read files from the NAS system,
            %which contains the cumulative files of all images taken. Thus,
            %we will need to check these read files in toBeProcessed
            %against files that are already processed in the PD folder.
            %This is why the substructures contain the respective adjusted
            %file name and processed file name. 
            if selectedButton == "Access SharePoint folders"
                app.selectionState = 2;
                app.selectedFolder = folder; 
                folderString = strsplit(folder, '\');
                app.FolderEditField.Value = folderString{end}; 
                %working for 4X images so far
                app.directoryPath = app.selectedFolder;
                fileParse = sprintf('*%s*.tif',"4X");
                fileFind = dir(fullfile(app.directoryPath,fileParse));
                app.autoFileGroupGlobal = {fileFind.name}';
                app.NumberofFilestobeReadEditField.Value = string(numel(app.autoFileGroupGlobal));
                app.ADSImagesCheckBox.Enable = "off";
                %assignin('base','autofilegroup',app.autoFileGroupGlobal);
            elseif selectedButton == "ADS Images"
                app.selectionState = 3;
                app.ADSImagesCheckBox.Value = 1;
                app.selectedFolder = folder; 
                folderString = strsplit(folder, '\');
                app.FolderEditField.Value = folderString{end}; 
                %working for 4X images so far
                app.directoryPath = app.selectedFolder;
                fileParse = sprintf('.jpg'); %will need to adjust this
                fileFind = dir(fullfile(app.directoryPath,fileParse));
                app.autoFileGroupGlobal = {fileFind.name}';
                app.NumberofFilestobeReadEditField.Value = string(numel(app.autoFileGroupGlobal));
                %assignin('base','autofilegroup',app.autoFileGroupGlobal);
            elseif selectedButton == "SELECT"
                app.selectionState = 1; 
                app.ADSImagesCheckBox.Enable ="off";
                app.ReadImagesfromSingleVesselCheckBox.Visible = "on";
                app.selectedFolder = folder; 
                folderString = strsplit(folder, '\');
                app.FolderEditField.Value = folderString{end};
                app.NumberofFilestobeReadEditField.Value = string(numel(app.fileList));
                fileGroup = struct2table(app.fileList);
                fileGroup = table2struct(fileGroup,"ToScalar",true);
                %need full path for NAS files
                for i = 1:numel(app.fileList)
                    app.autoFileGroupGlobal{i,1} = [fileGroup.folder{i} '\' fileGroup.name{i}]; 
                end
                if numel(folderString) > 1
                    msg = ['It appears there are multiple vessels in ' app.FolderEditField.Value '. For performance, it is recommended to select a single vessel.'];
                    banner = 'Select Single Folder';
                    selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, 'Options', {'Select Vessel', 'Continue'});
                    switch selection
                        case 'Select Vessel'
                            app.ReadImagesfromSingleVesselCheckBox.Value = 1; 
                            app.VesselIDDropDown.Visible = 'on';
                            app.VesselIDDropDownLabel.Visible = 'on';
                            ReadImagesfromSingleVesselCheckBoxValueChanged(app, 1);
                            app.STARTButton.Enable = 'on';
                            app.BATCHButton.Enable = 'on';
                        case 'Continue'
                            return;
                    end
                end

                %check aggregate box by default
                app.AggregateRepeatedImageFilesNASCheckBox.Enable = 'on';
                app.AggregateRepeatedImageFilesNASCheckBox.Value = 1; 
            end
        end

        % Value changed function: ReadImagesfromSingleVesselCheckBox
        function ReadImagesfromSingleVesselCheckBoxValueChanged(app, event)
            value = app.ReadImagesfromSingleVesselCheckBox.Value;
            if value == 1
                app.VesselIDDropDown.Visible = 'on';
                app.VesselIDDropDownLabel.Visible = 'on';
                dirFileNames = fieldnames(app.toBeProcessed);
                dirFileNamesStrings = convertCharsToStrings(dirFileNames);
                app.VesselIDDropDown.Items = [{""}; dirFileNamesStrings];
                app.VesselIDDropDown.ItemsData = [{""}; dirFileNamesStrings];
            else
                app.VesselIDDropDown.Visible = 'off';
                app.VesselIDDropDownLabel.Visible = 'off';
                app.VesselIDDropDown.Items = {};
            end
        end

        % Value changed function: VesselIDDropDown
        function VesselIDDropDownValueChanged(app, event)
            value = app.VesselIDDropDown.Value;
            app.autoFileGroupGlobal = [];
            app.individualVesselFolder = convertCharsToStrings(value);
            vesselFolderStr = strsplit(app.individualVesselFolder,'_');
            vesselFolderName = [vesselFolderStr{1} '-' vesselFolderStr{2} ' ' vesselFolderStr{end}];
            app.vesselLetterTag = string(vesselFolderStr{end});
            app.processedVesselFolderName = [vesselFolderName '-PROCESSED'];
            app.FolderEditField.Value = vesselFolderName; 
            app.AggregateSelectedVesselCheckBox.Enable = 'on'; 

            folderStrings = fieldnames(app.toBeProcessed);
            app.individualVesselFolderIndex = find(folderStrings == app.individualVesselFolder);
            app.individualVesselFolder = folderStrings{app.individualVesselFolderIndex};
            app.NumberofFilestobeReadEditField.Value = string(numel(app.toBeProcessed.(app.individualVesselFolder).FileName));
            app.individualToBeProcessed = app.toBeProcessed.(app.individualVesselFolder);
            app.autoFileGroupGlobal = cellstr(app.toBeProcessed.(app.individualVesselFolder).FilePath); 
           
            assignin('base','individualToBeProcessed',app.individualToBeProcessed);

            if isfolder(['Microscope Images\' app.processedVesselFolderName]) == 1
                folderState = 1; 
                folderCount = 0;  
                while folderState == 1 
                msg = ['Use ' app.processedVesselFolderName ' as location for processed images for selected vessel?'];
                banner = 'Folder Exists in Sphere Image Processing';
                selection = uiconfirm(app.SphereImageProcessingUIFigure,msg,banner,'Options',{'YES','NO'});
            % Handle response
                    switch selection
                        case 'YES'
                            disp(['Files of Same Vessel Lot will be added to ' app.processedVesselFolderName]);
                            folderState = 0;
                        case 'NO'
                            folderCount = folderCount + 1; 
                            app.processedVesselFolderName = [app.processedVesselFolderName '(' folderCount ')'];
                            disp(['Files of Same Vessel Lot will be Assigned to ' app.processedVesselFolderName]);
                    end
                end
            end
            
        end

        % Value changed function: ImagePropertyDropDown
        function ImagePropertyDropDownValueChanged(app, event)
            app.imageProperty = string(app.ImagePropertyDropDown.Value);            
            app.DisplayMontageButton.Enable = 'on';  
            app.newSphereStats.ImageProperty = app.imageProperty;
            if app.flagFilter == 1
                DisplayMontageButtonPushed(app,event)
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            if value == 1
                app.STDFilterSpinner.Enable = 'on';
            else 
                app.STDFilterSpinner.Enable = 'off';
                cla(app.UIAxes, 'reset');
                imshow(app.originalImage, 'Parent',app.UIAxes);
            end
        end

        % Value changed function: STDFilterSpinner
        function STDFilterSpinnerValueChanged(app, event)
            value = app.STDFilterSpinner.Value;
            %%% ---------------- %%%
            if app.imageProperty == "LIGHT"
                S = stdfilt(app.originalImage,ones(3));
                cla(app.UIAxes, 'reset');
                imshow(S<value, 'Parent',app.UIAxes); %ridge lines 
                pause(1);
                M = S>=value;  %map where the std is large
                imshow(M,'Parent',app.UIAxes); %map
            elseif app.imageProperty == "LIGHT-NONHOMOGENEOUS"
                S = stdfilt(app.originalImage,ones(3));
                cla(app.UIAxes, 'reset');
                imshow(S<value, 'Parent',app.UIAxes); %ridge lines 
                pause(1);
                M = S>=value;  %map where the std is large
                imshow(M,'Parent',app.UIAxes); %map
            elseif app.imageProperty == "NO LIGHT-UNSATURATED"
                S = stdfilt(app.originalImage,ones(3));
                cla(app.UIAxes, 'reset');
                imshow(S<value, 'Parent',app.UIAxes); %ridge lines 
                pause(1);
                M = S>=value;  %map where the std is large
                M = ~M; %reverse image
                imshow(M,'Parent',app.UIAxes); %map
            end
            app.tempImage = M; 
            app.GenerateBinaryImageButton.Enable = 'on';
        end

        % Value changed function: FilterMaxCheckBox
        function FilterMaxCheckBoxValueChanged(app, event)
            value = app.FilterMaxCheckBox.Value;
              %Connected spheres get erased
            if value == 1
                app.binaryImage = app.tempImage;
                filterState = 1;
                while filterState == 1
                    cla(app.UIAxes,'reset');
                    imshow(app.binaryImage,'Parent',app.UIAxes);
                    filterProps = regionprops(app.binaryImage,'Area');
                    areaVals = [filterProps.Area];
                    areaVals_excludeHigh = max(areaVals);
                    msg = 'Filter out max area?';
                    banner = 'Eliminate large areas';
                    selection = uiconfirm(app.SphereImageProcessingUIFigure, msg,banner,...
                        'Options', {'YES','NO'});
                    switch selection
                        case 'YES'
                            filterState = 1; 
                            app.binaryImage = bwareafilt(app.tempImage,[1,areaVals_excludeHigh-100]); %range is the allowable pixel area
                        case 'NO'
                            filterState = 0; 
                    end
                end
            end
            app.binaryImage = imfill(app.binaryImage, 'holes');
            cla(app.UIAxes,'reset');
            imshow(app.binaryImage, 'Parent', app.UIAxes);
            app.BWAreaOpenCheckBox.Enable = 'on';
            app.RemoveBorderObjectsCheckBox.Enable = 'on';
            app.SmoothingCheckBox.Enable = 'on';
            app.ViewOverlayButton.Enable = 'on';
        end

        % Button pushed function: HELPButton
        function HELPButtonPushed(app, event)
            %add demo script and populate threshold values when complete
            [lowThreshold,highThreshold] = thresholdHELP(app.fullFileNameGlobal);
%             assignin('base',"lowThreshold",lowThreshold);
%             assignin('base',"highThreshold",highThreshold);
            %click display histogram and update spinner
            DisplayHistogramButtonPushed(app,event)
            app.CheckBox.Value = 1;
            CheckBoxValueChanged(app,event)
            app.CheckBox_2.Value = 1; 
            CheckBox_2ValueChanged(app,event)
            app.LowSpinner.Value = lowThreshold; 
            app.HighSpinner.Value = highThreshold; 
            LowSpinnerValueChanged(app,event)
            HighSpinnerValueChanged(app,event)
        end

        % Button pushed function: ReprocessflaggedfilesButton
        function ReprocessflaggedfilesButtonPushed(app, event)
            %find flagged files
            app.flagFilter = 1; 
            app.CalibrateButton.Enable = 'off';
            app.DropDown.Enable = 'off';
            app.FlaggedFileEditField.Enable = 'on';
            app.ProgressEditField_2.Enable = 'on';
            app.ofLabel.Enable = 'on';
            app.ProgressEditField_3.Enable = 'on';
            app.CommentsEditField.Enable = 'on';
            app.ReadImageButton.Enable = 'on';
            %first, perform a general directory and subdirectory search of
            %all files
            flagSearch = dir(fullfile('**\Individual Image Data\*.xlsx'));
            flagDetectTable = []; 
            for n = 1:length(flagSearch)
                %read excel file and grab length
                filename = [flagSearch(n).folder '\' flagSearch(n).name];
                opts = detectImportOptions(filename,'NumHeaderLines',0); %reads first row
                flagTable = readtable(filename,opts);
                for p = 1:height(flagTable)
                    if flagTable.FLAG(p) == 1
                        flagDetectTable = [flagDetectTable; flagTable(p,:)];
                    end
                end
                flagTable = [];
            end
            %select files to overwrite (can select one, multiple, or all)
            [indx,tf] = listdlg('PromptString',{'Select the flagged files';...
                'you wish to overwrite.';''},...
                'SelectionMode','single','ListString',flagDetectTable.Name); %If the user clicks Cancel, presses Esc, or clicks the 
                                           % close button (X) in the dialog box title bar, then the tf return value is 0. 
            if tf == 0
                uiwait(msgbox({'No selection was recorded.',...
                    'Returning to default: All files skipped.'}));
            end
            
            app.flagFileList = flagDetectTable(indx,:);
            %assignin('base','indx',indx);
            app.ProgressEditField_3.Value = num2str(height(app.flagFileList));
            app.flagFileListCount = 0;
            
            %disable select file drop down and populate it with "FLAGGED
            %FILES"
            %compare former and latter processing
            %prompt user to accept or reject new file
            %update excel file, individual sphere data, and reprocess aggregate data
        end

        % Value changed function: VisualizeCheckBox
        function VisualizeCheckBoxValueChanged(app, event)
            value = app.VisualizeCheckBox.Value;
            if value == 1
                app.ManualCheckBox.Enable = 'off';
                app.AutoCheckBox.Enable = 'off';
                app.VisualizePanel.Visible = 'on';
                app.EXITButton.Enable = 'on';
            else
                app.ManualCheckBox.Enable = 'on';
                app.AutoCheckBox.Enable = 'on';
                app.VisualizePanel.Visible = 'off';
                app.EXITButton.Enable = 'off';
                if app.CenterPanel.AutoResizeChildren == "off"
                    RefreshButtonPushed(app,event); 
                    %ALSO CLEAR ALL DATA^
                end
            end
        end

        % Button pushed function: SelectAggregateFileButton
        function SelectAggregateFileButtonPushed(app, event)
            app.compareState = 0; 
            [file,path] = uigetfile('*.xlsx');
            if isequal(file,0)
               disp('User selected Cancel')
               uiwait(msgbox('No file selected.'));
            end
            app.FileEditField_2.Value = string(file);
            filename = [path file];
            %plot data in subplots 
            opts = detectImportOptions(filename,'NumHeaderLines',0); %reads first row
            app.vizTable = readtable(filename,opts);
            
            %app.UIAxes.Visible = 'on';
            app.CenterPanel.AutoResizeChildren = 'off';
            app.ax1 = subplot(2,2,1, 'Parent',app.CenterPanel);
            app.ax2 = subplot(2,2,2, 'Parent',app.CenterPanel);
            app.ax3 = subplot(2,2,[3,4], 'Parent',app.CenterPanel);
            %ax4 = subplot(2,2,4, 'Parent',app.CenterPanel);
            
            %--------------------SUBPLOT 1-----------------------------%
            xData = datetime(app.vizTable.Date,'InputFormat','ddMMMyyy');
            yData1 = app.vizTable.Mean; 
            p1 = plot(app.ax1,xData,yData1,'DisplayName','Mean Diameter');
            xlim(app.ax1,[xData(1) xData(end)]);
            ylim(app.ax1,[0 max(yData1)+25]);
            xlabel(app.ax1,'Image Date');
            ylabel(app.ax1,'Mean Diameter (um)');
            title(app.ax1, 'Mean Diameter by Sample Date');

            app.PlotTitleEditField_2.Value = "Mean Diameter by Sample Date";
            app.XLabelEditField.Value = "Image Date";
            app.YLabelEditField.Value = "Mean Diameter (um)";
            %--------------------SUBPLOT 2--------CUSTOMIZABLE--------%
            hold(app.ax2,'on');            
            yData2 = app.vizTable.Max;
            yData3 = app.vizTable.Min;
            p2 = plot(app.ax2,xData,[yData2,yData3],'DisplayName','Max/Min Diameter');
            xlim(app.ax2,[xData(1) xData(end)]);
            ylim(app.ax2,[0 max(yData2)+25]);
            xlabel(app.ax2,'Image Date');
            ylabel(app.ax2,'Diameter (um)');
            title(app.ax2, 'Max/Min by Sample Date');
            hold(app.ax2, 'off');

            app.PlotTitleEditField_3.Value = "Max/Min by Sample Date --> DEFAULT";
            dataNames = ["Date";"Count"; "Max/Min"; "Mean"; "Median"; "Std"; "AverageCircularity"; "AverageSolidity"; "AverageEccentricity";"Polydispersity"];
            app.XDataDropDown.Items = [{""}; dataNames];
            app.XDataDropDown.ItemsData = [{""}; dataNames];
            app.YDataDropDown.Items = [{""}; dataNames];
            app.YDataDropDown.ItemsData = [{""}; dataNames];
            
            app.XDataDropDown.Value = "Date";
            XDataDropDownValueChanged(app,event)
            app.YDataDropDown.Value = "Max/Min";
            YDataDropDownValueChanged(app,event)
            %--------------------SUBPLOT 3-----------------------------%
            hold(app.ax3,'on');
            yData5 = app.vizTable.AverageCircularity;
            yData6 = app.vizTable.AverageSolidity;
            yData7 = app.vizTable.AverageEccentricity;
            %yData8 = app.vizTable.Polydispersity;
            p5 = plot(app.ax3,xData,yData5,'DisplayName','AverageCircularity');
            p6 = plot(app.ax3,xData,yData6,'DisplayName','AverageSolidity');
            p7 = plot(app.ax3,xData,yData7,'DisplayName','AverageEccentricity');
            %p8 = plot(ax3,xData,yData8,'DisplayName','polyDispersity');
            xlim(app.ax3,[xData(1) xData(end)]);
            xlabel(app.ax3,'Image Date');
            ylabel(app.ax3,'regionprops Value');
            title(app.ax3, 'Sphere Characteristics by Sample Date');
            legend(app.ax3, [p5 p6 p7], 'location','bestoutside');
            hold(app.ax3,'off');
            
            app.PlotTitleEditField_4.Value = "Sphere Characteristics by Sample Date";
            app.XLabelEditField_2.Value = "Image Date";
            app.YLabelEditField_2.Value = "regionprops Value";
            app.CircularityCheckBox.Value = 1;
            app.SolidityCheckBox.Value = 1; 
            app.EccentricityCheckBox.Value = 1; 
            %use panel to manipulate data and titles 
            %add check boxes to include aggregate data components
         
        end

        % Value changed function: XDataDropDown
        function XDataDropDownValueChanged(app, event)
            value = app.XDataDropDown.Value;
            app.xChoice = string(value);
            if app.compareState == 0
                if app.xChoice == "Date"
                    app.xDataChoice = datetime(app.vizTable.(app.xChoice),'InputFormat','ddMMMyyyy');
                elseif app.xChoice == "Max/Min"
                    app.xDataChoice = [app.vizTable.Max, app.vizTable.Min];
                else
                    app.xDataChoice = app.vizTable.(app.xChoice);
                end
            end
        end

        % Value changed function: YDataDropDown
        function YDataDropDownValueChanged(app, event)
            value = app.YDataDropDown.Value;
            app.yChoice = string(value);
            if app.compareState == 0
                if app.yChoice == "Date"
                    app.yDataChoice = datetime(app.vizTable.(app.yChoice),'InputFormat','ddMMMyyyy');
                elseif app.yChoice == "Max/Min"
                    app.yDataChoice = [app.vizTable.Max, app.vizTable.Min];
                else
                    app.yDataChoice = app.vizTable.(app.yChoice);
                end
            end
        end

        % Value changed function: ShowPassagesButton
        function ShowPassagesButtonValueChanged(app, event)
            value = app.ShowPassagesButton.Value;
            %get passage events from vizTable
            if app.compareState == 0
                vesselNum = cellfun(@(x) regexp(x,'\d+','match'),app.vizTable.VesselID);
                changeSearch = ischange(str2double(vesselNum));%returns logical index on date of ID change
                changeSearch = circshift(changeSearch,-1); %shift cells to capture day of passage
                xData = datetime(app.vizTable.Date,'InputFormat','ddMMMyyyy');
                xData = xData(changeSearch)';
                if value == 1
                    ylimit = ylim(app.ax1);
                    yTop = ones(1,numel(xData)).*ylimit(2);
                    yBottom = ones(1,numel(xData)).*ylimit(1);
                    %plot on ax1 and ax3
                    hold(app.ax1, 'on');
                    passageLines = plot(app.ax1,[xData;xData], [yTop;yBottom],'*:','HandleVisibility','on',...
                        'DisplayName','Passage');
                    hold(app.ax1, 'off');
                    
                    ylimit = ylim(app.ax3);
                    yTop = ones(1,numel(xData)).*ylimit(2);
                    yBottom = ones(1,numel(xData)).*ylimit(1);
                    hold(app.ax3, 'on');
                    passageLines2 = plot(app.ax3,[xData;xData], [yTop;yBottom],'*:','HandleVisibility','on',...
                        'DisplayName','Passage');
                    hold(app.ax3, 'off');
                else
                    delete(app.ax1.Children(1:numel(xData)));
                    delete(app.ax3.Children(1:numel(xData)));
                end
            else
                vesselIDs_nonGraphical = string(fieldnames(app.vesselCompare));
                childCount = 0; 
                hold(app.ax1,'on');
                for k = 1 : length(vesselIDs_nonGraphical)
                    vesselNum = cellfun(@(x) regexp(x,'\d+','match'),string(app.vesselCompare.(vesselIDs_nonGraphical(k)).VesselID));
                    changeSearch = ischange(str2double(vesselNum));
                    changeSearch = circshift(changeSearch,-1);
                    [xData,yData] = vesselVizComparison(app.vesselCompare, "Mean");
                    xPlotData = xData{k};
                    xPlotData = [xPlotData{:}]';
                    xPlotData = xPlotData(changeSearch)';
                    %assignin('base','xPlotData',xPlotData);
                    childCount = childCount + numel(xPlotData);
                    yPlotData = yData{k};
                    yPlotData = yPlotData(changeSearch)'; 
                    %assignin('base','yPlotData',yPlotData);
                    %plot only on ax1
                    if value == 1
                        clrs=['r','g','b','c','m','y','k'];
                        plot(app.ax1,xPlotData, yPlotData,'*','Color',clrs(k),'HandleVisibility','on',...
                            'DisplayName','Passage');
                    else
                        delete(app.ax1.Children(1:numel(childCount)));
                    end
                end
                hold(app.ax1,'on');             
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
           if app.VisualizeCheckBox.Value == 1 && app.compareState == 0 %update all fields and data specs
               if app.ShowPassagesButton.Value == 1
                   app.ShowPassagesButton.Value = 0;
                   ShowPassagesButtonValueChanged(app,event)
               end
    
               %--------------------SUBPLOT 1-UPDATE-------------------------%
    
                cla(app.ax1,'reset');
                xData = datetime(app.vizTable.Date,'InputFormat','ddMMMyyy');
                yData1 = app.vizTable.Mean; 
                p1 = plot(app.ax1,xData,yData1,'DisplayName','Mean Diameter');
                xlim(app.ax1,[xData(1) xData(end)]);
                ylim(app.ax1,[0 max(yData1)+25]);
                xlabel(app.ax1,app.XLabelEditField.Value);
                ylabel(app.ax1,app.YLabelEditField.Value);
                title(app.ax1, app.PlotTitleEditField_2.Value);
                %legend(app.ax1, p1,'location','best'); %too crowded for legend
    
                %--------------------SUBPLOT 2--------CUSTOMIZABLE-----------%
    
                cla(app.ax2,'reset');
                hold(app.ax2,'on'); 
                if app.xChoice == "Date"
                    pChoice = plot(app.ax2,app.xDataChoice,app.yDataChoice,'DisplayName',...
                        [convertStringsToChars(app.yChoice) ' vs ' convertStringsToChars(app.xChoice)]);
                else
                    pChoice = scatter(app.ax2,app.xDataChoice,app.yDataChoice, 20, "blue","filled");
                end
                xlim(app.ax2,'auto');
                ylim(app.ax2,'auto');
                xlabel(app.ax2,app.xChoice);
                ylabel(app.ax2,app.yChoice);
                title(app.ax2, [convertStringsToChars(app.yChoice) ' vs ' convertStringsToChars(app.xChoice)]);
                hold(app.ax2, 'off');
    
                app.PlotTitleEditField_3.Value = [convertStringsToChars(app.yChoice) ' vs ' convertStringsToChars(app.xChoice)];
    
                %--------------------SUBPLOT 3-UPDATE------------------------%
    
                cla(app.ax3, 'reset')
                hold(app.ax3,'on');
                legendGroup = [];
                if app.CircularityCheckBox.Value == 1 
                    yData5 = app.vizTable.AverageCircularity; 
                    p5 = plot(app.ax3,xData,yData5,'DisplayName','AverageCircularity');
                    legendGroup = [legendGroup, p5];
                end
                if app.SolidityCheckBox.Value == 1
                    yData6 = app.vizTable.AverageSolidity; 
                    p6 = plot(app.ax3,xData,yData6,'DisplayName','AverageSolidity');
                    legendGroup = [legendGroup, p6];
                end
                if app.EccentricityCheckBox.Value == 1
                    yData7 = app.vizTable.AverageEccentricity;
                    p7 = plot(app.ax3,xData,yData7,'DisplayName','AverageEccentricity');
                    legendGroup = [legendGroup, p7];
                end
                if app.PolydispersityCheckBox.Value == 1
                    yData8 = app.vizTable.Polydispersity;
                    p8 = plot(app.ax3,xData,yData8,'DisplayName','polyDispersity');
                    legendGroup = [legendGroup, p8];
                end
                xlim(app.ax3,[xData(1) xData(end)]);
                xlabel(app.ax3,app.XLabelEditField_2.Value);
                ylabel(app.ax3,app.YLabelEditField_2.Value);
                title(app.ax3, app.PlotTitleEditField_4.Value);
                legend(app.ax3,legendGroup,'location','bestoutside');
                hold(app.ax3,'off');
           elseif app.VisualizeCheckBox.Value == 1 && app.compareState == 1

                %--------------------SUBPLOT 1----UPDATE---------------------%
                cla(app.ax1,'reset');
                [xData,yData,vesselIDs] = vesselVizComparison(app.vesselCompare, "Mean");
                hold(app.ax1,'on');
                legend(app.ax1,'show','location','bestoutside');
                for j = 1 : length(vesselIDs)
                    xPlotData = xData{j};
                    xPlotData = [xPlotData{:}]';
                    yPlotData = yData{j};
                    plot(app.ax1, xPlotData, yPlotData,'DisplayName',vesselIDs(j));
                end
                xlabel(app.ax1,app.XLabelEditField.Value);
                ylabel(app.ax1,app.YLabelEditField.Value);
                title(app.ax1, app.PlotTitleEditField_2.Value);

                %--------------------SUBPLOT 2--------CUSTOMIZABLE-----------%
    
                cla(app.ax2,'reset');
                hold(app.ax2,'on'); 
                if app.xChoice == "Date"
                    [xData, yData, vesselIDs] = vesselVizComparison(app.vesselCompare,[app.xChoice, app.yChoice]);
                    legend(app.ax2,'show','location','bestoutside');
                    for j = 1 : length(vesselIDs)
                        xPlotData = xData{j};
                        xPlotData = [xPlotData{:}]';
                        yPlotData = yData{j};
                        plot(app.ax2, xPlotData, yPlotData,'DisplayName',vesselIDs(j));
                    end
                elseif app.yChoice == "Date"
                    [xData, yData, vesselIDs] = vesselVizComparison(app.vesselCompare,[app.xChoice, app.yChoice]);
                    legend(app.ax2,'show','location','bestoutside');
                    for j = 1 : length(vesselIDs)
                        xPlotData = xData{j};
                        yPlotData = yData{j};
                        yPlotData = [yPlotData{:}]';
                        plot(app.ax2, xPlotData, yPlotData,'DisplayName',vesselIDs(j));
                    end
                else
                    [xData, yData, vesselIDs] = vesselVizComparison(app.vesselCompare,[app.xChoice, app.yChoice]);
                    legend(app.ax2,'show','location','bestoutside');
                    clrs=['r','g','b','c','m','y','k'];
                    for j = 1 : length(vesselIDs)
                        xPlotData = xData{j};
                        yPlotData = yData{j};
                        scatter(app.ax2, xPlotData, yPlotData, 20,clrs(j),"filled",'DisplayName',vesselIDs(j));
                    end
                end
                xlim(app.ax2,'auto');
                ylim(app.ax2,'auto');
                xlabel(app.ax2,app.xChoice);
                ylabel(app.ax2,app.yChoice);
                title(app.ax2, [convertStringsToChars(app.yChoice) ' vs ' convertStringsToChars(app.xChoice)]);
                hold(app.ax2, 'off');
    
                app.PlotTitleEditField_3.Value = [convertStringsToChars(app.yChoice) ' vs ' convertStringsToChars(app.xChoice)]; 

           elseif app.VisualizeCheckBox.Value == 0 &&app.compareState == 0
                app.CenterPanel.AutoResizeChildren = 'on';
                delete(app.ax1);
                delete(app.ax2);
                delete(app.ax3);
           elseif app.VisualizeCheckBox.Value == 0 && app.compareState == 1
                app.CenterPanel.AutoResizeChildren = 'on';
                delete(app.ax1);
                delete(app.ax2);
           end
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            close(app.SphereImageProcessingUIFigure);
        end

        % Button pushed function: BATCHButton
        function BATCHButtonPushed(app, event)
            app.STARTButton.Enable = 'off';
            [batchFileGroup,batchAggregateTable] = batchProcess(app.toBeProcessed, app.individualVesselFolder); %individualVesselFolder is already string
            assignin('base','batchFileGroup',batchFileGroup);

            initialSearch = dir(fullfile('**\Individual Image Data\*.xlsx'));
            initialSearch = initialSearch(~startsWith({initialSearch.name},'~'));
            [initialDetectStore] = individualImageDataSearch(initialSearch,0); %sorts data as well!
            
            appFig = gcf; 
            %plot data in subplots 
            batchDateStrings = fieldnames(batchFileGroup);
            for i = 1 : length(batchDateStrings)
                %convert batch date string to datetime and then back
                batchSplit = strsplit(batchDateStrings{i},'_');
                batchDate = string(datetime(batchSplit{end},'InputFormat',"ddMMMyyyy"),"ddMMMyyyy");
                if isempty(batchAggregateTable) || isempty(find(string(batchAggregateTable.Date) == batchDate, 1))
                    batchState = 0; %prompt user to process control file prior to automation
                    sizeBatch = numel(batchFileGroup.(batchDateStrings{i}));
                    if sizeBatch <= 4
                        app.batchAx = [];
                        for k = 1:sizeBatch
                            app.batchAx = [app.batchAx subplot(2,2,k)];
                        end
                    elseif 4 < sizeBatch && sizeBatch <= 9
                        app.batchAx = [];
                        for k = 1:sizeBatch
                            app.batchAx = [app.batchAx subplot(3,3,k)]; 
                        end
                    elseif 9 < sizeBatch && sizeBatch <= 16
                        app.batchAx = [];
                        for k = 1:sizeBatch
                            app.batchAx = [app.batchAx subplot(4,4,k)];
                        end 
                    elseif 16 < sizeBatch && sizeBatch <= 25
                        app.batchAx = [];
                        for k = 1:sizeBatch
                            app.batchAx = [app.batchAx subplot(5,5,k)];
                        end
                    end
        
                    for j = 1:sizeBatch
                        imshow(batchFileGroup.(batchDateStrings{i})(j),'Parent', app.batchAx(j));
                    end

                    batchFig = gcf; 
        
                    uiwait(msgbox('Select the image you wish to process as the control mean:'));
        
                    selectState = 0; 
                    while selectState == 0
                        w = waitforbuttonpress; %0 if clicked, 1 if key pressed    
                        clickedAx = app.batchAx == gca();
                        imagePos = find(clickedAx == 1);
                        %close;
                        app.UIAxes.Visible = 'on';
                        cla(app.UIAxes,'reset');
                        app.SphereImageProcessingUIFigure.Visible = 'off';
                        app.SphereImageProcessingUIFigure.Visible = 'on';
                        imshow(batchFileGroup.(batchDateStrings{i})(clickedAx'),'Parent',app.UIAxes);
                        pause(3);
                        msg = ['Image position ' num2str(imagePos) ' was selected...use as control?'];
                        banner = 'Selected Image';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                            'Options', {'YES','Choose Again'});
                         switch selection
                            case 'YES'
                                selectState = 1; 
                                selectedImageLogical = clickedAx';
                                close(batchFig);
                            case 'Choose Again'
                                cla(app.UIAxes,'reset');
                                app.UIAxes.Visible = 'off';
                                figure(batchFig);
                                selectState = 0; 
                                selectedImageLogical = clickedAx';
                                for q = 1:sizeBatch
                                    imshow(batchFileGroup.(batchDateStrings{i})(q),'Parent', app.batchAx(q));
                                end
                                uiwait(msgbox('Select the image you wish to process as the reference mean:'));
                         end
                        %[selectState,selectedImageLogical] = subplotSelect(clickedAx,batchFileGroup,batchDateStrings,i);
                    end
                    selectedImage = batchFileGroup.(batchDateStrings{i})(selectedImageLogical);
                    disp(selectedImage); %complete path of selected image
                    uiwait(msgbox('You will now process the selected image to use as a reference mean.'));
                else
                    %close(batchFig);
                    batchState = 1; %automated process will begin with control mean selected
                    aggregateStats = batchAggregateTable(string(batchAggregateTable.Date) == batchDate,:);
                    uiwait(msgbox({"Aggregate mean detected for vessel ID and date.";...
                        string(['Mean Diameter is ' num2str(aggregateStats.Mean) 'um']);...
                        string(['for ' aggregateStats.VesselID{1} ' on ' aggregateStats.Date{1}])}));
                end
            %%%~~~~~~~~~~~~~~~~~~~~~~~~%%%
            %%%  process control image %%%
            %%%~~~~~~~~~~~~~~~~~~~~~~~~%%%
            % aquire reference/control stats
                if batchState == 0
                    %%%find image index in individualToBeProcessed
                    selectedImageFileName = strsplit(selectedImage,'\');
                    selectedImageFileName = selectedImageFileName(end);%NAS file name 
                    processedSearch = app.individualToBeProcessed.FileName == selectedImageFileName; 
                    processedIndex = find(processedSearch == 1,1,'first'); %index of selected image in structure
                    assignin('base','processedIndex',processedIndex);
                    app.ImageProcessingEditField.Value = "WAITING";
                    app.ExportingDataEditField.Value = "WAITING";
                    %get full file name
                    app.ProgressEditField.Value = [num2str(1) ' of ' num2str(numel(selectedImage))];
                    %individual vessels processed
                    app.FILENAMEEditField.Value = app.individualToBeProcessed.AdjustedFileName(processedIndex);
                    app.SphereStats.Name = app.individualToBeProcessed.AdjustedFileName(processedIndex);
                    app.ImageDateEditField.Value = app.individualToBeProcessed.FileDate(processedIndex);
                    app.fullFileNameGlobal = app.individualToBeProcessed.FilePath(processedIndex);
                    %get vessel ID
                    vesselFileName = app.individualToBeProcessed.AdjustedFileName(processedIndex);
                    vesselString = strsplit(vesselFileName, ' ');
                    vesselString = vesselString{1};
                    app.SphereStats.VesselID = vesselString; 
          
                    %read and create greyscale image
                    app.originalImage = imread(app.fullFileNameGlobal);

                    %%% Assess image size
                    imageSize = imfinfo(app.fullFileNameGlobal);
                    imageWidth = imageSize.Width;
                    imageHeight = imageSize.Height; 
                    %%%

                    cla(app.UIAxes, 'reset');
                    imshow(app.originalImage, 'Parent',app.UIAxes);
                    %determine if input image is NO LIGHT or NORMAL 
    
                    msg = 'Select Image Property for Modified Processing';
                    banner = 'Image Property';
                    selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'NO LIGHT','LIGHT','NO LIGHT-UNSATURATED', 'LIGHT-NONHOMOGENEOUS'});                   
                    app.imageProperty = convertCharsToStrings(selection);

                    if app.imageProperty == "NO LIGHT"
                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        %begin parsed processing
                        app.originalImage = rgb2gray(app.originalImage);
                        %app.UIAxes.Visible = 'on';
                        cla(app.UIAxes, 'reset');
        
                        % Display the greyscale image.
                        imshow(app.originalImage, 'Parent',app.UIAxes);
        
                        %enhance greyscale with IMADJUST
                        cla(app.UIAxes,'reset');
                        app.originalImage = imadjust(app.originalImage); %This seems to be the best!
                        imshow(app.originalImage, 'Parent',app.UIAxes);
                        pause(1);
          
                        %display histogram
                        [pixelCount, grayLevels] = imhist(app.originalImage);
                        cla(app.UIAxes,'reset');
                        bar(pixelCount,'Parent',app.UIAxes);
                        title(app.UIAxes,'Histogram of original image');
                        xlim(app.UIAxes,[0 (grayLevels(end)+5)]); % Scale x axis manually.
                        grid(app.UIAxes,'on');
        
                        %prompt for thresholding value
                        % Ask the user for the real-world distance.
                        userPrompt = {'Choose low threshold:', 'Choose high threshold:'};
                        dialogTitle = 'Specify threshold value for binary image';
                        numberOfLines = 1;
                        %opts.Resize = 'on';
                        def = {'',''};
                        answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                        if isempty(answer)
	                        return;
                        end
                        lowThreshold = str2double(answer{1});
                        highThreshold = str2double(answer{2});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        autoStore = [autoStore string(answer{2})];
                        %%% ---------------- %%%
        
                        %show user input
                        app.binaryImage = (app.originalImage >= lowThreshold) & (app.originalImage <= highThreshold);    
                        % Show the threshold as a vertical red bar on the histogram.
                        hold(app.UIAxes,'on');
                        maxYValue = ylim(app.UIAxes);
                        line(app.UIAxes,[lowThreshold, lowThreshold], maxYValue, 'Color', 'r');
                        line(app.UIAxes,[highThreshold,highThreshold],maxYValue, 'Color', 'r');
                        % Place a text label on the bar chart showing the threshold.
                        annotationText1 = sprintf('Low Thresholded at %d gray levels', lowThreshold);
                        annotationText2 = sprintf('High Thresholded at %d gray levels', highThreshold);
                        % For text(), the x and y need to be of the data class "double" so let's cast both to double.
                        text(double(lowThreshold + 5), double(0.5 * maxYValue(2)), annotationText1, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes);
                        text(double(highThreshold + 5), double(0.5 * maxYValue(2)), annotationText2, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes);
                        text(double(highThreshold - 70), double(0.94 * maxYValue(2)), 'Background', 'FontSize', 10, 'Color', [0 0 .5],'Parent',app.UIAxes);
                        text(double(highThreshold + 50), double(0.94 * maxYValue(2)), 'Foreground', 'FontSize', 10, 'Color', [0 0 .5],'Parent',app.UIAxes);
                        hold(app.UIAxes,'off');
        
                        %generate binary image
                        % Do a "hole fill" to get rid of any background pixels or "holes" inside the blobs.
                        app.binaryImage = imfill(app.binaryImage, 'holes');
                        cla(app.UIAxes, 'reset');
                        imshow(app.binaryImage, 'Parent', app.UIAxes);
        
                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
                                app.binaryImage = imclearborder(app.binaryImage,4);
                                cla(app.UIAxes, 'reset');
                                imshow(app.binaryImage,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%
   
                        %bwareaopen
                        app.binaryImage = bwareaopen(app.binaryImage,200);
                        cla(app.UIAxes,'reset');
                        imshow(app.binaryImage,'Parent',app.UIAxes);
                        title(app.UIAxes,'Objects Smaller than Set Value in Foreground are Filtered');
                        pause(1)
      
                        app.binaryImage = bwmorph(app.binaryImage,'close');
                        imshow(app.binaryImage,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1)

                        %overlay
                        cla(app.UIAxes,'reset');
                        imshow(labeloverlay(app.originalImage, app.binaryImage), 'Parent', app.UIAxes);
                        title(app.UIAxes, 'Mask Over Original Image');
                        pause(1)
            
                        %Start watershed transform processing here:
                        app.D = -bwdist(~app.binaryImage);
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld), 'Parent', app.UIAxes);
                        pause(1)
        
                        app.bw2 = app.binaryImage;
                        app.bw2(app.Ld == 0) = 0; 
                        %imshow(app.bw2,'Parent',app.UIAxes);
        
                        %extendedmin
                        app.mask = imextendedmin(app.D, 2); %catch more spheres with 1
                        cla(app.UIAxes, 'reset');
                        imshowpair(app.binaryImage, app.mask, 'blend','Parent',app.UIAxes);
                        pause(1)
        
                        %apply watershed to binary mask
                        app.D2 = imimposemin(app.D, app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = app.binaryImage; 
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3, 'Parent', app.UIAxes);
                        pause(1)
        
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";
                        
                    elseif app.imageProperty == "LIGHT" %add light processing
                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
                        userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
                        dialogTitle = 'Ridge Line';
                        numberOfLines = 1;
                        def = {''};
                        answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                        if isempty(answer)
	                        return;
                        end
                        ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%
    
                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes); %map
                        pause(1)
                        
                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
                                M = imclearborder(M,4);
                                cla(app.UIAxes, 'reset');
                                imshow(M,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%

                        % apply bwareaopen
                        BWopen = bwareaopen(M, 200);
                        cla(app.UIAxes, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes);
                        pause(1)

                        
                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWopen;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes);
                            title(app.UIAxes,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWopen,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%
                        
                        %fill holes

                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Filled Binary Image');
                        pause(1)

                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfill;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfill,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%

                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1) 
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes);
                        pause(1);
                        
                        H = 2; %not extremely necessary to manually enter this number
                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes)
    
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";

                    elseif app.imageProperty == "NO LIGHT-UNSATURATED" %unsaturated, no light image (no clear boundaries, OR overcrowding)

                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
                        userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
                        dialogTitle = 'Ridge Line Value';
                        numberOfLines = 1;
                        def = {''};
                        answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                        if isempty(answer)
	                        return;
                        end
                        ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%
    
                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        M = ~M; %reverse image
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes); %map
                        pause(1)

                        %Erode filled image until the overlay fits the
                        %original
                        BWerode = M;
                        erodeState = 1;
                        radius = 0;
                        while erodeState == 1
                            cla(app.UIAxes,'reset');
                            imshow(BWerode, 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Erode Image');
                            msg = 'Are spheres disconnected enough to filter out negative space?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    erodeState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('disk',radius);
                                    BWerode = imerode(M,seD);
                                    BWerode = imerode(BWerode,seD);
                                    erodeState = 1; 
                                case 'Exit'
                                    erodeState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %%%%%% NEED TO FINE TUNE THIS!%%%%%%%%
                        %Connected spheres get erased
                        BWfilt = BWerode;
                        filterState = 1;
                        while filterState == 1
                            cla(app.UIAxes,'reset');
                            imshow(BWfilt,'Parent',app.UIAxes);
                            filterProps = regionprops(BWfilt,'Area');
                            areaVals = [filterProps.Area];
                            areaVals_excludeHigh = max(areaVals);
                            msg = 'Filter out max area?';
                            banner = 'Eliminate large areas';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg,banner,...
                                'Options', {'YES','NO'});
                            switch selection
                                case 'YES'
                                    filterState = 1; 
                                    BWfilt = bwareafilt(BWerode,[1,areaVals_excludeHigh-100]); %range is the allowable pixel area
                                case 'NO'
                                    filterState = 0; 
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(areaVals_excludeHigh)];
                        %%% ---------------- %%%

                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
                                BWfilt = imclearborder(BWfilt,4);
                                cla(app.UIAxes, 'reset');
                                imshow(BWfilt,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%

                        BWopen= bwareaopen(BWfilt, 200);
                        cla(app.UIAxes, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes);
                        pause(1)

                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWopen;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes);
                            title(app.UIAxes,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWopen,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%
                        
                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Filled Binary Image');
                        pause(1)

                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfill;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfill,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1)
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes);
                        pause(1);

                        H = 2; %not extremely necessary to manually enter this number
                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes)
    
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";

                    elseif app.imageProperty == "LIGHT-NONHOMOGENEOUS" %inconsitent sphere sizes in light processing

                        app.ImageProcessingEditField.Value = "WORKING";
                        app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
                        userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
                        dialogTitle = 'Ridge Line Value';
                        numberOfLines = 1;
                        def = {''};
                        answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                        if isempty(answer)
	                        return;
                        end
                        ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%

                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes); %map
                        pause(1)

                        BWopen= bwareaopen(M, 200);
                        cla(app.UIAxes, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes);
                        pause(1)
                        
                        %Erode filled image until the overlay fits the
                        %original
                        BWerode = BWopen;
                        erodeState = 1;
                        radius = 0;
                        while erodeState == 1
                            cla(app.UIAxes,'reset');
                            imshow(BWerode, 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Erode Image');
                            msg = 'Are spheres disconnected enough to start fill process?';
                            banner = 'Erode Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    erodeState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('disk',radius);
                                    BWerode = imerode(BWopen,seD);
                                    BWerode = imerode(BWerode,seD);
                                    erodeState = 1; 
                                case 'Exit'
                                    erodeState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%

                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWerode;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes);
                            title(app.UIAxes,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWerode,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%

                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Filled Binary Image');
                        pause(1)

                         %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
                                BWfilt = imclearborder(BWfill,4);
                                cla(app.UIAxes, 'reset');
                                imshow(BWfilt,'Parent',app.UIAxes);
                                title(app.UIAxes,'Cleared Border Image');
                            case 'NO'
                                BWfilt = BWfill; 
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%

                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfilt;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes);
                            title(app.UIAxes, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfilt,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0; 
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes);
                        title(app.UIAxes, 'Closed Binary Image');
                        pause(1)
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes);
                        pause(1);
                        
                        H = 2; %not extremely necessary to manually enter this number

                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes)
    
                        app.ImageProcessingEditField.Value = "DONE";
                        app.ExportingDataEditField.Value = "WORKING";
                    end

                    %%% PROCESS REFERENCE MEAN IMAGE %%%
                    %%%%%--------------------------------------------%%%%%
                    %%%%%--------------SPHERE DATA-------------------%%%%%
                    %%%%%--------------------------------------------%%%%%
    
                    %%%% ADD distance per pixel
                    if app.ADSImagesCheckBox.Value == 0
                        if imageWidth == 2592
                            app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                        elseif imageWidth == 1024
                            app.distancePerPixel = 2.7037; %scale bar of 4X image, small
                        else
                            app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                        end
                    else
                        app.distancePerPixel = 1.8587; %Martonio's measurement
                    end
                
                    app.props = regionprops(app.bw3,app.originalImage, 'all');
                    app.numberOfSpheres = numel(app.props);
        
                    % Print out the measurements to the command window, and display blob numbers on the image.
                    % Print header line in the command window.
                    fprintf(1,'Sphere #      Mean Intensity  Area   Perimeter    Centroid       Diameter\n');
                    % Extract all the mean diameters into an array.
                    % The "diameter" is the "Equivalent Circular Diameter", which is the diameter of a circle with the same number of pixels as the blob.
                    % Enclosing in brackets is a nice trick to concatenate all the values from all the structure fields (every structure in the props structure array).
    
                    blobECD = [app.props.EquivDiameter];
    
                    %prompt for thresholding value
                    % Ask the user for the real-world distance.
                    userPrompt = {'Minimum Diameter:', 'Maximum Diameter:'};
                    dialogTitle = 'Choose Size Exclusion Filter:';
                    numberOfLines = 1;
                    %opts.Resize = 'on';
                    def = {num2str(min(blobECD)),num2str(max(blobECD))};
                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines, def);
                    if isempty(answer)
                        return;
                    end
                    minSize = str2double(answer{1});
                    maxSize = str2double(answer{2});
                    %%% AUTO STORE VALUE %%%
                    autoStore = [autoStore string(answer{1})];
                    autoStore = [autoStore string(answer{2})];
                    %%% ---------------- %%%
                    % Loop over all blobs printing their measurements to the command window.
                    count = 0;
                    kStore = [];
                    if app.ADSImagesCheckBox == 1
                        for k = 1 : app.numberOfSpheres          % Loop through all blobs.
                            if blobECD(k) >= minSize && blobECD(k) <= maxSize %minimum calculated diameter to filter out tiny detected blobs
                                count = count + 1;
                                % Find the individual measurements of each blob.  They are field of each structure in the props strucutre array.
                                % You could use the bracket trick (like with blobECD above) OR you can get the value from the field of this particular structure.
                                % I'm showing you both ways and you can use the way you like best.
                                meanGL = app.props(k).MeanIntensity;		% Get average intensity.
                                blobArea = app.props(k).Area;				% Get area.
                                blobPerimeter = app.props(k).Perimeter;		% Get perimeter.
                                blobCentroid = app.props(k).Centroid;		% Get centroid one at a time
                                blobDiameter(count) = blobECD(k);              %Specific diameter value
                                kStore = [kStore; k];       %for indexing props after
                                % Now do the printing of this blob's measurements to the command window.
                                fprintf(1,'#%2d %17.1f %11.1f %8.1f %8.1f %8.1f % 8.1f\n', k, meanGL, blobArea, blobPerimeter, blobCentroid, blobDiameter(count));
                                % Put the "blob number" labels on the grayscale image that is showing the red boundaries on it.
                                %text(blobCentroid(1), blobCentroid(2), num2str(count), 'FontSize', textFontSize, 'FontWeight', 'Bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                            end
                        end
                    else
                        for k = 1 : app.numberOfSpheres          % Loop through all blobs.
                            if blobECD(k) >= minSize && blobECD(k) <= maxSize %minimum calculated diameter to filter out tiny detected blobs
                                count = count + 1;
                                % Find the individual measurements of each blob.  They are field of each structure in the props strucutre array.
                                % You could use the bracket trick (like with blobECD above) OR you can get the value from the field of this particular structure.
                                % I'm showing you both ways and you can use the way you like best.
                                meanGL = app.props(k).MeanIntensity;		% Get average intensity.
                                blobArea = app.props(k).Area;				% Get area.
                                blobPerimeter = app.props(k).Perimeter;		% Get perimeter.
                                blobCentroid = app.props(k).Centroid;		% Get centroid one at a time
                                blobDiameter(count) = blobECD(k);              %Specific diameter value
                                kStore = [kStore; k];       %for indexing props after
                                % Now do the printing of this blob's measurements to the command window.
                                fprintf(1,'#%2d %17.1f %11.1f %8.1f %8.1f %8.1f % 8.1f\n', k, meanGL, blobArea, blobPerimeter, blobCentroid, blobDiameter(count));
                                % Put the "blob number" labels on the grayscale image that is showing the red boundaries on it.
                                %text(blobCentroid(1), blobCentroid(2), num2str(count), 'FontSize', textFontSize, 'FontWeight', 'Bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                            end
                        end
                    end
    
        
                    %Convert applicable fields to microns using calibration value
                    app.newProps = app.props(kStore);
                    app.sphereDiameter = zeros(numel(app.newProps),1);
                    app.circularity = zeros(numel(app.newProps),1);
                    app.solidity = zeros(numel(app.newProps),1);
                    app.eccentricity = zeros(numel(app.newProps),1);
                    for k = 1:numel(app.newProps)
                        app.newProps(k).EquivDiameter = app.newProps(k).EquivDiameter.*app.distancePerPixel;
                        app.sphereDiameter(k) = app.newProps(k).EquivDiameter; 
                        app.circularity(k) = app.newProps(k).Circularity;
                        app.solidity(k) = app.newProps(k).Solidity; 
                        app.eccentricity(k) = app.newProps(k).Eccentricity; 
                    end
                    app.numberOfSpheres = numel(app.newProps);
                    sprintf('%d spheres ultimately detected.\n', app.numberOfSpheres);
                    if app.ADSImagesCheckBox.Value == 0
                        app.SphereStats.Date = app.ImageDateEditField.Value;
                    elseif app.ADSImagesCheckBox.Value == 1
                        app.SphereStats.ID = app.FILENAMEEditField.Value; 
                        A = imfinfo(app.fullFileNameGlobal);
                        B = A.FileModDate;
                        C = strsplit(B,' ');
                        app.SphereStats.Date = C{1};
                    end
                    app.SphereStats.count = app.numberOfSpheres;
                    app.SphereStats.max = max(app.sphereDiameter); % converted
                    app.SphereStats.min = min(app.sphereDiameter); % converted
                    app.SphereStats.mean = mean(app.sphereDiameter); % converted
                    app.SphereStats.median = median(app.sphereDiameter); %converted
                    app.SphereStats.std = std(app.sphereDiameter); %converted
                    %find mode bin size
                    [N, edges] = histcounts(app.sphereDiameter,20);
                    maxIndex = find(N==max(N));
                    modeBin = [];
                    if numel(maxIndex) > 1
                        edgeStore = zeros(numel(maxIndex)*2, 1);
                        for j = 1:numel(maxIndex)
                            edgeStore(j) = edges(maxIndex(j));
                            edgeStore(j+1) = edges(maxIndex(j)+1);
                            if j == 1                         
                                modeBin = [modeBin [num2str(edgeStore(j)) '-' num2str(edgeStore(j+1))]];
                            else
                                modeBin = [modeBin ', ' [num2str(edgeStore(j)) '-' num2str(edgeStore(j+1))]];
                            end
                        end
                        app.SphereStats.modeBin = modeBin;
                    else
                        edge1 = edges(maxIndex);
                        edge2 = edges(maxIndex+1);
                        app.SphereStats.modeBin = [num2str(edge1) '-' num2str(edge2)];
                    end
      
                    app.CaptureLabel.Visible = 'on';
                    app.NumberofSpheresEditField.Visible = 'on';
                    app.NumberofSpheresEditFieldLabel.Visible = 'on';
                    app.MaxDiameterEditField.Visible = 'on';
                    app.MaxDiameterEditFieldLabel.Visible = 'on';
                    app.MinDiameterEditField.Visible = 'on';
                    app.MinDiameterEditFieldLabel.Visible = 'on';
                    app.AverageDiameterEditField.Visible = 'on';
                    app.AverageDiameterEditFieldLabel.Visible = 'on';
                    app.AnalysisLabel.Visible = 'on';
                    app.PlotTitleEditField.Visible = 'on';
                    app.PlotTitleEditFieldLabel.Visible = 'on';
                    app.ViewPDFButton.Visible = 'on';
                    app.ViewCDFButton.Visible = 'on';
                    app.PolydispersityIndexEditField.Visible = 'on';
                    app.PolydispersityIndexEditFieldLabel.Visible = 'on';
                    app.muEditField.Visible = 'on';
                    app.muEditFieldLabel.Visible = 'on';
                    app.sigmaEditField.Visible = 'on';
                    app.sigmaEditFieldLabel.Visible = 'on';
                    app.AverageDoCEditField.Visible = 'on';
                    app.AverageDoCEditFieldLabel.Visible = 'on';
                    app.AverageSolidityEditField.Visible = 'on';
                    app.AverageSolidityEditFieldLabel.Visible = 'on';
                    app.AverageEccentricityEditFieldLabel.Visible = 'on';
                    app.AverageEccentricityEditField.Visible = 'on';
        
                    %Populate Results Section
                    app.NumberofSpheresEditField.Value = string(app.numberOfSpheres); 
                    app.MaxDiameterEditField.Value = string(app.SphereStats.max);
                    app.MinDiameterEditField.Value = string(app.SphereStats.min);
                    app.AverageDiameterEditField.Value = string(app.SphereStats.mean);
    
                    app.SphereStats.AverageCircularity = mean(app.circularity);
                    app.SphereStats.AverageSolidity = mean(app.solidity);
                    app.SphereStats.AverageEccentricity = mean(app.eccentricity);
                    app.AverageDoCEditField.Value = num2str(app.SphereStats.AverageCircularity);
                    app.AverageSolidityEditField.Value = num2str(app.SphereStats.AverageSolidity);
                    app.AverageEccentricityEditField.Value = num2str(app.SphereStats.AverageEccentricity);
                    
                    if numel(app.sphereDiameter) > 1
                        %Run PDF Distribution
                        %create PDF from spherediameter
                        app.sphereDiameter = app.sphereDiameter(:);
                        cla(app.UIAxes,'reset');
                        hold(app.UIAxes,'on');
                        LegHandles = [];
                        LegText = {};
            
                        % --- Plot data originally in dataset "sphereDiameter data"
            
                        [CdfF,CdfX] = ecdf(app.sphereDiameter,'Function','cdf');  % compute empirical cdf
                        BinInfo.rule = 3;
                        BinInfo.nbins = 40;
                        [~,BinEdge] = internal.stats.histbins(app.sphereDiameter,[],[],BinInfo,CdfF,CdfX);
                        [BinHeight,BinCenter] = ecdfhist(CdfF,CdfX,'edges',BinEdge);
                        hLine = bar(BinCenter,BinHeight,'hist','Parent',app.UIAxes);
                        set(hLine,'FaceColor','none','EdgeColor',[0.333333 0 0.666667],...
                            'LineStyle','-', 'LineWidth',1);
                        xlabel(app.UIAxes,'Sphere Diameter (um)');
                        ylabel(app.UIAxes,'Density');
                        LegHandles(end+1) = hLine;
                        LegText{end+1} = 'sphereDiameter data';
            
                        % Create grid where function will be computed
            
                        XLim = get(app.UIAxes,'XLim'); %previously gca (get current axis)
                        XLim = XLim + [-1 1] * 0.01 * diff(XLim);
                        XGrid = linspace(XLim(1),XLim(2),100);
            
                        % --- Create fit "normal fit"
                        pd1 = fitdist(app.sphereDiameter, 'normal'); 
                        YPlot = pdf(pd1,XGrid);
                        hLine = plot(XGrid,YPlot,'Color',[1 0 0],...
                            'LineStyle','-', 'LineWidth',2,...
                            'Marker','none', 'MarkerSize',6,'Parent',app.UIAxes);
                        LegHandles(end+1) = hLine;
                        LegText{end+1} = 'normal fit';
            
                        % Adjust figure
            
                        box(app.UIAxes,'on');
                        hold(app.UIAxes,'off');
            
                        % Create legend from accumulated handles and labels
            
                        hLegend = legend(app.UIAxes,LegHandles,LegText,'Orientation', 'vertical', 'FontSize', 9, 'Location', 'northeast');
                        set(hLegend,'Interpreter','none');
                        
                        pause(1);
        
                        %Calculate polydispersity
                        app.muEditField.Value = string(pd1.mu);
                        app.sigmaEditField.Value = string(pd1.sigma);
                        polyD = (pd1.sigma/pd1.mu)^2;
                        app.PolydispersityIndexEditField.Value = string(polyD);
                        app.SphereStats.PolyDispersity = polyD; 
                    else
                        app.muEditField.Value = string(mean(app.sphereDiameter));
                        app.sigmaEditField.Value = string(std(app.sphereDiameter));
                        polyD = (std(app.sphereDiameter)/mean(app.sphereDiameter))^2; 
                        app.PolydispersityIndexEditField.Value = string(polyD);
                        app.SphereStats.PolyDispersity = polyD; 
                    end
    
                    %FLAG FILE IF PROCESSING REQUIRES FOLLOW-UP
                    msg = 'Flag File for Follow-up?';
                    banner = 'Flag File';
                    selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                    'Options', {'YES','NO'});  
                    switch selection
                        case 'YES'
                            app.SphereStats.FLAG = 1;
                            %prompt for flag comments
                            userPrompt = {'Flag Notes'};
                            dialogTitle = 'Comments';
                            numberOfLines = 1;
                            def = {''};
                            answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                            app.SphereStats.Comments = answer{1};
                        case 'NO'
                            app.SphereStats.FLAG = 0;
                            app.SphereStats.Comments = "Reference";
                    end
    
                    %add ImageProperty to the sphere stats
                    app.SphereStats.ImageProperty = app.imageProperty;
                    app.SphereStats.ImageSize = [num2str(imageWidth) ' x ' num2str(imageHeight)];
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                    %EXPORTING%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                               %AGGREGATE FILES OF SAME NAME SCHEME%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
                    if app.AggregateRepeatedImageFilesNASCheckBox.Value == 1
                        %include file in aggregate
                        msg = 'Include file in aggregated counts?';
                        banner = 'Did you mess up tho';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
                                include = 1; 
                                app.SphereStats.includedInAggregate = "YES";
                            case 'NO'
                                app.SphereStats.includedInAggregate = "NO";
                        end
                    end
                    %%%% Add in AUTO Processing Data! %%%%
                    autoStore = [autoStore "AUTO"];
                    autoStore = strjoin(autoStore,'_');
                    app.SphereStats.AutoProcess = autoStore; 
    
                    %put native path with original file name into excel file
                    %%%%%EXPORTING TO FILE AND THEN MOVING TO DIFFERENT FOLDER
                    % detect existing folders and files, then overwrite files
                    % if needed
                    if app.selectionState == 1 && app.ReadImagesfromSingleVesselCheckBox.Value == 1 %NAS files from individual folder
                        
                        %put native path with original file name into excel file
                        app.SphereStats.FilePath = app.fullFileNameGlobal;
                        app.SphereStats.ProcessedFileName = app.individualToBeProcessed.ProcessedFileName(processedIndex);  
                        app.SphereStats.ProcessedFolder = app.processedVesselFolderName;
                        folderPath = [app.pwdGlobal '\Microscope Images\'];
                        folderName = app.processedVesselFolderName;
                        if isfolder(folderPath) == 0
                            mkdir(folderPath,folderName);
                        end
                        newFolderPath = [folderPath folderName '\'];
                        newFolderName = 'Individual Image Data';
                        if isfolder([newFolderPath newFolderName]) == 0
                            mkdir(newFolderPath,newFolderName);
                        end
                        %get vessel letter
                        vesselFileName = app.FILENAMEEditField.Value; 
                        vesselString = strsplit(vesselFileName, ' ');
                        vesselString = vesselString{1};
                        vesselLetter = regexp(vesselString,'([A-Z]+)','match');
                        excelFileName = vesselLetter{1};
                        excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                        %add operator ID
                        currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                        nameAndDate = [app.operatorInitials currentDate];
                        app.operatorID = strjoin(nameAndDate,' ');
                        app.SphereStats.Operator = app.operatorID;

                        sphereTable = struct2table(app.SphereStats);
    
                        if isfile(excelPathLocal) == 1                     
                            writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                        else
                            writetable(sphereTable, excelPathLocal);
                        end
                       
                        %save individual sphere data as .mat file in same folder
                        splitFileName = strsplit(app.individualToBeProcessed.ProcessedFileName(processedIndex),'.');
                        splitFileName = convertStringsToChars(splitFileName{1});
                        spherePropsFileName = [newFolderPath newFolderName '\' splitFileName]; 
                        disp(spherePropsFileName);
                        %%%
                        if numel(app.sphereDiameter) > 1
                            individualSphereTable = struct2table(app.newProps);
                            save(spherePropsFileName, 'individualSphereTable');
                        else
                            individualSphereTable = struct2table(app.newProps,'AsArray',true);
                            save(spherePropsFileName, 'individualSphereTable');
                        end
                    
                    elseif app.selectionState == 2
                        newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                        newFolderName = [app.FolderEditField.Value '-PROCESSED']; 
                        if isfolder([newFolderPath newFolderName]) == 0
                            mkdir(newFolderPath,newFolderName);
                        end
                        splitFileName = strsplit(app.FILENAMEEditField.Value,' ');
                        excelFileName = splitFileName{1};
                        excelPathLocal = [app.pwdGlobal '\Microscope Images\' newFolderName '\' excelFileName '.xlsx'];

                        currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                        nameAndDate = [app.operatorInitials currentDate];
                        app.operatorID = strjoin(nameAndDate,' ');
                        app.SphereStats.Operator = app.operatorID;

                        sphereTable = struct2table(app.SphereStats);
    
                        %%% SORTING %%%
                        sphereTable.Date = datetime(sphereTable.Date,'InputFormat',"ddMMMyyyy");
                        sphereTable = sortrows(sphereTable,'Date');
                        sphereTable.Date = string(sphereTable.Date,"ddMMMyyyy");
                        %%% END SORTING %%%
    
                        if isfile(excelPathLocal) == 1
                            writetable(sphereTable, excelPathLocal,'WriteMode','Append','WriteVariableNames', false);
                        else
                            writetable(sphereTable, excelPathLocal);
                        end
                        
                        %save individual sphere data as .mat file in same folder
                        spherePropsFileName = [app.pwdGlobal '\Microscope Images\' newFolderName '\' splitFileName{1} '-' splitFileName{2}];
                        if numel(app.sphereDiameter) > 1
                            individualSphereTable = struct2table(app.newProps);
                            save(spherePropsFileName, 'individualSphereTable');
                        else
                            individualSphereTable = struct2table(app.newProps,'AsArray',true);
                            save(spherePropsFileName, 'individualSphereTable');
                        end
        
                        %move processed file to new PROCESSED folder
                        moveFileSource = [newFolderPath app.FolderEditField.Value '\' app.autoFileGroupGlobal{i}];
                        moveFilePath = [newFolderPath newFolderName];
                        movefile(moveFileSource, moveFilePath);
                    
                    elseif app.selectionState == 3
                        newFolderPath = app.pwdGlobal;
                        newFolderName = [app.FolderEditField.Value '-PROCESSED']; 
                        if isfolder([newFolderPath newFolderName]) == 0
                            mkdir(newFolderPath,newFolderName);
                        end
                        splitFileName = strsplit(app.FILENAMEEditField.Value,' ');
                        excelFileName = splitFileName{1};
                        excelPathLocal = [app.pwdGlobal '\' newFolderName '\' excelFileName '.xlsx'];

                        currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                        nameAndDate = [app.operatorInitials currentDate];
                        app.operatorID = strjoin(nameAndDate,' ');
                        app.SphereStats.Operator = app.operatorID;

                        sphereTable = struct2table(app.SphereStats);
    
                        %%% SORTING %%%
                        sphereTable.Date = datetime(sphereTable.Date,'InputFormat',"ddMMMyyyy");
                        sphereTable = sortrows(sphereTable,'Date');
                        sphereTable.Date = string(sphereTable.Date,"ddMMMyyyy");
                        %%% END SORTING %%%
    
                        if isfile(excelPathLocal) == 1
                            writetable(sphereTable, excelPathLocal,'WriteMode','Append','WriteVariableNames', false);
                        else
                            writetable(sphereTable, excelPathLocal);
                        end
                        
                        %Commented Out until Proper Algorithm Warrants
                        %post-processing
                        %save individual sphere data as .mat file in same folder
                        spherePropsFileName = [app.pwdGlobal '\' newFolderName '\' app.autoFileGroupGlobal{i}];
                        if numel(app.sphereDiameter) > 1
                            individualSphereTable = struct2table(app.newProps);
                            save(spherePropsFileName, 'individualSphereTable');
                        else
                            individualSphereTable = struct2table(app.newProps,'AsArray',true);
                            save(spherePropsFileName, 'individualSphereTable');
                        end
        
                        %move processed file to new PROCESSED folder
                        moveFileSource = [newFolderPath '\' app.FolderEditField.Value '\' app.autoFileGroupGlobal{i}];
                        moveFilePath = [newFolderPath '\' newFolderName];
                        movefile(moveFileSource, moveFilePath);
                    end
    
                    app.ExportingDataEditField.Value = "DONE";                        
    
                    %reset variables
                    %keep SphereStats for reference statistics
                    app.sphereDiameter = [];
                    blobECD = [];
                    meanGL = [];
                    blobArea = [];
                    blobPerimeter = [];
                    blobCentroid = [];
                    blobDiameter = [];
                    app.props = [];
                    app.newProps = [];
                    app.circularity = [];
                    app.solidity = [];
                    app.eccentricity = [];
                    autoStore = []; 

                    referenceStats = app.SphereStats; 
                    app.SphereStats = [];
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    uiwait(msgbox({'Reference mean acquired! You will now';...
                    'proceed to batch processing.'}));
                    
                    app.individualToBeProcessed.FileName(processedIndex) = [];
                    app.individualToBeProcessed.AdjustedFileName(processedIndex) = [];
                    app.individualToBeProcessed.ProcessedFileName(processedIndex) = [];
                    app.individualToBeProcessed.FileDate(processedIndex) = [];
                    app.individualToBeProcessed.FilePath(processedIndex) = [];
                else
                    %change fieldnames 
                    newNames = {'VesselID';'Date';'count';'max';'min';'mean';'median';'std';'modeBin';'AverageCircularity';...
                        'AverageSolidity';'AverageEccentricity';'Polydispersity'};
                    referenceStruct = table2struct(aggregateStats,"ToScalar",true);
                    oldNames = fieldnames(referenceStruct);
                    for b = 1:numel(oldNames)
                        referenceStats.(newNames{b}) = referenceStruct.(oldNames{b});
                    end       
                end
            %%%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~%%%
            %%% begin batch processing %%%
            %%%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~%%%
                %iterate through image properties. below are the test input
                %values 
                %create batch file group of non-selected images
                singleBatchFiles = batchFileGroup.(batchDateStrings{i}); 
                if batchState == 0 %no aggregate files found
                    singleBatchFiles(singleBatchFiles == selectedImage) = []; 
                end
                assignin('base','singleBatchFiles',singleBatchFiles);
                
                noLightArray = "NO LIGHT";
                lightArray = "LIGHT" + strings(5,1);
                lightNONArray = "LIGHT-NONHOMOGENEOUS" + strings(5,1);
                imagePropertyID = [noLightArray; lightArray; lightNONArray]; %imageProperty function ID
                imagePropertyInputs = ["245_260";"10";"8";"6";"4";"2";"10";"8";"6";"4";"2"]; %test input values
                
                fileCounter = 0;
                for m = 1:numel(singleBatchFiles)

                    fileCounter = fileCounter + 1; 
                    %%%find image index in individualToBeProcessed
                    batchImageFileName = strsplit(singleBatchFiles(m),'\');
                    batchImageFileName = batchImageFileName(end);%NAS file name 
                    batchSearch = app.individualToBeProcessed.FileName == batchImageFileName; 
                    batchIndex = find(batchSearch == 1,1,"first"); %index of selected image in structure

                    app.FILENAMEEditField.Value = app.individualToBeProcessed.AdjustedFileName(batchIndex);
                    app.ImageDateEditField.Value = app.individualToBeProcessed.FileDate(batchIndex);
                    app.fullFileNameGlobal = app.individualToBeProcessed.FilePath(batchIndex);

                    app.ImageProcessingEditField.Value = "WAITING";
                    app.ExportingDataEditField.Value = "WAITING";
                    app.ProgressEditField.Value = [num2str(fileCounter) ' of ' num2str(numel(singleBatchFiles))];

                    originalBatchImage = imread(singleBatchFiles(m)); %app.FILENAMEEditField.Value
                    cla(app.UIAxes,'reset');
                    imshow(originalBatchImage,'Parent',app.UIAxes);
                    title(app.UIAxes,convertStringsToChars(singleBatchFiles(m)));
                    pause(3);
                    originalBatchImage = rgb2gray(originalBatchImage);
                    originalBatchImage = imadjust(originalBatchImage);
                    
                    assignin('base','individualToBeProcessed',app.individualToBeProcessed);
                    assignin('base','batchIndex',batchIndex);
                    %individual vessels processed

                    app.FILENAMEEditField.Value = app.individualToBeProcessed.AdjustedFileName(batchIndex);
                    app.ImageDateEditField.Value = app.individualToBeProcessed.FileDate(batchIndex);
                    app.fullFileNameGlobal = app.individualToBeProcessed.FilePath(batchIndex);
                    %get vessel ID

                    vesselFileName = app.individualToBeProcessed.AdjustedFileName(batchIndex);

                    vesselString = strsplit(vesselFileName, ' ');
                    vesselString = vesselString{1};

                    %%% Assess image size
                    imageSize = imfinfo(app.fullFileNameGlobal);
                    imageWidth = imageSize.Width;
                    imageHeight = imageSize.Height; 
                    percentDiffStore = zeros(numel(imagePropertyInputs),1);
                    for n = 1:numel(imagePropertyInputs)
                        [finalBatchImage,autoStore] = imagePropertyProcessing(originalBatchImage,imagePropertyID(n),imagePropertyInputs(n));
                        %%%% ADD distance per pixel %%%%
                        if app.ADSImagesCheckBox.Value == 0
                            if imageWidth == 2592
                                app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                            elseif imageWidth == 1024
                                app.distancePerPixel = 2.7037; %scale bar of 4X image, small
                            else
                                app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                            end
                        else
                            app.distancePerPixel = 1.8587; %Martonio's measurement
                        end
                        app.SphereStats.Name = app.individualToBeProcessed.AdjustedFileName(batchIndex);
                        app.SphereStats.VesselID = vesselString; 
                        app.SphereStats.Date = app.ImageDateEditField.Value; 
                        [app.SphereStats,autoStore,percentDiff,app.newProps] = imagePropertyAnalysis(finalBatchImage,originalBatchImage,autoStore,...
                            referenceStats, app.SphereStats, app.distancePerPixel); 
                        %accumulate all data into substructures until best
                        %percent difference is selected
                        app.SphereStats.ImageProperty = imagePropertyID(n);
                        app.SphereStats.ImageSize = [num2str(imageWidth) ' x ' num2str(imageHeight)];
                        app.SphereStats.includedInAggregate = "YES";
                        app.SphereStats.AutoProcess = autoStore;
                        app.SphereStats.FilePath = app.fullFileNameGlobal;
                        app.SphereStats.ProcessedFileName = app.individualToBeProcessed.ProcessedFileName(batchIndex);  
                        app.SphereStats.ProcessedFolder = app.processedVesselFolderName;

                        percentDiffStore(n) = percentDiff;
                        iterNames = ['RUN_' num2str(n)];
                        disp(iterNames);
                        SphereStatsTOTAL.(iterNames) = app.SphereStats;
                        propsTOTAL.(iterNames) = app.newProps;
                        app.SphereStats = [];
                        app.newProps = [];
                    end
                    %determine which processing iteration to keep, indexed
                    %by the minimum percentDiff
                    percentDiffIndex = percentDiffStore == min(percentDiffStore);
                    totalStatsNames = fieldnames(SphereStatsTOTAL);
                    totalStatsIndex = totalStatsNames(percentDiffIndex);
                    if isempty(totalStatsIndex)
                        optimalSphereStats = SphereStatsTOTAL.(totalStatsNames{5}); %default to run 5
                        optimalProps = propsTOTAL.(totalStatsNames{5});
                        optimalSphereStats.Comments = strjoin([optimalSphereStats.Comments "No Spheres Captured"],'-');
                        optimalSphereStats.FLAG = 1;
                    else
                        optimalSphereStats = SphereStatsTOTAL.(totalStatsIndex{1});
                        optimalProps = propsTOTAL.(totalStatsIndex{1});
                        optimalSphereStats.Comments = strjoin([optimalSphereStats.Comments...
                            [num2str(min(percentDiffStore)) '% Difference']],'-');
                    end
                    %assignin('base','optimalSphereStats',optimalSphereStats);
                    %%%% filter out excess %diff and new props!
                    if min(percentDiffStore) > 25 || optimalSphereStats.count == 0
                        optimalSphereStats.includedInAggregate = "NO";
                        optimalSphereStats.FLAG = 1; 
                    end
                    %now write the file!
                    folderPath = [app.pwdGlobal '\Microscope Images\'];
                    folderName = app.processedVesselFolderName;
                    if isfolder(folderPath) == 0
                        mkdir(folderPath,folderName);
                    end
                    newFolderPath = [folderPath folderName '\'];
                    newFolderName = 'Individual Image Data';
                    if isfolder([newFolderPath newFolderName]) == 0
                        mkdir(newFolderPath,newFolderName);
                    end
                    %get vessel letter
                    vesselFileName = app.FILENAMEEditField.Value; 
                    vesselString = strsplit(vesselFileName, ' ');
                    vesselString = vesselString{1};
                    vesselLetter = regexp(vesselString,'([A-Z]+)','match');
                    excelFileName = vesselLetter{1};
                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                    %add operator ID
                    currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                    nameAndDate = [app.operatorInitials currentDate];
                    app.operatorID = strjoin(nameAndDate,' ');
                    optimalSphereStats.Operator = app.operatorID; 
                    
                    try
                        sphereTable = struct2table(optimalSphereStats);
                    catch
                        sphereTable = struct2table(optimalSphereStats,"AsArray",true);
                    end

                    %%% SORTING %%%
                    sphereTable.Date = datetime(sphereTable.Date,'InputFormat',"ddMMMyyyy");
                    sphereTable = sortrows(sphereTable,'Date');
                    sphereTable.Date = string(sphereTable.Date,"ddMMMyyyy");
                    %%% END SORTING %%%

                    if isfile(excelPathLocal) == 1                     
                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                    else
                        writetable(sphereTable, excelPathLocal);
                    end
                   
                    %save individual sphere data as .mat file in same folder
                    splitFileName = strsplit(app.individualToBeProcessed.ProcessedFileName(batchIndex),'.');
                    splitFileName = convertStringsToChars(splitFileName{1});
                    spherePropsFileName = [newFolderPath newFolderName '\' splitFileName]; 
                    disp(spherePropsFileName);
                    %%%
                    if numel(app.newProps) > 1
                        individualSphereTable = struct2table(optimalProps);
                        save(spherePropsFileName, 'individualSphereTable');
                    else
                        individualSphereTable = struct2table(optimalProps,'AsArray',true);
                        save(spherePropsFileName, 'individualSphereTable');
                    end

                    SphereStatsTOTAL = [];
                    optimalSphereStats = [];
                    propsTOTAL = [];
                    optimalProps = [];
                    pause(3);

                    app.individualToBeProcessed.FileName(batchIndex) = [];
                    app.individualToBeProcessed.AdjustedFileName(batchIndex) = [];
                    app.individualToBeProcessed.ProcessedFileName(batchIndex) = [];
                    app.individualToBeProcessed.FileDate(batchIndex) = [];
                    app.individualToBeProcessed.FilePath(batchIndex) = [];
                end
                
                pause(5);

                uiwait(msgbox('Processing will stop and aggregate data will be updated.'));

                finalSearch = dir(fullfile('**\Individual Image Data\*.xlsx'));
                [finalDetectStore] = individualImageDataSearch(finalSearch, 1);
                %assignin('base','finalDetectStore',finalDetectStore);
                [filesToAggregate] = detectChange(initialDetectStore, finalDetectStore);

                if numel(filesToAggregate) == 0
                    %account for new folder processed that is not counted
                    %in initialDetectStore
                    if numel(finalDetectStore.File) > numel(initialDetectStore.File)
                        msg = 'Novel processed folder detected...continue?';
                        banner = 'Aggregate Data Processing';
                        selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                        'Options', {'Aggregate Initial Data','Exit'});  
                            switch selection
                                case 'Aggregate Initial'
                                    fileSetDiff = setdiff(finalDetectStore.File, initialDetectStore.File);
                                    assignin('base','fileSetDiff',fileSetDiff);
                                    initialDetectStore.File = [initialDetectStore.File fileSetDiff];
                                    initialDetectStore.Num(numel(initialDetectStore.File)) = 0;
                                    %assignin('base','initialDetectStore_update',initialDetectStore);
                                case 'Exit'
                                    %do nothing and continue :)
                            end
                    else
                        uiwait(msgbox('No new files detected.'));
                    end
                    uiwait(msgbox('Initial file detection updated. Click OK to process.'));
                    [filesToAggregate] = detectChange(initialDetectStore, finalDetectStore);
                    for x = 1:numel(filesToAggregate)
                        filename = filesToAggregate(x); 
                        %find files that are to be included in aggregate data
                        opts = detectImportOptions(filename,'NumHeaderLines',0); %reads first row
                        fullTable = readtable(filename,opts);
                        condYES = convertCharsToStrings(fullTable.includedInAggregate) == "YES";
                        newFullTable = fullTable(condYES, :);
                        %assignin('base','newfulltable',newFullTable);
                        if height(newFullTable) == 0
                            disp("No new aggregate files to add")
                        else
                            writeCount = 0;
                            for j = 1:height(newFullTable)
                                %find processed file data and parse necessary data points
                                pathSplit = strsplit(filename, '\');
                                pathSplit = pathSplit(1:end-1);
                                parentPath = convertStringsToChars(strjoin(pathSplit, '\'));
                                %now load the corresponding processed data while conditionally
                                aggregateFile = load([parentPath '\' convertStringsToChars(newFullTable.ProcessedFileName{j})]); %loads struct with individualSphereTable as field
                                %appending data to duplicate image files        
                                if j == 1
                                    aggregateTable = aggregateFile.individualSphereTable; 
                                    %%%steps to remove file data
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
%                                     %%%%%%%%%%% remove file data %%%%%
                                    if height(newFullTable) == 1
                                        writetable(aggregateTable,excelPathLocal);
                                        disp('Single file uploaded');
                                    end
                                elseif j < height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                                    %concatenate sphere data of the same date
                                    aggregateTableToAppend = aggregateFile.individualSphereTable; 
                                    aggregateTable = [aggregateTable; aggregateTableToAppend];
                                elseif j == height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                                    %accounts for end of loop where dates match
                                    aggregateTableToAppend = aggregateFile.individualSphereTable; 
                                    aggregateTable = [aggregateTable; aggregateTableToAppend];
        
                                    %analyze aggregate data 
                                    [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end

                                elseif j == height(newFullTable) && (string(newFullTable.Date{j}) ~= string(newFullTable.Date{j-1}))
                                    %accounts for end of loop where dates do not match
                                    %will need to analyze data of current aggregateTable AND
                                    %endTable
        
                                    %analyze aggregate data before the mismatch
                                    [aggregateStructure] = aggregateData(newFullTable, aggregateTable,j);
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
        
                                    %then pass new aggregateFile through
                                    %aggregateData() 
                                    aggregateEndTable = aggregateFile.individualSphereTable;
        
                                    %analyze aggregate end data 
                                    [aggregateStructure] = aggregateData(newFullTable, aggregateEndTable,j+1); %add 1 to j index for function
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
                                else
                                    writeCount = writeCount + 1; 
                                    %analyze aggregate data of the current aggregateTable
                                    %new exportable excel file
                                    %analyze aggregate data 
                                    [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);
                                    %then, clear aggregateTable and assign new aggregateFile to it
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    %assignin('base','aggregateStructure',aggregateStructure);
                                    if isfile(excelPathLocal) == 1 && writeCount == 1
                                        writetable(sphereTable, excelPathLocal,'WriteMode','replacefile');
                                        disp(['Data reset from ' excelPathLocal]);
                                    elseif isfile(excelPathLocal) == 1 && writeCount ~= 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
                                    
                                    %clear aggregateTable
                                    aggregateTable = [];
                                    sphereTable = []; 
                                    aggregateTable = aggregateFile.individualSphereTable;                           
                                end
                            end
                        end
                    end
                else
                    for x = 1:numel(filesToAggregate)
                        filename = filesToAggregate(x); 
                        %find files that are to be included in aggregate data
                        opts = detectImportOptions(filename,'NumHeaderLines',0); %reads first row
                        fullTable = readtable(filename,opts);
                        condYES = convertCharsToStrings(fullTable.includedInAggregate) == "YES";
                        newFullTable = fullTable(condYES, :);
                        %assignin('base','newfulltable',newFullTable);
                        if height(newFullTable) == 0
                            disp("No new aggregate files to add")
                        else
                            writeCount = 0;
                            for j = 1:height(newFullTable)
                                %disp(j);
                                %find processed file data and parse necessary data points
                                pathSplit = strsplit(filename, '\');
                                pathSplit = pathSplit(1:end-1);
                                parentPath = convertStringsToChars(strjoin(pathSplit, '\'));
                                %now load the corresponding processed data while conditionally
                                aggregateFile = load([parentPath '\' convertStringsToChars(newFullTable.ProcessedFileName{j})]); %loads struct with individualSphereTable as field
                                %appending data to duplicate image files        
                                if j == 1
                                    aggregateTable = aggregateFile.individualSphereTable; 
                                    %assignin('base',"aggregateTable",aggregateTable);
                                    %%%steps to remove file data
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
%                                     %%%%%%%%%%% remove file data %%%%%
%                                     if isfile(excelPathLocal) == 1
%                                         writetable(aggregateTable, excelPathLocal,'WriteMode','replacefile');
%                                         disp(['Data cleared from ' excelPathLocal]);
%                                     end
                                elseif j < height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                                    %concatenate sphere data of the same date
                                    aggregateTableToAppend = aggregateFile.individualSphereTable; 
                                    aggregateTable = [aggregateTable; aggregateTableToAppend];
                                elseif j == height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                                    %accounts for end of loop where dates match
                                    aggregateTableToAppend = aggregateFile.individualSphereTable; 
                                    aggregateTable = [aggregateTable; aggregateTableToAppend];
        
                                    %analyze aggregate data 
                                    [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end

                                elseif j == height(newFullTable) && (string(newFullTable.Date{j}) ~= string(newFullTable.Date{j-1}))
                                    %accounts for end of loop where dates do not match
                                    %will need to analyze data of current aggregateTable AND
                                    %endTable
        
                                    %analyze aggregate data before the mismatch
                                    [aggregateStructure] = aggregateData(newFullTable, aggregateTable,j);
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
        
                                    %then pass new aggregateFile through
                                    %aggregateData() 
                                    aggregateEndTable = aggregateFile.individualSphereTable;
        
                                    %analyze aggregate end data 
                                    [aggregateStructure] = aggregateData(newFullTable, aggregateEndTable,j+1); %add 1 to j index for function
        
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    if isfile(excelPathLocal) == 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
                                else
                                    writeCount = writeCount + 1; 
                                    %analyze aggregate data of the current aggregateTable
                                    %new exportable excel file
                                    %analyze aggregate data 
                                    [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);
                                    %then, clear aggregateTable and assign new aggregateFile to it
                                    %%%write aggregate excel file
                                    newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                                    newFolderName = app.processedVesselFolderName;
                                    if isfolder([newFolderPath newFolderName]) == 0
                                        mkdir(newFolderPath,newFolderName);
                                    end
                                    %get vessel letter
                                    vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                                    vesselLetter = vesselLetter{1};
                                    excelFileName = [vesselLetter '-Aggregated Data'];
                                    excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                                    excelPathLocal = [excelPathLocal{1:end}];
                                    sphereTable = struct2table(aggregateStructure);
                                    %assignin('base','aggregateStructure',aggregateStructure);
                                    if isfile(excelPathLocal) == 1 && writeCount == 1
                                        writetable(sphereTable, excelPathLocal,'WriteMode','replacefile');
                                        disp(['Data reset from ' excelPathLocal]);
                                    elseif isfile(excelPathLocal) == 1 && writeCount ~= 1
                                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                                    else
                                        writetable(sphereTable, excelPathLocal);
                                    end
                                    
                                    %clear aggregateTable
                                    aggregateTable = [];
                                    sphereTable = []; 
                                    aggregateTable = aggregateFile.individualSphereTable;                           
                                end
                            end
                        end
                    end
                end
                msg = 'Done! Continue to next batch?';
                banner = 'Export is Complete';
                selection = uiconfirm(app.SphereImageProcessingUIFigure, msg, banner, ...
                'Options', {'YES','Return Home'});  
                switch selection
                    case 'YES'
                        %continue
                    case 'Return Home'
                        exit = "YES";
                        break;  %get outta here
                end
            end
            if i == length(batchDateStrings)
                processMessage = sprintf(['No files remain in %s.\n',...
                        'Returning home.'],app.FolderEditField.Value);
                uiwait(msgbox(processMessage));
                exit = "YES";
            end
            if exit == "YES"
                cla(app.UIAxes,'reset');
                app.UIAxes.Visible = 'off';
                app.AutoCheckBox.Value = 0;
                AutoCheckBoxValueChanged(app,event);

                app.CaptureLabel.Visible = 'off';
                app.NumberofSpheresEditField.Visible = 'off';
                app.NumberofSpheresEditFieldLabel.Visible = 'off';
                app.MaxDiameterEditField.Visible = 'off';
                app.MaxDiameterEditFieldLabel.Visible = 'off';
                app.MinDiameterEditField.Visible = 'off';
                app.MinDiameterEditFieldLabel.Visible = 'off';
                app.AverageDiameterEditField.Visible = 'off';
                app.AverageDiameterEditFieldLabel.Visible = 'off';
                app.AnalysisLabel.Visible = 'off';
                app.PlotTitleEditField.Visible = 'off';
                app.PlotTitleEditFieldLabel.Visible = 'off';
                app.ViewPDFButton.Visible = 'off';
                app.ViewCDFButton.Visible = 'off';
                app.PolydispersityIndexEditField.Visible = 'off';
                app.PolydispersityIndexEditFieldLabel.Visible = 'off';
                app.muEditField.Visible = 'off';
                app.muEditFieldLabel.Visible = 'off';
                app.sigmaEditField.Visible = 'off';
                app.sigmaEditFieldLabel.Visible = 'off';
                app.AverageDoCEditField.Visible = 'off';
                app.AverageDoCEditFieldLabel.Visible = 'off';
                app.AverageSolidityEditField.Visible = 'off';
                app.AverageSolidityEditFieldLabel.Visible = 'off';
                app.AverageEccentricityEditFieldLabel.Visible = 'off';
                app.AverageEccentricityEditField.Visible = 'off';
    
                %Populate Results Section
                app.NumberofSpheresEditField.Value = ""; 
                app.MaxDiameterEditField.Value = "";
                app.MinDiameterEditField.Value = "";
                app.AverageDiameterEditField.Value = "";
                app.AverageDoCEditField.Value = "";
                app.AverageSolidityEditField.Value = "";
                app.AverageEccentricityEditField.Value = "";
                app.muEditField.Value = "";
                app.sigmaEditField.Value = "";
                app.PolydispersityIndexEditField.Value = "";
                    %get outta here
                app.SphereImageProcessingUIFigure.Visible = 'off';
                app.SphereImageProcessingUIFigure.Visible = 'on';
                %reset all values on right panel as well
            end
%if not: 
%(1) plot in uiimage and make clickable
%(2) process image selected
        end

        % Close request function: SphereImageProcessingUIFigure
        function SphereImageProcessingUIFigureCloseRequest(app, event)
            if app.keepLockFile == 1
                delete(app)
            else
                delete(app.lockFileName); %removes file if operator closes app
                delete(app)  
            end
        end

        % Button pushed function: SelectGroupButton
        function SelectGroupButtonPushed(app, event)
            %first, perform a general directory and subdirectory search of
            %all files
            app.SelectAggregateFileButton.Enable = 'off';
            app.compareState = 1; 
            %app.ShowPassagesButton.Enable = 'off';

            fileParse = sprintf('*%s*.xlsx',"-Aggregated Data");
            aggFileSearch = dir(fullfile('**',fileParse));
            assignin('base',"aggFileSearch",aggFileSearch);
            app.FileEditField_2.Value = [num2str(length(aggFileSearch)) ' files selected.'];
            
            %create fieldnames for comparison vessels, also used to
            %populate list dialogue prompt
            compFieldNames = strings(length(aggFileSearch),1);
            compFileNames = strings(length(aggFileSearch),1);
            for n = 1:length(aggFileSearch)
                %fieldnames
                vesselName = strsplit(aggFileSearch(n).name, '-'); %cell array with vessel name in first index
                vesselName = string(vesselName{1});
                lotName = strsplit(aggFileSearch(n).folder, '\');
                lotName = strsplit(lotName{end},' ');
                lotName = string(lotName{1});
                compFieldNames(n,1) = strrep(strjoin([lotName,vesselName],'-'),'-','_');
                compFileNames(n,1) = string([aggFileSearch(n).folder '\' aggFileSearch(n).name]);
            end
            % assignin('base','compFieldNames',compFieldNames);
            %select files to overwrite (can select one, multiple, or all)
            [indx,tf] = listdlg('PromptString',{'Select the vessel tags';...
                'you wish to compare.';''},...
                'SelectionMode','multiple','ListString',compFieldNames); %If the user clicks Cancel, presses Esc, or clicks the 
                                          % close button (X) in the dialog box title bar, then the tf return value is 0. 
            if tf == 0
                uiwait(msgbox({'No selection was recorded.';...
                    'Please try again or exit.'}));
                [indx,tf] = listdlg('PromptString',{'Select the vessel tags';...
                'you wish to compare.';''},...
                'SelectionMode','single','ListString',compFieldNames); %If the user clicks Cancel, presses Esc, or clicks the 
                                           % close button (X) in the dialog box title bar, then the tf return value is 0.
                if tf == 0
                    app.VisualizeCheckBox.Value = 0; 
                    VisualizeCheckBoxValueChanged(app,event);
                end
            end

            compFieldNames = compFieldNames(indx);
            compFileNames = compFileNames(indx);
            
            for g = 1:length(compFieldNames)
                %initialize substructure array
                app.vesselCompare.(compFieldNames(g)) = [];
                app.vesselCompare.(compFieldNames(g)).FileName = compFileNames(g); 

                opts = detectImportOptions(compFileNames(g),'NumHeaderLines',0); %reads first row
                aggTable = readtable(compFileNames(g),opts);
                variableNames = aggTable.Properties.VariableNames'; %cell array
                
                for p = 1:length(variableNames)
                    if  string(variableNames{p}) == "Date"
                        d1 = datetime(aggTable.Date(1),'InputFormat','ddMMMyyyy');
                        for q = 1:height(aggTable)
                            d2 = datetime(aggTable.Date(q),'InputFormat','ddMMMyyyy');
                            numDays = caldays(between(d1,d2,'days'));
                            aggTable.Date(q) = {numDays+1}; 
                        end
                    end
                    app.vesselCompare.(compFieldNames(g)).(variableNames{p}) = aggTable.(variableNames{p});
                end
                aggTable = [];
            end
            
            assignin('base','vesselCompare',app.vesselCompare);

            app.CenterPanel.AutoResizeChildren = 'off';
            app.ax1 = subplot(2,2,[1,2], 'Parent',app.CenterPanel);
            app.ax2 = subplot(2,2,[3,4], 'Parent',app.CenterPanel);
            
            %--------------------SUBPLOT 1-----------------------------%
            [xData,yData,vesselIDs] = vesselVizComparison(app.vesselCompare, "Mean");
            hold(app.ax1,'on');
            legend(app.ax1,'show','location','bestoutside');
            for j = 1 : length(vesselIDs)
                xPlotData = xData{j};
                xPlotData = [xPlotData{:}]';
                yPlotData = yData{j};
                plot(app.ax1, xPlotData, yPlotData,'DisplayName',vesselIDs(j));
            end
            xlabel(app.ax1,'Vessel Duration (days)');
            ylabel(app.ax1,'Mean Diameter (um)');
            title(app.ax1, 'Mean Diameter by Vessel Duration');
                % legend(app.ax1, vesselIDs, 'location', 'bestoutside');

            app.PlotTitleEditField_2.Value = "Mean Diameter by Vessel Duration";
            app.XLabelEditField.Value = "Vessel Duration (days)";
            app.YLabelEditField.Value = "Mean Diameter (um)";

            %--------------------SUBPLOT 2--------CUSTOMIZABLE--------%
            [xData, yData, vesselIDs] = vesselVizComparison(app.vesselCompare, "Count");
            hold(app.ax2,'on'); 
            legend(app.ax2,'show','location','bestoutside');
            for j = 1 : length(vesselIDs)
                xPlotData = xData{j};
                xPlotData = [xPlotData{:}]';
                yPlotData = yData{j};
                plot(app.ax2, xPlotData, yPlotData,'DisplayName',vesselIDs(j));
            end
            %xlim(app.ax2,[xData(1) xData(end)]);
            %ylim(app.ax2,[0 max(yData2)+25]);
            xlabel(app.ax2,'Vessel Duration (days');
            ylabel(app.ax2,'Count (# of spheres)');
            title(app.ax2, 'Count by Vessel Duration');
            hold(app.ax2, 'off');

            app.PlotTitleEditField_3.Value = "Count by Vessel Duration --> DEFAULT";
            dataNames = ["Date";"Count"; "Max"; "Mean"; "Median"; "Std"; "AverageCircularity"; "AverageSolidity"; "AverageEccentricity";"Polydispersity"];
            app.XDataDropDown.Items = [{""}; dataNames];
            app.XDataDropDown.ItemsData = [{""}; dataNames];
            app.YDataDropDown.Items = [{""}; dataNames];
            app.YDataDropDown.ItemsData = [{""}; dataNames];
            
            app.XDataDropDown.Value = "Date";
            XDataDropDownValueChanged(app,event)
            app.YDataDropDown.Value = "Count";
            YDataDropDownValueChanged(app,event)

            %--------------------SUBPLOT 3--Disabled----------------------%
            app.PLOT3Label.Enable = 'off';
            app.PlotTitleEditField_4.Enable = 'off';
            app.XLabelEditField_2.Enable = 'off';
            app.YLabelEditField_2.Enable = 'off';
            app.CircularityCheckBox.Enable = 'off';
            app.SolidityCheckBox.Enable = 'off'; 
            app.EccentricityCheckBox.Enable = 'off'; 
            app.PolydispersityCheckBox.Enable = 'off';
            %use panel to manipulate data and titles 
            %add check boxes to include aggregate data components
        end

        % Value changed function: AggregateSelectedVesselCheckBox
        function AggregateSelectedVesselCheckBoxValueChanged(app, event)
            value = app.AggregateSelectedVesselCheckBox.Value;
            if value == 1 
                app.RunButton.Visible = 'on';
                app.RunButton.Enable = 'on';
            else
                app.RunButton.Enable = 'off';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            fileParse = sprintf('*%s*.xlsx',app.vesselLetterTag);
            fileSearch = dir(fullfile('**',fileParse));
            fileSearch(string({fileSearch.name}) ~= (app.vesselLetterTag + ".xlsx")) = [];

            filename = [fileSearch.folder '\' fileSearch.name]; 
            %find files that are to be included in aggregate data
            opts = detectImportOptions(filename,'NumHeaderLines',0); %reads first row
            fullTable = readtable(filename,opts);
            %%% SORTING %%%
            fullTable.Date = datetime(fullTable.Date,'InputFormat',"ddMMMyyyy");
            fullTable = sortrows(fullTable,'Date');
            fullTable.Date = string(fullTable.Date,"ddMMMyyyy");
            %%% END SORTING %%%
            condYES = convertCharsToStrings(fullTable.includedInAggregate) == "YES";
            newFullTable = fullTable(condYES, :);
            %assignin('base','newfulltable',newFullTable);
            if height(newFullTable) == 0
                disp("No new aggregate files to add")
            else
                writeCount = 0;
                for j = 1:height(newFullTable)
                    %disp(j);
                    %find processed file data and parse necessary data points
                    pathSplit = strsplit(filename, '\');
                    pathSplit = pathSplit(1:end-1);
                    parentPath = convertStringsToChars(strjoin(pathSplit, '\'));
                    %now load the corresponding processed data while conditionally
                    aggregateFile = load([parentPath '\' convertStringsToChars(newFullTable.ProcessedFileName{j})]); %loads struct with individualSphereTable as field
                    %appending data to duplicate image files        
                    if j == 1
                        aggregateTable = aggregateFile.individualSphereTable; 
                        %assignin('base',"aggregateTable",aggregateTable);
                        %%%steps to remove file data
                        newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                        newFolderName = app.processedVesselFolderName;
                        if isfolder([newFolderPath newFolderName]) == 0
                            mkdir(newFolderPath,newFolderName);
                        end
                        %get vessel letter
                        vesselLetter = regexp(newFullTable.VesselID(j),'([A-Z]+)','match');
                        vesselLetter = vesselLetter{1};
                        excelFileName = [vesselLetter '-Aggregated Data'];
                        excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                        excelPathLocal = [excelPathLocal{1:end}];
%                                     %%%%%%%%%%% remove file data %%%%%
%                                     if isfile(excelPathLocal) == 1
%                                         writetable(aggregateTable, excelPathLocal,'WriteMode','replacefile');
%                                         disp(['Data cleared from ' excelPathLocal]);
%                                     end
                    elseif j < height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                        %concatenate sphere data of the same date
                        aggregateTableToAppend = aggregateFile.individualSphereTable; 
                        aggregateTable = [aggregateTable; aggregateTableToAppend];
                    elseif j == height(newFullTable) && (string(newFullTable.Date{j}) == string(newFullTable.Date{j-1}))
                        %accounts for end of loop where dates match
                        aggregateTableToAppend = aggregateFile.individualSphereTable; 
                        aggregateTable = [aggregateTable; aggregateTableToAppend];

                        %analyze aggregate data 
                        [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);

                        %%%write aggregate excel file
                        newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                        newFolderName = app.processedVesselFolderName;
                        if isfolder([newFolderPath newFolderName]) == 0
                            mkdir(newFolderPath,newFolderName);
                        end
                        %get vessel letter
                        vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                        vesselLetter = vesselLetter{1};
                        excelFileName = [vesselLetter '-Aggregated Data'];
                        excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                        excelPathLocal = [excelPathLocal{1:end}];
                        sphereTable = struct2table(aggregateStructure);
                        if isfile(excelPathLocal) == 1
                            writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                        else
                            writetable(sphereTable, excelPathLocal);
                        end

                    elseif j == height(newFullTable) && (string(newFullTable.Date{j}) ~= string(newFullTable.Date{j-1}))
                        %accounts for end of loop where dates do not match
                        %will need to analyze data of current aggregateTable AND
                        %endTable

                        %analyze aggregate data before the mismatch
                        [aggregateStructure] = aggregateData(newFullTable, aggregateTable,j);

                        %%%write aggregate excel file
                        newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                        newFolderName = app.processedVesselFolderName;
                        if isfolder([newFolderPath newFolderName]) == 0
                            mkdir(newFolderPath,newFolderName);
                        end
                        %get vessel letter
                        vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                        vesselLetter = vesselLetter{1};
                        excelFileName = [vesselLetter '-Aggregated Data'];
                        excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                        excelPathLocal = [excelPathLocal{1:end}];
                        sphereTable = struct2table(aggregateStructure);
                        if isfile(excelPathLocal) == 1
                            writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                        else
                            writetable(sphereTable, excelPathLocal);
                        end

                        %then pass new aggregateFile through
                        %aggregateData() 
                        aggregateEndTable = aggregateFile.individualSphereTable;

                        %analyze aggregate end data 
                        [aggregateStructure] = aggregateData(newFullTable, aggregateEndTable,j+1); %add 1 to j index for function

                        %%%write aggregate excel file
                        newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                        newFolderName = app.processedVesselFolderName;
                        if isfolder([newFolderPath newFolderName]) == 0
                            mkdir(newFolderPath,newFolderName);
                        end
                        %get vessel letter
                        vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                        vesselLetter = vesselLetter{1};
                        excelFileName = [vesselLetter '-Aggregated Data'];
                        excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                        excelPathLocal = [excelPathLocal{1:end}];
                        sphereTable = struct2table(aggregateStructure);
                        if isfile(excelPathLocal) == 1
                            writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                        else
                            writetable(sphereTable, excelPathLocal);
                        end
                    else
                        writeCount = writeCount + 1; 
                        %analyze aggregate data of the current aggregateTable
                        %new exportable excel file
                        %analyze aggregate data 
                        [aggregateStructure] = aggregateData(newFullTable,aggregateTable,j);
                        %then, clear aggregateTable and assign new aggregateFile to it
                        %%%write aggregate excel file
                        newFolderPath = [app.pwdGlobal '\Microscope Images\'];
                        newFolderName = app.processedVesselFolderName;
                        if isfolder([newFolderPath newFolderName]) == 0
                            mkdir(newFolderPath,newFolderName);
                        end
                        %get vessel letter
                        vesselLetter = regexp(newFullTable.VesselID(j-1),'([A-Z]+)','match');
                        vesselLetter = vesselLetter{1};
                        excelFileName = [vesselLetter '-Aggregated Data'];
                        excelPathLocal = [newFolderPath newFolderName '\' excelFileName '.xlsx'];
                        excelPathLocal = [excelPathLocal{1:end}];
                        sphereTable = struct2table(aggregateStructure);
                        %assignin('base','aggregateStructure',aggregateStructure);
                        if isfile(excelPathLocal) == 1 && writeCount == 1
                            writetable(sphereTable, excelPathLocal,'WriteMode','replacefile');
                            disp(['Data reset from ' excelPathLocal]);
                        elseif isfile(excelPathLocal) == 1 && writeCount ~= 1
                            writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                        else
                            writetable(sphereTable, excelPathLocal);
                        end
                        
                        %clear aggregateTable
                        aggregateTable = [];
                        sphereTable = []; 
                        aggregateTable = aggregateFile.individualSphereTable;                           
                    end
                end
            end
            uiwait(msgbox('Aggregate File Updated!'));
            app.AggregateSelectedVesselCheckBox.Value = 0; 
            AggregateSelectedVesselCheckBoxValueChanged(app,event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SphereImageProcessingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {698, 698, 698};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {698, 698};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x', 249};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SphereImageProcessingUIFigure = uifigure('Visible', 'off');
            app.SphereImageProcessingUIFigure.Name = 'Sphere Image Processing';
            app.SphereImageProcessingUIFigure.CloseRequestFcn = createCallbackFcn(app, @SphereImageProcessingUIFigureCloseRequest, true);
            app.SphereImageProcessingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SphereImageProcessingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AutoCheckBox = uicheckbox(app.LeftPanel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.ManualCheckBox = uicheckbox(app.LeftPanel);
            app.ManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCheckBoxValueChanged, true);
            app.ManualCheckBox.Text = 'Manual';
            app.VisualizeCheckBox = uicheckbox(app.LeftPanel);
            app.VisualizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizeCheckBoxValueChanged, true);
            app.VisualizeCheckBox.Text = 'Visualize';
            app.ManualProcessingPanel = uipanel(app.LeftPanel);
            app.ManualProcessingPanel.Title = 'Manual Processing';
            app.ManualProcessingPanel.Visible = 'off';
            app.SelectImagefromFileLabel = uilabel(app.ManualProcessingPanel);
            app.SelectImagefromFileLabel.Text = '(1) Select Image from File';
            app.DropDown = uidropdown(app.ManualProcessingPanel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.Label_2 = uilabel(app.ManualProcessingPanel);
            app.Label_2.Text = '(2) ';
            app.ReadImageButton = uibutton(app.ManualProcessingPanel, 'push');
            app.ReadImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReadImageButtonPushed, true);
            app.ReadImageButton.Enable = 'off';
            app.ReadImageButton.Text = 'Read Image';
            app.GreyImageContrastEnhanceLabel = uilabel(app.ManualProcessingPanel);
            app.GreyImageContrastEnhanceLabel.Text = '(4) Grey Image Contrast Enhance ';
            app.imadjustCheckBox = uicheckbox(app.ManualProcessingPanel);
            app.imadjustCheckBox.ValueChangedFcn = createCallbackFcn(app, @imadjustCheckBoxValueChanged, true);
            app.imadjustCheckBox.Enable = 'off';
            app.imadjustCheckBox.Text = 'imadjust';
            app.DisplayMontageButton = uibutton(app.ManualProcessingPanel, 'push');
            app.DisplayMontageButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMontageButtonPushed, true);
            app.DisplayMontageButton.Enable = 'off';
            app.DisplayMontageButton.Text = 'Display Montage';
            app.ThresholdingLabel = uilabel(app.ManualProcessingPanel);
            app.ThresholdingLabel.Text = '(5) Thresholding';
            app.DisplayHistogramButton = uibutton(app.ManualProcessingPanel, 'push');
            app.DisplayHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayHistogramButtonPushed, true);
            app.DisplayHistogramButton.Enable = 'off';
            app.DisplayHistogramButton.Text = 'Display Histogram ';
            app.LowSpinnerLabel = uilabel(app.ManualProcessingPanel);
            app.LowSpinnerLabel.Enable = 'off';
            app.LowSpinnerLabel.Text = 'Low ';
            app.LowSpinner = uispinner(app.ManualProcessingPanel);
            app.LowSpinner.ValueChangedFcn = createCallbackFcn(app, @LowSpinnerValueChanged, true);
            app.LowSpinner.Enable = 'off';
            app.GenerateBinaryImageButton = uibutton(app.ManualProcessingPanel, 'push');
            app.GenerateBinaryImageButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBinaryImageButtonPushed, true);
            app.GenerateBinaryImageButton.Enable = 'off';
            app.GenerateBinaryImageButton.Text = 'Generate Binary Image';
            app.AdditionalProcessingLabel = uilabel(app.ManualProcessingPanel);
            app.AdditionalProcessingLabel.Text = '(6) Additional Processing ';
            app.BWAreaOpenSpinner = uispinner(app.ManualProcessingPanel);
            app.BWAreaOpenSpinner.ValueChangedFcn = createCallbackFcn(app, @BWAreaOpenSpinnerValueChanged, true);
            app.BWAreaOpenSpinner.Enable = 'off';
            app.BWAreaOpenCheckBox = uicheckbox(app.ManualProcessingPanel);
            app.BWAreaOpenCheckBox.ValueChangedFcn = createCallbackFcn(app, @BWAreaOpenCheckBoxValueChanged, true);
            app.BWAreaOpenCheckBox.Enable = 'off';
            app.BWAreaOpenCheckBox.Text = 'BW Area Open';
            app.RemoveBorderObjectsCheckBox = uicheckbox(app.ManualProcessingPanel);
            app.RemoveBorderObjectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveBorderObjectsCheckBoxValueChanged, true);
            app.RemoveBorderObjectsCheckBox.Enable = 'off';
            app.RemoveBorderObjectsCheckBox.Text = 'Remove Border Objects';
            app.SmoothingCheckBox = uicheckbox(app.ManualProcessingPanel);
            app.SmoothingCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothingCheckBoxValueChanged, true);
            app.SmoothingCheckBox.Enable = 'off';
            app.SmoothingCheckBox.Text = 'Smoothing';
            app.ViewOverlayButton = uibutton(app.ManualProcessingPanel, 'push');
            app.ViewOverlayButton.ButtonPushedFcn = createCallbackFcn(app, @ViewOverlayButtonPushed, true);
            app.ViewOverlayButton.Enable = 'off';
            app.ViewOverlayButton.Text = 'View Overlay';
            app.MaskingWatershedandSizingLabel = uilabel(app.ManualProcessingPanel);
            app.MaskingWatershedandSizingLabel.Text = '(7) Masking, Watershed, and Sizing';
            app.IterSpinnerLabel = uilabel(app.ManualProcessingPanel);
            app.IterSpinnerLabel.Enable = 'off';
            app.IterSpinnerLabel.Text = 'Iter';
            app.IterSpinner = uispinner(app.ManualProcessingPanel);
            app.IterSpinner.ValueChangedFcn = createCallbackFcn(app, @IterSpinnerValueChanged, true);
            app.IterSpinner.Enable = 'off';
            app.ImageExtendedMinimumSpinnerLabel = uilabel(app.ManualProcessingPanel);
            app.ImageExtendedMinimumSpinnerLabel.Enable = 'off';
            app.ImageExtendedMinimumSpinnerLabel.Text = 'Image Extended Minimum';
            app.ImageExtendedMinimumSpinner = uispinner(app.ManualProcessingPanel);
            app.ImageExtendedMinimumSpinner.ValueChangedFcn = createCallbackFcn(app, @ImageExtendedMinimumSpinnerValueChanged, true);
            app.ImageExtendedMinimumSpinner.Enable = 'off';
            app.ApplyWatershedButton = uibutton(app.ManualProcessingPanel, 'push');
            app.ApplyWatershedButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyWatershedButtonPushed, true);
            app.ApplyWatershedButton.Enable = 'off';
            app.ApplyWatershedButton.Text = 'Apply Watershed';
            app.ProcessImageButton = uibutton(app.ManualProcessingPanel, 'push');
            app.ProcessImageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImageButtonPushed, true);
            app.ProcessImageButton.Enable = 'off';
            app.ProcessImageButton.Text = 'Process Image';
            app.SaveProtocolCheckBox = uicheckbox(app.ManualProcessingPanel);
            app.SaveProtocolCheckBox.Enable = 'off';
            app.SaveProtocolCheckBox.Text = 'Save Protocol';
            app.CalibrateButton = uibutton(app.ManualProcessingPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.HighSpinnerLabel = uilabel(app.ManualProcessingPanel);
            app.HighSpinnerLabel.Enable = 'off';
            app.HighSpinnerLabel.Text = 'High';
            app.HighSpinner = uispinner(app.ManualProcessingPanel);
            app.HighSpinner.ValueChangedFcn = createCallbackFcn(app, @HighSpinnerValueChanged, true);
            app.HighSpinner.Enable = 'off';
            app.CheckBox = uicheckbox(app.ManualProcessingPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.ManualProcessingPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = '';
            app.ImagePropertyLabel = uilabel(app.ManualProcessingPanel);
            app.ImagePropertyLabel.Text = '(3) Image Property';
            app.ImagePropertyDropDown = uidropdown(app.ManualProcessingPanel);
            app.ImagePropertyDropDown.Items = {};
            app.ImagePropertyDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagePropertyDropDownValueChanged, true);
            app.ImagePropertyDropDown.Enable = 'off';
            app.ImagePropertyDropDown.Value = {};
            app.HELPButton = uibutton(app.ManualProcessingPanel, 'push');
            app.HELPButton.ButtonPushedFcn = createCallbackFcn(app, @HELPButtonPushed, true);
            app.HELPButton.Enable = 'off';
            app.HELPButton.Text = 'HELP';
            app.CheckBox_3 = uicheckbox(app.ManualProcessingPanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Enable = 'off';
            app.CheckBox_3.Text = '';
            app.STDFilterSpinnerLabel = uilabel(app.ManualProcessingPanel);
            app.STDFilterSpinnerLabel.Enable = 'off';
            app.STDFilterSpinnerLabel.Text = 'STD Filter';
            app.STDFilterSpinner = uispinner(app.ManualProcessingPanel);
            app.STDFilterSpinner.ValueChangedFcn = createCallbackFcn(app, @STDFilterSpinnerValueChanged, true);
            app.STDFilterSpinner.Enable = 'off';
            app.FilterMaxCheckBox = uicheckbox(app.ManualProcessingPanel);
            app.FilterMaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterMaxCheckBoxValueChanged, true);
            app.FilterMaxCheckBox.Enable = 'off';
            app.FilterMaxCheckBox.Text = 'Filter Max';
            app.VisualizePanel = uipanel(app.LeftPanel);
            app.VisualizePanel.Title = 'Visualize';
            app.VisualizePanel.Visible = 'off';
            app.SelectAggregateFileButton = uibutton(app.VisualizePanel, 'push');
            app.SelectAggregateFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAggregateFileButtonPushed, true);
            app.SelectAggregateFileButton.Text = 'Select Aggregate File';
            app.FileEditField_2Label = uilabel(app.VisualizePanel);
            app.FileEditField_2Label.Text = 'File';
            app.FileEditField_2 = uieditfield(app.VisualizePanel, 'text');
            app.PlotTitleEditField_2Label = uilabel(app.VisualizePanel);
            app.PlotTitleEditField_2Label.Text = 'Plot Title';
            app.PlotTitleEditField_2 = uieditfield(app.VisualizePanel, 'text');
            app.XLabelEditFieldLabel = uilabel(app.VisualizePanel);
            app.XLabelEditFieldLabel.Text = 'X Label';
            app.XLabelEditField = uieditfield(app.VisualizePanel, 'text');
            app.XLabelEditField_2Label = uilabel(app.VisualizePanel);
            app.XLabelEditField_2Label.Text = 'Y Label';
            app.YLabelEditField = uieditfield(app.VisualizePanel, 'text');
            app.PLOT1Label_2 = uilabel(app.VisualizePanel);
            app.PLOT1Label_2.Text = '------------------------PLOT (1)-----------------------';
            app.PLOT2Label = uilabel(app.VisualizePanel);
            app.PLOT2Label.Text = '------------------------PLOT (2)-----------------------';
            app.RefreshButton = uibutton(app.VisualizePanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PlotTitleEditField_3Label = uilabel(app.VisualizePanel);
            app.PlotTitleEditField_3Label.Text = 'Plot Title';
            app.PlotTitleEditField_3 = uieditfield(app.VisualizePanel, 'text');
            app.XDataDropDownLabel = uilabel(app.VisualizePanel);
            app.XDataDropDownLabel.Text = 'X Data';
            app.XDataDropDown = uidropdown(app.VisualizePanel);
            app.XDataDropDown.Items = {};
            app.XDataDropDown.ValueChangedFcn = createCallbackFcn(app, @XDataDropDownValueChanged, true);
            app.XDataDropDown.Value = {};
            app.YDataDropDownLabel = uilabel(app.VisualizePanel);
            app.YDataDropDownLabel.Text = 'Y Data';
            app.YDataDropDown = uidropdown(app.VisualizePanel);
            app.YDataDropDown.Items = {};
            app.YDataDropDown.ValueChangedFcn = createCallbackFcn(app, @YDataDropDownValueChanged, true);
            app.YDataDropDown.Value = {};
            app.PLOT3Label = uilabel(app.VisualizePanel);
            app.PLOT3Label.Text = '-----------------------PLOT (3)------------------------';
            app.XLabelEditFieldLabel_2 = uilabel(app.VisualizePanel);
            app.XLabelEditFieldLabel_2.Text = 'X Label';
            app.XLabelEditField_2 = uieditfield(app.VisualizePanel, 'text');
            app.XLabelEditField_2Label_2 = uilabel(app.VisualizePanel);
            app.XLabelEditField_2Label_2.Text = 'Y Label';
            app.YLabelEditField_2 = uieditfield(app.VisualizePanel, 'text');
            app.PlotTitleEditField_4Label = uilabel(app.VisualizePanel);
            app.PlotTitleEditField_4Label.Text = 'Plot Title';
            app.PlotTitleEditField_4 = uieditfield(app.VisualizePanel, 'text');
            app.IncludeLabel = uilabel(app.VisualizePanel);
            app.IncludeLabel.Text = 'Include: ';
            app.CircularityCheckBox = uicheckbox(app.VisualizePanel);
            app.CircularityCheckBox.Text = 'Circularity';
            app.SolidityCheckBox = uicheckbox(app.VisualizePanel);
            app.SolidityCheckBox.Text = 'Solidity';
            app.EccentricityCheckBox = uicheckbox(app.VisualizePanel);
            app.EccentricityCheckBox.Text = 'Eccentricity';
            app.PolydispersityCheckBox = uicheckbox(app.VisualizePanel);
            app.PolydispersityCheckBox.Text = 'Polydispersity';
            app.ShowPassagesButton = uibutton(app.VisualizePanel, 'state');
            app.ShowPassagesButton.ValueChangedFcn = createCallbackFcn(app, @ShowPassagesButtonValueChanged, true);
            app.ShowPassagesButton.Text = 'Show Passages';
            app.SelectGroupButton = uibutton(app.VisualizePanel, 'push');
            app.SelectGroupButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGroupButtonPushed, true);
            app.SelectGroupButton.Text = 'Select Group';
            app.AutomaticProcessingPanel = uipanel(app.LeftPanel);
            app.AutomaticProcessingPanel.Title = 'Automatic Processing';
            app.NumberofFilestobeReadEditFieldLabel = uilabel(app.AutomaticProcessingPanel);
            app.NumberofFilestobeReadEditFieldLabel.Text = 'Number of Files to be Read ';
            app.NumberofFilestobeReadEditField = uieditfield(app.AutomaticProcessingPanel, 'text');
            app.WritingSpecificationsLabel = uilabel(app.AutomaticProcessingPanel);
            app.WritingSpecificationsLabel.Text = 'Writing Specifications: ';
            app.ImageProcessingFilesCheckBox = uicheckbox(app.AutomaticProcessingPanel);
            app.ImageProcessingFilesCheckBox.Enable = 'off';
            app.ImageProcessingFilesCheckBox.Text = 'Image Processing Files';
            app.ImageProcessingFilesCheckBox.Value = true;
            app.SphereDataCheckBox = uicheckbox(app.AutomaticProcessingPanel);
            app.SphereDataCheckBox.Enable = 'off';
            app.SphereDataCheckBox.Text = 'Sphere Data ';
            app.SphereDataCheckBox.Value = true;
            app.Label_4 = uilabel(app.AutomaticProcessingPanel);
            app.Label_4.Text = '-----------------------------------------------------------';
            app.FILENAMEEditFieldLabel = uilabel(app.AutomaticProcessingPanel);
            app.FILENAMEEditFieldLabel.Text = 'FILENAME';
            app.FILENAMEEditField = uieditfield(app.AutomaticProcessingPanel, 'text');
            app.ImageProcessingEditFieldLabel = uilabel(app.AutomaticProcessingPanel);
            app.ImageProcessingEditFieldLabel.Text = 'Image Processing...';
            app.ImageProcessingEditField = uieditfield(app.AutomaticProcessingPanel, 'text');
            app.ExportingDataEditFieldLabel = uilabel(app.AutomaticProcessingPanel);
            app.ExportingDataEditFieldLabel.Text = 'Exporting Data...';
            app.ExportingDataEditField = uieditfield(app.AutomaticProcessingPanel, 'text');
            app.Label_6 = uilabel(app.AutomaticProcessingPanel);
            app.Label_6.Text = '-----------------------------------------------------------';
            app.STARTButton = uibutton(app.AutomaticProcessingPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.ProgressEditFieldLabel = uilabel(app.AutomaticProcessingPanel);
            app.ProgressEditFieldLabel.Text = 'Progress';
            app.FolderEditField = uieditfield(app.AutomaticProcessingPanel, 'text');
            app.ProgressEditField = uieditfield(app.AutomaticProcessingPanel, 'text');
            app.SelectFolderButton = uibutton(app.AutomaticProcessingPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder ';
            app.ImageDateEditFieldLabel = uilabel(app.AutomaticProcessingPanel);
            app.ImageDateEditFieldLabel.Text = 'Image Date';
            app.ImageDateEditField = uieditfield(app.AutomaticProcessingPanel, 'text');
            app.ADSImagesCheckBox = uicheckbox(app.AutomaticProcessingPanel);
            app.ADSImagesCheckBox.Text = 'ADS Images';
            app.ReadImagesfromSingleVesselCheckBox = uicheckbox(app.AutomaticProcessingPanel);
            app.ReadImagesfromSingleVesselCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReadImagesfromSingleVesselCheckBoxValueChanged, true);
            app.ReadImagesfromSingleVesselCheckBox.Visible = 'off';
            app.ReadImagesfromSingleVesselCheckBox.Text = ' Read Images from Single Vessel';
            app.VesselIDDropDownLabel = uilabel(app.AutomaticProcessingPanel);
            app.VesselIDDropDownLabel.Visible = 'off';
            app.VesselIDDropDownLabel.Text = 'Vessel ID';
            app.VesselIDDropDown = uidropdown(app.AutomaticProcessingPanel);
            app.VesselIDDropDown.Items = {};
            app.VesselIDDropDown.ValueChangedFcn = createCallbackFcn(app, @VesselIDDropDownValueChanged, true);
            app.VesselIDDropDown.Visible = 'off';
            app.VesselIDDropDown.Value = {};
            app.AggregateRepeatedImageFilesNASCheckBox = uicheckbox(app.AutomaticProcessingPanel);
            app.AggregateRepeatedImageFilesNASCheckBox.Enable = 'off';
            app.AggregateRepeatedImageFilesNASCheckBox.Text = 'Aggregate Repeated Image Files (NAS)';
            app.Label_7 = uilabel(app.AutomaticProcessingPanel);
            app.Label_7.Text = '-----------------------------------------------------------';
            app.BATCHButton = uibutton(app.AutomaticProcessingPanel, 'push');
            app.BATCHButton.ButtonPushedFcn = createCallbackFcn(app, @BATCHButtonPushed, true);
            app.BATCHButton.Enable = 'off';
            app.BATCHButton.Text = 'BATCH';
            app.BATCHSTATUSLabel = uilabel(app.AutomaticProcessingPanel);
            app.BATCHSTATUSLabel.Text = 'BATCH STATUS';
            app.Label_8 = uilabel(app.AutomaticProcessingPanel);
            app.Label_8.Text = '-----------------------------------------------------------';
            app.Label_9 = uilabel(app.AutomaticProcessingPanel);
            app.Label_9.Text = '-----------------------------------------------------------';
            app.RunButton = uibutton(app.AutomaticProcessingPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Visible = 'off';
            app.RunButton.Text = 'Run';
            app.AggregateSelectedVesselCheckBox = uicheckbox(app.AutomaticProcessingPanel);
            app.AggregateSelectedVesselCheckBox.ValueChangedFcn = createCallbackFcn(app, @AggregateSelectedVesselCheckBoxValueChanged, true);
            app.AggregateSelectedVesselCheckBox.Enable = 'off';
            app.AggregateSelectedVesselCheckBox.Text = 'Aggregate Selected Vessel';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UITable = uitable(app.CenterPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.UITable_2 = uitable(app.CenterPanel);
            app.UITable_2.ColumnName = '';
            app.UITable_2.RowName = {};
            app.UITable_2.Visible = 'off';
            app.OLDDATALabel = uilabel(app.CenterPanel);
            app.OLDDATALabel.Visible = 'off';
            app.OLDDATALabel.Text = 'OLD DATA';
            app.NEWDATALabel = uilabel(app.CenterPanel);
            app.NEWDATALabel.Visible = 'off';
            app.NEWDATALabel.Text = 'NEW DATA';
            app.RightPanel = uipanel(app.GridLayout);
            app.ResultsLabel = uilabel(app.RightPanel);
            app.ResultsLabel.Text = 'Results: ';
            app.FileEditFieldLabel = uilabel(app.RightPanel);
            app.FileEditFieldLabel.Visible = 'off';
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.RightPanel, 'text');
            app.FileEditField.Visible = 'off';
            app.VesselIDEditFieldLabel = uilabel(app.RightPanel);
            app.VesselIDEditFieldLabel.Visible = 'off';
            app.VesselIDEditFieldLabel.Text = 'Vessel ID';
            app.VesselIDEditField = uieditfield(app.RightPanel, 'text');
            app.VesselIDEditField.Visible = 'off';
            app.EditField = uieditfield(app.RightPanel, 'text');
            app.EditField.Visible = 'off';
            app.Label = uilabel(app.RightPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '@';
            app.CaptureLabel = uilabel(app.RightPanel);
            app.CaptureLabel.Enable = 'off';
            app.CaptureLabel.Text = '------------------------Capture-----------------------';
            app.NumberofSpheresEditFieldLabel = uilabel(app.RightPanel);
            app.NumberofSpheresEditFieldLabel.Visible = 'off';
            app.NumberofSpheresEditFieldLabel.Text = 'Number of Spheres';
            app.NumberofSpheresEditField = uieditfield(app.RightPanel, 'text');
            app.NumberofSpheresEditField.Visible = 'off';
            app.MaxDiameterEditFieldLabel = uilabel(app.RightPanel);
            app.MaxDiameterEditFieldLabel.Visible = 'off';
            app.MaxDiameterEditFieldLabel.Text = 'Max Diameter';
            app.MaxDiameterEditField = uieditfield(app.RightPanel, 'text');
            app.MaxDiameterEditField.Visible = 'off';
            app.MinDiameterEditFieldLabel = uilabel(app.RightPanel);
            app.MinDiameterEditFieldLabel.Visible = 'off';
            app.MinDiameterEditFieldLabel.Text = 'Min Diameter';
            app.MinDiameterEditField = uieditfield(app.RightPanel, 'text');
            app.MinDiameterEditField.Visible = 'off';
            app.AverageDiameterEditFieldLabel = uilabel(app.RightPanel);
            app.AverageDiameterEditFieldLabel.Visible = 'off';
            app.AverageDiameterEditFieldLabel.Text = 'Average Diameter';
            app.AverageDiameterEditField = uieditfield(app.RightPanel, 'text');
            app.AverageDiameterEditField.Visible = 'off';
            app.umLabel = uilabel(app.RightPanel);
            app.umLabel.Visible = 'off';
            app.umLabel.Text = 'um';
            app.umLabel_2 = uilabel(app.RightPanel);
            app.umLabel_2.Visible = 'off';
            app.umLabel_2.Text = 'um';
            app.umLabel_3 = uilabel(app.RightPanel);
            app.umLabel_3.Visible = 'off';
            app.umLabel_3.Text = 'um';
            app.PolydispersityIndexEditFieldLabel = uilabel(app.RightPanel);
            app.PolydispersityIndexEditFieldLabel.Visible = 'off';
            app.PolydispersityIndexEditFieldLabel.Text = 'Polydispersity Index';
            app.PolydispersityIndexEditField = uieditfield(app.RightPanel, 'text');
            app.PolydispersityIndexEditField.Visible = 'off';
            app.AnalysisLabel = uilabel(app.RightPanel);
            app.AnalysisLabel.Enable = 'off';
            app.AnalysisLabel.Text = '-----------------------Analysis-------------------------';
            app.ViewPDFButton = uibutton(app.RightPanel, 'push');
            app.ViewPDFButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPDFButtonPushed, true);
            app.ViewPDFButton.Visible = 'off';
            app.ViewPDFButton.Text = 'View PDF';
            app.ViewCDFButton = uibutton(app.RightPanel, 'push');
            app.ViewCDFButton.ButtonPushedFcn = createCallbackFcn(app, @ViewCDFButtonPushed, true);
            app.ViewCDFButton.Visible = 'off';
            app.ViewCDFButton.Text = 'View CDF';
            app.muEditFieldLabel = uilabel(app.RightPanel);
            app.muEditFieldLabel.Visible = 'off';
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.RightPanel, 'text');
            app.muEditField.Visible = 'off';
            app.sigmaEditFieldLabel = uilabel(app.RightPanel);
            app.sigmaEditFieldLabel.Visible = 'off';
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.RightPanel, 'text');
            app.sigmaEditField.Visible = 'off';
            app.PlotTitleEditFieldLabel = uilabel(app.RightPanel);
            app.PlotTitleEditFieldLabel.Visible = 'off';
            app.PlotTitleEditFieldLabel.Text = 'Plot Title';
            app.PlotTitleEditField = uieditfield(app.RightPanel, 'text');
            app.PlotTitleEditField.Visible = 'off';
            app.AverageDoCEditFieldLabel = uilabel(app.RightPanel);
            app.AverageDoCEditFieldLabel.Visible = 'off';
            app.AverageDoCEditFieldLabel.Text = 'Average DoC';
            app.AverageDoCEditField = uieditfield(app.RightPanel, 'text');
            app.AverageDoCEditField.Visible = 'off';
            app.ExportSphereDataButton = uibutton(app.RightPanel, 'push');
            app.ExportSphereDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSphereDataButtonPushed, true);
            app.ExportSphereDataButton.Enable = 'off';
            app.ExportSphereDataButton.Visible = 'off';
            app.ExportSphereDataButton.Text = 'Export Sphere Data';
            app.AverageSolidityEditFieldLabel = uilabel(app.RightPanel);
            app.AverageSolidityEditFieldLabel.Visible = 'off';
            app.AverageSolidityEditFieldLabel.Text = 'Average Solidity';
            app.AverageSolidityEditField = uieditfield(app.RightPanel, 'text');
            app.AverageSolidityEditField.Visible = 'off';
            app.FlaggedFilesLabel = uilabel(app.RightPanel);
            app.FlaggedFilesLabel.Enable = 'off';
            app.FlaggedFilesLabel.Text = 'Flagged Files:';
            app.UpdateLabel = uilabel(app.RightPanel);
            app.UpdateLabel.Enable = 'off';
            app.UpdateLabel.Text = '-----------------------Update-------------------------';
            app.ReprocessflaggedfilesButton = uibutton(app.RightPanel, 'push');
            app.ReprocessflaggedfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessflaggedfilesButtonPushed, true);
            app.ReprocessflaggedfilesButton.Enable = 'off';
            app.ReprocessflaggedfilesButton.Text = 'Re-process flagged files';
            app.FlaggedFileEditFieldLabel = uilabel(app.RightPanel);
            app.FlaggedFileEditFieldLabel.Enable = 'off';
            app.FlaggedFileEditFieldLabel.Text = 'Flagged File';
            app.FlaggedFileEditField = uieditfield(app.RightPanel, 'text');
            app.FlaggedFileEditField.Enable = 'off';
            app.ProgressEditField_2Label = uilabel(app.RightPanel);
            app.ProgressEditField_2Label.Enable = 'off';
            app.ProgressEditField_2Label.Text = 'Progress';
            app.ProgressEditField_2 = uieditfield(app.RightPanel, 'text');
            app.ProgressEditField_2.Enable = 'off';
            app.ofLabel = uilabel(app.RightPanel);
            app.ofLabel.Enable = 'off';
            app.ofLabel.Text = 'of';
            app.ProgressEditField_3 = uieditfield(app.RightPanel, 'text');
            app.ProgressEditField_3.Enable = 'off';
            app.CommentsEditFieldLabel = uilabel(app.RightPanel);
            app.CommentsEditFieldLabel.Enable = 'off';
            app.CommentsEditFieldLabel.Text = 'Comments:';
            app.CommentsEditField = uieditfield(app.RightPanel, 'text');
            app.CommentsEditField.Enable = 'off';
            app.AverageEccentricityEditFieldLabel = uilabel(app.RightPanel);
            app.AverageEccentricityEditFieldLabel.Visible = 'off';
            app.AverageEccentricityEditFieldLabel.Text = 'Average Eccentricity';
            app.AverageEccentricityEditField = uieditfield(app.RightPanel, 'text');
            app.AverageEccentricityEditField.Visible = 'off';
            app.EXITButton = uibutton(app.RightPanel, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Enable = 'off';
            app.EXITButton.Text = 'EXIT';
            % Show the figure after all components are created
            app.SphereImageProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SphereImageAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SphereImageProcessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SphereImageProcessingUIFigure)
        end
    end
end