classdef SphereImageAnalysis_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SphereImageAnalysisUIFigure    matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        StatusPanel                    matlab.ui.container.Panel
        BatchStatusLabel               matlab.ui.control.Label
        ProgressBar_2                  wt.ProgressBar
        ImageStatusLabel               matlab.ui.control.Label
        DateEditField_Status           matlab.ui.control.EditField
        DateEditField_2Label           matlab.ui.control.Label
        FileEditField                  matlab.ui.control.EditField
        FileEditFieldLabel             matlab.ui.control.Label
        ProgressBar                    wt.ProgressBar
        SetupPanel                     matlab.ui.container.Panel
        Button                         matlab.ui.control.Button
        SaveEditField                  matlab.ui.control.EditField
        SaveEditFieldLabel             matlab.ui.control.Label
        SemanticSegStartButton         matlab.ui.control.Button
        NOLIGHTDropDown                matlab.ui.control.DropDown
        NOLIGHTDropDownLabel           matlab.ui.control.Label
        SEMANTICSEGMENTATIONSETTINGSLabel  matlab.ui.control.Label
        LIGHTDropDown                  matlab.ui.control.DropDown
        LIGHTDropDownLabel             matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        NumFilestoProcessEditField     matlab.ui.control.EditField
        NumFilestoProcessEditFieldLabel  matlab.ui.control.Label
        VisualizeButton                matlab.ui.control.Button
        ReprocessButton                matlab.ui.control.Button
        GuidedSegButton                matlab.ui.control.Button
        SELECTMODESTARTLabel           matlab.ui.control.Label
        SemanticSegButton              matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        LotEditField                   matlab.ui.control.EditField
        LotEditFieldLabel              matlab.ui.control.Label
        PROCESSSPECIFICITYLabel        matlab.ui.control.Label
        ImportButton                   matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ResultsPanel                   matlab.ui.container.Panel
        BatchLabel                     matlab.ui.control.Label
        umLabel_4                      matlab.ui.control.Label
        AverageSolidityEditField       matlab.ui.control.EditField
        AverageSolidityEditFieldLabel  matlab.ui.control.Label
        AverageDoCEditField            matlab.ui.control.EditField
        AverageDoCEditFieldLabel       matlab.ui.control.Label
        STDEditField                   matlab.ui.control.EditField
        STDEditFieldLabel              matlab.ui.control.Label
        AverageEccentricityEditField   matlab.ui.control.EditField
        AverageEccentricityEditFieldLabel  matlab.ui.control.Label
        PolydispersityIndexEditField   matlab.ui.control.EditField
        PolydispersityIndexEditFieldLabel  matlab.ui.control.Label
        umLabel_3                      matlab.ui.control.Label
        umLabel_2                      matlab.ui.control.Label
        umLabel                        matlab.ui.control.Label
        AverageDiameterEditField       matlab.ui.control.EditField
        AverageDiameterEditFieldLabel  matlab.ui.control.Label
        MinDiameterEditField           matlab.ui.control.EditField
        MinDiameterEditFieldLabel      matlab.ui.control.Label
        MaxDiameterEditField           matlab.ui.control.EditField
        MaxDiameterEditFieldLabel      matlab.ui.control.Label
        NumberofSpheresEditField       matlab.ui.control.EditField
        NumberofSpheresEditFieldLabel  matlab.ui.control.Label
        CaptureLabel                   matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        pwdGlobal %present working directory of current operator
        saveDir %Processed Images subfolder for ease of access
        toBeProcessed %imageDatastore of imported images
        cultureLot %imported culture lot name, character array
        cultureLotPath %path of imported culture lot
        fileTable %indexed table of parsed files (for sorting)
        batchAx %axes component for batch analysis
        %%% from Sphere Image Analysis v1
        fullFileNameGlobal %full path of selected file
        originalImage %greyscale image and subsequent enhanced image
        sphere_imadjust %greyscale contrast enhance
        sphere_histeq %greyscale contrast enhance
        sphere_adapthisteq %greyscale contrast enhance
        binaryImage %binary image
        binaryImage_final %binary image
        binaryImage_bwAreaOpen %binary image
        binaryImage_noBorder %binary image
        binaryImage_smoothing %binary image
        binaryImage_preProcess %binary image
        tempImage %intermediate image
        D %distance transform of binary image
        Ld %watershed -->imshow(label2rgb(Ld))
        bw2 %placeholder binary image
        mask %image mask
        D2 %imimposemin distance transform on mask
        bw3 %placeholder binary image
        Ld2 %second watershed transform
        distancePerPixel %called from workspace via spatialCalibration function
        props %region properties of binary imgage
        numberOfSpheres %intial and final sphere count
        newProps %region properties of filtered sphere sizes
        sphereDiameter % array of sphere diameters, duh
        SphereStats %quick reference of image stats
        circularity %degreee of circularity of spheres, ranges from 0 to 1.
        solidity %degree of solidity of spheres
        eccentricity %degree of perpendicular radii ratio

        %semanticseg
        dlnn_all %structure of all networks
        dlnn_light_names %names of light networks
        dlnn_light_selected %selected from dropdown
        dlnn_light %loaded network
        dlnn_nolight %loaded network
        dlnn_nolight_names %names of no light networks
        dlnn_nolight_selected %selected from dropdown
        inputSize_LIGHT
        inputSize_NOLIGHT
        targetSize %input size for dlNN

        %maintenance, formatting, and tracking
        keepLockFile
        lockFileName
        captionFontSize
        operatorInitials
        operatorID
        driveID
        stopFlagBatch
        stopFlagImage

        %from SIA_v1
         selectionState %identifies selected folder options for organization
        directoryPath
        autoFileGroupGlobal
        selectedFolder
        imageProperty %nolight (0) vs light (1)
        fileList
        individualVesselFolder %selected from dropdown
        individualVesselFolderIndex
        processedVesselFolderName
        individualToBeProcessed
        aggregateSphereStats %for aggregated data
        aggregateDiameters
        aggregateCircularities
        aggregateSolidities
        aggregateEccentricities
        overwriteIndex %structure of file components that will be used to rewrite excel file 
        flagFilter %marker to separate manual mode strategies
        flagFileList % table of flagged files to be re-processed
        flagFileListCount
        oldSphereStats
        newSphereStats
        xChoice
        xDataChoice
        yChoice
        yDataChoice
        vizTable
        ax1
        ax2
        ax3
        vesselCompare %structure holding every comparison vessel
        compareState
        vesselLetterTag
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pwdGlobal = pwd;
            %have user select save directory
            app.saveDir = [pwd '\Processed Images'];
            app.SaveEditField.Enable = "on";
            app.SaveEditFieldLabel.Enable = "on"; 
            app.Button.Enable = "on";
            app.SaveEditField.Value = app.saveDir; 
            app.keepLockFile = 0; 
            app.captionFontSize = 14;
            app.stopFlagBatch = false; 
            app.stopFlagImage = false;
            %Check if locked file is present (if yes, then another operator
            %has the application open. 
            app.lockFileName = [app.pwdGlobal '\lockFile.txt'];
            %^text file with operator ID of last person to use app
            if isfile(app.lockFileName)
                %identify user that has file open
                fid = fopen('lockFile.txt');
                tline = fgetl(fid);
                lineStore = strings(3,1); 
                count = 0;
                while ischar(tline)
                    count = count+1; 
                    disp(tline)
                    lineStore(count,1) = tline; 
                    tline = fgetl(fid);
                end
                fclose(fid);
                uiwait(msgbox(lineStore)); %modal default
                app.keepLockFile = 1;
                delete(app.SphereImageAnalysisUIFigure);
            else
                  %%% close potentially opened excel files that are opened
                % user specific until other servers can be accessed
                try
                    %Check if an Excel server is running
                    ex = actxGetRunningServer('Excel.Application');
                catch ME
                    disp(ME.message)
                end
                if exist('ex','var')
                    %Get the names of all open Excel files
                    wbs = ex.Workbooks;
                    %List the entire path of all excel workbooks that are currently open
                    fileStore = strings(wbs.Count,1);
                    while wbs.Count > 0
                        fileStore = string(ex.ActiveWorkbook.FullName);
                        fileMessage = sprintf(['Opened Excel files detected. To ensure files are properly \n',...
                        'written, the following Excel file will be saved and closed: \n%s\n'],fileStore);
                        uiwait(warndlg(fileMessage));
                        invoke(ex.ActiveWorkbook,'Save');
                        invoke(ex.ActiveWorkbook,'Close');
                    end
                    Quit(ex);
                    delete(ex);
                    clear ex
                    closeExcel()
                end
    
                currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                userPrompt = {'Operator Initials:'};
                dialogTitle = 'Input Operator ID Before Starting:';
                numberOfLines = 1;
                def = {''};
                answer = inputdlg(userPrompt, dialogTitle, numberOfLines, def);
                if isempty(answer)
                    app.operatorInitials = "NULL";
                end
                app.operatorInitials = answer{1}; 
                nameAndDate = [app.operatorInitials currentDate];
                app.operatorID = strjoin(nameAndDate,' ');
    
                %write lockFile
                formatSpec = ['Last operator did not close the application.\n',...
                    'Operator ID: %s.\n', 'Please confirm the application and associated files are closed.'];
                fid = fopen(app.lockFileName,'w');
                fprintf(fid,formatSpec,app.operatorID);
                fclose(fid);

                %%find proper drive ID and confirm

                [app.driveID] = driveIDParse();
                if app.driveID == "NULL"
                    userPrompt = {'Mounted Drive Letter:'};
                    dialogTitle = 'Input Drive Letter for Microscope Images';
                    numberOfLines = 1;
                    def = {''};
                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines, def);
                    if isempty(answer)
                        app.driveID = 'Z'; %default
                    end
                    app.driveID = answer{1};
                else
                    msg = {'Confirm AB126 Cell Culture Images';...
                        ['NAS is mounted to drive ' app.driveID '.']};
                    banner = 'Mounted Drive ID';
                    selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner,...
                        'Options', {'Confirm','Assign Different Letter'});
                    switch selection
                        case 'Confirm'
                            %do nothing
                        case 'Assign Different Letter'
                            userPrompt = {'Mounted Drive Letter:'};
                            dialogTitle = 'Input Drive Letter (letter only) for Microscope Images';
                            numberOfLines = 1;
                            def = {''};
                            answer = inputdlg(userPrompt, dialogTitle, numberOfLines, def);
                            if isempty(answer)
                                app.driveID = 'Z'; %default
                            end
                            app.driveID = answer{1};
                    end
                end
                app.ImportButton.Enable = 'on';
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [app.toBeProcessed, folder, app.fileList, app.fileTable] = fileSearch_v2('no folder yet',0); %ADD choose again function if cancelled

            %populate fields
            [app.cultureLot] = findLot(folder);
            app.cultureLotPath = [app.saveDir '\' app.cultureLot];
            [app.toBeProcessed, app.fileTable] = fileListParse_v2(app.fileTable, ...
                app.toBeProcessed,app.cultureLotPath);

            app.LotEditField.Enable = 'on';

            
            app.LotEditField.Value = app.cultureLot; 
            app.NumFilestoProcessEditField.Enable = 'on';
            app.NumFilestoProcessEditField.Value = string(numel(app.fileTable.fileRoot));

            %initialize process settings buttons
            app.SemanticSegButton.Enable = 'on';
            app.GuidedSegButton.Enable = 'on';
            %app.ReprocessButton.Enable = 'on';
            %app.VisualizeButton.Enable = 'on';
            
            %check and create cultureLot directory
            if isfolder(app.cultureLotPath) == 0
                mkdir(app.saveDir,app.cultureLot);
                disp(['Folder created at ' app.cultureLotPath]);
            end
            
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                app.CheckBox_2.Enable = 'off';
            else
                app.CheckBox_2.Enable = 'on';
            end
        end

        % Callback function
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value == 1
                app.CheckBox.Enable = 'off';
            else
                app.CheckBox.Enable = 'on';
            end
        end

        % Button pushed function: SemanticSegButton
        function SemanticSegButtonPushed(app, event)
            app.GuidedSegButton.Enable = 'off';
            app.ReprocessButton.Enable = 'off';
            app.VisualizeButton.Enable = 'off';
            app.FileEditField.Enable = 'on';
            app.DateEditField_Status.Enable = 'on';
            app.ImageStatusLabel.Visible = 'on';
            app.BatchStatusLabel.Visible = 'on';
            app.ProgressBar.Visible = 'on';
            app.ProgressBar_2.Visible = 'on';
            
            app.SEMANTICSEGMENTATIONSETTINGSLabel.Visible = 'on';
            app.LIGHTDropDown.Visible = 'on';
            app.LIGHTDropDownLabel.Visible = 'on';
            app.NOLIGHTDropDown.Visible = 'on';
            app.NOLIGHTDropDownLabel.Visible = 'on';
            app.SemanticSegStartButton.Visible = 'on';
           

            dlnnSearch = dir(fullfile([app.pwdGlobal '\Deep Learning NN Repository\*.mat']));
            %filter out stats and image-specific networks
            dlnnSearch(contains(string({dlnnSearch.name}),"stats")) = [];
            app.dlnn_all = dlnnSearch; 
            assignin('base','all',app.dlnn_all);
            app.dlnn_nolight_names = app.dlnn_all(contains(string({app.dlnn_all.name}),"NOLIGHT"));
            A = setdiff(string({app.dlnn_all.name}),string({app.dlnn_nolight_names.name}));
            assignin('base','A',A);
            A_indx = ismember(string({app.dlnn_all.name}), A);
            app.dlnn_light_names = app.dlnn_all(A_indx); 

            %populate
            app.dlnn_nolight_names = string(erase(string({app.dlnn_nolight_names.name}),'.mat'))'; %remove extension
            app.NOLIGHTDropDown.Items = [{""};app.dlnn_nolight_names];
            app.NOLIGHTDropDown.ItemsData = [{""};app.dlnn_nolight_names];
            app.dlnn_nolight_selected = [];
            app.inputSize_NOLIGHT = []; 

            app.dlnn_light_names = string(erase(string({app.dlnn_light_names.name}), '.mat'))'; %remove extension
            app.LIGHTDropDown.Items = [{""};app.dlnn_light_names];
            app.LIGHTDropDown.ItemsData = [{""};app.dlnn_light_names];
            app.dlnn_light_selected = [];
            app.inputSize_LIGHT = [];
            %continue to dropdown menus
           
        end

        % Button pushed function: GuidedSegButton
        function GuidedSegButtonPushed(app, event)
            %app.STARTButton.Enable = 'off';
            app.SemanticSegButton.Enable = 'off';
            app.ReprocessButton.Enable = 'off';
            app.VisualizeButton.Enable = 'off';
            app.FileEditField.Enable = 'on';
            app.DateEditField_Status.Enable = 'on';
            app.ImageStatusLabel.Visible = 'on';
            app.BatchStatusLabel.Visible = 'on';
            app.ProgressBar.Visible = 'on';
            app.ProgressBar_2.Visible = 'on';
            
            [fileTable_batch,aggregateTable_batch] = batchProcess_v2(app.toBeProcessed, app.fileTable, app.cultureLot, app.cultureLotPath);            
            assignin('base','fileTable_batch',fileTable_batch);

            % initialSearch = dir(fullfile([app.cultureLotPath '\**\*.xlsx']));
            % initialSearch = initialSearch(~startsWith({initialSearch.name},'~'));
            % [initialDetectStore] = individualImageDataSearch(initialSearch,0); %sorts data as well!

            appFig = gcf;
            %find unique batch IDs for processing
            batchIDs = unique(fileTable_batch.batchID);
            for i = 1 : numel(batchIDs)
                batchGroup = fileTable_batch(fileTable_batch.batchID == batchIDs(i),:);
                assignin('base','batchGroup',batchGroup);
                batchDate = batchGroup.fileDate(1); 
                if isempty(aggregateTable_batch) || isempty(find(string(aggregateTable_batch.Date) == batchDate, 1))
                    batchState = 0; %prompt user to process control file prior to automation
                    sizeBatch = numel(batchGroup.filePath);
                    if sizeBatch <= 4
                        app.batchAx = [];
                        for k = 1:sizeBatch
                            app.batchAx = [app.batchAx subplot(2,2,k)];
                        end
                    elseif 4 < sizeBatch && sizeBatch <= 9
                        app.batchAx = [];
                        for k = 1:sizeBatch
                            app.batchAx = [app.batchAx subplot(3,3,k)]; 
                        end
                    elseif 9 < sizeBatch && sizeBatch <= 16
                        app.batchAx = [];
                        for k = 1:sizeBatch
                            app.batchAx = [app.batchAx subplot(4,4,k)];
                        end 
                    elseif 16 < sizeBatch && sizeBatch <= 25
                        app.batchAx = [];
                        for k = 1:sizeBatch
                            app.batchAx = [app.batchAx subplot(5,5,k)];
                        end
                    end
        
                    for j = 1:sizeBatch
                        imshow(batchGroup.filePath(j),'Parent', app.batchAx(j));
                    end

                    batchFig = gcf; 
        
                    uiwait(msgbox('Select the image you wish to process as the control mean:'));
        
                    selectState = 0; 
                    while selectState == 0
                        w = waitforbuttonpress; %0 if clicked, 1 if key pressed    
                        clickedAx = app.batchAx == gca();
                        imagePos = find(clickedAx == 1);
                        %close;
                        app.UIAxes5.Visible = 'on'; %top right image
                        cla(app.UIAxes5,'reset');
                        app.SphereImageAnalysisUIFigure.Visible = 'off';
                        app.SphereImageAnalysisUIFigure.Visible = 'on';
                        imshow(batchGroup.filePath(clickedAx'),'Parent',app.UIAxes5);
                        pause(3);
                        msg = ['Image position ' num2str(imagePos) ' was selected...use as control?'];
                        banner = 'Selected Image';
                        selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner,...
                            'Options', {'YES','Choose Again'});
                         switch selection
                            case 'YES'
                                selectState = 1; 
                                selectedImageLogical = clickedAx';
                                close(batchFig);
                            case 'Choose Again'
                                cla(app.UIAxes5,'reset');
                                app.UIAxes5.Visible = 'off';
                                figure(batchFig);
                                selectState = 0; 
                                selectedImageLogical = clickedAx';
                                for q = 1:sizeBatch
                                    imshow(batchGroup.filePath(q),'Parent', app.batchAx(q));
                                end
                                uiwait(msgbox('Select the image you wish to process as the reference mean:'));
                         end
                        %[selectState,selectedImageLogical] = subplotSelect(clickedAx,batchFileGroup,batchDateStrings,i);
                    end
                    selectedImage = batchGroup.filePath(selectedImageLogical);
                    disp(selectedImage); %complete path of selected image
                    uiwait(msgbox('You will now process the selected image to use as a reference mean.'));
                else
                    %close(batchFig);
                    batchState = 1; %automated process will begin with control mean selected
                    aggregateStats = aggregateTable_batch(string(aggregateTable_batch.Date) == batchDate,:);
                    uiwait(msgbox({"Aggregate mean detected for vessel ID and date.";...
                        string(['Mean Diameter is ' num2str(aggregateStats.Mean) 'um']);...
                        string(['for ' aggregateStats.VesselID{1} ' on ' aggregateStats.Date{1}])}));
                end
            %%%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~%%%
                            %%%  process control image %%%
            %%%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~%%%
                if batchState == 0
                    %%%find image index in individualToBeProcessed
                    %selectedImageFileName = strsplit(selectedImage,'\');
                    %selectedImageFileName = selectedImageFileName(end);%NAS file name 
                    processedSearch = batchGroup.filePath == selectedImage; 
                    processedIndex = find(processedSearch == 1,1,'first'); %index of selected image in structure
                    assignin('base','processedIndex',processedIndex);
                    % app.ImageProcessingEditField.Value = "WAITING";
                    % app.ExportingDataEditField.Value = "WAITING";
                    %get full file name
                    % app.ProgressEditField.Value = [num2str(1) ' of ' num2str(numel(selectedImage))];
                    %individual vessels processed
                    app.FileEditField.Value = batchGroup.fileRoot(processedIndex);
                    app.SphereStats.Name = batchGroup.fileRoot(processedIndex);
                    app.DateEditField_Status.Value = batchGroup.fileDate(processedIndex);
                    app.fullFileNameGlobal = batchGroup.filePath(processedIndex);
                    %get vessel ID
                    app.SphereStats.VesselID = batchGroup.vesselID(processedIndex); 
          
                    %read and create greyscale image
                    app.originalImage = imread(app.fullFileNameGlobal);

                    %%% Assess image size
                    imageSize = imfinfo(app.fullFileNameGlobal);
                    imageWidth = imageSize.Width;
                    imageHeight = imageSize.Height; 
                    %%%
                    
                    app.UIAxes4.Visible = 'on';
                    cla(app.UIAxes4, 'reset');
                    cla(app.UIAxes5, 'reset');
                    imshow(app.originalImage, 'Parent',app.UIAxes5);
                    imshow(app.originalImage, 'Parent',app.UIAxes4);
                    %determine if input image is NO LIGHT or NORMAL 
    
                    msg = 'Select Image Property for Modified Processing';
                    banner = 'Image Property';
                    selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner, ...
                        'Options', {'NO LIGHT','LIGHT','NO LIGHT-UNSATURATED', 'LIGHT-NONHOMOGENEOUS'});                   
                    app.imageProperty = convertCharsToStrings(selection);

                    if app.imageProperty == "NO LIGHT"
                        % app.ImageProcessingEditField.Value = "WORKING";
                        % app.ExportingDataEditField.Value = "WAITING";
                        %begin parsed processing
                        app.originalImage = rgb2gray(app.originalImage);
                        %app.UIAxes.Visible = 'on';
                        cla(app.UIAxes5,'reset');
        
                        % Display the greyscale image.
                        imshow(app.originalImage, 'Parent',app.UIAxes5);
        
                        %enhance greyscale with IMADJUST
                        %app.UIAxes3.Visible = 'on'; %top left image
                        cla(app.UIAxes5,'reset');
                        app.originalImage = imadjust(app.originalImage); %This seems to be the best!
                        imshow(app.originalImage, 'Parent',app.UIAxes5);
                        pause(1);
          
                        %display histogram
                        [pixelCount, grayLevels] = imhist(app.originalImage);
                        cla(app.UIAxes5,'reset');
                        bar(pixelCount,'Parent',app.UIAxes5);
                        title(app.UIAxes5,'Histogram of original image');
                        xlim(app.UIAxes5,[0 (grayLevels(end)+5)]); % Scale x axis manually.
                        grid(app.UIAxes5,'on');
        
                        %prompt for thresholding value
                        % Ask the user for the real-world distance.
                        userPrompt = {'Choose low threshold:', 'Choose high threshold:'};
                        dialogTitle = 'Specify threshold value for binary image';
                        numberOfLines = 1;
                        %opts.Resize = 'on';
                        def = {'',''};
                        answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                        if isempty(answer)
	                        return;
                        end
                        lowThreshold = str2double(answer{1});
                        highThreshold = str2double(answer{2});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        autoStore = [autoStore string(answer{2})];
                        %%% ---------------- %%%
        
                        %show user input
                        app.binaryImage = (app.originalImage >= lowThreshold) & (app.originalImage <= highThreshold);    
                        % Show the threshold as a vertical red bar on the histogram.
                        hold(app.UIAxes5,'on');
                        maxYValue = ylim(app.UIAxes5);
                        line(app.UIAxes5,[lowThreshold, lowThreshold], maxYValue, 'Color', 'r');
                        line(app.UIAxes5,[highThreshold,highThreshold],maxYValue, 'Color', 'r');
                        % Place a text label on the bar chart showing the threshold.
                        annotationText1 = sprintf('Low Thresholded at %d gray levels', lowThreshold);
                        annotationText2 = sprintf('High Thresholded at %d gray levels', highThreshold);
                        % For text(), the x and y need to be of the data class "double" so let's cast both to double.
                        text(double(lowThreshold + 5), double(0.5 * maxYValue(2)), annotationText1, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes5);
                        text(double(highThreshold + 5), double(0.5 * maxYValue(2)), annotationText2, 'FontSize', 10, 'Color', [0 .5 0],'Parent',app.UIAxes5);
                        text(double(highThreshold - 70), double(0.94 * maxYValue(2)), 'Background', 'FontSize', 10, 'Color', [0 0 .5],'Parent',app.UIAxes5);
                        text(double(highThreshold + 50), double(0.94 * maxYValue(2)), 'Foreground', 'FontSize', 10, 'Color', [0 0 .5],'Parent',app.UIAxes5);
                        hold(app.UIAxes5,'off');
        
                        %generate binary image
                        % Do a "hole fill" to get rid of any background pixels or "holes" inside the blobs.
                        app.binaryImage = imfill(app.binaryImage, 'holes');
                        cla(app.UIAxes5, 'reset');
                        imshow(app.binaryImage, 'Parent', app.UIAxes5);
        
                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
                                app.binaryImage = imclearborder(app.binaryImage,4);
                                cla(app.UIAxes5, 'reset');
                                imshow(app.binaryImage,'Parent',app.UIAxes5);
                                title(app.UIAxes5,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%
   
                        %bwareaopen
                        app.binaryImage = bwareaopen(app.binaryImage,200);
                        cla(app.UIAxes5,'reset');
                        imshow(app.binaryImage,'Parent',app.UIAxes5);
                        title(app.UIAxes5,'Objects Smaller than Set Value in Foreground are Filtered');
                        pause(1)
      
                        app.binaryImage = bwmorph(app.binaryImage,'close');
                        imshow(app.binaryImage,'Parent',app.UIAxes5);
                        title(app.UIAxes5, 'Closed Binary Image');
                        pause(1)

                        %overlay
                        cla(app.UIAxes5,'reset');
                        imshow(labeloverlay(app.originalImage, app.binaryImage), 'Parent', app.UIAxes5);
                        title(app.UIAxes5, 'Mask Over Original Image');
                        pause(1)
            
                        %Start watershed transform processing here:
                        app.D = -bwdist(~app.binaryImage);
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes5, 'reset');
                        imshow(label2rgb(app.Ld), 'Parent', app.UIAxes5);
                        pause(1)
        
                        app.bw2 = app.binaryImage;
                        app.bw2(app.Ld == 0) = 0; 
                        %imshow(app.bw2,'Parent',app.UIAxes);
        
                        %extendedmin
                        app.mask = imextendedmin(app.D, 2); %catch more spheres with 1
                        cla(app.UIAxes5, 'reset');
                        imshowpair(app.binaryImage, app.mask, 'blend','Parent',app.UIAxes5);
                        pause(1)
        
                        %apply watershed to binary mask
                        app.D2 = imimposemin(app.D, app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = app.binaryImage; 
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes5, 'reset');
                        imshow(app.bw3, 'Parent', app.UIAxes5);
                        pause(1)
        
                        % app.ImageProcessingEditField.Value = "DONE";
                        % app.ExportingDataEditField.Value = "WORKING";
                        
                    elseif app.imageProperty == "LIGHT" %add light processing
                        % app.ImageProcessingEditField.Value = "WORKING";
                        % app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
                        userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
                        dialogTitle = 'Ridge Line';
                        numberOfLines = 1;
                        def = {''};
                        answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                        if isempty(answer)
	                        return;
                        end
                        ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%
    
                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes5, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes5); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes5); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes5); %map
                        pause(1)
                        
                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
                                M = imclearborder(M,4);
                                cla(app.UIAxes5, 'reset');
                                imshow(M,'Parent',app.UIAxes5);
                                title(app.UIAxes5,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%

                        % apply bwareaopen
                        BWopen = bwareaopen(M, 200);
                        cla(app.UIAxes5, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes5);
                        pause(1)

                        
                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWopen;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes5);
                            title(app.UIAxes5,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWopen,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%
                        
                        %fill holes

                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes5);
                        title(app.UIAxes5, 'Filled Binary Image');
                        pause(1)

                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfill;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes5,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes5);
                            title(app.UIAxes5, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfill,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%

                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes5);
                        title(app.UIAxes5, 'Closed Binary Image');
                        pause(1) 
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes5, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes5);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes5, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes5);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes5, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes5);
                        pause(1);
                        
                        H = 2; %not extremely necessary to manually enter this number
                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes5, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes5);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes5, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes5)
    
                        % app.ImageProcessingEditField.Value = "DONE";
                        % app.ExportingDataEditField.Value = "WORKING";

                    elseif app.imageProperty == "NO LIGHT-UNSATURATED" %unsaturated, no light image (no clear boundaries, OR overcrowding)

                        % app.ImageProcessingEditField.Value = "WORKING";
                        % app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
                        userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
                        dialogTitle = 'Ridge Line Value';
                        numberOfLines = 1;
                        def = {''};
                        answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                        if isempty(answer)
	                        return;
                        end
                        ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%
    
                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes5, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes5); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        M = ~M; %reverse image
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes5); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes5); %map
                        pause(1)

                        %Erode filled image until the overlay fits the
                        %original
                        BWerode = M;
                        erodeState = 1;
                        radius = 0;
                        while erodeState == 1
                            cla(app.UIAxes5,'reset');
                            imshow(BWerode, 'Parent', app.UIAxes5);
                            title(app.UIAxes5, 'Erode Image');
                            msg = 'Are spheres disconnected enough to filter out negative space?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    erodeState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('disk',radius);
                                    BWerode = imerode(M,seD);
                                    BWerode = imerode(BWerode,seD);
                                    erodeState = 1; 
                                case 'Exit'
                                    erodeState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %%%%%% NEED TO FINE TUNE THIS!%%%%%%%%
                        %Connected spheres get erased
                        BWfilt = BWerode;
                        filterState = 1;
                        while filterState == 1
                            cla(app.UIAxes5,'reset');
                            imshow(BWfilt,'Parent',app.UIAxes5);
                            filterProps = regionprops(BWfilt,'Area');
                            areaVals = [filterProps.Area];
                            areaVals_excludeHigh = max(areaVals);
                            msg = 'Filter out max area?';
                            banner = 'Eliminate large areas';
                            selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg,banner,...
                                'Options', {'YES','NO'});
                            switch selection
                                case 'YES'
                                    filterState = 1; 
                                    BWfilt = bwareafilt(BWerode,[1,areaVals_excludeHigh-100]); %range is the allowable pixel area
                                case 'NO'
                                    filterState = 0; 
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(areaVals_excludeHigh)];
                        %%% ---------------- %%%

                        %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
                                BWfilt = imclearborder(BWfilt,4);
                                cla(app.UIAxes5, 'reset');
                                imshow(BWfilt,'Parent',app.UIAxes5);
                                title(app.UIAxes5,'Cleared Border Image');
                                pause(1);
                            case 'NO'
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%

                        BWopen= bwareaopen(BWfilt, 200);
                        cla(app.UIAxes5, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes5);
                        pause(1)

                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWopen;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes5);
                            title(app.UIAxes5,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWopen,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%
                        
                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes5);
                        title(app.UIAxes5, 'Filled Binary Image');
                        pause(1)

                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfill;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes5,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes5);
                            title(app.UIAxes5, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfill,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes5);
                        title(app.UIAxes5, 'Closed Binary Image');
                        pause(1)
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes5, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes5);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes5, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes5);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes5, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes5);
                        pause(1);

                        H = 2; %not extremely necessary to manually enter this number
                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes5, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes5);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes5, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes5)
    
                        % app.ImageProcessingEditField.Value = "DONE";
                        % app.ExportingDataEditField.Value = "WORKING";

                    elseif app.imageProperty == "LIGHT-NONHOMOGENEOUS" %inconsitent sphere sizes in light processing

                        % app.ImageProcessingEditField.Value = "WORKING";
                        % app.ExportingDataEditField.Value = "WAITING";
                        app.originalImage = rgb2gray(app.originalImage);
                        app.originalImage = imadjust(app.originalImage);
    
                        %prompt for STD Ridge Line value
                        userPrompt = {'large spheres, 1 <= Value <= smaller spheres, 10'};
                        dialogTitle = 'Ridge Line Value';
                        numberOfLines = 1;
                        def = {''};
                        answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                        if isempty(answer)
	                        return;
                        end
                        ridgeVal = str2double(answer{1});
                        %%% AUTO STORE VALUE %%%
                        autoStore = string(answer{1});
                        %%% ---------------- %%%

                        S = stdfilt(app.originalImage,ones(3));
                        cla(app.UIAxes5, 'reset');
                        imshow(S<ridgeVal, 'Parent',app.UIAxes5); %ridge lines 
                        pause(1);
                        M = S>=ridgeVal;  %map where the std is large
                        I2 = app.originalImage.*uint8(M);  %apply map
                        imshow(I2,'Parent',app.UIAxes5); %blanked out everything but ridge lines.  \
                        imshow(M,'Parent',app.UIAxes5); %map
                        pause(1)

                        BWopen= bwareaopen(M, 200);
                        cla(app.UIAxes5, 'reset');
                        imshow(BWopen,'Parent',app.UIAxes5);
                        pause(1)
                        
                        %Erode filled image until the overlay fits the
                        %original
                        BWerode = BWopen;
                        erodeState = 1;
                        radius = 0;
                        while erodeState == 1
                            cla(app.UIAxes5,'reset');
                            imshow(BWerode, 'Parent', app.UIAxes5);
                            title(app.UIAxes5, 'Erode Image');
                            msg = 'Are spheres disconnected enough to start fill process?';
                            banner = 'Erode Image';
                            selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    erodeState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('disk',radius);
                                    BWerode = imerode(BWopen,seD);
                                    BWerode = imerode(BWerode,seD);
                                    erodeState = 1; 
                                case 'Exit'
                                    erodeState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%

                        %Dilate
                        %prelocate dilated image
                        BWsdil = BWerode;
                        dilateState = 1;
                        thickness = 0;
                        while dilateState == 1
                            imshow(BWsdil,'Parent',app.UIAxes5);
                            title(app.UIAxes5,'Dilated Gradient Mask');
                            msg = 'Are Target Sphere Edges Connected?';
                            banner = 'Connect Edges';
                            selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    dilateState = 0; 
                                case 'NO'
                                    thickness = thickness + 1; 
                                    se90 = strel('line',thickness,90);
                                    se0 = strel('line',thickness,0);
                                    BWsdil = imdilate(BWerode,[se90 se0]);
                                    dilateState = 1; 
                                case 'Exit'
                                    dilateState = 0;
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(thickness)];
                        %%% ---------------- %%%

                        BWfill = imfill(BWsdil,'holes');
                        imshow(BWfill,'Parent',app.UIAxes5);
                        title(app.UIAxes5, 'Filled Binary Image');
                        pause(1)

                         %Remove connected Objects on Border
                        msg = 'Clear Border Objects?';
                        banner = 'Border Objects';
                        selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner, ...
                        'Options', {'YES','NO'});  
                        switch selection
                            case 'YES'
                                BWfilt = imclearborder(BWfill,4);
                                cla(app.UIAxes5, 'reset');
                                imshow(BWfilt,'Parent',app.UIAxes5);
                                title(app.UIAxes5,'Cleared Border Image');
                            case 'NO'
                                BWfilt = BWfill; 
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        %%% ---------------- %%%

                        %Erode filled image until the overlay fits the
                        %original
                        BWoverlay = BWfilt;
                        overlayState = 1;
                        radius = 0;
                        while overlayState == 1
                            cla(app.UIAxes5,'reset');
                            imshow(labeloverlay(app.originalImage, BWoverlay), 'Parent', app.UIAxes5);
                            title(app.UIAxes5, 'Mask Over Original Image');
                            msg = 'Does the mask capture the original image?';
                            banner = 'Erode Dilated Image';
                            selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner,...
                                'Options', {'YES','NO','Exit'});
                            switch selection
                                case 'YES'
                                    overlayState = 0; 
                                case 'NO'
                                    radius = radius + 1; 
                                    seD = strel('diamond',radius);
                                    BWoverlay = imerode(BWfilt,seD);
                                    BWoverlay = imerode(BWoverlay,seD);
                                    overlayState = 1; 
                                case 'Exit'
                                    overlayState = 0; 
                            end
                        end
                        %%% AUTO STORE VALUE %%%
                        autoStore = [autoStore string(selection)];
                        autoStore = [autoStore string(radius)];
                        %%% ---------------- %%%
                        %Close Image (dilate and erode)
                        BWfinal = bwmorph(BWoverlay,'close');
                        imshow(BWfinal,'Parent',app.UIAxes5);
                        title(app.UIAxes5, 'Closed Binary Image');
                        pause(1)
                        
                        app.D = -bwdist(~BWfinal);
                        cla(app.UIAxes5, 'reset');
                        imshow(app.D,[],'Parent',app.UIAxes5);
                        pause(1)
                        
                        app.Ld = watershed(app.D);
                        cla(app.UIAxes5, 'reset');
                        imshow(label2rgb(app.Ld),'Parent',app.UIAxes5);
                        pause(1)
                        
                        app.bw2 = BWfinal;
                        app.bw2(app.Ld == 0) = 0;
                        cla(app.UIAxes5, 'reset');
                        imshow(app.bw2,'Parent',app.UIAxes5);
                        pause(1);
                        
                        H = 2; %not extremely necessary to manually enter this number

                        app.mask = imextendedmin(app.D,H);
                        cla(app.UIAxes5, 'reset');
                        imshowpair(BWfinal,app.mask,'blend','Parent',app.UIAxes5);
                        pause(1)
                        
                        app.D2 = imimposemin(app.D,app.mask);
                        app.Ld2 = watershed(app.D2);
                        app.bw3 = BWfinal;
                        app.bw3(app.Ld2 == 0) = 0;
                        cla(app.UIAxes5, 'reset');
                        imshow(app.bw3,'Parent',app.UIAxes5)
    
                        % app.ImageProcessingEditField.Value = "DONE";
                        % app.ExportingDataEditField.Value = "WORKING";
                    end
                
                            %%% PROCESS REFERENCE MEAN IMAGE %%%
                    %%%%%--------------------------------------------%%%%%
                    %%%%%--------------SPHERE DATA-------------------%%%%%
                    %%%%%--------------------------------------------%%%%%
                    
                    %%%% ADD distance per pixel
                    if imageWidth == 2592
                        app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                    elseif imageWidth == 1024
                        app.distancePerPixel = 2.7037; %scale bar of 4X image, small
                    else
                        app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                    end
                
                    app.props = regionprops(app.bw3,app.originalImage, 'all');
                    app.numberOfSpheres = numel(app.props);
        
                    % Print out the measurements to the command window, and display blob numbers on the image.
                    % Print header line in the command window.
                    fprintf(1,'Sphere #      Mean Intensity  Area   Perimeter    Centroid       Diameter\n');
                    % Extract all the mean diameters into an array.
                    % The "diameter" is the "Equivalent Circular Diameter", which is the diameter of a circle with the same number of pixels as the blob.
                    % Enclosing in brackets is a nice trick to concatenate all the values from all the structure fields (every structure in the props structure array).
    
                    blobECD = [app.props.EquivDiameter];
    
                    %prompt for thresholding value
                    % Ask the user for the real-world distance.
                    userPrompt = {'Minimum Diameter:', 'Maximum Diameter:'};
                    dialogTitle = 'Choose Size Exclusion Filter:';
                    numberOfLines = 1;
                    %opts.Resize = 'on';
                    def = {num2str(min(blobECD)),num2str(max(blobECD))};
                    answer = inputdlg(userPrompt, dialogTitle, numberOfLines, def);
                    if isempty(answer)
                        return;
                    end
                    minSize = str2double(answer{1});
                    maxSize = str2double(answer{2});
                    %%% AUTO STORE VALUE %%%
                    autoStore = [autoStore string(answer{1})];
                    autoStore = [autoStore string(answer{2})];
                    %%% ---------------- %%%
                    % Loop over all blobs printing their measurements to the command window.
                    count = 0;
                    kStore = [];
                    for k = 1 : app.numberOfSpheres          % Loop through all blobs.
                        if blobECD(k) >= minSize && blobECD(k) <= maxSize %minimum calculated diameter to filter out tiny detected blobs
                            count = count + 1;
                            % Find the individual measurements of each blob.  They are field of each structure in the props strucutre array.
                            % You could use the bracket trick (like with blobECD above) OR you can get the value from the field of this particular structure.
                            % I'm showing you both ways and you can use the way you like best.
                            meanGL = app.props(k).MeanIntensity;		% Get average intensity.
                            blobArea = app.props(k).Area;				% Get area.
                            blobPerimeter = app.props(k).Perimeter;		% Get perimeter.
                            blobCentroid = app.props(k).Centroid;		% Get centroid one at a time
                            blobDiameter(count) = blobECD(k);              %Specific diameter value
                            kStore = [kStore; k];       %for indexing props after
                            % Now do the printing of this blob's measurements to the command window.
                            fprintf(1,'#%2d %17.1f %11.1f %8.1f %8.1f %8.1f % 8.1f\n', k, meanGL, blobArea, blobPerimeter, blobCentroid, blobDiameter(count));
                            % Put the "blob number" labels on the grayscale image that is showing the red boundaries on it.
                            %text(blobCentroid(1), blobCentroid(2), num2str(count), 'FontSize', textFontSize, 'FontWeight', 'Bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                        end
                    end
                    %Convert applicable fields to microns using calibration value
                    app.newProps = app.props(kStore);
                    app.sphereDiameter = zeros(numel(app.newProps),1);
                    app.circularity = zeros(numel(app.newProps),1);
                    app.solidity = zeros(numel(app.newProps),1);
                    app.eccentricity = zeros(numel(app.newProps),1);
                    for k = 1:numel(app.newProps)
                        app.newProps(k).EquivDiameter = app.newProps(k).EquivDiameter.*app.distancePerPixel;
                        app.sphereDiameter(k) = app.newProps(k).EquivDiameter; 
                        app.circularity(k) = app.newProps(k).Circularity;
                        app.solidity(k) = app.newProps(k).Solidity; 
                        app.eccentricity(k) = app.newProps(k).Eccentricity; 
                    end
                    app.numberOfSpheres = numel(app.newProps);
                    sprintf('%d spheres ultimately detected.\n', app.numberOfSpheres);
                    app.SphereStats.Date = app.DateEditField_Status.Value;
                    app.SphereStats.count = app.numberOfSpheres;
                    app.SphereStats.max = max(app.sphereDiameter); % converted
                    app.SphereStats.min = min(app.sphereDiameter); % converted
                    app.SphereStats.mean = mean(app.sphereDiameter); % converted
                    app.SphereStats.median = median(app.sphereDiameter); %converted
                    app.SphereStats.std = std(app.sphereDiameter); %converted
                    %find mode bin size
                    [N, edges] = histcounts(app.sphereDiameter,20);
                    maxIndex = find(N==max(N));
                    modeBin = [];
                    if numel(maxIndex) > 1
                        edgeStore = zeros(numel(maxIndex)*2, 1);
                        for j = 1:numel(maxIndex)
                            edgeStore(j) = edges(maxIndex(j));
                            edgeStore(j+1) = edges(maxIndex(j)+1);
                            if j == 1                         
                                modeBin = [modeBin [num2str(edgeStore(j)) '-' num2str(edgeStore(j+1))]];
                            else
                                modeBin = [modeBin ', ' [num2str(edgeStore(j)) '-' num2str(edgeStore(j+1))]];
                            end
                        end
                        app.SphereStats.modeBin = modeBin;
                    else
                        edge1 = edges(maxIndex);
                        edge2 = edges(maxIndex+1);
                        app.SphereStats.modeBin = [num2str(edge1) '-' num2str(edge2)];
                    end
      
                    app.CaptureLabel.Enable = 'on';
                    app.NumberofSpheresEditField.Enable = 'on';
                    app.NumberofSpheresEditFieldLabel.Enable = 'on';
                    app.MaxDiameterEditField.Enable = 'on';
                    app.MaxDiameterEditFieldLabel.Enable = 'on';
                    app.MinDiameterEditField.Enable = 'on';
                    app.MinDiameterEditFieldLabel.Enable = 'on';
                    app.AverageDiameterEditField.Enable = 'on';
                    app.AverageDiameterEditFieldLabel.Enable = 'on';
                    app.PolydispersityIndexEditField.Enable = 'on';
                    app.PolydispersityIndexEditFieldLabel.Enable = 'on';
                    app.STDEditField.Enable = 'on';
                    app.STDEditFieldLabel.Enable = 'on';
                    app.AverageDoCEditField.Enable = 'on';
                    app.AverageDoCEditFieldLabel.Enable = 'on';
                    app.AverageSolidityEditField.Enable = 'on';
                    app.AverageSolidityEditFieldLabel.Enable = 'on';
                    app.AverageEccentricityEditFieldLabel.Enable = 'on';
                    app.AverageEccentricityEditField.Enable = 'on';
                    app.umLabel.Enable = 'on';
                    app.umLabel_2.Enable = 'on';
                    app.umLabel_3.Enable = 'on';
                    app.umLabel_4.Enable = 'on';
        
                    %Populate Results Section
                    app.NumberofSpheresEditField.Value = string(app.numberOfSpheres); 
                    app.MaxDiameterEditField.Value = string(app.SphereStats.max);
                    app.MinDiameterEditField.Value = string(app.SphereStats.min);
                    app.AverageDiameterEditField.Value = string(app.SphereStats.mean);
    
                    app.SphereStats.AverageCircularity = mean(app.circularity);
                    app.SphereStats.AverageSolidity = mean(app.solidity);
                    app.SphereStats.AverageEccentricity = mean(app.eccentricity);
                    app.AverageDoCEditField.Value = num2str(app.SphereStats.AverageCircularity);
                    app.AverageSolidityEditField.Value = num2str(app.SphereStats.AverageSolidity);
                    app.AverageEccentricityEditField.Value = num2str(app.SphereStats.AverageEccentricity);
                    
                    if numel(app.sphereDiameter) > 1
                        %Run PDF Distribution
                        %create PDF from spherediameter
                        app.sphereDiameter = app.sphereDiameter(:);
                        cla(app.UIAxes5,'reset');
                        hold(app.UIAxes5,'on');
                        LegHandles = [];
                        LegText = {};
            
                        % --- Plot data originally in dataset "sphereDiameter data"
            
                        [CdfF,CdfX] = ecdf(app.sphereDiameter,'Function','cdf');  % compute empirical cdf
                        BinInfo.rule = 3;
                        BinInfo.nbins = 40;
                        [~,BinEdge] = internal.stats.histbins(app.sphereDiameter,[],[],BinInfo,CdfF,CdfX);
                        [BinHeight,BinCenter] = ecdfhist(CdfF,CdfX,'edges',BinEdge);
                        hLine = bar(BinCenter,BinHeight,'hist','Parent',app.UIAxes5);
                        set(hLine,'FaceColor','none','EdgeColor',[0.333333 0 0.666667],...
                            'LineStyle','-', 'LineWidth',1);
                        xlabel(app.UIAxes5,'Sphere Diameter (um)');
                        ylabel(app.UIAxes5,'Density');
                        LegHandles(end+1) = hLine;
                        LegText{end+1} = 'sphereDiameter data';
            
                        % Create grid where function will be computed
            
                        XLim = get(app.UIAxes5,'XLim'); %previously gca (get current axis)
                        XLim = XLim + [-1 1] * 0.01 * diff(XLim);
                        XGrid = linspace(XLim(1),XLim(2),100);
            
                        % --- Create fit "normal fit"
                        pd1 = fitdist(app.sphereDiameter, 'normal'); 
                        YPlot = pdf(pd1,XGrid);
                        hLine = plot(XGrid,YPlot,'Color',[1 0 0],...
                            'LineStyle','-', 'LineWidth',2,...
                            'Marker','none', 'MarkerSize',6,'Parent',app.UIAxes5);
                        LegHandles(end+1) = hLine;
                        LegText{end+1} = 'normal fit';
            
                        % Adjust figure
            
                        box(app.UIAxes5,'on');
                        hold(app.UIAxes5,'off');
            
                        % Create legend from accumulated handles and labels
            
                        hLegend = legend(app.UIAxes5,LegHandles,LegText,'Orientation', 'vertical', 'FontSize', 9, 'Location', 'northeast');
                        set(hLegend,'Interpreter','none');
                        
                        pause(1);
        
                        %Calculate polydispersity
                        app.STDEditField.Value = string(pd1.sigma);
                        polyD = (pd1.sigma/pd1.mu)^2;
                        app.PolydispersityIndexEditField.Value = string(polyD);
                        app.SphereStats.PolyDispersity = polyD; 
                    else
                        app.STDEditField.Value = string(std(app.sphereDiameter));
                        polyD = (std(app.sphereDiameter)/mean(app.sphereDiameter))^2; 
                        app.PolydispersityIndexEditField.Value = string(polyD);
                        app.SphereStats.PolyDispersity = polyD; 
                    end
    
                    %FLAG FILE IF PROCESSING REQUIRES FOLLOW-UP
                    msg = 'Flag File for Follow-up?';
                    banner = 'Flag File';
                    selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner, ...
                    'Options', {'YES','NO'});  
                    switch selection
                        case 'YES'
                            app.SphereStats.FLAG = 1;
                            %prompt for flag comments
                            userPrompt = {'Flag Notes'};
                            dialogTitle = 'Comments';
                            numberOfLines = 1;
                            def = {''};
                            answer = inputdlg(userPrompt, dialogTitle, numberOfLines,def);
                            app.SphereStats.Comments = answer{1};
                        case 'NO'
                            app.SphereStats.FLAG = 0;
                            app.SphereStats.Comments = "Reference";
                    end
    
                    %add ImageProperty to the sphere stats
                    app.SphereStats.ImageProperty = app.imageProperty;
                    app.SphereStats.ImageSize = [num2str(imageWidth) ' x ' num2str(imageHeight)];
                    
                     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                    %EXPORTING%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                               %AGGREGATE FILES OF SAME NAME SCHEME%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
                    %include file in aggregate
                    msg = 'Include file in aggregated counts?';
                    banner = 'Did you mess up tho';
                    selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner, ...
                    'Options', {'YES','NO'});  
                    switch selection
                        case 'YES'
                            include = 1; 
                            app.SphereStats.includedInAggregate = "YES";
                        case 'NO'
                            app.SphereStats.includedInAggregate = "NO";
                    end
                    %%%% Add in AUTO Processing Data! %%%%
                    autoStore = [autoStore "AUTO"];
                    autoStore = strjoin(autoStore,'_');
                    app.SphereStats.AutoProcess = autoStore; 

                    %put native path with original file name into excel file
                    app.SphereStats.FilePath = app.fullFileNameGlobal;
                    app.SphereStats.ProcessedFileName = strtrim(batchGroup.fileRoot(processedIndex));  
                    app.SphereStats.ProcessedFolder = batchGroup.vesselLetterStore(processedIndex);
                    folderPath = [app.saveDir '\' app.cultureLot];
                    folderName = convertStringsToChars(batchGroup.vesselLetterStore(processedIndex));
                    if isfolder(folderPath) == 0
                        mkdir(folderPath,folderName);
                    end
                    newFolderPath = [folderPath '\' folderName];
                    %newFolderName = 'Individual Image Data';
                    if isfolder(newFolderPath) == 0
                        mkdir(folderPath,folderName);
                    end
                    %get vessel letter
                    excelFileName = convertStringsToChars(batchGroup.vesselLetterStore(processedIndex));
                    excelPathLocal = [folderPath '\' folderName '\' excelFileName '.xlsx'];
                    %add operator ID
                    currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                    nameAndDate = [app.operatorInitials currentDate];
                    app.operatorID = strjoin(nameAndDate,' ');
                    app.SphereStats.Operator = app.operatorID;
                   
                    %save individual sphere data as .mat file in same folder
                    splitFileName = convertStringsToChars(strtrim(batchGroup.fileRoot(processedIndex)));
                    spherePropsFileName = [folderPath '\' folderName '\' splitFileName '.mat'];
                    count = 0; 
                    if isfile(spherePropsFileName)
                        while isfile(spherePropsFileName)
                            count = count+1; 
                            splitFileName_new = [splitFileName ' (' num2str(count) ')'];
                            app.SphereStats.ProcessedFileName = strtrim(string(splitFileName_new));
                            spherePropsFileName = [folderPath '\' folderName '\' splitFileName_new '.mat'];
                        end
                    end

                    sphereTable = struct2table(app.SphereStats);
                    
                    if isfile(excelPathLocal) == 1                     
                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                    else
                        writetable(sphereTable, excelPathLocal);
                    end
                    disp(spherePropsFileName);
                    %%%
                    if numel(app.sphereDiameter) > 1
                        individualSphereTable = struct2table(app.newProps);
                        save(spherePropsFileName, 'individualSphereTable','-mat');
                    else
                        individualSphereTable = struct2table(app.newProps,'AsArray',true);
                        save(spherePropsFileName, 'individualSphereTable','-mat');
                    end

                    %reset variables
                    %keep SphereStats for reference statistics
                    app.sphereDiameter = [];
                    blobECD = [];
                    meanGL = [];
                    blobArea = [];
                    blobPerimeter = [];
                    blobCentroid = [];
                    blobDiameter = [];
                    app.props = [];
                    app.newProps = [];
                    app.circularity = [];
                    app.solidity = [];
                    app.eccentricity = [];
                    autoStore = []; 

                    referenceStats = app.SphereStats; 
                    app.SphereStats = [];
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    uiwait(msgbox({'Reference mean acquired! You will now';...
                    'proceed to batch processing.'}));
                    
                    % app.individualToBeProcessed.FileName(processedIndex) = [];
                    % app.individualToBeProcessed.AdjustedFileName(processedIndex) = [];
                    % app.individualToBeProcessed.ProcessedFileName(processedIndex) = [];
                    % app.individualToBeProcessed.FileDate(processedIndex) = [];
                    % app.individualToBeProcessed.FilePath(processedIndex) = [];
                else
                    %change fieldnames 
                    newNames = {'VesselID';'Date';'count';'max';'min';'mean';'median';'std';'percCV';'modeBin';'AverageCircularity';...
                        'AverageSolidity';'AverageEccentricity';'Polydispersity'};
                    referenceStruct = table2struct(aggregateStats,"ToScalar",true);
                    oldNames = fieldnames(referenceStruct);
                    for b = 1:numel(oldNames)
                        referenceStats.(newNames{b}) = referenceStruct.(oldNames{b});
                    end       
                end

                 %%%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~%%%
                %%% begin batch processing %%%
                %%%~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~%%%
                cla(app.UIAxes5,'reset');
                cla(app.UIAxes4,'reset');
                %app.UIAxes5.Visible = 'off';

                % app.UIAxes.Visible = 'on';
                % cla(app.UIAxes,'reset');
                % app.UIAxes2.Visible = 'on';
                % cla(app.UIAxes2,'reset');
                % app.UIAxes3.Visible = 'on';
                % cla(app.UIAxes3,'reset');
                %iterate through image properties. below are the test input
                %values 
                %create batch file group of non-selected images
                singleBatchFiles = batchGroup.filePath; 
                if batchState == 0 %no aggregate files found
                    singleBatchFiles(singleBatchFiles == selectedImage) = [];
                    batchGroup(batchGroup.filePath == selectedImage,:) = [];
                end
                assignin('base','singleBatchFiles',singleBatchFiles);
                
                noLightArray = "NO LIGHT" + strings(3,1);
                lightArray = "LIGHT" + strings(5,1);
                lightNONArray = "LIGHT-NONHOMOGENEOUS" + strings(5,1);
                imagePropertyID = [noLightArray; lightArray; lightNONArray]; %imageProperty function ID
                imagePropertyInputs = ["245_255";"215_255";"175_255";"10";"8";"6";"4";"2";"10";"8";"6";"4";"2"]; %test input values

                fileCounter = 0;
                for m = 1:numel(singleBatchFiles)

                    fileCounter = fileCounter + 1; 
                    %%%find image index in individualToBeProcessed
                    batchImageFileName = batchGroup.fileRoot(m);
                    % batchSearch = app.individualToBeProcessed.FileName == batchImageFileName; 
                    % batchIndex = find(batchSearch == 1,1,"first"); %index of selected image in structure

                    app.FileEditField.Value = batchGroup.fileRoot(m);
                    app.DateEditField_Status.Value = batchGroup.fileDate(m);
                    app.fullFileNameGlobal = batchGroup.filePath(m);
                    % 
                    % app.ImageProcessingEditField.Value = "WAITING";
                    % app.ExportingDataEditField.Value = "WAITING";
                    % app.ProgressEditField.Value = [num2str(fileCounter) ' of ' num2str(numel(singleBatchFiles))];

                    originalBatchImage = imread(singleBatchFiles(m)); %app.FILENAMEEditField.Value
                    
                    cla(app.UIAxes5,'reset');
                    imshow(originalBatchImage,'Parent',app.UIAxes5);
                    imshow(originalBatchImage,'Parent',app.UIAxes4);
                    title(app.UIAxes5,convertStringsToChars(singleBatchFiles(m)));
                    pause(3);
                    originalBatchImage = rgb2gray(originalBatchImage);
                    originalBatchImage = imadjust(originalBatchImage);
                    
                    %assignin('base','individualToBeProcessed',app.individualToBeProcessed);
                    %assignin('base','batchIndex',batchIndex);
                    %individual vessels processed

                    %get vessel ID

                    %vesselFileName = batchGroup.fileRoot(m);
                    vesselString = batchGroup.vesselID(m);

                    %%% Assess image size
                    imageSize = imfinfo(app.fullFileNameGlobal);
                    imageWidth = imageSize.Width;
                    imageHeight = imageSize.Height; 
                    percentDiffStore = zeros(numel(imagePropertyInputs),1);
                    for n = 1:numel(imagePropertyInputs)
                        [finalBatchImage,autoStore] = imagePropertyProcessing(originalBatchImage,imagePropertyID(n),imagePropertyInputs(n));
                        %%%% ADD distance per pixel %%%%
                        if imageWidth == 2592
                            app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                        elseif imageWidth == 1024
                            app.distancePerPixel = 2.7037; %scale bar of 4X image, small
                        else
                            app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                        end
                        app.SphereStats.Name = batchGroup.fileRoot(m);
                        app.SphereStats.VesselID = vesselString; 
                        app.SphereStats.Date = app.DateEditField_Status.Value; 
                        [app.SphereStats,autoStore,percentDiff,app.newProps] = imagePropertyAnalysis(finalBatchImage,originalBatchImage,autoStore,...
                            referenceStats, app.SphereStats, app.distancePerPixel,0); 
                        %accumulate all data into substructures until best
                        %percent difference is selected
                        app.SphereStats.ImageProperty = imagePropertyID(n);
                        app.SphereStats.ImageSize = [num2str(imageWidth) ' x ' num2str(imageHeight)];
                        app.SphereStats.includedInAggregate = "YES";
                        app.SphereStats.AutoProcess = autoStore;
                        app.SphereStats.FilePath = app.fullFileNameGlobal;
                        app.SphereStats.ProcessedFileName = strtrim(batchGroup.fileRoot(m));  %remove whitespace
                        app.SphereStats.ProcessedFolder = batchGroup.vesselLetterStore(m); 

                        percentDiffStore(n) = percentDiff;
                        iterNames = ['RUN_' num2str(n)];
                        disp(iterNames);
                        SphereStatsTOTAL.(iterNames) = app.SphereStats;
                        propsTOTAL.(iterNames) = app.newProps;
                        app.SphereStats = [];
                        app.newProps = [];
                    end
                    %determine which processing iteration to keep, indexed
                    %by the minimum percentDiff
                    percentDiffIndex = percentDiffStore == min(percentDiffStore);
                    totalStatsNames = fieldnames(SphereStatsTOTAL);
                    totalStatsIndex = totalStatsNames(percentDiffIndex);
                    if isempty(totalStatsIndex)
                        optimalSphereStats = SphereStatsTOTAL.(totalStatsNames{5}); %default to run 5
                        optimalProps = propsTOTAL.(totalStatsNames{5});
                        optimalSphereStats.Comments = strjoin([optimalSphereStats.Comments "No Spheres Captured"],'-');
                        optimalSphereStats.FLAG = 1;
                    else
                        optimalSphereStats = SphereStatsTOTAL.(totalStatsIndex{1});
                        optimalProps = propsTOTAL.(totalStatsIndex{1});
                        optimalSphereStats.Comments = strjoin([optimalSphereStats.Comments...
                            [num2str(min(percentDiffStore)) '% Difference']],'-');
                    end
                    %assignin('base','optimalSphereStats',optimalSphereStats);
                    %%%% filter out excess %diff and new props!
                    if min(percentDiffStore) > 25 || optimalSphereStats.count == 0
                        optimalSphereStats.includedInAggregate = "NO";
                        optimalSphereStats.FLAG = 1; 
                    end
                    %now write the file!
                    folderPath = [app.saveDir '\' app.cultureLot];
                    folderName = convertStringsToChars(batchGroup.vesselLetterStore(m));
                    if isfolder(folderPath) == 0
                        mkdir(folderPath,folderName);
                    end
                    newFolderPath = [folderPath '\' folderName];
                    %newFolderName = 'Individual Image Data';
                    if isfolder(newFolderPath) == 0
                        mkdir(folderPath,folderName);
                    end
                    %get vessel letter;
                    excelFileName = convertStringsToChars(batchGroup.vesselLetterStore(m));
                    excelPathLocal = [folderPath '\' folderName '\' excelFileName '.xlsx'];
                    %add operator ID
                    currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                    nameAndDate = [app.operatorInitials currentDate];
                    app.operatorID = strjoin(nameAndDate,' ');
                    optimalSphereStats.Operator = app.operatorID; 
           
                   
                    %save individual sphere data as .mat file in same folder
                    splitFileName = batchGroup.fileRoot(m); 
                    splitFileName = convertStringsToChars(strtrim(splitFileName));
                    spherePropsFileName = [folderPath '\' folderName '\' splitFileName '.mat']; 
                    count = 0; 
                    if isfile(spherePropsFileName)
                        while isfile(spherePropsFileName)
                            count = count + 1; 
                            splitFileName_new = [splitFileName ' (' num2str(count) ')'];
                            optimalSphereStats.ProcessedFileName = strtrim(string(splitFileName_new));
                            spherePropsFileName = [folderPath '\' folderName '\' splitFileName_new '.mat'];
                        end
                    end
                    disp(spherePropsFileName);

                    try
                        sphereTable = struct2table(optimalSphereStats);
                    catch
                        sphereTable = struct2table(optimalSphereStats,"AsArray",true);
                    end

                    %%% SORTING %%%
                    sphereTable.Date = datetime(sphereTable.Date,'InputFormat',"ddMMMyyyy");
                    sphereTable = sortrows(sphereTable,'Date');
                    sphereTable.Date = string(sphereTable.Date,"ddMMMyyyy");
                    %%% END SORTING %%%

                    if isfile(excelPathLocal) == 1                     
                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                    else
                        writetable(sphereTable, excelPathLocal);
                    end
                   
                    %%%
                    if numel(app.newProps) > 1
                        individualSphereTable = struct2table(optimalProps);
                        save(spherePropsFileName, 'individualSphereTable','-mat');
                    else
                        individualSphereTable = struct2table(optimalProps,'AsArray',true);
                        save(spherePropsFileName, 'individualSphereTable','-mat');
                    end

                    SphereStatsTOTAL = [];
                    optimalSphereStats = [];
                    propsTOTAL = [];
                    optimalProps = [];
                    pause(3);    
                end

                pause(1);

                uiwait(msgbox('Processing will stop and aggregate data will be updated.'));

                %%%%%%%%%%%%%%%%%%%
                %AGGREGATE
                %%%%%%%%%%%%%%%%%%%

                finalSearch = dir(fullfile([app.cultureLotPath '\*\*.xlsx']));
                finalSearch(contains(string({finalSearch.name})',"-Aggregated Data"),:) = []; %remove aggregated data files from search
                [finalDetectStore] = individualImageDataSearch(finalSearch, 1);
                %assignin('base','finalDetectStore',finalDetectStore);
                filesToAggregate = finalDetectStore.File';
                for x = 1:numel(filesToAggregate)
                    filename = filesToAggregate(x); 
                    %find files that are to be included in aggregate data
                    opts = detectImportOptions(filename,'NumHeaderLines',0); %reads first row
                    tempTable = readtable(filename, opts);
                    if x == 1
                        fullTable = tempTable; 
                    elseif x > 1
                        fullTable = [fullTable; tempTable];
                    end
                end
                %sort table by date
                fullTable.Date = datetime(fullTable.Date,'InputFormat',"ddMMMyyyy");
                fullTable = sortrows(fullTable,'Date');
                fullTable.Date = upper(string(fullTable.Date,"ddMMMyyyy"));
                %end sorting
                condYES = convertCharsToStrings(fullTable.includedInAggregate) == "YES";
                newFullTable = fullTable(condYES, :);
                assignin('base','newfulltable',newFullTable);
                %%%%%%%%%%%%%

                tableVessels_cell = unique(newFullTable.ProcessedFolder); %cell array with vessel letter
                tableVessels = string(tableVessels_cell);
                tableVessels = sort(tableVessels);
                for j = 1 : numel(tableVessels)
                        vesselTable = newFullTable(string(newFullTable.ProcessedFolder) == tableVessels(j),:);
                        tableDates_cell = unique(vesselTable.Date); %cell array
                        tableDates = string(tableDates_cell);
                        tableDatetimes = datetime(tableDates,'InputFormat','ddMMMyyyy');
                        tableDatetimes = sort(tableDatetimes);
                        tableDates = upper(string(tableDatetimes,'ddMMMyyyy'));
                        for k = 1 : numel(tableDates)
                            [aggregateDateStruct] = aggregateData_v2(vesselTable, tableDates(k), app.cultureLotPath);
                            %%%write aggregate excel file
                            newFolderPath = app.cultureLotPath;
                            newFolderName = char(tableVessels(j));
                            if isfolder([newFolderPath newFolderName]) == 0
                                mkdir(newFolderPath,newFolderName);
                            end
                            excelFileName = [char(tableVessels(j)) '-Aggregated Data'];
                            excelPathLocal = [newFolderPath '\' newFolderName '\' excelFileName '.xlsx'];
                            %excelPathLocal = [excelPathLocal{1:end}];
                            sphereTable = struct2table(aggregateDateStruct);
                            if isfile(excelPathLocal) == 1 && k > 1
                                writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                            else
                                writetable(sphereTable, excelPathLocal, 'WriteMode', 'overwrite');
                            end
                        end
                end
                

                tableDates_cell = unique(newFullTable.Date); %cell array
                tableDates = string(tableDates_cell);
                tableDatetimes = datetime(tableDates,'InputFormat','ddMMMyyyy');
                tableDatetimes = sort(tableDatetimes);
                tableDates = upper(string(tableDatetimes,'ddMMMyyyy'));
                
                for j = 1 : numel(tableDates)
                    [aggregateDateStruct] = aggregateData_v2(newFullTable, tableDates(j), app.cultureLotPath);
                    %%%write aggregate excel file
                    newFolderPath = app.saveDir;
                    newFolderName = app.cultureLot;
                    if isfolder([newFolderPath newFolderName]) == 0
                        mkdir(newFolderPath,newFolderName);
                    end
                    excelFileName = [app.cultureLot '-Aggregated Data'];
                    excelPathLocal = [newFolderPath '\' newFolderName '\' excelFileName '.xlsx'];
                    %excelPathLocal = [excelPathLocal{1:end}];
                    sphereTable = struct2table(aggregateDateStruct);
                    if isfile(excelPathLocal) == 1 && j > 1
                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                    else
                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'overwrite');
                    end
                end

                msg = 'Done! Continue to next batch?';
                banner = 'Export is Complete';
                selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner, ...
                'Options', {'YES','Return Home'});  
                switch selection
                    case 'YES'
                        %continue
                    case 'Return Home'
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% need to reset processed files!
                        exit = "YES";
                        break;  %get outta here
                end
            end

            if i == length(batchIDs)
                processMessage = sprintf(['No files remain in %s.\n',...
                        'Returning home.'],app.LotEditField.Value);
                uiwait(msgbox(processMessage));
                exit = "YES";
            end
            if exit == "YES"
                cla(app.UIAxes4,'reset');
                cla(app.UIAxes5,'reset');
                app.UIAxes5.Visible = 'off';
                app.UIAxes4.Visible = 'off';
    
                % %Populate Results Section
                % app.NumberofSpheresEditField.Value = ""; 
                % app.MaxDiameterEditField.Value = "";
                % app.MinDiameterEditField.Value = "";
                % app.AverageDiameterEditField.Value = "";
                % app.AverageDoCEditField.Value = "";
                % app.AverageSolidityEditField.Value = "";
                % app.AverageEccentricityEditField.Value = "";
                % app.muEditField.Value = "";
                % app.sigmaEditField.Value = "";
                % app.PolydispersityIndexEditField.Value = "";
                    %get outta here
                app.SphereImageAnalysisUIFigure.Visible = 'off';
                app.SphereImageAnalysisUIFigure.Visible = 'on';
                [app.toBeProcessed, app.fileTable] = fileListParse_v2(app.fileTable, ...
                            app.toBeProcessed,app.cultureLotPath);
                %reset all values on right panel as well
            end

        end

        % Button pushed function: ReprocessButton
        function ReprocessButtonPushed(app, event)
            
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            
        end

        % Close request function: SphereImageAnalysisUIFigure
        function SphereImageAnalysisUIFigureCloseRequest(app, event)
            if app.keepLockFile == 1
                delete(app)
            else
                delete(app.lockFileName); %removes file if operator closes app
                delete(app)  
            end
        end

        % Button pushed function: SemanticSegStartButton
        function SemanticSegStartButtonPushed(app, event)
            [fileTable_batch,~] = batchProcess_v2(app.toBeProcessed, app.fileTable, app.cultureLot, app.cultureLotPath);
            assignin('base','fileTable_batch',fileTable_batch);

            batchIDs = unique(fileTable_batch.batchID); %sending batchIDs through semantic seg 
                                                        %They will also
                                                        %have a light/no
                                                        %light filter :)
            app.ProgressBar.Indeterminate = "on";
            %app.ProgressBar.startProgress("Process starting...")   
            app.ProgressBar.ShowCancel = "on";
            app.ProgressBar_2.Indeterminate = "on";
            app.ProgressBar_2.startProgress("Process starting...")
            app.ProgressBar_2.ShowCancel = "on";
            % tStart_total = tic; 
            for i = 1 : numel(batchIDs)

                app.ProgressBar.startProgress("Process starting...")

                if app.stopFlagBatch == true
                    cla(app.UIAxes4,'reset');
                    cla(app.UIAxes5,'reset');
                    app.UIAxes5.Visible = 'off';
                    app.UIAxes4.Visible = 'off';
                    app.SphereImageAnalysisUIFigure.Visible = 'off';
                    app.SphereImageAnalysisUIFigure.Visible = 'on';
                    %reset all values on right panel as well
                    msg = 'Batch cancelled. Select IMPORT before processing again.';
                    uiwait(warndlg(msg));
                    exit = "YES";
                    break; 
                end
                batchGroup = fileTable_batch(fileTable_batch.batchID == batchIDs(i),:);
                assignin('base','batchGroup',batchGroup);
                % batchDatastore = imageDatastore(batchGroup.filePath);
                tStart_batch = tic; 

                for m = 1:numel(batchGroup.filePath)

                    if app.stopFlagImage == true
                        cla(app.UIAxes4,'reset');
                        cla(app.UIAxes5,'reset');
                        app.UIAxes5.Visible = 'off';
                        app.UIAxes4.Visible = 'off';
                        app.SphereImageAnalysisUIFigure.Visible = 'off';
                        app.SphereImageAnalysisUIFigure.Visible = 'on';
                        %reset all values on right panel as well
                        msg = 'Image cancelled. Select IMPORT before processing again.';
                        uiwait(warndlg(msg));
                        exit = "YES";
                        break; 
                    end
                    tStart_image = tic;   
                    app.FileEditField.Value = batchGroup.fileRoot(m);
                    app.DateEditField_Status.Value = batchGroup.fileDate(m);
                    app.fullFileNameGlobal = batchGroup.filePath(m);
                    
                    originalBatchImage = imread(batchGroup.filePath(m)); 

                     %%% Assess image size
                    imageSize = imfinfo(app.fullFileNameGlobal);
                    imageWidth = imageSize.Width;
                    imageHeight = imageSize.Height; 

                    %%%% ADD distance per pixel
                    if imageWidth == 2592
                        app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                    elseif imageWidth == 1024
                        app.distancePerPixel = 2.7037; %scale bar of 4X image, small
                    else
                        app.distancePerPixel = 1.0920; %scale bar of 4X image, large/normal
                    end

                    newBatchImage = imresize(originalBatchImage,app.targetSize(1:end-1)); %target size determined by DLNN input %[height width]
                    %this will be a a data point taken from the corresponding "stats"
                    %file in the DLNN repository
                    if i == 1 && m == 1
                        app.UIAxes5.Visible = 'on';
                        app.UIAxes4.Visible = 'on';
                    end
                    cla(app.UIAxes5,'reset');
                    cla(app.UIAxes4,'reset');
                    imshow(originalBatchImage,'Parent',app.UIAxes5);
                    imshow(originalBatchImage,'Parent',app.UIAxes4);
                    title(app.UIAxes5,convertStringsToChars(batchGroup.fileRoot(m)));
                    pause(1); 
                    
                    vesselString = batchGroup.vesselID(m);

                    if batchGroup.DLimageProp(m) == "LIGHT"

                        semSeg_image = semanticseg(newBatchImage, app.dlnn_light);
                        BW = semSeg_image == 'Sphere'; %filter labels for spheres
                        cla(app.UIAxes5,'reset');
                        imshow(BW,'Parent',app.UIAxes5);
                        pause(1);
                        imshow(labeloverlay(newBatchImage,semSeg_image,'Transparency',0.4),'Parent',app.UIAxes5);
                        autoStore = app.dlnn_light_selected;
                        finalBatchImage = imresize(BW,[imageHeight imageWidth]); %return to original size for analysis

                    elseif batchGroup.DLimageProp(m) == "NO LIGHT"
                        
                        semSeg_image = semanticseg(newBatchImage, app.dlnn_nolight);
                        BW = semSeg_image == 'Sphere'; %filter labels for spheres

                        % ADD DEBRIS? Outfit without debris but include
                        % functionality if proper dlnn is employed
                        cla(app.UIAxes5,'reset');
                        imshow(BW,'Parent',app.UIAxes5);
                        pause(1);
                        imshow(labeloverlay(newBatchImage,semSeg_image,'Transparency',0.4),'Parent',app.UIAxes5);
                        autoStore = app.dlnn_light_selected;
                        finalBatchImage = imresize(BW,[imageHeight imageWidth]); %return to original size for analysis

                    end
                    
                    %watershed processing
                    app.D = -bwdist(~finalBatchImage);
                    app.Ld = watershed(app.D);
                    app.bw2 = finalBatchImage;
                    app.bw2(app.Ld == 0) = 0; 
                    app.mask = imextendedmin(app.D, 4); 
                    app.D2 = imimposemin(app.D,app.mask);
                    app.Ld2 = watershed(app.D2); 
                    app.bw3 = finalBatchImage; 
                    app.bw3(app.Ld2 == 0) = 0;

                    app.SphereStats.Name = batchGroup.fileRoot(m);
                    app.SphereStats.VesselID = vesselString; 
                    app.SphereStats.Date =  batchGroup.fileDate(m); 
                    [app.SphereStats,autoStore,~,app.newProps] = imagePropertyAnalysis(app.bw3,rgb2gray(originalBatchImage),autoStore,...
                        0, app.SphereStats, app.distancePerPixel,1);

                   %circularity thresholding (either dilate image or
                   %comment on circularity
                    app.SphereStats.ImageProperty = batchGroup.DLimageProp(m);
                    app.SphereStats.ImageSize = [num2str(imageWidth) ' x ' num2str(imageHeight)];
                    if app.SphereStats.count <= 1
                        app.SphereStats.includedInAggregate = "NO";
                    else
                        app.SphereStats.includedInAggregate = "YES";
                    end
                    app.SphereStats.AutoProcess = autoStore;
                    app.SphereStats.FilePath = app.fullFileNameGlobal;
                    app.SphereStats.ProcessedFileName = strtrim(batchGroup.fileRoot(m));  %remove whitespace
                    app.SphereStats.ProcessedFolder = batchGroup.vesselLetterStore(m);

                    %now write the file!
                    folderPath = [app.saveDir '\' app.cultureLot];
                    folderName = convertStringsToChars(batchGroup.vesselLetterStore(m));
                    if isfolder(folderPath) == 0
                        mkdir(folderPath,folderName);
                    end
                    newFolderPath = [folderPath '\' folderName];
                    %newFolderName = 'Individual Image Data';
                    if isfolder(newFolderPath) == 0
                        mkdir(folderPath,folderName);
                    end
                    %get vessel letter;
                    excelFileName = convertStringsToChars(batchGroup.vesselLetterStore(m));
                    excelPathLocal = [folderPath '\' folderName '\' excelFileName '.xlsx'];
                    %add operator ID
                    currentDate = string(datetime('now','Format','d-MMM-y HH:mm:ss'),"ddMMMyyyy HH:mm:ss"); 
                    nameAndDate = [app.operatorInitials currentDate];
                    app.operatorID = strjoin(nameAndDate,' ');
                    app.SphereStats.Operator = app.operatorID;

                    %save individual sphere data as .mat file in same folder
                    splitFileName = batchGroup.fileRoot(m); 
                    splitFileName = convertStringsToChars(strtrim(splitFileName));
                    spherePropsFileName = [folderPath '\' folderName '\' splitFileName '.mat']; 
                    count = 0; 
                    if isfile(spherePropsFileName)
                        while isfile(spherePropsFileName)
                            count = count + 1; 
                            splitFileName_new = [splitFileName ' (' num2str(count) ')'];
                            app.SphereStats.ProcessedFileName = strtrim(string(splitFileName_new));
                            spherePropsFileName = [folderPath '\' folderName '\' splitFileName_new '.mat'];
                        end
                    end
                    disp(spherePropsFileName);

                    try
                        sphereTable = struct2table(app.SphereStats);
                    catch
                        sphereTable = struct2table(app.SphereStats,"AsArray",true);
                    end

                    %%% SORTING %%%
                    sphereTable.Date = datetime(sphereTable.Date,'InputFormat',"ddMMMyyyy");
                    sphereTable = sortrows(sphereTable,'Date');
                    sphereTable.Date = string(sphereTable.Date,"ddMMMyyyy");
                    %%% END SORTING %%%

                    if isfile(excelPathLocal) == 1                     
                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                    else
                        writetable(sphereTable, excelPathLocal);
                    end

                    %%%
                    if numel(app.newProps) > 1
                        individualSphereTable = struct2table(app.newProps);
                        save(spherePropsFileName, 'individualSphereTable','-mat');
                    else
                        individualSphereTable = struct2table(app.newProps,'AsArray',true);
                        save(spherePropsFileName, 'individualSphereTable','-mat');
                    end

                    pause(1);
                    tEnd_image = toc(tStart_image);
                    if m == 1
                        app.ProgressBar.Indeterminate = "off";
                        app.ProgressBar.setProgress((m/numel(batchGroup.filePath)),"Process running...");
                        pause(1)
                        app.ProgressBar.ShowTimeRemaining = "on";
                        % remTime = seconds((numel(batchGroup.filePath)-m)*tEnd_image);
                        % remTime.Format = 'mm:ss.SS';
                        % app.ProgressBar.RemainingTime = remTime; 
                    elseif m > 1 && m < numel(batchGroup.filePath)
                        app.ProgressBar.setProgress((m/numel(batchGroup.filePath)),"Process running...");
                        pause(1)
                        % remTime = seconds((numel(batchGroup.filePath)-m)*tEnd_image);
                        % remTime.Format = 'mm:ss.SS';
                        % app.ProgressBar.RemainingTime = remTime; 
                    elseif m == numel(batchGroup.filePath)
                        app.ProgressBar.finishProgress("Batch finished!");
                        app.ProgressBar.Indeterminate = "on";
                        app.ProgressBar.ShowTimeRemaining = "off";
                    end
                end

                pause(1);
                
                %%%%%%%%%%%%%%%%%%%
                %AGGREGATE
                %%%%%%%%%%%%%%%%%%%

                finalSearch = dir(fullfile([app.cultureLotPath '\*\*.xlsx']));
                finalSearch(contains(string({finalSearch.name})',"-Aggregated Data"),:) = []; %remove aggregated data files from search
                [finalDetectStore] = individualImageDataSearch(finalSearch, 1);
                %assignin('base','finalDetectStore',finalDetectStore);
                filesToAggregate = finalDetectStore.File';
         
                for x = 1:numel(filesToAggregate)
                    filename = filesToAggregate(x); 
                    % PERFORM FINAL CHECK OF VALID DATA BY RUNNING
                    % isoutliers by date
                    [fullTable_mod] = outlierCheck(filename);
                    %assignin('base',['fullTable' num2str(x)],fullTable_mod);
                    %find files that are to be included in aggregate data
                    opts = detectImportOptions(filename,'NumHeaderLines',0); %reads first row
                    tempTable = readtable(filename, opts);
                    if x == 1
                        fullTable = tempTable; 
                    elseif x > 1
                        fullTable = [fullTable; tempTable];
                    end
                end
                %sort table by date
                fullTable.Date = datetime(fullTable.Date,'InputFormat',"ddMMMyyyy");
                fullTable = sortrows(fullTable,'Date');
                fullTable.Date = upper(string(fullTable.Date,"ddMMMyyyy"));
                %end sorting
                condYES = convertCharsToStrings(fullTable.includedInAggregate) == "YES";
                %%%%%
                condNO = convertCharsToStrings(fullTable.includedInAggregate) == "NO";
                %%%%%
                newFullTable = fullTable(condYES, :);
                assignin('base','newfulltable',newFullTable);
                %%%%%%%%%%%%%

                tableVessels_cell = unique(newFullTable.ProcessedFolder); %cell array with vessel letter
                tableVessels = string(tableVessels_cell);
                tableVessels = sort(tableVessels);
                for j = 1 : numel(tableVessels)
                        vesselTable = newFullTable(string(newFullTable.ProcessedFolder) == tableVessels(j),:);
                        tableDates_cell = unique(vesselTable.Date); %cell array
                        tableDates = string(tableDates_cell);
                        tableDatetimes = datetime(tableDates,'InputFormat','ddMMMyyyy');
                        tableDatetimes = sort(tableDatetimes);
                        tableDates = upper(string(tableDatetimes,'ddMMMyyyy'));
                        for k = 1 : numel(tableDates)
                            [aggregateDateStruct] = aggregateData_v2(vesselTable, tableDates(k), app.cultureLotPath);
                            %%%write aggregate excel file
                            newFolderPath = app.cultureLotPath;
                            newFolderName = char(tableVessels(j));
                            if isfolder([newFolderPath '\' newFolderName]) == 0
                                mkdir(newFolderPath,newFolderName);
                            end
                            excelFileName = [char(tableVessels(j)) '-Aggregated Data'];
                            excelPathLocal = [newFolderPath '\' newFolderName '\' excelFileName '.xlsx'];
                            %excelPathLocal = [excelPathLocal{1:end}];
                            sphereTable = struct2table(aggregateDateStruct);
                            if isfile(excelPathLocal) == 1 && k > 1
                                writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                            else
                                writetable(sphereTable, excelPathLocal, 'WriteMode', 'overwrite');
                            end
                        end
                end

                tableDates_cell = unique(newFullTable.Date); %cell array
                tableDates = string(tableDates_cell);
                tableDatetimes = datetime(tableDates,'InputFormat','ddMMMyyyy');
                tableDatetimes = sort(tableDatetimes);
                tableDates = upper(string(tableDatetimes,'ddMMMyyyy'));

                for j = 1 : numel(tableDates)
                    [aggregateDateStruct] = aggregateData_v2(newFullTable, tableDates(j), app.cultureLotPath);
                    %%%write aggregate excel file
                    newFolderPath = app.saveDir;
                    newFolderName = app.cultureLot;
                    if isfolder([newFolderPath '\' newFolderName]) == 0
                        mkdir(newFolderPath,newFolderName);
                    end
                    excelFileName = [app.cultureLot '-Aggregated Data'];
                    excelPathLocal = [newFolderPath '\' newFolderName '\' excelFileName '.xlsx'];
                    %excelPathLocal = [excelPathLocal{1:end}];
                    sphereTable = struct2table(aggregateDateStruct);
                    if isfile(excelPathLocal) == 1 && j > 1
                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'Append','WriteVariableNames',false);
                    else
                        writetable(sphereTable, excelPathLocal, 'WriteMode', 'overwrite');
                    end
                end

                % msg = 'Done! Continue to next batch?';
                % banner = 'Export is Complete';
                % selection = uiconfirm(app.SphereImageAnalysisUIFigure, msg, banner, ...
                % 'Options', {'YES','Return Home'});  
                % switch selection
                %     case 'YES'
                %         %continue
                %     case 'Return Home'
                %         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% need to reset processed files!
                %         exit = "YES";
                %         break;  %get outta here
                % end
                disp('Export complete. Advancing to next batch.');
                tEnd_batch = toc(tStart_batch);
                if i == 1
                    app.ProgressBar_2.Indeterminate = "off";
                    app.ProgressBar_2.setProgress((i/numel(batchIDs)),"Process running...");
                    app.ProgressBar_2.ShowTimeRemaining = "on";
                    pause(1)
                    % remTime = seconds((numel(batchIDs)-i)*tEnd_batch);
                    % remTime.Format = 'mm:ss.SS';
                    % app.ProgressBar_2.RemainingTime =  remTime; 
                elseif i > 1 && i < numel(batchIDs)
                    app.ProgressBar_2.setProgress((i/numel(batchIDs)),"Process running...");
                    pause(1)
                    % remTime = seconds((numel(batchIDs)-i)*tEnd_batch);
                    % remTime.Format = 'mm:ss.SS';
                    % app.ProgressBar_2.RemainingTime =  remTime; 
                elseif i == numel(batchIDs)
                    app.ProgressBar_2.finishProgress("Batch finished!");
                    app.ProgressBar_2.Indeterminate = "on";
                    app.ProgressBar_2.ShowTimeRemaining = "off";
                end
            end
        
            if i == length(batchIDs)
                processMessage = sprintf(['No files remain in %s.\n',...
                        'Returning home.'],app.LotEditField.Value);
                uiwait(msgbox(processMessage));
                exit = "YES";
            end
            if exit == "YES"
                cla(app.UIAxes4,'reset');
                cla(app.UIAxes5,'reset');
                app.UIAxes5.Visible = 'off';
                app.UIAxes4.Visible = 'off';

                % %Populate Results Section
                % app.NumberofSpheresEditField.Value = ""; 
                % app.MaxDiameterEditField.Value = "";
                % app.MinDiameterEditField.Value = "";
                % app.AverageDiameterEditField.Value = "";
                % app.AverageDoCEditField.Value = "";
                % app.AverageSolidityEditField.Value = "";
                % app.AverageEccentricityEditField.Value = "";
                % app.muEditField.Value = "";
                % app.sigmaEditField.Value = "";
                % app.PolydispersityIndexEditField.Value = "";
                    %get outta here
                app.SphereImageAnalysisUIFigure.Visible = 'off';
                app.SphereImageAnalysisUIFigure.Visible = 'on';
                %reset all values on right panel as well

                [app.toBeProcessed, app.fileTable] = fileListParse_v2(app.fileTable, ...
                            app.toBeProcessed,app.cultureLotPath);
                app.stopFlagBatch = false; 
                app.stopFlagImage = false; 
            end

                   
        end

        % Value changed function: LIGHTDropDown
        function LIGHTDropDownValueChanged(app, event)
            value = app.LIGHTDropDown.Value;
            app.dlnn_light_selected = value; %string
            disp([char(app.dlnn_light_selected) ' selected as LIGHT DLNN']);
            selectedStruct = app.dlnn_all(contains(string({app.dlnn_all.name}), app.dlnn_light_selected),:);
            if isfile([selectedStruct.folder '\' selectedStruct.name]) 
                A = load([selectedStruct.folder '\' selectedStruct.name]);
                app.dlnn_light = A.trainedNetwork_1; %default annotation after exporting trained network
                assignin('base',"A",A);
                app.inputSize_LIGHT = app.dlnn_light.Layers(1,1).InputSize; 
            else
                app.dlnn_light_selected = []; 
                app.inputSize_LIGHT = []; 
                message = 'Network not loaded. Please select DLNN from menu.'; 
                uiwait(warndlg(message));
            end            
            if ~isempty(app.dlnn_light_selected) && ~isempty(app.dlnn_nolight_selected) && isequal(app.inputSize_LIGHT,app.inputSize_NOLIGHT)
                app.SemanticSegStartButton.Enable = 'on';
                app.LIGHTDropDown.BackgroundColor = [0.39, 0.83, 0.07];
                app.targetSize = app.inputSize_NOLIGHT;
            elseif ~isempty(app.dlnn_light_selected) && ~isempty(app.dlnn_nolight_selected) && ~isequal(app.inputSize_LIGHT,app.inputSize_NOLIGHT)
                app.SemanticSegStartButton.Enable = 'off';
                app.LIGHTDropDown.BackgroundColor = [0.92, 0.20, 0.20];
                message = sprintf(['inputSize_LIGHT: %s /n', 'inputSize_NOLIGHT: %s /n','Do not match. Please try again.'],...
                    num2str(app.inputSize_LIGHT), num2str(app.inputSize_NOLIGHT)); 
                uiwait(warndlg(message));
            elseif isempty(app.dlnn_light_selected) || isempty(app.dlnn_nolight_selected)
                app.SemanticSegStartButton.Enable = 'off';
            end
            %do something with network stats here?
            %address network image size differences if applicable
        end

        % Value changed function: NOLIGHTDropDown
        function NOLIGHTDropDownValueChanged(app, event)
            value = app.NOLIGHTDropDown.Value;
            app.dlnn_nolight_selected = value; %string
            disp([char(app.dlnn_nolight_selected) ' selected as NO LIGHT DLNN']);
            selectedStruct = app.dlnn_all(contains(string({app.dlnn_all.name}), app.dlnn_nolight_selected),:);
            if isfile([selectedStruct.folder '\' selectedStruct.name])
                B = load([selectedStruct.folder '\' selectedStruct.name]); 
                app.dlnn_nolight = B.trainedNetwork_1; %default annotation after exporting trained network
                assignin('base',"B",B);
                app.inputSize_NOLIGHT = app.dlnn_nolight.Layers(1,1).InputSize; 
            else
                app.dlnn_nolight_selected = [];
                app.inputSize_NOLIGHT = [];
                message = 'Network not loaded. Please select DLNN from menu.'; 
                uiwait(warndlg(message));
            end
            if ~isempty(app.dlnn_light_selected) && ~isempty(app.dlnn_nolight_selected) && isequal(app.inputSize_LIGHT,app.inputSize_NOLIGHT)
                app.SemanticSegStartButton.Enable = 'on';
                app.NOLIGHTDropDown.BackgroundColor = [0.39, 0.83, 0.07];
                app.targetSize = app.inputSize_NOLIGHT;
            elseif ~isempty(app.dlnn_light_selected) && ~isempty(app.dlnn_nolight_selected) && ~isequal(app.inputSize_LIGHT,app.inputSize_NOLIGHT)
                app.SemanticSegStartButton.Enable = 'off';
                app.NOLIGHTDropDown.BackgroundColor = [0.92, 0.20, 0.20];
                message = sprintf(['inputSize_LIGHT: (%s) \n', 'inputSize_NOLIGHT: (%s) \n','Do not match. Please try again.'],...
                    num2str(app.inputSize_LIGHT(1:end-1)), num2str(app.inputSize_NOLIGHT(1:end-1))); 
                uiwait(warndlg(message));
            elseif isempty(app.dlnn_light_selected) || isempty(app.dlnn_nolight_selected)
                app.SemanticSegStartButton.Enable = 'off';
            end
        end

        % Callback function: ProgressBar_2
        function ProgressBar_2CancelPressed(app, event)
            app.stopFlagBatch = true; 
        end

        % Callback function: ProgressBar
        function ProgressBarCancelPressed(app, event)
            app.stopFlagImage = true; 
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %change save directory
            [folder] = uigetdir();
            app.saveDir = [folder '\Processed Images'];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SphereImageAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {678, 678, 678};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {678, 678};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {292, '1x', 250};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SphereImageAnalysisUIFigure = uifigure('Visible', 'off');
            app.SphereImageAnalysisUIFigure.Name = 'Sphere Image Analysis';
            app.SphereImageAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @SphereImageAnalysisUIFigureCloseRequest, true);
            app.SphereImageAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SphereImageAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SetupPanel = uipanel(app.LeftPanel);
            app.SetupPanel.Title = 'Setup';
            app.ImportButton = uibutton(app.SetupPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Icon = fullfile(pathToMLAPP, 'icons8-folder-375.png');
            app.ImportButton.Enable = 'off';
            app.ImportButton.Tooltip = {'Import Images from Collins NAS'};
            app.ImportButton.Text = 'Import';
            app.PROCESSSPECIFICITYLabel = uilabel(app.SetupPanel);
            app.PROCESSSPECIFICITYLabel.Enable = 'off';
            app.PROCESSSPECIFICITYLabel.Text = 'PROCESS SPECIFICITY';
            app.LotEditFieldLabel = uilabel(app.SetupPanel);
            app.LotEditFieldLabel.Enable = 'off';
            app.LotEditFieldLabel.Text = 'Lot';
            app.LotEditField = uieditfield(app.SetupPanel, 'text');
            app.LotEditField.Editable = 'off';
            app.LotEditField.Enable = 'off';
            app.Label = uilabel(app.SetupPanel);
            app.Label.Text = '--------------------------------------------------------';
            app.SemanticSegButton = uibutton(app.SetupPanel, 'push');
            app.SemanticSegButton.ButtonPushedFcn = createCallbackFcn(app, @SemanticSegButtonPushed, true);
            app.SemanticSegButton.Icon = fullfile(pathToMLAPP, 'icon-mw-neural-net.png');
            app.SemanticSegButton.Enable = 'off';
            app.SemanticSegButton.Text = 'Semantic Seg';
            app.SELECTMODESTARTLabel = uilabel(app.SetupPanel);
            app.SELECTMODESTARTLabel.Enable = 'off';
            app.SELECTMODESTARTLabel.Text = 'SELECT MODE + START';
            app.GuidedSegButton = uibutton(app.SetupPanel, 'push');
            app.GuidedSegButton.ButtonPushedFcn = createCallbackFcn(app, @GuidedSegButtonPushed, true);
            app.GuidedSegButton.Icon = fullfile(pathToMLAPP, 'icons8-handshake-100.png');
            app.GuidedSegButton.Enable = 'off';
            app.GuidedSegButton.Text = 'Guided Seg';
            app.ReprocessButton = uibutton(app.SetupPanel, 'push');
            app.ReprocessButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessButtonPushed, true);
            app.ReprocessButton.Icon = fullfile(pathToMLAPP, 'icons8-restart-250.png');
            app.ReprocessButton.Enable = 'off';
            app.ReprocessButton.Text = 'Re-process';
            app.VisualizeButton = uibutton(app.SetupPanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Icon = fullfile(pathToMLAPP, 'icons8-analysis-80.png');
            app.VisualizeButton.Enable = 'off';
            app.VisualizeButton.Text = 'Visualize';
            app.NumFilestoProcessEditFieldLabel = uilabel(app.SetupPanel);
            app.NumFilestoProcessEditFieldLabel.Enable = 'off';
            app.NumFilestoProcessEditFieldLabel.Text = 'Num Files to Process';
            app.NumFilestoProcessEditField = uieditfield(app.SetupPanel, 'text');
            app.NumFilestoProcessEditField.Editable = 'off';
            app.NumFilestoProcessEditField.Enable = 'off';
            app.Label_2 = uilabel(app.SetupPanel);
            app.Label_2.Text = '--------------------------------------------------------';
            app.Label_3 = uilabel(app.SetupPanel);
            app.LIGHTDropDownLabel = uilabel(app.SetupPanel);
            app.LIGHTDropDownLabel.Visible = 'off';
            app.LIGHTDropDownLabel.Text = 'LIGHT';
            app.LIGHTDropDown = uidropdown(app.SetupPanel);
            app.LIGHTDropDown.Items = {};
            app.LIGHTDropDown.ValueChangedFcn = createCallbackFcn(app, @LIGHTDropDownValueChanged, true);
            app.LIGHTDropDown.Visible = 'off';
            app.LIGHTDropDown.Tooltip = {'Green: Input Sizes Match'; 'Red: Input Sizes Do Not Match'};
            app.LIGHTDropDown.Value = {};
            app.SEMANTICSEGMENTATIONSETTINGSLabel = uilabel(app.SetupPanel);
            app.SEMANTICSEGMENTATIONSETTINGSLabel.Visible = 'off';
            app.SEMANTICSEGMENTATIONSETTINGSLabel.Text = 'SEMANTIC SEGMENTATION SETTINGS';
            app.NOLIGHTDropDownLabel = uilabel(app.SetupPanel);
            app.NOLIGHTDropDownLabel.Visible = 'off';
            app.NOLIGHTDropDownLabel.Text = 'NO LIGHT';
            app.NOLIGHTDropDown = uidropdown(app.SetupPanel);
            app.NOLIGHTDropDown.Items = {};
            app.NOLIGHTDropDown.ValueChangedFcn = createCallbackFcn(app, @NOLIGHTDropDownValueChanged, true);
            app.NOLIGHTDropDown.Visible = 'off';
            app.NOLIGHTDropDown.Tooltip = {'Green: Input Sizes Match'; 'Red: Input Sizes Do Not Match'};
            app.NOLIGHTDropDown.Value = {};
            app.SemanticSegStartButton = uibutton(app.SetupPanel, 'push');
            app.SemanticSegStartButton.ButtonPushedFcn = createCallbackFcn(app, @SemanticSegStartButtonPushed, true);
            app.SemanticSegStartButton.Enable = 'off';
            app.SemanticSegStartButton.Visible = 'off';
            app.SemanticSegStartButton.Text = 'Semantic Seg Start';
            app.SaveEditFieldLabel = uilabel(app.SetupPanel);
            app.SaveEditFieldLabel.Enable = 'off';
            app.SaveEditFieldLabel.Text = 'Save :';
            app.SaveEditField = uieditfield(app.SetupPanel, 'text');
            app.SaveEditField.Enable = 'off';
            app.Button = uibutton(app.SetupPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'icons8-menu-vertical-100.png');
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.StatusPanel = uipanel(app.LeftPanel);
            app.StatusPanel.Title = 'Status';
            app.ProgressBar = wt.ProgressBar(app.StatusPanel);
            app.ProgressBar.CancelPressedFcn = createCallbackFcn(app, @ProgressBarCancelPressed, true);
            app.ProgressBar.Visible = 'off';
            app.FileEditFieldLabel = uilabel(app.StatusPanel);
            app.FileEditFieldLabel.Enable = 'off';
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.StatusPanel, 'text');
            app.FileEditField.Editable = 'off';
            app.FileEditField.Enable = 'off';
            app.DateEditField_2Label = uilabel(app.StatusPanel);
            app.DateEditField_2Label.Enable = 'off';
            app.DateEditField_2Label.Text = 'Date';
            app.DateEditField_Status = uieditfield(app.StatusPanel, 'text');
            app.DateEditField_Status.Editable = 'off';
            app.DateEditField_Status.Enable = 'off';
            app.ImageStatusLabel = uilabel(app.StatusPanel);
            app.ImageStatusLabel.Visible = 'off';
            app.ImageStatusLabel.Text = 'Image Status';
            app.ProgressBar_2 = wt.ProgressBar(app.StatusPanel);
            app.ProgressBar_2.CancelPressedFcn = createCallbackFcn(app, @ProgressBar_2CancelPressed, true);
            app.ProgressBar_2.Visible = 'off';
            app.BatchStatusLabel = uilabel(app.StatusPanel);
            app.BatchStatusLabel.Visible = 'off';
            app.BatchStatusLabel.Text = 'Batch Status';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            app.UIAxes5 = uiaxes(app.GridLayout2);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.Visible = 'off';
            app.ResultsPanel = uipanel(app.GridLayout3);
            app.ResultsPanel.Title = 'Results';
            app.CaptureLabel = uilabel(app.ResultsPanel);
            app.CaptureLabel.Enable = 'off';
            app.CaptureLabel.Text = '---------------------Capture--------------------';
            app.NumberofSpheresEditFieldLabel = uilabel(app.ResultsPanel);
            app.NumberofSpheresEditFieldLabel.Enable = 'off';
            app.NumberofSpheresEditFieldLabel.Text = 'Number of Spheres';
            app.NumberofSpheresEditField = uieditfield(app.ResultsPanel, 'text');
            app.NumberofSpheresEditField.Enable = 'off';
            app.MaxDiameterEditFieldLabel = uilabel(app.ResultsPanel);
            app.MaxDiameterEditFieldLabel.Enable = 'off';
            app.MaxDiameterEditFieldLabel.Text = 'Max Diameter';
            app.MaxDiameterEditField = uieditfield(app.ResultsPanel, 'text');
            app.MaxDiameterEditField.Enable = 'off';
            app.MinDiameterEditFieldLabel = uilabel(app.ResultsPanel);
            app.MinDiameterEditFieldLabel.Enable = 'off';
            app.MinDiameterEditFieldLabel.Text = 'Min Diameter';
            app.MinDiameterEditField = uieditfield(app.ResultsPanel, 'text');
            app.MinDiameterEditField.Enable = 'off';
            app.AverageDiameterEditFieldLabel = uilabel(app.ResultsPanel);
            app.AverageDiameterEditFieldLabel.Enable = 'off';
            app.AverageDiameterEditFieldLabel.Text = 'Average Diameter';
            app.AverageDiameterEditField = uieditfield(app.ResultsPanel, 'text');
            app.AverageDiameterEditField.Enable = 'off';
            app.umLabel = uilabel(app.ResultsPanel);
            app.umLabel.Enable = 'off';
            app.umLabel.Text = 'um';
            app.umLabel_2 = uilabel(app.ResultsPanel);
            app.umLabel_2.Enable = 'off';
            app.umLabel_2.Text = 'um';
            app.umLabel_3 = uilabel(app.ResultsPanel);
            app.umLabel_3.Enable = 'off';
            app.umLabel_3.Text = 'um';
            app.PolydispersityIndexEditFieldLabel = uilabel(app.ResultsPanel);
            app.PolydispersityIndexEditFieldLabel.Enable = 'off';
            app.PolydispersityIndexEditFieldLabel.Text = 'Polydispersity Index';
            app.PolydispersityIndexEditField = uieditfield(app.ResultsPanel, 'text');
            app.PolydispersityIndexEditField.Enable = 'off';
            app.AverageEccentricityEditFieldLabel = uilabel(app.ResultsPanel);
            app.AverageEccentricityEditFieldLabel.Enable = 'off';
            app.AverageEccentricityEditFieldLabel.Text = 'Average Eccentricity';
            app.AverageEccentricityEditField = uieditfield(app.ResultsPanel, 'text');
            app.AverageEccentricityEditField.Enable = 'off';
            app.STDEditFieldLabel = uilabel(app.ResultsPanel);
            app.STDEditFieldLabel.Enable = 'off';
            app.STDEditFieldLabel.Text = 'STD';
            app.STDEditField = uieditfield(app.ResultsPanel, 'text');
            app.STDEditField.Enable = 'off';
            app.AverageDoCEditFieldLabel = uilabel(app.ResultsPanel);
            app.AverageDoCEditFieldLabel.Enable = 'off';
            app.AverageDoCEditFieldLabel.Text = 'Average DoC';
            app.AverageDoCEditField = uieditfield(app.ResultsPanel, 'text');
            app.AverageDoCEditField.Enable = 'off';
            app.AverageSolidityEditFieldLabel = uilabel(app.ResultsPanel);
            app.AverageSolidityEditFieldLabel.Enable = 'off';
            app.AverageSolidityEditFieldLabel.Text = 'Average Solidity';
            app.AverageSolidityEditField = uieditfield(app.ResultsPanel, 'text');
            app.AverageSolidityEditField.Enable = 'off';
            app.umLabel_4 = uilabel(app.ResultsPanel);
            app.umLabel_4.Enable = 'off';
            app.umLabel_4.Text = 'um';
            app.BatchLabel = uilabel(app.ResultsPanel);
            app.BatchLabel.Enable = 'off';
            app.BatchLabel.Text = '----------------------Batch---------------------';
            % Show the figure after all components are created
            app.SphereImageAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SphereImageAnalysis_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SphereImageAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SphereImageAnalysisUIFigure)
        end
    end
end