classdef KEGG_parser_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        File_t            matlab.ui.container.Menu
        kegg_update       matlab.ui.container.Menu
        Close_t           matlab.ui.container.Menu
        Help_t            matlab.ui.container.Menu
        Readme_t          matlab.ui.container.Menu
        del_uncon         matlab.ui.control.Button
        bid_flag          matlab.ui.control.CheckBox
        gn_flag           matlab.ui.control.CheckBox
        pcp_flag          matlab.ui.control.CheckBox
        text5             matlab.ui.control.Label
        text4             matlab.ui.control.Label
        text3             matlab.ui.control.Label
        text2             matlab.ui.control.Label
        edge_rev          matlab.ui.control.Button
        export_txt        matlab.ui.control.Button
        export_xml        matlab.ui.control.Button
        save_2col         matlab.ui.control.Button
        load_local_coll   matlab.ui.control.Button
        refresh_layout    matlab.ui.control.Button
        edge_col          matlab.ui.control.DropDown
        search_local_bg   matlab.ui.control.Button
        save              matlab.ui.control.Button
        edge_del          matlab.ui.control.Button
        edge_add          matlab.ui.control.Button
        node_del          matlab.ui.control.Button
        node_add          matlab.ui.control.Button
        address_bar       matlab.ui.control.EditField
        text1             matlab.ui.control.Label
        load_www          matlab.ui.control.Button
        search_local_xml  matlab.ui.control.Button
        node2             matlab.ui.control.DropDown
        node1             matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function KEGG_parser_OpeningFcn(app, varargin)
            % --- Executes just before KEGG_parser is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to KEGG_parser (see VARARGIN)
            
            % Choose default command line output for KEGG_parser
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Menu selected function: Close_t
        function Close_t_Callback(app, event)
            % ---Close Graph Window and GUI
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Close_t (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            usr = get(handles.figure1,'Userdata');
            if ~isempty(usr)
                try
                    close(get(usr.bh.hgAxes,'Parent'));
                end
            end
            clearvars -global kepp_path
            close(handles.figure1);
        end

        % Menu selected function: Readme_t
        function Readme_t_Callback(app, event)
            % ---Display Help
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Readme_t (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fid = fopen('README.txt');
            C = textscan(fid, '%s','Delimiter','\n');
            screen = get(0,'ScreenSize');
            msgh = figure('Visible', 'on','Units','pixels','position',...
                [100 50 screen(3)/2 screen(4)-100],'MenuBar', 'none',...
                'NumberTitle','off','Name','Help');
            h = uicontrol(msgh,'Style','Listbox','String',C{:},'Units','pixels',...
                'position', [10 10 screen(3)/2-25 screen(4)-125],'HorizontalAlignment','left');
            set(msgh,'Visible', 'on');
        end

        % Button pushed function: del_uncon
        function del_uncon_Callback(app, event)
            % --- Executes on button press in del_uncon.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to del_uncon (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            usr = get(handles.figure1,'Userdata');
            bg = usr.bg;
            cm = full(getmatrix(bg));
            horiz = find(sum(cm,2)==0); % terminal nodes
            vert = find(sum(cm,1)==0); % start nodes
            uncon = intersect(horiz, vert);
            for i = length(uncon):-1:1
            bg = node_del(bg,uncon(i));
            end
            par_pos = get(get(usr.bh.hgAxes,'Parent'),'Position');
            close(get(usr.bh.hgAxes,'Parent'));
            bh = view(bg);
            par = get(bh.hgAxes,'Parent');
            set(par,'Units','pixels')
            % pos = get(par,'Position');
            % pos(3) = pos(3)*0.75;
            % pos(4) = pos(3)*0.75;
            set(par,'Position',par_pos);
            usr.bg = bg;
            usr.bh = bh;
            set(handles.figure1,'Userdata',usr);
            set(handles.node1,'String',get(bg.Nodes,'ID'),...
                'Max',length(get(bg.Nodes,'ID')),'Value',1);
            set(handles.node2,'String',get(bg.Nodes,'ID'),...
                'Max',length(get(bg.Nodes,'ID')),'Value',1);
            figure(handles.figure1);
        end

        % Button pushed function: edge_add
        function edge_add_Callback(app, event)
            % --- adds edge between nodes specified by "Node 1" and "Node 2" selection boxes
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edge_add (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Source = get(handles.node1,'Value');
            Sink = get(handles.node2,'Value');
            usr = get(handles.figure1,'Userdata');
            edge_type_val = get(handles.edge_col,'Value');
            edge_type_def = get(handles.edge_col,'String');
            edge_type = char(edge_type_def(edge_type_val));
            try
            bg = usr.bg.deepCopy;
            catch
                bg = usr.bg;
            end
            bg = edge_add(bg,Source, Sink,edge_type);
            par_pos = get(get(usr.bh.hgAxes,'Parent'),'Position');
            close(get(usr.bh.hgAxes,'Parent'));
            dolayout(bg,'PathsOnly', true)
            bh = view(bg);
            par = get(bh.hgAxes,'Parent');
            set(par,'Units','pixels')
            % pos = get(par,'Position');
            % pos(3) = pos(3)*0.75;
            % pos(4) = pos(3)*0.75;
            set(par,'Position',par_pos);
            usr.bg = bg;
            usr.bh = bh;
            set(handles.figure1,'Userdata',usr);
            figure(handles.figure1);
        end

        % Button pushed function: edge_del
        function edge_del_Callback(app, event)
            % --- deletes edge between nodes specified by "Node 1" and "Node 2" selection boxes
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edge_del (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ind_1 = get(handles.node1,'Value');
            ind_2 = get(handles.node2,'Value');
            usr = get(handles.figure1,'Userdata');
            try
                bg = usr.bg.deepCopy;
                bg = edge_del(bg, ind_1, ind_2);
            catch
                bg = usr.bg;
                bg = edge_del(bg, ind_1, ind_2);
            end
            par_pos = get(get(usr.bh.hgAxes,'Parent'),'Position');
            close(get(usr.bh.hgAxes,'Parent'));
            bh = view(bg);
            par = get(bh.hgAxes,'Parent');
            set(par,'Units','pixels');
            % pos = get(par,'Position');
            % pos(3) = pos(3)*0.75;
            % pos(4) = pos(3)*0.75;
            set(par,'Position',par_pos);
            usr.bg = bg;
            usr.bh = bh;
            set(handles.figure1,'Userdata',usr);
            figure(handles.figure1);
        end

        % Button pushed function: edge_rev
        function edge_rev_Callback(app, event)
            % --- reverses edge direction between nodes specified by "Node 1" and "Node 2" selection boxes
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edge_rev (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Source = get(handles.node1,'Value');
            Sink = get(handles.node2,'Value');
            usr = get(handles.figure1,'Userdata');
            try
            bg = usr.bg.deepCopy;
            catch
             bg = usr.bg;
            end
            bg = edge_del(bg, Source, Sink);
            par_pos = get(get(usr.bh.hgAxes,'Parent'),'Position');
            close(get(usr.bh.hgAxes,'Parent'));
            edge_type_val = get(handles.edge_col,'Value');
            edge_type_def = get(handles.edge_col,'String');
            edge_type = char(edge_type_def(edge_type_val));
            bg = edge_add(bg.deepCopy,Sink, Source,edge_type);
            dolayout(bg,'PathsOnly', true)
            bh = view(bg);
            par = get(bh.hgAxes,'Parent');
            set(par,'Units','pixels')
            % pos = get(par,'Position');
            % pos(3) = pos(3)*0.75;
            % pos(4) = pos(3)*0.75;
            set(par,'Position',par_pos);
            usr.bg = bg;
            usr.bh = bh;
            set(handles.figure1,'Userdata',usr);
            figure(handles.figure1);
        end

        % Button pushed function: export_txt
        function export_txt_Callback(app, event)
            % --- Executes on button press in export_txt.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to export_txt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            disp('Here will be text export option for Cytoscape');
        end

        % Button pushed function: export_xml
        function export_xml_Callback(app, event)
            % --- Executes on button press in export_xml.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to export_xml (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            disp('Here will be export to xml');
        end

        % Menu selected function: kegg_update
        function kegg_update_Callback(app, event)
            % ---Batch pathway download and parsing for selected organism
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to kegg_update (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pcp = get(handles.pcp_flag,'Value');
            gn = get(handles.gn_flag,'Value');
            bid = get(handles.bid_flag,'Value');
            fix_flag = [pcp,gn,bid];
            kegg_batch(fix_flag);
        end

        % Button pushed function: load_local_coll
        function load_local_coll_Callback(app, event)
            % --- loads parsed KEGG pathway map from locally stored collection in MAT file
            % Collection is array of structures, each element represents separate
            % pathway
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to load_local_coll (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global kegg_path
            global full_path_col
            if isempty(kegg_path)
            [FileName,PathName] = uigetfile({'*.mat'},'File Selector');
            full_path_col = [PathName,FileName];
            set(handles.address_bar,'String',full_path_col);
            s = load(full_path_col);
            kegg_path = s.(char(fieldnames(s)));
            end
            defs = cellstr(strvcat(kegg_path(:).definition));
            defs = [{'New'};defs];
            [Selection,ok] = listdlg('ListString',defs);
            if isempty(Selection)
                return;
            elseif Selection ==1
                return; % maybe in future something else
            else
                disp('ok');
            end
            bg = kegg_path(Selection-1).bg.deepCopy;
            bh = view(bg);
            par = get(bh.hgAxes,'Parent');
            set(par,'Units','pixels')
            pos = get(par,'Position');
            pos(3) = pos(3)*0.75;
            pos(4) = pos(3)*0.75;
            set(par,'Position',pos)
            id = get(bg.Nodes,'ID');
            % set(handles.node1,'String',id,'Max', length(id));
            % set(handles.node2,'String',id,'Max', length(id));
            set(handles.node1,'String',id,'Max', length(id),'Value',1);
            set(handles.node2,'String',id,'Max', length(id),'Value',1);
            usr.bg = bg;
            usr.bh = bh;
            usr.h = [];
            set(handles.figure1,'Userdata',usr);
            figure(handles.figure1)
        end

        % Button pushed function: load_www
        function load_www_Callback(app, event)
            % --- downloads and parses KEGG pathway map specified in address bar
            %(requires pathway id, e.g. hsa04062)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to load_www (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            map_name = get(handles.address_bar,'string');
            map_name = strtrim(map_name);
            url = 'http://www.kegg.jp/kegg-bin/download?entry=xxx&format=kgml';
            full_path =  regexprep(url, 'xxx', map_name);
            pcp = get(handles.pcp_flag,'Value');
            gn = get(handles.gn_flag,'Value');
            bid = get(handles.bid_flag,'Value');
            fix_flag = [pcp,gn,bid];
            [bg, ~, ~] = parse_KEGG_xml(full_path,fix_flag);
            set(handles.address_bar,'String',full_path);
            setappdata(hObject,'bg',bg);
            bh = view(bg);
            par = get(bh.hgAxes,'Parent');
            set(par,'Units','pixels')
            pos = get(par,'Position');
            pos(3) = pos(3)*0.75;
            pos(4) = pos(3)*0.75;
            set(par,'Position',pos)
            id = get(bg.Nodes,'ID');
            set(handles.node1,'String',id,'Max', length(id), 'Value', 1);
            set(handles.node2,'String',id,'Max', length(id),'Value', 1);
            h = load_image(map_name);
            usr.bg = bg;
            usr.bh = bh;
            usr.h = h;
            set(handles.figure1,'Userdata',usr);
            figure(handles.figure1)
        end

        % Button pushed function: node_add
        function node_add_Callback(app, event)
            % --- adds new node to pathway graph object
            %(node properties can be edited with right-click->Node Properties)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to node_add (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            usr = get(handles.figure1,'Userdata');
            bh = usr.bh;
            bg = usr.bg;
            par = get(bh.hgAxes,'Parent');
            node_pos = get(par, 'CurrentPoint');
            node_size = [46.08, 17.28];
            UserData.List = [];
            UserData.Link = [];
            bg = node_add(bg, node_pos, node_size, UserData);
            par_pos = get(get(usr.bh.hgAxes,'Parent'),'Position');
            close(get(usr.bh.hgAxes,'Parent'));
            bh = view(bg);
            par = get(bh.hgAxes,'Parent');
            set(par,'Units','pixels');
            % pos = get(par,'Position');
            % pos(3) = pos(3)*0.75;
            % pos(4) = pos(3)*0.75;
            set(par,'Position',par_pos);
            id = get(bg.Nodes,'ID');
            set(handles.node1,'String',id,'Max', length(id));
            set(handles.node2,'String',id,'Max', length(id));
            usr.bg = bg;
            usr.bh = bh;
            set(handles.figure1,'Userdata',usr);
        end

        % Button pushed function: node_del
        function node_del_Callback(app, event)
            % --- deletes node specifird by "Node 1" selection box only
            %(also removes all edges to/from the node)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to node_del (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            NodeID = get(handles.node1,'Value');
            usr = get(handles.figure1,'Userdata');
            bg = usr.bg;
            bg = node_del(bg,NodeID);
            par_pos = get(get(usr.bh.hgAxes,'Parent'),'Position');
            close(get(usr.bh.hgAxes,'Parent'));
            bh = view(bg);
            par = get(bh.hgAxes,'Parent');
            set(par,'Units','pixels')
            % pos = get(par,'Position');
            % pos(3) = pos(3)*0.75;
            % pos(4) = pos(3)*0.75;
            set(par,'Position',par_pos);
            usr.bg = bg;
            usr.bh = bh;
            set(handles.figure1,'Userdata',usr);
            set(handles.node1,'String',get(bg.Nodes,'ID'),...
                'Max',length(get(bg.Nodes,'ID')),'Value',1);
            set(handles.node2,'String',get(bg.Nodes,'ID'),...
                'Max',length(get(bg.Nodes,'ID')),'Value',1);
            figure(handles.figure1);
        end

        % Button pushed function: refresh_layout
        function refresh_layout_Callback(app, event)
            % --- saves current graph layout, corrects edge positions bewteen nodes.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to refresh_layout (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            usr = get(handles.figure1,'Userdata');
            for i = 1:length(usr.bh.Nodes);
            %     if i == 64
                  %  disp('aaa');
                %end
                usr.bg.Nodes(i).Position = usr.bh.Nodes(i).Position;
                usr.bg.Nodes(i).Size = usr.bh.Nodes(i).Size;
                if ~strcmp(usr.bg.Nodes(i).Label,usr.bh.Nodes(i).Label)
                    usr.bg.Nodes(i).Label = usr.bh.Nodes(i).Label;
                end
                if ~strcmp(usr.bg.Nodes(i).Shape,usr.bh.Nodes(i).Shape)
                    usr.bg.Nodes(i).Shape = usr.bh.Nodes(i).Shape;
                end
                if ~strcmp(usr.bg.Nodes(i).ID,usr.bh.Nodes(i).ID)
                    usr.bg.Nodes(i).ID = usr.bh.Nodes(i).ID;
                end
            
            end
            bg = usr.bg.deepCopy;
            bh = usr.bh.deepCopy;
            dolayout(bg, 'Pathsonly', true);
            close(get(usr.bh.hgAxes,'Parent'));
            % dolayout(bh, 'Pathsonly', true);
            bh = view(bg);
            usr.bg = bg.deepCopy;
            usr.bh = bh;
            
            id = get(usr.bg.Nodes,'ID');
            set(handles.node1,'String',id,'Max', length(id));
            set(handles.node2,'String',id,'Max', length(id));
            set(handles.figure1,'Userdata',usr);
        end

        % Button pushed function: save_2col
        function save_2col_Callback(app, event)
            % --- replaces pathway graph object with new one in collection file
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to save_2col (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global kegg_path
            global full_path_col
            if isempty(kegg_path)
            if isempty(full_path_col)
            [FileName,PathName] = uigetfile({'*.mat'},'File Selector');
            full_path_col = [PathName,FileName];
            set(handles.address_bar,'String',full_path_col);
            s = load(full_path_col);
            kegg_path = s.(char(fieldnames(s)));
            end
            end
            defs = cellstr(strvcat(kegg_path(:).definition));
            defs = [{'New'};defs];
            [Selection,ok] = listdlg('ListString',defs);
            usr = get(handles.figure1,'Userdata');
            bg = usr.bg;
            if isempty(Selection)
                return;
            elseif Selection == 1
                kegg_path(end+1).entry_id = get(bg,'ID');
                kegg_path(end+1).definition = get(bg,'Label');
                kegg_path(end+1).bg = bg.deepCopy;
            else
                kegg_path(Selection-1).bg = bg.deepCopy;
            end
            disp('ok');
            if isempty(full_path_col)
                save kegg_path kegg_path;
            else
            save(full_path_col,'kegg_path');
            end
        end

        % Button pushed function: save
        function save_Callback(app, event)
            % ---  saves pathway graph object as single MAT file
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            usr = get(handles.figure1,'Userdata');
            bg = usr.bg;
            [filename, pathname] = uiputfile({'*.mat'},'Save as');
            save([pathname,filename],'bg');
        end

        % Button pushed function: search_local_bg
        function search_local_bg_Callback(app, event)
            % --- loads parsed KEGG pathway map from MAT file
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to search_local_bg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName,PathName] = uigetfile({'*.mat'},'File Selector');
            full_path = [PathName,FileName];
            if any(full_path)==0
                return;
            end
            set(handles.address_bar,'String',full_path);
            s = load(full_path);
            bg = s.(char(fieldnames(s)));
            bh = view(bg);
            par = get(bh.hgAxes,'Parent');
            set(par,'Units','pixels')
            pos = get(par,'Position');
            pos(3) = pos(3)*0.75;
            pos(4) = pos(3)*0.75;
            set(par,'Position',pos)
            id = get(bg.Nodes,'ID');
            set(handles.node1,'String',id,'Max', length(id));
            set(handles.node2,'String',id,'Max', length(id));
            usr.bg = bg;
            usr.bh = bh;
            usr.h = [];
            set(handles.figure1,'Userdata',usr);
            figure(handles.figure1)
        end

        % Button pushed function: search_local_xml
        function search_local_xml_Callback(app, event)
            % --- loads and parses KEGG pathway map from downloaded KGML file
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to search_local_xml (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName,PathName] = uigetfile({'*.xml'},'File Selector');
            full_path = [PathName,FileName];
            if any(full_path)==0
                return;
            end
            pcp = get(handles.pcp_flag,'Value');
            gn = get(handles.gn_flag,'Value');
            bid = get(handles.bid_flag,'Value');
            fix_flag = [pcp,gn,bid];
            assignin('base',"full_path",full_path);
            assignin('base',"fix_flag",fix_flag); 
            [bg, ~, ~] = parse_KEGG_xml(full_path, fix_flag);
            set(handles.address_bar,'String',full_path);
            bh = view(bg);
            path_name = get(bg,'ID');
            path_name = char(regexp(path_name,'hsa\d+','match'));
            if ~isempty(path_name)
            try
                h = load_image(path_name);
            catch
                disp('Check inet connection')
            end
            end
            par = get(bh.hgAxes,'Parent');
            set(par,'Units','pixels')
            pos = get(par,'Position');
            pos(3) = pos(3)*0.75;
            pos(4) = pos(3)*0.75;
            set(par,'Position',pos)
            id = get(bg.Nodes,'ID');
            set(handles.node1,'String',id,'Max', length(id));
            set(handles.node2,'String',id,'Max', length(id));
            usr.bg = bg;
            usr.bh = bh;
            %usr.h = h;
            set(handles.figure1,'Userdata',usr);
            figure(handles.figure1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'KEGG_parser';
            app.figure1.Tag = 'figure1';
            app.File_t = uimenu(app.figure1);
            app.File_t.Accelerator = 'F';
            app.File_t.Text = 'File';
            app.File_t.Tag = 'File_t';
            app.kegg_update = uimenu(app.File_t);
            app.kegg_update.MenuSelectedFcn = createCallbackFcn(app, @kegg_update_Callback, true);
            app.kegg_update.Separator = 'on';
            app.kegg_update.Accelerator = 'K';
            app.kegg_update.Text = 'Batch KEGG update';
            app.kegg_update.Tag = 'kegg_update';
            app.Close_t = uimenu(app.File_t);
            app.Close_t.MenuSelectedFcn = createCallbackFcn(app, @Close_t_Callback, true);
            app.Close_t.Accelerator = 'C';
            app.Close_t.Text = 'Close';
            app.Close_t.Tag = 'Close_t';
            app.Help_t = uimenu(app.figure1);
            app.Help_t.Text = 'Help';
            app.Help_t.Tag = 'Help_t';
            app.Readme_t = uimenu(app.Help_t);
            app.Readme_t.MenuSelectedFcn = createCallbackFcn(app, @Readme_t_Callback, true);
            app.Readme_t.Separator = 'on';
            app.Readme_t.Accelerator = 'R';
            app.Readme_t.Text = 'Readme';
            app.Readme_t.Tag = 'Readme_t';
            app.node1 = uidropdown(app.figure1);
            app.node1.Items = {'node1'};
            app.node1.Tag = 'node1';
            app.node1.Value = 'node1';
            app.node2 = uidropdown(app.figure1);
            app.node2.Items = {'node1'};
            app.node2.Tag = 'node2';
            app.node2.Value = 'node1';
            app.search_local_xml = uibutton(app.figure1, 'push');
            app.search_local_xml.ButtonPushedFcn = createCallbackFcn(app, @search_local_xml_Callback, true);
            app.search_local_xml.Tag = 'search_local_xml';
            app.search_local_xml.Text = 'Load local xml';
            app.load_www = uibutton(app.figure1, 'push');
            app.load_www.ButtonPushedFcn = createCallbackFcn(app, @load_www_Callback, true);
            app.load_www.Tag = 'load_www';
            app.load_www.Text = 'Load xml from KEGG';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Address';
            app.address_bar = uieditfield(app.figure1, 'text');
            app.address_bar.Tag = 'address_bar';
            app.node_add = uibutton(app.figure1, 'push');
            app.node_add.ButtonPushedFcn = createCallbackFcn(app, @node_add_Callback, true);
            app.node_add.Tag = 'node_add';
            app.node_add.Text = 'Add Node';
            app.node_del = uibutton(app.figure1, 'push');
            app.node_del.ButtonPushedFcn = createCallbackFcn(app, @node_del_Callback, true);
            app.node_del.Tag = 'node_del';
            app.node_del.Text = 'Delete Node';
            app.edge_add = uibutton(app.figure1, 'push');
            app.edge_add.ButtonPushedFcn = createCallbackFcn(app, @edge_add_Callback, true);
            app.edge_add.Tag = 'edge_add';
            app.edge_add.Text = 'Add Edge';
            app.edge_del = uibutton(app.figure1, 'push');
            app.edge_del.ButtonPushedFcn = createCallbackFcn(app, @edge_del_Callback, true);
            app.edge_del.Tag = 'edge_del';
            app.edge_del.Text = 'Delete Edge';
            app.save = uibutton(app.figure1, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @save_Callback, true);
            app.save.Tag = 'save';
            app.save.Text = 'Save';
            app.search_local_bg = uibutton(app.figure1, 'push');
            app.search_local_bg.ButtonPushedFcn = createCallbackFcn(app, @search_local_bg_Callback, true);
            app.search_local_bg.Tag = 'search_local_bg';
            app.search_local_bg.Text = 'Load local map';
            app.edge_col = uidropdown(app.figure1);
            app.edge_col.Items = {'activation', 'inhibition', 'binding'};
            app.edge_col.Tag = 'edge_col';
            app.edge_col.Value = 'activation';
            app.refresh_layout = uibutton(app.figure1, 'push');
            app.refresh_layout.ButtonPushedFcn = createCallbackFcn(app, @refresh_layout_Callback, true);
            app.refresh_layout.Tag = 'refresh_layout';
            app.refresh_layout.Text = 'Refresh Layout';
            app.load_local_coll = uibutton(app.figure1, 'push');
            app.load_local_coll.ButtonPushedFcn = createCallbackFcn(app, @load_local_coll_Callback, true);
            app.load_local_coll.Tag = 'load_local_coll';
            app.load_local_coll.Text = 'Load local collection';
            app.save_2col = uibutton(app.figure1, 'push');
            app.save_2col.ButtonPushedFcn = createCallbackFcn(app, @save_2col_Callback, true);
            app.save_2col.Tag = 'save_2col';
            app.save_2col.Text = 'Save 2 collection';
            app.export_xml = uibutton(app.figure1, 'push');
            app.export_xml.ButtonPushedFcn = createCallbackFcn(app, @export_xml_Callback, true);
            app.export_xml.Tag = 'export_xml';
            app.export_xml.Enable = 'off';
            app.export_xml.Text = 'Export xml';
            app.export_txt = uibutton(app.figure1, 'push');
            app.export_txt.ButtonPushedFcn = createCallbackFcn(app, @export_txt_Callback, true);
            app.export_txt.Tag = 'export_txt';
            app.export_txt.Enable = 'off';
            app.export_txt.Text = 'Export txt';
            app.edge_rev = uibutton(app.figure1, 'push');
            app.edge_rev.ButtonPushedFcn = createCallbackFcn(app, @edge_rev_Callback, true);
            app.edge_rev.Tag = 'edge_rev';
            app.edge_rev.Text = 'Reverse Edge';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Node 1';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Node 2';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Interaction type';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = {'Automatically fix:'; '(only xml files)'};
            app.pcp_flag = uicheckbox(app.figure1);
            app.pcp_flag.Tag = 'pcp_flag';
            app.pcp_flag.Text = 'Protein-compound-protein interactions';
            app.pcp_flag.Value = true;
            app.gn_flag = uicheckbox(app.figure1);
            app.gn_flag.Tag = 'gn_flag';
            app.gn_flag.Text = 'Group nodes';
            app.gn_flag.Value = true;
            app.bid_flag = uicheckbox(app.figure1);
            app.bid_flag.Tag = 'bid_flag';
            app.bid_flag.Text = 'Binding interaction directions';
            app.bid_flag.Value = true;
            app.del_uncon = uibutton(app.figure1, 'push');
            app.del_uncon.ButtonPushedFcn = createCallbackFcn(app, @del_uncon_Callback, true);
            app.del_uncon.Tag = 'del_uncon';
            app.del_uncon.Text = 'Delete Unconnected';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KEGG_parser_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)KEGG_parser_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end